classdef APD_restitution < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        APD_analysis_UIFigure           matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadfileMenu                    matlab.ui.container.Menu
        WorkspaceMenu                   matlab.ui.container.Menu
        SaveWorkspaceMenu               matlab.ui.container.Menu
        LoadWorkspaceMenu               matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        tool_tip                        matlab.ui.control.EditField
        TabGroup                        matlab.ui.container.TabGroup
        ViewframeandROIsTab             matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        panel_display_area              matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        SETCOLOURMAPSATURATIONPanel     matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        MaxColourmap                    matlab.ui.control.NumericEditField
        MaxLabel                        matlab.ui.control.Label
        LoadROIsignalButton             matlab.ui.control.Button
        load_ROI                        matlab.ui.control.Button
        save_ROI                        matlab.ui.control.Button
        DeleteallROIsButton             matlab.ui.control.Button
        ROIdropdown                     matlab.ui.control.DropDown
        deleteROI                       matlab.ui.control.Button
        ROI_type                        matlab.ui.control.DropDown
        select_ROI                      matlab.ui.control.Button
        SignalprocessingandAPDTab       matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        InvertsignalsButton             matlab.ui.control.StateButton
        ShowROIDropDown                 matlab.ui.control.DropDown
        ShowROIDropDownLabel            matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        LoadBioPACTab                   matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        ParametersdefinedButton         matlab.ui.control.Button
        ManuallydefineparametersButton  matlab.ui.control.Button
        PACINGPROTOCOLINFORMATIONPanel  matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        StimulusdurationmsEditField     matlab.ui.control.EditField
        StimulusdurationmsEditFieldLabel  matlab.ui.control.Label
        StimuliperstepEditField         matlab.ui.control.EditField
        StimuliperstepEditFieldLabel    matlab.ui.control.Label
        FrequencystepHzEditField        matlab.ui.control.EditField
        FrequencystepHzEditFieldLabel   matlab.ui.control.Label
        MaximumfrequencyHzEditField     matlab.ui.control.EditField
        MaximumfrequencyHzEditFieldLabel  matlab.ui.control.Label
        MinimumfrequencyHzEditField     matlab.ui.control.EditField
        MinimumfrequencyHzEditFieldLabel  matlab.ui.control.Label
        StimulusTypeEditField           matlab.ui.control.EditField
        StimulusTypeEditFieldLabel      matlab.ui.control.Label
        MOVEBIOPACSIGNALPanel           matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        MovedownButton                  matlab.ui.control.Button
        IncreaseinYaxisButton           matlab.ui.control.Button
        MoveupButton                    matlab.ui.control.Button
        ReduceinYaxisButton             matlab.ui.control.Button
        BioPACfileEditField             matlab.ui.control.EditField
        BioPACfileEditFieldLabel        matlab.ui.control.Label
        AutomaticSearchFolderEditField  matlab.ui.control.EditField
        AutomaticSearchFolderEditFieldLabel  matlab.ui.control.Label
        ShowBioPACDropDown              matlab.ui.control.DropDown
        SwitchBioPACShow_Hide           matlab.ui.control.Switch
        ManualfileselectionandimportButton  matlab.ui.control.Button
        ChangesearchfolderButton        matlab.ui.control.Button
        TemporalFilteringTab            matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        UndoButton                      matlab.ui.control.Button
        filterorder                     matlab.ui.control.NumericEditField
        FilterorderLabel                matlab.ui.control.Label
        cutofffrequency                 matlab.ui.control.NumericEditField
        CutofffrequencyHzLabel          matlab.ui.control.Label
        TemporalFilterType              matlab.ui.control.DropDown
        temporalfilter                  matlab.ui.control.Button
        APDParametersTab                matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        MinprominenceEditField          matlab.ui.control.NumericEditField
        MinprominenceEditFieldLabel     matlab.ui.control.Label
        ROITime                         matlab.ui.control.EditField
        TimesLabel                      matlab.ui.control.Label
        DeleteAPDsButton                matlab.ui.control.Button
        ROI_BCL                         matlab.ui.control.EditField
        BCLmsEditFieldLabel             matlab.ui.control.Label
        ROI_dVdt                        matlab.ui.control.EditField
        dVdtmaxaumsEditFieldLabel       matlab.ui.control.Label
        ROI_APD                         matlab.ui.control.EditField
        APDmsEditFieldLabel             matlab.ui.control.Label
        ComputeallAPDsButton            matlab.ui.control.Button
        rmpmethod_ROI                   matlab.ui.control.DropDown
        RMPcalculationDropDownLabel     matlab.ui.control.Label
        dfdt_maxpercentageEditField     matlab.ui.control.NumericEditField
        dfdt_maxpercentageEditFieldLabel  matlab.ui.control.Label
        APDpercentageEditField          matlab.ui.control.NumericEditField
        APDpercentageEditFieldLabel     matlab.ui.control.Label
        ROI_signal_UIAxes               matlab.ui.control.UIAxes
        APDrestitutioncurveTab          matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        Panel_3                         matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        EXPORTDATAPanel                 matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        ExportmeantraceButton           matlab.ui.control.Button
        FileformatDropDown              matlab.ui.control.DropDown
        FileformatDropDownLabel         matlab.ui.control.Label
        ChangeFolderButtonSaveto        matlab.ui.control.Button
        SavetoEditField                 matlab.ui.control.EditField
        SavetoEditFieldLabel            matlab.ui.control.Label
        ExportselectedAPtraceButton     matlab.ui.control.Button
        ExportfulltraceButton           matlab.ui.control.Button
        RESTITUTIONPARAMETERSPanel      matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        IgnorelossofcaptureLabel        matlab.ui.control.Label
        IgnorelossofcaptureSwitch       matlab.ui.control.Switch
        UselastEditField                matlab.ui.control.NumericEditField
        UselastLabel                    matlab.ui.control.Label
        NumberofAPtouseeachfromeachstepasaofthetotalAPinastepLabel  matlab.ui.control.Label
        GetdataButton                   matlab.ui.control.Button
        PLOTSRELATIVETOPACINGTIMESPanel  matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        ErrorbartypeDropDown            matlab.ui.control.DropDown
        ErrorbartypeDropDownLabel       matlab.ui.control.Label
        APDvsDImeananderrorButton       matlab.ui.control.Button
        APDvsfrequencymeananderrorButton  matlab.ui.control.Button
        APDvsBCLmeananderrorButton      matlab.ui.control.Button
        APDsequentialallAPButton        matlab.ui.control.Button
        restitution_curve_UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        main_display_axes %axes for the frame visualisation
        first_frame 
        sif_header
        total_recording_time
        old_file_info % Variable to save the current file info before opening the file selector which will erase the file info and we still wnt it in case the file selector window is closed without selecting
        file_info = struct('file','','path','','name','','type','','multipart',0) %file info of the selected file. It contains the name and the path of the file (app.file_info.name and app.file_info.path)
        figure_size
        plot_colours = [ 0    0.4470    0.7410;    0.8500    0.3250    0.0980;    0.9290    0.6940    0.1250;    0.4940    0.1840    0.5560;    0.4660    0.6740    0.1880;    0.3010    0.7450    0.9330;    0.6350    0.0780    0.1840];
        ROI_handle =[]
        ROI_signal
        ROI_time
        signal_plot_handle
        fps
        previous_ROI_signal
        channel_data
        BioPAC_file
        BioPAC_path_file
        BioPAC_plot_handle
        BioPAC_Yaxis_zoom_factor = 0.2;
        BioPAC_baseline = 0.8;
        min_freq
        max_freq
        num_steps
        frequencies
        stim_dur
        stim_bcl
        stim_freq
        freq_step
        stim_per_step
        stim_times
        APD_patches_handles
        APD_trace
        BCL_activation
        ACT
        meanAPD_stdAPD_freq_BCL_DI_trace
        APD_freq_BCL_DI_trace_valid %trace with only the last X selected AP
        APD_freq_BCL_DI_trace %trace with all AP delivered from a stimulus
        restitution_plot_handle
    

    end
    
    methods (Access = private)
        
        function select_file(app)
            if app.file_info.file ~= 0 %if there is a file name already loaded, save it in a variable in case the pop-up window is closed without selecting a file
                app.old_file_info = app.file_info;
            end
            
            [app.file_info.file , app.file_info.path] = uigetfile({'*.sif','Andor files (*.sif)';'*.dat','Photometrics files (*.dat)';'*.mat','MAT-Files (*.mat)';'*.tif; *.tiff','Tiff files (*.tif, *.tiff)';'*.fits','FITS file (*.fits)';'*.*','All Files (*.*)'},'File selector');
            [~, app.file_info.name, app.file_info.type] = fileparts(app.file_info.file); % Find file type
            figure(app.APD_analysis_UIFigure);
        end
        
        function file_name = file_exists(app,suffix,filetype)
            file_name = [app.SavetoEditField.Value  app.file_info.name '_' suffix filetype];% '_1' filetype];
            if exist(file_name, 'file')
                % Get number of files:
                file_list     = dir([app.SavetoEditField.Value  app.file_info.name '_' suffix '_*' filetype]);
                file_string     = lower(sprintf('%s ', file_list.name));
                new_num = length(file_list)+1;
%                 file_num     = sscanf(file_string, [[app.file_info.name '_' suffix '_'], '%d', filetype, '*']);
%                 new_num   = max(file_num) + 1;
    
    
                file_name = [app.SavetoEditField.Value app.file_info.name '_' suffix '_' num2str(new_num) filetype];
                %file_name = [app.file_info.path app.file_info.name '_' suffix '_' num2str(new_num) filetype];
            end
        end
        
        function update_ROI_dropdowns(app)
            % Update ROI dropdown
            num_ROI = num2cell(1:length(app.ROI_handle));
            app.ROIdropdown.Items = cellfun(@(x) sprintf('ROI %d',x),num_ROI,'UniformOutput',false);
            app.ROIdropdown.ItemsData = num_ROI;
            if length(app.ROI_handle) == 0
                app.ROIdropdown.Value = {};
            else
                app.ROIdropdown.Value = length(app.ROI_handle);
            end
            
            app.ShowROIDropDown.Items = cellfun(@(x) sprintf('ROI %d',x),num_ROI,'UniformOutput',false);
            app.ShowROIDropDown.ItemsData = num_ROI;
            if length(app.ROI_handle) == 0
                app.ShowROIDropDown.Value = {};
            else
                app.ShowROIDropDown.Value = length(app.ROI_handle);
            end
            
            
        end
        
        function invert_signal(app,k)
            
            progress_bar = uiprogressdlg(app.APD_analysis_UIFigure,'Title','Inverting ROI signal','Indeterminate','on');
            
            signal = app.ROI_signal{k};
            inverted_signal = max(signal(:)) - signal;
            app.ROI_signal{k} = inverted_signal;
            
            
            close(progress_bar)
            
        end
    
    
    function BioPAC_file = search_BioPAC_file(app)
            % BioPAC file finder
            OM_file = app.file_info.name; % 'XXh-YYm-ZZs'
            
            time_stamp = regexp(OM_file,'[\d\.]+','match');
            time_stamp = str2double(time_stamp); % [XX YY ZZ]
            % Convert timestamp to absolute time (seconds)
            OM_absolute_time = time_stamp(1)*3600 + time_stamp(2)*60 + time_stamp(3);
            
            file_list  = dir(strcat(app.AutomaticSearchFolderEditField.Value,'*T*.acq')); %find Acqknowledge files
            file_list = file_list(2:end); % not take into account the first file which is t
            num_files = length(file_list);
            
            list_absolute_times = zeros(num_files,1);
            
            for k=1:num_files
                % compute absolute time for each file
                time_stamp = regexp(file_list(k).name,'[\d*]+','match');
                time_stamp = str2double(time_stamp);
                time_stamp = time_stamp(end-2:end);
                list_absolute_times(k) = time_stamp(1)*3600 + time_stamp(2)*60 + time_stamp(3);
            end
            
            time_diff = list_absolute_times - OM_absolute_time;
            
            idx = find(time_diff < 0 );
            idx = idx(end); % Take the BioPAC recording that started before the OM recording (ideally that's the one)
            
            BioPAC_file = file_list(idx).name;
        end


        function load_BioPAC(app)
            file_name = app.BioPAC_path_file;

            [~,~,extension] = fileparts(file_name);
            
            if strcmpi(extension,'.acq')
                BioPAC = load_acq(file_name,0);

                chan_num = length(BioPAC.data(1,:)); %number of channels
                num_samples = length(BioPAC.data(:,1)); %length of acquisition in samples

                %Get channel names
                chan_names = struct2cell(BioPAC.hdr.per_chan_data);
                chan_names = chan_names(3,1,:);

                %get units
                chan_units = struct2cell(BioPAC.hdr.per_chan_data);
                chan_units = chan_units(8,1,:);

                app.channel_data.sampling_time = BioPAC.hdr.graph.sample_time*1e-3; %from ms to s

                app.channel_data.elec_stim.idx = find(strcmpi(chan_names,'Electrical Stimulation'));
                app.channel_data.camera.idx = find(strcmp(chan_names,'Camera Exposure'));

            elseif strcmpi(extension,'.xlsx')
                BioPAC = struct('data',[]);
                
                xlsx_data = readtable(file_name);

                BioPAC.data = table2array(xlsx_data);

                chan_names = xlsx_data.Properties.VariableNames;
                chan_units = chan_names;

                app.channel_data.sampling_time = mean(diff(xlsx_data.TIME));
                
                app.channel_data.elec_stim.idx = find(strcmpi(chan_names,'Stimulus'));
                app.channel_data.camera.idx = find(strcmp(chan_names,'Cam_Exp'));

                % Invert camera and stimulus channels
                BioPAC.data(:,app.channel_data.elec_stim.idx) = 5 - BioPAC.data(:,app.channel_data.elec_stim.idx);
                BioPAC.data(:,app.channel_data.camera.idx) = 5 - BioPAC.data(:,app.channel_data.camera.idx);

            elseif strcmpi(extension,'.fits')
                % create biopac structure
                BioPAC = struct('data',[]);

                % generate list of stimulus frequncies and calculate total
                % stimulation duration
                frequencylist = str2double(app.MinimumfrequencyHzEditField.Value):str2double(app.FrequencystepHzEditField.Value):str2double(app.MaximumfrequencyHzEditField.Value);
                totalstimulationduration = sum(str2double(app.StimuliperstepEditField.Value)./frequencylist);

                % Generate channel names and units
                chan_names = {'Time','Electrical Stimulation','Cam_Exp'};
                chan_units = {'s','V','V'};

                % Define sampling time
                app.channel_data.sampling_time = 1/app.fps;


                % Generate data
                time_recording = 0:1/app.fps:app.ROI_time(end);
                elec_stim_data = zeros(size(time_recording));
                camera_data = 5*ones(size(time_recording));

                stimdur_ind = ceil(str2double(app.StimulusdurationmsEditField.Value)*app.fps/1000);

                
                % Find estimated start point
                start_bcl = find(abs(app.BCL_activation-1000/frequencylist(1))<0.01*1000/frequencylist(1),1);
                start_time = app.ACT(start_bcl)-3*str2double(app.StimulusdurationmsEditField.Value);
                next_start = find(time_recording>start_time/1000,1);

                for k = 1:length(frequencylist)
                    for m = 1:str2double(app.StimuliperstepEditField.Value)
                        elec_stim_data(next_start:next_start+stimdur_ind) = ones(stimdur_ind,1);
                        elec_stim_data(next_start+stimdur_ind+1:next_start+2*stimdur_ind) =  -ones(stimdur_ind,1);
                        if m == str2double(app.StimuliperstepEditField.Value) && k ~= length(frequencylist)
                            next_start = next_start + floor(app.fps/frequencylist(k+1));
                        else
                            next_start = next_start + floor(app.fps/frequencylist(k));
                        end
                    end
                end

                BioPAC.data = [time_recording',elec_stim_data',camera_data'];
                app.channel_data.elec_stim.idx = 2;
                app.channel_data.camera.idx = 3;

            end


            %Find at which position (channel) each particular measurement is stored
            app.channel_data.local_perf.idx = find(strcmp(chan_names,'Local Perfusion'));
            %app.channel_data.elec_stim.idx = find(strcmpi(chan_names,'Electrical Stimulation'));
            app.channel_data.ECG1.idx = find(strcmp(chan_names,'ECG I'));
            if isempty(app.channel_data.ECG1.idx)
                app.channel_data.ECG1.idx = find(strcmp(chan_names,'ECG'));
            end
            app.channel_data.ECG2.idx = find(strcmp(chan_names,'ECG II'));
            app.channel_data.ECG3.idx = find(strcmp(chan_names,'ECG III'));
            app.channel_data.aort_press.idx = find(strcmp(chan_names,'Aortic Pressure'));
            app.channel_data.aort_press.idx = find(strcmp(chan_names,'Perfusion Pressure'));
            app.channel_data.aort_flow.idx = find(strcmp(chan_names,'Aortic Flow'));
            app.channel_data.temp.idx = find(strcmp(chan_names,'Temperature'));
            app.channel_data.LED.idx = find(strcmp(chan_names,'LED'));
            app.channel_data.LED.idx = find(strcmp(chan_names,'LED Trigger'));
            
            % Load Camera data
            if ~isempty(app.channel_data.camera.idx)
                app.channel_data.camera.units = chan_units{app.channel_data.camera.idx};
                app.channel_data.camera.data = BioPAC.data(:,app.channel_data.camera.idx);
            end           
            
            sampling_rate = 1/app.channel_data.sampling_time;
             %% Extract data only during camera exposure ON
            %Case where camera is off at the beginning of BioPAC recording
            
            try
                camera_ON = find(app.channel_data.camera.data > 0.5);
                recording_start_notcut = camera_ON(1);
                if strcmp(app.file_info.type,'.tiff')
                    duration = max(app.ROI_time);
                    recording_end_notcut = recording_start_notcut + duration*sampling_rate;
                else
                    recording_end_notcut = camera_ON(end);
                end
            catch
                Fs = sampling_rate;
                x = app.channel_data.camera.data;
%                 fc = 5;
%                 Wn = (2/Fs)*fc;
%                 b = fir1(40,Wn,'low',kaiser(41,3));
%                 
%                 camera_exposure_filtered = filter(b,1,x);
% 
%                 init_point = floor(size(x,1)/2);
%                 end_point = floor(size(x,1)/2) + 1e4;
%                 x_mid = mean(camera_exposure_filtered(init_point:end_point));
%                 
%                 x_start = mean(camera_exposure_filtered(1:1e4));
%                 
%                 thresh = mean([x_mid, x_start]);
% 
%                 camera_ON = find(camera_exposure_filtered > thresh);
%                 recording_start_notcut = camera_ON(1);
%                 recording_end_notcut = camera_ON(end);

                %duration = (app.processed_data.frame_range(2)-1)/app.data.fps; %s
                duration = max(app.ROI_time);
                
                square_signal = ones(1, length(x))*0;
                square_signal(1:duration*Fs) = 1;
                
                s1 = abs(x);
                %s1(s1>0.004) = 0.004;
                s1= s1/max(s1);
                s2 = square_signal;
                
                % Cross correlation
                [C21,lag21] = xcorr(s2,s1);
                C21 = C21/max(C21);
                
                [~,I21] = max(C21); %Max of Xcorrelation
                t21 = lag21(I21);

                recording_start_notcut = -t21;
                recording_end_notcut = -t21 + duration*Fs;


            end
            
            app.channel_data.camera.data = app.channel_data.camera.data(recording_start_notcut:recording_end_notcut);
            
             %Store data vector if the measurement is registered
            if ~isempty(app.channel_data.local_perf.idx)
                app.channel_data.local_perf.units = chan_units{app.channel_data.local_perf.idx};
                app.channel_data.local_perf.data = BioPAC.data(recording_start_notcut:recording_end_notcut,app.channel_data.local_perf.idx);
            end
            if ~isempty(app.channel_data.elec_stim.idx)
                app.channel_data.elec_stim.units = chan_units{app.channel_data.elec_stim.idx};
                app.channel_data.elec_stim.data = BioPAC.data(recording_start_notcut:recording_end_notcut,app.channel_data.elec_stim.idx);
            end
            if ~isempty(app.channel_data.ECG1.idx)
                app.channel_data.ECG1.units = chan_units{app.channel_data.ECG1.idx};
                app.channel_data.ECG1.data = BioPAC.data(recording_start_notcut:recording_end_notcut,app.channel_data.ECG1.idx);
            end
            if ~isempty(app.channel_data.ECG2.idx)
                app.channel_data.ECG2.units = chan_units{app.channel_data.ECG2.idx};
                app.channel_data.ECG2.data = BioPAC.data(recording_start_notcut:recording_end_notcut,app.channel_data.ECG2.idx);
            end
            if ~isempty(app.channel_data.ECG3.idx)
                app.channel_data.ECG3.units = chan_units{app.channel_data.ECG3.idx};
                app.channel_data.ECG3.data = BioPAC.data(recording_start_notcut:recording_end_notcut,app.channel_data.ECG3.idx);
            end
            if ~isempty(app.channel_data.aort_press.idx)
                app.channel_data.aort_press.units = chan_units{app.channel_data.aort_press.idx};
                app.channel_data.aort_press.data = BioPAC.data(recording_start_notcut:recording_end_notcut,app.channel_data.aort_press.idx);
            end
            if ~isempty(app.channel_data.aort_flow.idx)
                app.channel_data.aort_flow.units = chan_units{app.channel_data.aort_flow.idx};
                app.channel_data.aort_flow.data = BioPAC.data(recording_start_notcut:recording_end_notcut,app.channel_data.aort_flow.idx);
            end
            if ~isempty(app.channel_data.temp.idx)
                app.channel_data.temp.units = chan_units{app.channel_data.temp.idx};
                app.channel_data.temp.data = BioPAC.data(recording_start_notcut:recording_end_notcut,app.channel_data.temp.idx);
            end

            if ~isempty(app.channel_data.LED.idx)
                app.channel_data.LED.units = chan_units{app.channel_data.LED.idx};
                app.channel_data.LED.data = BioPAC.data(recording_start_notcut:recording_end_notcut,app.channel_data.LED.idx);
            end


            num_samples = length(app.channel_data.camera.data);
            if app.channel_data.sampling_time >= 1
                app.channel_data.sampling_time = BioPAC.hdr.graph.sample_time*1e-3; %from ms to s
            end
            app.channel_data.time_recording = 0:app.channel_data.sampling_time:app.channel_data.sampling_time*(num_samples-1);
            
            app.channel_data.time_recording = linspace(0,app.ROI_time(end),length(app.channel_data.camera.data ));
            
%             
%             if ~(recording_start_notcut == 1) %The camera is not active when the Acq starts recording (nothing missing in the beginning)
%                 app.channel_data.time_recording = time - (recording_start_notcut-1)*app.channel_data.sampling_time;
%             elseif recording_start_notcut == 1 && ~(recording_end_notcut == num_samples) %camera already active when Acq starts and is off when Acq stops = align with end time
%                 app.channel_data.time_recording = time + ((app.data.n_frames_full-1)/app.data.fps - time(recording_end_notcut-1));
%             end
             characterise_pacing_protocol(app)

        end


        function update_BioPAC(app)
            app.load_BioPAC;
            app.SwitchBioPACShow_HideValueChanged;
        end


        function initialize_BioPAC_data(app)
            %TODO: function to delete stuff if new file is loaded

        end


        function plot_ROI_signal(app,k)
            if ~isempty(app.signal_plot_handle)
                delete(app.signal_plot_handle)
            end
            
            if ~isempty(app.APD_patches_handles)
                for m=1:length(app.APD_patches_handles)
                    delete(app.APD_patches_handles{m})
                end
            end
            try
                app.signal_plot_handle = plot(app.ROI_signal_UIAxes, app.ROI_time, app.ROI_signal{k});
                xlim(app.ROI_signal_UIAxes,[min(app.ROI_time), max(app.ROI_time)])
                ylim(app.ROI_signal_UIAxes,[min(app.ROI_signal{k}), max(app.ROI_signal{k})])
                hold(app.ROI_signal_UIAxes, 'off');
            catch
            end
            
        end
        
        function characterise_pacing_protocol(app)
            %First, find out what kind of stimulation has been used
            electrical_stim_applied = ~(all(app.channel_data.elec_stim.data==0));
            try
                LED_stim_applied = ~(all(app.channel_data.LED.data == 0));
            end


            if electrical_stim_applied == 1
                app.StimulusTypeEditField.Value = 'Electrical';
                %find peaks of electrical stimulation (we have an upwards peak in the derivative at start and end of stimulus since it is a bipolar stimulus
                diff_elec = -diff(app.channel_data.elec_stim.data);
                diff_elec = diff_elec./max(diff_elec); %normalise to standarize peak finding
                [peaks_height,~] = findpeaks(diff_elec,app.channel_data.time_recording(1:end-1)*1000,'MinPeakHeight',0.6);
                diff_elec = diff_elec/mean(peaks_height); %renormalize to mean peak height
                [~,app.stim_times] = findpeaks(diff_elec,app.channel_data.time_recording(1:end-1)*1000,'MinPeakHeight',0.4);
                if min(diff(app.stim_times(:))) < 20
                    %find peaks of electrical stimulation (we have an upwards peak in the derivative at start and end of stimulus since it is a bipolar stimulus
                    diff_elec = diff(app.channel_data.elec_stim.data);
                    diff_elec = diff_elec./max(diff_elec); %normalise to standarize peak finding
                    [peaks_height,~] = findpeaks(diff_elec,app.channel_data.time_recording(1:end-1)*1000,'MinPeakHeight',0.6);
                    diff_elec = diff_elec/mean(peaks_height); %renormalize to mean peak height
                    [~,app.stim_times] = findpeaks(diff_elec,app.channel_data.time_recording(1:end-1)*1000,'MinPeakHeight',0.4);
                end

            elseif LED_stim_applied == 1
                app.StimulusTypeEditField.Value = 'Optical';
                %Find peaks of optical stimulation (we have upwards peak in the derivative at start of the sitmulus only
                diff_optic = diff(app.channel_data.LED.data);
                diff_optic = diff_optic./max(diff_optic); %normalise to standarize peak finding
                [~,pks_times] = findpeaks(diff_optic,app.channel_data.time_recording(1:end-1)*1000,'MinPeakHeight',0.4); %0.3 has not been tested yet (1.04.2021)
                app.stim_times = pks_time; %in this case every peak should correspond to start of stimulus 
                
                %for stim dur and bcl etc..
                diff_pks_times = abs(diff(pks_times)); %taking the absolute value so similarly as in electric stimulation we have a peak at start of pulse and another one at end, so we can use the same code
                app.stim_dur = mean(diff_pks_times(1:2:end));
            end


            app.stim_bcl = diff(app.stim_times);
            app.stim_freq = 1./(app.stim_bcl/1000);
            
            diff_stim_freq = diff(app.stim_freq);
            [pks_height,pks_locs] = findpeaks(diff_stim_freq,'MinPeakHeight',0.7*max(diff_stim_freq));
            app.freq_step = mean(pks_height);
            app.freq_step = round(round(app.freq_step*1000)/1000,2,'significant');
            app.stim_per_step = pks_locs(1)+1;
            
            app.min_freq = mean(app.stim_freq(1:app.stim_per_step));
            app.max_freq = mean(app.stim_freq(end - app.stim_per_step+1 : end));
            
            app.num_steps = round((app.max_freq - app.min_freq)/app.freq_step)+1;
            %Vector with the frequency of each step
            app.frequencies = ones(size(app.stim_times));
            for k = 1:app.num_steps
                app.frequencies((k-1)*app.stim_per_step+1 :(k-1)*app.stim_per_step+app.stim_per_step) = app.min_freq + (k-1)*app.freq_step;
            end
            
            app.MinimumfrequencyHzEditField.Value = sprintf('%.02f',app.min_freq);
            app.MaximumfrequencyHzEditField.Value = sprintf('%.02f',app.max_freq);
            app.FrequencystepHzEditField.Value = sprintf('%.02f',app.freq_step);
            app.StimuliperstepEditField.Value = num2str(app.stim_per_step);
            app.StimulusdurationmsEditField.Value = sprintf('%.02f',app.stim_dur);
                
            freqs = app.min_freq:app.freq_step:app.max_freq;
            times = zeros(size(freqs));
            for k = 1:length(freqs)
                ind = find(app.stim_freq>=0.98*floor(20*freqs(k))/20,1,'first');
                times(k) = app.stim_times(ind);
            end
            [times'./1000 freqs']
        end
        
        function tool_tip_update(app)
            app.figure_size = get(app.APD_analysis_UIFigure,'position');
            set(app.tool_tip,'Position', [app.figure_size(1) , app.figure_size(4)-17.5, app.figure_size(3), 20 ]);
            set(app.TabGroup,'Position', [app.figure_size(1), app.figure_size(2), app.figure_size(3), app.figure_size(4)-17-0.95*app.figure_size(2)] );
            try
                full_split_path = regexp([app.file_info.path,app.file_info.file],'/','split'); % Mac separator
                file_name_with_short_path = [full_split_path{end-2},'/', full_split_path{end-1},'/', full_split_path{end}];
            catch 
                full_split_path = regexp([app.file_info.path,app.file_info.file],'\','split'); % windows separator
                file_name_with_short_path = [full_split_path{end-2},'\', full_split_path{end-1},'\', full_split_path{end}];
            end
                
            app.tool_tip.Value = sprintf(['File name: %s\t\t' ...
                'Image size: %dx%dx%d\t\t' ...
                'Frame rate: %.3f fps\t\t' ...
                'Recording length: %.1f s'],...
                file_name_with_short_path,...
                app.sif_header.width,...
                app.sif_header.height,...
                app.sif_header.kineticLength,...
                app.fps,...
                app.total_recording_time);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set([app.TabGroup,app.FileMenu],'Visible','off')
            drawnow
            set(app.APD_analysis_UIFigure,'WindowState','maximized');
            drawnow
            pause(2)
            
            set([app.FileMenu],'Visible','on')
            pause(2)
            app.figure_size = get(app.APD_analysis_UIFigure,'position');
            
            set(app.TabGroup,'Position', [app.figure_size(1), app.figure_size(2), app.figure_size(3), app.figure_size(4)-17-0.95*app.figure_size(2)] );
            set(app.tool_tip,'Position', [app.figure_size(1) , app.figure_size(4)-17.5, app.figure_size(3), 20 ]);
            set([app.tool_tip],'Visible','on')
            pause(2)
            app.figure_size = get(app.APD_analysis_UIFigure,'position');
            set(app.tool_tip,'Position', [app.figure_size(1) , app.figure_size(4)-17.5, app.figure_size(3), 20 ]);
            set(app.TabGroup,'Position', [app.figure_size(1), app.figure_size(2), app.figure_size(3), app.figure_size(4)-17-0.95*app.figure_size(2)] );
            
        end

        % Menu selected function: LoadfileMenu
        function LoadfileMenuSelected(app, event)
            % First we will ask to select the APD restitution file and only the first frame will be loaded
            % 1. Select file
            select_file(app)
            
            % 2. Load first frame
            progress_bar = uiprogressdlg(app.APD_analysis_UIFigure,'Title','Loading first frame','Indeterminate','on');
            if strcmp(app.file_info.type,'.sif')
                try
                    loaded_sif = sifreadnk([app.file_info.path, app.file_info.file],[1 2],2);
                catch ME
                    errordlg(sprintf(['Error: there is a problem with the selected SIF file. Error code:',ME.message], 'SIF load error')) % Display error warning window if something went wrong when loading the data
                end
                app.first_frame = loaded_sif.imageData(:,:,1);
            elseif strcmp(app.file_info.type, '.tif') || strcmp(app.file_info.type, '.tiff')
                % Load data
                file = [app.file_info.path,app.file_info.file];
                warning('off')
                stack = tiffreadVolume(file);
                warning('on')

                new_image = zeros(size(stack));

                if size(stack,3) == 1
                    % If loaded a single image, presume it is part of multiple tiffs and
                    % converts into a stack
                    underscore_indices = regexp(file,'_');
                    file_base = file(1:underscore_indices(end));
                    file_list = natsortfiles(dir([file_base '*.tiff']));
                    clearvars stack;
                    stack = imread([file_list(1).folder '\' file_list(1).name]);
                    app.first_frame = single(stack);
                    app.sif_header.kineticLength = length(file_list);
                else
                    new_image = single(stack);
                    app.first_frame = new_image(:,:,1);
                    app.sif_header.kineticLength = size(new_image,3);
                end
                app.sif_header.width = size(new_image,2);
                app.sif_header.height = size(new_image,1);

            elseif strcmp(app.file_info.type, '.fits')
                image_metadata = fitsinfo([app.file_info.path,app.file_info.file]);
                app.sif_header.width = image_metadata.PrimaryData.Size(2);
                app.sif_header.height = image_metadata.PrimaryData.Size(1);

                % Check whether multipart file
                if ~isempty(image_metadata.PrimaryData.Keywords{image_metadata.PrimaryData.Keywords(:,1)=="USERTXT1",2})
                    app.file_info.multipart = 1;
                    % Other files
                    [~,file_name,~] = fileparts(app.file_info.file);
                    file_list = dir([app.file_info.path,file_name,'_X*.fits']);
                    image_metadata2 = fitsinfo([app.file_info.path,file_list(end).name]);

                    total_length = regexp(image_metadata2.PrimaryData.Keywords{image_metadata2.PrimaryData.Keywords(:,1)=="USERTXT1",2},'-[0-9]+\.',"match");
                    app.sif_header.kineticLength = str2double(total_length{1}(2:end-1));
                else
                    app.sif_header.kineticLength = image_metadata.PrimaryData.Size(3);
                end
                
                % Load first frame
                app.first_frame = flip(fitsread([app.file_info.path,app.file_info.file],PixelRegion={[1,app.sif_header.height],[1,app.sif_header.width],[1,1]}),1);
            end

            app.first_frame = app.first_frame - min(app.first_frame(:));
            app.first_frame = app.first_frame./max(app.first_frame(:));
            
             %3. Create axes and display first frame only
            if ~isempty(app.main_display_axes)
                delete(app.main_display_axes);
                app.main_display_axes=[];
            end
            panel_display_position = get(app.panel_display_area, 'Position');
            app.main_display_axes = axes(app.panel_display_area, 'Units','Pixels','Position',[1,1,panel_display_position(3),panel_display_position(4)],'XTick',[],'YTick',[]);
            imshow(app.first_frame,'Parent',app.main_display_axes);
            
            % Delete APD restitution graphs or ROI signal graphs if not empty
            if ~isempty(app.signal_plot_handle)
                delete(app.signal_plot_handle);
                app.signal_plot_handle=[];
            end
            
            if ~isempty(app.restitution_plot_handle)
                delete(app.restitution_plot_handle)
            end
            
            close(progress_bar)
            
            % 4. Set default BioPAC search folder and save-to folder
            path_parts = regexp(app.file_info.path,'/','split');
            search_folder = string(join(path_parts(1:end-2),'/'));
            app.AutomaticSearchFolderEditField.Value = strcat(search_folder, '/BioPAC files/');
            app.SavetoEditField.Value = app.file_info.path;
            
            % 5. Load header of sif to get additional info of the recording
            if strcmp(app.file_info.type,'.sif')
                app.sif_header = sifreadHeader([app.file_info.path , app.file_info.file]);
                app.fps = 1/app.sif_header.stackCycleTime;
                if app.fps == Inf
                    fps_int = inputdlg('Enter fps:','Frame rate',[1 35],{'100'});
                    app.fps = str2double(fps_int(1));
                end
                
            elseif strcmp(app.file_info.type, '.tif') || strcmp(app.file_info.type, '.tiff')
                % Set frame rate
                fps_int = inputdlg('Enter fps:','Frame rate',[1 35],{'100'});

                app.fps = str2double(fps_int(1));

            elseif strcmp(app.file_info.type, '.fits')
                app.fps = 1/image_metadata.PrimaryData.Keywords{1==(strcmp(image_metadata.PrimaryData.Keywords(:,1),'KCT')),2};            % Frames per second (fps)
            end
            app.total_recording_time = (app.sif_header.kineticLength - 1)/app.fps;

            % 6. Update tooltip
            tool_tip_update(app)
            
            %7. Delete existing ROIs
            delete_all_ROIs(app)
            app.ROI_signal = [];
            
            set(app.TabGroup, 'Visible', 'on')
            
            %8. Disable buttons
            set([app.DeleteallROIsButton app.deleteROI app.save_ROI app.LoadROIsignalButton app.ShowBioPACDropDown app.SwitchBioPACShow_Hide app.GetdataButton app.APDsequentialallAPButton app.APDvsBCLmeananderrorButton app.APDvsDImeananderrorButton app.APDvsfrequencymeananderrorButton],'Enable','off')
            %
            
            %9. Delete APDs
            DeleteAPDsButtonPushed(app, event)
            
            %10. Delete protocol data
            app.MinimumfrequencyHzEditField.Value = '';
            app.MaximumfrequencyHzEditField.Value = '';
            app.FrequencystepHzEditField.Value = '';
            app.StimuliperstepEditField.Value = '';
            app.StimulusdurationmsEditField.Value = '';
            app.StimulusTypeEditField.Value = '';
            
            % 11. Biopac switch to hide
            app.SwitchBioPACShow_Hide.Value = 'Hide';
        end

        % Button pushed function: select_ROI
        function select_ROIButtonPushed(app, event)
             roi_label = ['1','2','3','4','5','6'];           
            
             if length(app.ROI_handle) == length(roi_label)
                 warndlg(sprintf(['Warning: Maxmimum number of ROIs allowed is ',num2str(length(roi_label))], 'Number of ROIs exceeded'))
             else
                % 1. Allow drawing of the ROI
    
                axes(app.main_display_axes)
               
                square_size = str2num(app.ROI_type.Value);
                
                roi_point = drawpoint('Parent',app.main_display_axes); %use mouse to select a point ROI
                point_pos = round(get(roi_point,'Position')); %extract coordinates of the point ROI
                delete(roi_point); %delete the point roi marker
                
                xmin = point_pos(1) - square_size/2;
                ymin =  point_pos(2) - square_size/2;
                width = square_size;
                height = square_size;

                app.ROI_handle{end+1} = drawrectangle('Parent',app.main_display_axes,'Position', [round(xmin) round(ymin) width height], 'Color',app.plot_colours(rem(1+length(app.ROI_handle),7),:),'Label',roi_label(1+length(app.ROI_handle)),'InteractionsAllowed','translate');


                % 2. Update ROI dropdown
                update_ROI_dropdowns(app)   
                
                % 3. Enable other buttons
                set([app.ROIdropdown, app.deleteROI, app.DeleteallROIsButton, app.LoadROIsignalButton, app.save_ROI],'Enable','on');
             end
            
            
        end

        % Button pushed function: deleteROI
        function delete_ROI(app, event)
            ROI_to_delete = app.ROIdropdown.Value;
            
            delete(app.ROI_handle{ROI_to_delete});
            app.ROI_handle(ROI_to_delete) = [];
            
            %TODO: delete loaded signal from the axis from the other Tab
 
            %Update ROI colour and label
            roi_label = ['1','2','3','4','5','6','7'];
            for k = 1:length(app.ROI_handle)
                set(app.ROI_handle{k},'Color',app.plot_colours(rem(k,7),:));
                set(app.ROI_handle{k},'Label',roi_label(k));
            end
            
            num_ROI = num2cell(1:length(app.ROI_handle));
            
            % Update ROI dropdown
            update_ROI_dropdowns(app)
        end

        % Button pushed function: DeleteallROIsButton
        function delete_all_ROIs(app, event)
            app.ROI_handle = [];
            
            cla(app.ROI_signal_UIAxes);

            update_ROI_dropdowns(app)
            
            panel_display_position = get(app.panel_display_area, 'Position');
            app.main_display_axes = axes(app.panel_display_area, 'Units','Pixels','Position',[1,1,panel_display_position(3),panel_display_position(4)],'XTick',[],'YTick',[]);
            imshow(app.first_frame,'Parent',app.main_display_axes);

        end

        % Button pushed function: save_ROI
        function save_ROIButtonPushed(app, event)
            save_file = file_exists(app,'ROI','.mat');
            roi_handles = app.ROI_handle;
            save(save_file,'roi_handles','-mat');
        end

        % Button pushed function: load_ROI
        function load_ROIButtonPushed(app, event)
            [ROI_file,ROI_path] = uigetfile({'*.mat';'*.*'},'ROI selector'); % Open ROI selector
            
            load([ROI_path ROI_file], 'roi_handles');
            if ~isempty(app.ROI_handle)
                cellfun(@delete, app.ROI_handle);
                app.ROI_handle = [];
            end
            
            app.ROI_handle = roi_handles;
            
            cellfun(@(x) set(x,'Parent',app.main_display_axes),app.ROI_handle);
            
            % Update ROI dropdowns
            update_ROI_dropdowns(app)
            
            
            % Enable other buttons
            set([app.ROIdropdown, app.deleteROI, app.DeleteallROIsButton, app.LoadROIsignalButton, app.save_ROI],'Enable','on');
            
        end

        % Button pushed function: LoadROIsignalButton
        function LoadROIsignalButtonPushed(app, event)
            progress_bar = uiprogressdlg(app.APD_analysis_UIFigure,'Title','Loading ROI signal','Indeterminate','on');

            k = app.ROIdropdown.Value;
            %  READ ROI SIGNAL FROM FILE
            file = [app.file_info.path,app.file_info.file];
            range = []; % read full time
            rangeType = 1; % read full time
            ROI_position = app.ROI_handle{k}.Position ; 
            only_signal = 1; %only read average ROI signal, not image data
            if strcmp(app.file_info.type,'.sif')
                data = sifreadROI(file,range, rangeType, ROI_position, only_signal);
                %data = sifreadROI_old_20230713(file,range, rangeType, ROI_position, only_signal);
            elseif strcmp(app.file_info.type, '.tif') || strcmp(app.file_info.type, '.tiff')
                % Load data
                file = [app.file_info.path,app.file_info.file];

                data.ROI_signal = zeros(2,app.sif_header.kineticLength);
                data.ROI_signal(1,:) = 0:1/app.fps:app.total_recording_time;

                underscore_indices = regexp(file,'_');
                file_base = file(1:underscore_indices(end));
                file_list = natsortfiles(dir([file_base '*.tiff']));

                for l = 1:size(file_list,1)
                    clearvars image;
                    image = imread([file_list(l).folder '\' file_list(l).name],PixelRegion={[ROI_position(2) ROI_position(2)+ROI_position(4)-1] [ROI_position(1) ROI_position(1)+ROI_position(3)-1]});
                    data.ROI_signal(2,l) = mean(single(image(:)));
                end
            elseif strcmp(app.file_info.type, '.fits')
                ROI_position = app.ROI_handle{k}.Position;

                data.ROI_signal = zeros(2,app.sif_header.kineticLength);
                data.ROI_signal(1,:) = 0:1/app.fps:app.total_recording_time;

                if app.file_info.multipart == 1
                    image_metadata = fitsinfo([app.file_info.path,app.file_info.file]);

                    image = fitsread([app.file_info.path,app.file_info.file],PixelRegion={[app.sif_header.height-ROI_position(2)-ROI_position(4),app.sif_header.height-ROI_position(2)-1] ,[ROI_position(1),ROI_position(1)+ROI_position(3)-1],[1,image_metadata.PrimaryData.Size(3)]});

                    % Other files
                    [~,file_name,~] = fileparts(app.file_info.file);
                    file_list = dir([app.file_info.path,file_name,'_X*.fits']);
                    for l = 1:length(file_list)
                        image_metadata = fitsinfo([app.file_info.path,file_list(l).name]);
                        tmp = fitsread([app.file_info.path,file_list(l).name],PixelRegion={[app.sif_header.height-ROI_position(2)-ROI_position(4),app.sif_header.height-ROI_position(2)-1] ,[ROI_position(1),ROI_position(1)+ROI_position(3)-1],[1,image_metadata.PrimaryData.Size(3)]});
                        image(:,:,end+1:end+size(tmp,3)) = tmp;
                    end
                else
                    image = fitsread([app.file_info.path,app.file_info.file],PixelRegion={[app.sif_header.height-ROI_position(2)-ROI_position(4),app.sif_header.height-ROI_position(2)-1] ,[ROI_position(1),ROI_position(1)+ROI_position(3)-1],[1,app.sif_header.kineticLength]});
                end
                data.ROI_signal(2,:) = squeeze(mean(single(image),[1,2]));
            end

            % Check that the length of the signal matches the number of frames of the recording known from the header
            if length(data.ROI_signal(2,:)) ~= app.sif_header.kineticLength
                close(progress_bar)
                uialert(app.APD_analysis_UIFigure,'Loading of signal incomplete :(','Try with a slighlty different position');
            else
            
                app.ROI_signal{k} = detrend(data.ROI_signal(2,:));
                app.ROI_time = data.ROI_signal(1,:);
            
                %Normalise between 0 and 1 
                app.ROI_signal{k} = app.ROI_signal{k} - min(app.ROI_signal{k});
                app.ROI_signal{k} = app.ROI_signal{k}./max(app.ROI_signal{k});


                %invert signal if necessary
                if app.InvertsignalsButton.Value
                    invert_signal(app,k)
                end
                
                % Plot ROI signal
                plot_ROI_signal(app,k)
                
                
                %update show ROI dropwdowns
                app.ShowROIDropDown.Value = app.ROIdropdown.Value;
                
                %save fps variable
                app.fps = 1/app.ROI_time(2);
                
                close(progress_bar)
                uialert(app.APD_analysis_UIFigure,'ROI signal loaded sucessfully!','Go to signal processing Tab to visualise it :)', 'Icon', 'success');
            end
        end

        % Value changed function: MaxColourmap
        function MaxColourmapValueChanged(app, event)
            factor = app.MaxColourmap.Value/100;
            imshow(app.first_frame, [min(app.first_frame(:)), max(app.first_frame(:))*factor ], 'Parent',app.main_display_axes);
            
            
        end

        % Value changed function: InvertsignalsButton
        function InvertsignalsButtonValueChanged(app, event)
            for k =1:length(app.ROI_signal)
                if ~isempty(app.ROI_signal{k})
                    %if there is a signal loaded, invert it if needed
                    invert_signal(app,k)
                    
                     % Plot ROI signal
                    plot_ROI_signal(app,k)
                end
            end
        end

        % Value changed function: ShowROIDropDown
        function ShowROIDropDownValueChanged(app, event)
            k = app.ShowROIDropDown.Value;
             % Plot ROI signal
            plot_ROI_signal(app,k)
            
            %Disable saving buttons and get data button
            set([app.GetdataButton, app.ExportfulltraceButton, app.ExportmeantraceButton, app.ExportselectedAPtraceButton],'Enable','off');
            
            %delete stuff on APD BCL and activation time
            app.ROI_APD.Value = '';
            app.ROI_dVdt.Value ='';
            app.ROI_BCL.Value = '';
            app.ROITime.Value = '';
            
            %delete restitution curve
            if ~isempty(app.restitution_plot_handle)
                delete(app.restitution_plot_handle)
            end
        end

        % Button pushed function: temporalfilter
        function temporalfilterButtonPushed(app, event)
            % Filter for all signals
             % Store data for undo
            app.previous_ROI_signal = app.ROI_signal;
            
            progress_bar = uiprogressdlg(app.APD_analysis_UIFigure,'Title','Filtering','Indeterminate','on');
            
            for k=1:length(app.ROI_signal)

                
                fc = app.cutofffrequency.Value;
                fs = app.fps;
                
                switch str2double(app.TemporalFilterType.Value)
                    case 1
                        [b,a] = butter(app.filterorder.Value,fc/(fs/2),'low');
                        
                    case 2
                        a0 = [1, 1, 0, 0];
                        f0 = [0, fc, fc*1.25, fs/2]./(fs/2);
                        b = firpm(app.filterorder.Value, f0, a0);
                        a = 1;

                end
                
                % Filter using a bidirectional filter so get zero phase offset
                app.ROI_signal{k} = FiltFiltM(b,a,app.ROI_signal{k},[]);
                
                
            end 
                
            %Re plot signal
            % Plot ROI signal
            plot_ROI_signal(app,app.ShowROIDropDown.Value)
            
            close(progress_bar)
        end

        % Value changed function: cutofffrequency
        function cutoff_limit(app, event)
           value = app.cutofffrequency.Value;
            if value > 0.5*app.fps
                app.cutofffrequency.Value = app.fps/2;
            end
            
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            temporal_save = app.ROI_signal;
            app.ROI_signal = app.previous_ROI_signal;
            app.previous_ROI_signal = temporal_save;
            
            % Replot
            % Plot ROI signal
            plot_ROI_signal(app,app.ShowROIDropDown.Value)
        end

        % Button pushed function: ChangesearchfolderButton
        function ChangesearchfolderButtonPushed(app, event)
            app.AutomaticSearchFolderEditField.Value = uigetdir();
            app.AutomaticSearchFolderEditField.Value = [app.AutomaticSearchFolderEditField.Value '/'];
        end

        % Button pushed function: ManualfileselectionandimportButton
        function ManualfileselectionandimportButtonPushed(app, event)
            [app.BioPAC_file , BioPAC_path] = uigetfile({'*.*'},'File selector');
            app.BioPAC_path_file = [BioPAC_path app.BioPAC_file];
            load_BioPAC(app);
            app.BioPACfileEditField.Value = [app.AutomaticSearchFolderEditField.Value app.BioPAC_file];
            
            set([app.ShowBioPACDropDown app.SwitchBioPACShow_Hide],'Enable','on');
            
            if ~isempty(app.ROI_APD.Value)
                set([app.GetdataButton],'Enable','on');
            end
            
            app.SwitchBioPACShow_Hide;
        end

        % Value changed function: SwitchBioPACShow_Hide
        function SwitchBioPACShow_HideValueChanged(app, event)
            switch_option = app.ShowBioPACDropDown.Value;
            
            current_xlimits = xlim(app.ROI_signal_UIAxes);
            
            if app.SwitchBioPACShow_Hide.Value == 'Show'
                %clear biopac plot if there was any
                delete(app.BioPAC_plot_handle)
                % Plot signal on axes

                 
                if ~isempty(app.channel_data.LED.idx) && ~isempty(app.channel_data.ECG2.idx)
                    channels_signal = {app.channel_data.ECG1.data, app.channel_data.ECG2.data, app.channel_data.ECG3.data, app.channel_data.elec_stim.data, app.channel_data.LED.data, app.channel_data.camera.data };
                elseif ~isempty(app.channel_data.LED.idx) && isempty(app.channel_data.ECG2.idx)
                    channels_signal = {app.channel_data.ECG1.data, 1 ,1, app.channel_data.elec_stim.data, app.channel_data.LED.data, app.channel_data.camera.data };
                elseif isempty(app.channel_data.LED.idx) && isempty(app.channel_data.ECG1.idx)
                    channels_signal = {1, 1 ,1, app.channel_data.elec_stim.data, 1, app.channel_data.camera.data };
                elseif isempty(app.channel_data.LED.idx) && isempty(app.channel_data.ECG2.idx)
                    channels_signal = {app.channel_data.ECG1.data, 1 ,1, app.channel_data.elec_stim.data, 1, app.channel_data.camera.data };
                else
                    channels_signal = {app.channel_data.ECG1.data, app.channel_data.ECG2.data, app.channel_data.ECG3.data, app.channel_data.elec_stim.data,app.channel_data.elec_stim.data, app.channel_data.camera.data};
                end
                
                signal_to_show = channels_signal{str2num(switch_option)};
                if signal_to_show ~= 1 | (isempty(app.channel_data.LED.idx) && isempty(app.channel_data.ECG2.idx))
                    signal_to_show_normalsed = channels_signal{str2num(switch_option)} - min(channels_signal{str2num(switch_option)});
                    signal_to_show_normalsed = signal_to_show_normalsed./max(signal_to_show_normalsed);
                else
                    signal_to_show_normalsed = ones(size(app.channel_data.time_recording))*0.5;
                end
                
                
                hold(app.ROI_signal_UIAxes, 'on')
                app.BioPAC_plot_handle = plot(app.ROI_signal_UIAxes, app.channel_data.time_recording, signal_to_show_normalsed*app.BioPAC_Yaxis_zoom_factor + app.BioPAC_baseline,'r');
                xlim(app.ROI_signal_UIAxes,current_xlimits);
                hold(app.ROI_signal_UIAxes, 'off')

                characterise_pacing_protocol(app)
            elseif app.SwitchBioPACShow_Hide.Value == 'Hide'
                
                set(app.BioPAC_plot_handle,'Visible','off');


            end
        end

        % Value changed function: ShowBioPACDropDown
        function ShowBioPACDropDownValueChanged(app, event)
            app.SwitchBioPACShow_HideValueChanged;
            
        end

        % Button pushed function: MoveupButton
        function MoveupButtonPushed(app, event)
            app.BioPAC_baseline = app.BioPAC_baseline + 0.1;
            app.SwitchBioPACShow_HideValueChanged;
        end

        % Button pushed function: MovedownButton
        function MovedownButtonPushed(app, event)
            app.BioPAC_baseline = app.BioPAC_baseline - 0.1;
            app.SwitchBioPACShow_HideValueChanged;
        end

        % Button pushed function: IncreaseinYaxisButton
        function IncreaseinYaxisButtonPushed(app, event)
            app.BioPAC_Yaxis_zoom_factor = app.BioPAC_Yaxis_zoom_factor + 0.1;
            app.SwitchBioPACShow_HideValueChanged;
        end

        % Button pushed function: ReduceinYaxisButton
        function ReduceinYaxisButtonPushed(app, event)
            app.BioPAC_Yaxis_zoom_factor = app.BioPAC_Yaxis_zoom_factor - 0.1;
            app.SwitchBioPACShow_HideValueChanged;
        end

        % Button pushed function: ComputeallAPDsButton
        function ComputeallAPDsButtonPushed(app, event)
            %Delete pathces and data from numeric fields if APD has been calculated before
            DeleteAPDsButtonPushed(app, event)
            
            fps = app.fps;
            APD_percentage = app.APDpercentageEditField.Value;
            
            % get ROI signal
            time = app.ROI_time;
            signal = app.ROI_signal{app.ShowROIDropDown.Value};
            % normalise signal and find number of AP and at which times the peak is (approx)
            signal_norm = signal - min(signal);
            signal_norm = signal_norm./max(signal_norm);
            % Find AP peaks
            [~, times] = findpeaks(sgolayfilt(double(signal_norm),2,21),time, 'MinPeakProminence', app.MinprominenceEditField.Value);
            [~, indices_peaks] = findpeaks(sgolayfilt(double(signal_norm),2,21), 'MinPeakProminence', app.MinprominenceEditField.Value);
            % Compute first approximation of BCL based on the peaks
            bcl_list = diff(times);
            % add a last bcl value for the last AP if the last AP could not have an associated BCL
            if length(bcl_list) < length(times)
                bcl_list = [bcl_list, time(end) - times(end)];
            end
            app.ROI_BCL.Value = num2str(bcl_list);
            app.ROITime.Value = num2str(times(1:end-1)./1000);
            
            %calculate dfdt
            dfdt = diff(signal); %/(1/fps);
            
            
            % normalise dfdt
            dfdt = dfdt-min(dfdt);
            dfdt = dfdt./max(dfdt);


            peak_last = length(times);
            APD = zeros(1,length(times));
            activation_time = zeros(1,length(times));
            repol_time = zeros(1, length(times));
            dVdtmax =  zeros(1, length(times));
            resting_V = zeros(1, length(times));
            V_max = zeros(1, length(times));

            k = 1;
            for peak = 1:peak_last
                
                % Find RMP and APD
                %RMP is mean V of BCL/2 to 5ms before AP upstroke to when this value is crossed to BCL/2 (or end) #select wanted peak to analyze

                if length(times) == 1 % if only 1 peak take the whole window
                    init_index = 1;
                    end_index = length(time)-1;
                    bcl = time(end);
                else
                    bcl = bcl_list(peak); 

                    % Find interval of AP in indices
                    init_index = max([find(time >= (times(peak) - bcl/1.5), 1), 1]) ;
                    end_index = min([find(time >= (times(peak) + bcl/1.5), 1),length(time)-1]);
                end
                % Find upstroke index within the interval
                end_index_for_upstroke = min([find(time >= (times(peak) + bcl/2), 1),length(time)-1]);
                upstroke_index = init_index + find(dfdt(init_index:end_index_for_upstroke)>=max(dfdt(init_index:end_index_for_upstroke)))-1;
                %interpolation to find accurate activation time
                interp_points = 1000;
                delta = 10;
                if upstroke_index - delta <= 0
                    lower_bound_interp = 1;
                else
                    lower_bound_interp = upstroke_index(1) - delta; %changed heeeereee
                end
                if upstroke_index + delta > length(time)-1
                    upper_bound_interp = length(time)-1;
                else
                    upper_bound_interp = upstroke_index(1) + delta; %changed heeereeeee
                end

                time_fine_grid = linspace(time(lower_bound_interp), time(upper_bound_interp), interp_points); %generate fine grid for interpolation in ms
                dfdt_interpolated = interp1(time(lower_bound_interp: upper_bound_interp), dfdt(lower_bound_interp: upper_bound_interp), time_fine_grid, 'spline'); %interpolate around the previously found start index
                [dfdtmax,idx_max_interpolated] = max(dfdt_interpolated); % find new dfdt max
                activation_time(peak) = 1000*time_fine_grid(idx_max_interpolated); %transofrm to ms
                
                % compute RMP from before and after upstroke
                init_RMP = nanmean(signal(init_index:(upstroke_index-ceil(0.005*fps))));
                cross_index = min([upstroke_index + find(signal(upstroke_index:end_index)<=init_RMP,1) - 1, end_index]);
                end_RMP = nanmean(signal(cross_index:end_index));
                
                % compute total RMP
                switch str2double(app.rmpmethod_ROI.Value)
                    case 1 %use mean of previous and after AP
                        resting_V(peak) = mean([init_RMP, end_RMP]);
                    case 2 %use minimum value pre upstroke
                        resting_V(peak) = min(signal(init_index:upstroke_index));
                    case 3 % use minimum value after AP
                        next_peak_index = min([find(time >= (times(peak) + bcl), 1),length(time)-1]);
                        resting_V(peak) = min(signal(upstroke_index: next_peak_index));
                    case 4 % take the average of min before upstroke and min after AP
                        pre_min =  min(signal(init_index:upstroke_index));
                        next_peak_index = min([find(time >= (times(peak) + bcl), 1),length(time)-1]);
                        post_min = min(signal(upstroke_index: next_peak_index));
                        resting_V(peak) = mean([pre_min, post_min]);
                end
                
                %overwrite V_max to just take into account the V_max within the given time window and compute amplitude
                V_max = max(signal(init_index:end_index));
                amp_V = ((100-APD_percentage)/100).*(V_max-resting_V(peak))+resting_V(peak);
                %Find index where the AP has recovered the given percentage (or if it didnt, take the last index)
                %repol_index = upstroke_index + min([find(signal(upstroke_index+1:end_index)<=amp_V,1,'first'),length(signal(upstroke_index:end_index))]);
                repol_index = indices_peaks(peak) + min([find(signal(indices_peaks(peak)+1:end_index)<=amp_V,1,'first'),length(signal(indices_peaks(peak):end_index))]);
                pre_repol_index = repol_index - 1;
                time_fine_grid = linspace(time(pre_repol_index), time(repol_index), interp_points); %generate fine grid for interpolation in ms
                Vm_interpolated = interp1(time(pre_repol_index: repol_index), signal(pre_repol_index: repol_index), time_fine_grid, 'linear');
                repol_index_interpolated =  min([ find(Vm_interpolated<=amp_V,1,'first'), length(time_fine_grid)]);
                
                repol_time(peak) = time_fine_grid(repol_index_interpolated);
                APD(peak) = 1000*repol_time(peak) - activation_time(peak);
                dVdtmax(peak) = dfdtmax/fps;
                Vmax(peak) = V_max;
            end


            %Write output APD, dVdt and BCL
            app.ROI_APD.Value = num2str(APD);
            app.ROI_dVdt.Value = num2str(dVdtmax);
            bcl_list = [diff(activation_time), time(end)*1000 - times(end)*1000];
            app.ROI_BCL.Value = num2str(bcl_list);
            app.ROITime.Value = num2str(activation_time./1000);

            % Plot AP and APD limits on the APDAxes
            colors = jet(256);
            APD_norm = APD- min(APD);
            APD_norm = 255*APD_norm./max(APD_norm) +1;
            
            for peak=1:peak_last
%                 xline(app.ROI_signal_UIAxes, activation_time(peak)/1000,'g--');
%                 xline(app.ROI_signal_UIAxes, repol_time(peak), 'r--');
                %yline(app.APDAxes, resting_V(peak),'b:');
%                 onethird = (repol_time(peak)- activation_time(peak)/1000)/3;
%                 line(app.ROI_signal_UIAxes, [activation_time(peak)/1000-onethird, repol_time(peak)+onethird], [resting_V(peak), resting_V(peak)], 'Color','b', 'LineStyle', ':');
                hold(app.ROI_signal_UIAxes,'on')
                x = [activation_time(peak)/1000, activation_time(peak)/1000,  repol_time(peak), repol_time(peak)];
                y = [resting_V(peak), Vmax(peak), Vmax(peak), resting_V(peak)];

                color = colors(ceil(APD_norm(peak)),:);
                app.APD_patches_handles{peak} = patch(app.ROI_signal_UIAxes, x, y, color, 'FaceAlpha',0.3, 'EdgeColor', 'none');
                
            end
            
            %Save APD , activation times, and BCL to global variables
            app.APD_trace = APD; %in ms
            app.BCL_activation = bcl_list; %in ms
            app.ACT = activation_time;
            
            if ~isempty(app.channel_data)
                set([app.GetdataButton],'Enable','on');
            end
            
            set(app.GetdataButton,'Enable','on');
        end

        % Button pushed function: DeleteAPDsButton
        function DeleteAPDsButtonPushed(app, event)
            for k=1:length(app.APD_patches_handles)
                delete(app.APD_patches_handles{k})
            end
            app.ROI_APD.Value = '';
            app.ROI_dVdt.Value = '';
            app.ROI_BCL.Value = '';
            app.ROITime.Value = '';
        end

        % Button pushed function: GetdataButton
        function GetdataButtonPushed(app, event)
            only_last_per_step = app.UselastEditField.Value/100; %take only last 50% of total stimuli per ste[
            %compare activation time of the AP with stimulation times
            app.APD_freq_BCL_DI_trace = zeros(length(app.stim_times) , 4);
            
            %Find the AP for each stimulus
            for k=1:length(app.stim_times)
                if k<length(app.stim_times)
                    %Find index of an AP that is within two consecutive stimuli
                    index_AP = find(((app.ACT>app.stim_times(k)).* (app.ACT<app.stim_times(k+1)))==1);
                else
                    index_AP = find(((app.ACT>app.stim_times(k)).* (app.ACT<(app.stim_times(k)+app.stim_times(k)-app.stim_times(k-1))))==1);
                end
                
                if isempty(index_AP) %if there was no AP within the stimulation period...
                    if k>round(app.stim_per_step*only_last_per_step) % if loss of capture wasnt due to first capturing attempt at the beggining of the protocol break the loop
                        
                        if strcmp(app.IgnorelossofcaptureSwitch.Value,'Off')
                            app.APD_freq_BCL_DI_trace(k:end,:) = NaN;
                            break
                           
                        else
                            app.APD_freq_BCL_DI_trace(k,:) = NaN;
                        end
                    else %if that happened during the first capturing beats, just write NaN and continue
                        app.APD_freq_BCL_DI_trace(k,:) = NaN;
                    end

                elseif length(index_AP)>1 % If more than one AP was detected...
                     if k>round(app.stim_per_step*only_last_per_step) %at some point different than the begining, break the loop
                        if strcmp(app.IgnorelossofcaptureSwitch.Value,'Off')
                            app.APD_freq_BCL_DI_trace(k:end,:) = NaN;
                            break
                        else
                            app.APD_freq_BCL_DI_trace(k,:) = NaN;
                        end
                     else %otherwise continue
                        app.APD_freq_BCL_DI_trace(k,:) = NaN;
                     end
                     
                else %if one AP was detected write it in the trace with its frequency, BCL and DI
                    APD = app.APD_trace(index_AP);
                    freq = app.frequencies(k);
                    BCL = 1000/(freq);
                    DI = BCL - APD;
                    app.APD_freq_BCL_DI_trace(k,:) = [ APD, freq, BCL , DI ];
                end

               
            end
            
            app.APD_freq_BCL_DI_trace_valid = zeros(round(length(app.stim_times)*only_last_per_step) , 4);
            app.meanAPD_stdAPD_freq_BCL_DI_trace = zeros(app.num_steps,6);
            for k=1:app.num_steps
                a = round((k-1)*app.stim_per_step*only_last_per_step +1);
                b = round((k-1)*app.stim_per_step*only_last_per_step + app.stim_per_step*only_last_per_step);
                
                c =  round(k* app.stim_per_step - app.stim_per_step*only_last_per_step +1);
                d = round(c-1 + app.stim_per_step*only_last_per_step);
                
                if d<=size(app.APD_freq_BCL_DI_trace,1) % If there are less stimuli than expected (due to loss of capture at some point)
                    app.APD_freq_BCL_DI_trace_valid(a:b ,:) = app.APD_freq_BCL_DI_trace(c:d,:);
                    if strcmp(app.IgnorelossofcaptureSwitch.Value,'Off')
                        mean_APD = mean(app.APD_freq_BCL_DI_trace_valid(a:b ,1));
                        std_APD = std(app.APD_freq_BCL_DI_trace_valid(a:b ,1));
                        sem_APD = std_APD/sqrt(length(app.APD_freq_BCL_DI_trace_valid(a:b ,1))); %calculate standard error
                    else
                        mean_APD = mean(app.APD_freq_BCL_DI_trace_valid(a:b ,1),'omitnan');
                        std_APD = std(app.APD_freq_BCL_DI_trace_valid(a:b ,1),'omitnan');
                        vector = app.APD_freq_BCL_DI_trace_valid(a:b ,1);
                        sem_APD = std_APD/sqrt(length(vector(~isnan(vector))));
                    end
                    
                    freq = app.min_freq + (k-1)*app.freq_step;
                    BCL = app.APD_freq_BCL_DI_trace_valid(a ,3);
                    DI = app.APD_freq_BCL_DI_trace_valid(a ,4);
                    app.meanAPD_stdAPD_freq_BCL_DI_trace(k,:) = [ mean_APD, std_APD, freq, BCL, DI, sem_APD];
                end
                
            end

            set([ app.APDvsfrequencymeananderrorButton, app.APDvsBCLmeananderrorButton, app.APDsequentialallAPButton, app.APDvsDImeananderrorButton,app.ErrorbartypeDropDown],'Enable', 'on')
            set([app.ExportfulltraceButton, app.ExportmeantraceButton, app.ExportselectedAPtraceButton],'Enable','on');
            APDvsfrequencymeananderrorButtonPushed(app, event)

        end

        % Button pushed function: APDvsBCLmeananderrorButton
        function APDvsBCLmeananderrorButtonPushed(app, event)
            if ~isempty(app.restitution_plot_handle)
                delete(app.restitution_plot_handle)
            end
            
            APD_mean = app.meanAPD_stdAPD_freq_BCL_DI_trace(:,1);
            BCL = app.meanAPD_stdAPD_freq_BCL_DI_trace(:,4);

            switch_option = app.ErrorbartypeDropDown.Value;
            if switch_option == '1' %std
                APD_errorbar = app.meanAPD_stdAPD_freq_BCL_DI_trace(:,2);
            else
                APD_errorbar = app.meanAPD_stdAPD_freq_BCL_DI_trace(:,6);
            end

            
            app.restitution_plot_handle = errorbar(app.restitution_curve_UIAxes, BCL,APD_mean,APD_errorbar,'d');
            app.restitution_curve_UIAxes.XLabel.String = 'BCL (ms)';
            set ( app.restitution_curve_UIAxes, 'xdir', 'normal' )
        end

        % Button pushed function: APDvsfrequencymeananderrorButton
        function APDvsfrequencymeananderrorButtonPushed(app, event)
            if ~isempty(app.restitution_plot_handle)
                delete(app.restitution_plot_handle)
            end
            
            APD_mean = app.meanAPD_stdAPD_freq_BCL_DI_trace(:,1);
            freq = app.meanAPD_stdAPD_freq_BCL_DI_trace(:,3);

            switch_option = app.ErrorbartypeDropDown.Value;
            if switch_option == '1' %std
                APD_errorbar = app.meanAPD_stdAPD_freq_BCL_DI_trace(:,2);
            else
                APD_errorbar = app.meanAPD_stdAPD_freq_BCL_DI_trace(:,6);
            end
            
            app.restitution_plot_handle = errorbar(app.restitution_curve_UIAxes, freq,APD_mean,APD_errorbar,'d');
            app.restitution_curve_UIAxes.XLabel.String = 'Frequency (Hz)';
            set ( app.restitution_curve_UIAxes, 'xdir', 'reverse' )
        end

        % Button pushed function: APDvsDImeananderrorButton
        function APDvsDImeananderrorButtonPushed(app, event)
            if ~isempty(app.restitution_plot_handle)
                delete(app.restitution_plot_handle)
            end
            
            APD_mean = app.meanAPD_stdAPD_freq_BCL_DI_trace(:,1);
            DI = app.meanAPD_stdAPD_freq_BCL_DI_trace(:,5);

            switch_option = app.ErrorbartypeDropDown.Value;
            if switch_option == '1' %std
                APD_errorbar = app.meanAPD_stdAPD_freq_BCL_DI_trace(:,2);
            else
                APD_errorbar = app.meanAPD_stdAPD_freq_BCL_DI_trace(:,6);
            end
            
            app.restitution_plot_handle = errorbar(app.restitution_curve_UIAxes, DI,APD_mean,APD_errorbar,'d');
            app.restitution_curve_UIAxes.XLabel.String = 'DI (ms)';
            set ( app.restitution_curve_UIAxes, 'xdir', 'normal' )
        end

        % Button pushed function: APDsequentialallAPButton
        function APDsequentialallAPButtonPushed(app, event)
            if ~isempty(app.restitution_plot_handle)
                delete(app.restitution_plot_handle)
            end
                        
            app.restitution_plot_handle = plot(app.restitution_curve_UIAxes, app.APD_freq_BCL_DI_trace(:,1),'o-');
            app.restitution_curve_UIAxes.XLabel.String = 'AP number (ms)';
            set ( app.restitution_curve_UIAxes, 'xdir', 'reverse' )
        end

        % Button pushed function: ExportfulltraceButton
        function ExportfulltraceButtonPushed(app, event)
            % Export full trace of APD delivered from stimuli
            T = array2table(app.APD_freq_BCL_DI_trace);
            T.Properties.VariableNames(1:end) = {'APD', 'freq', 'BCL', 'DI'};
            switch str2double(app.FileformatDropDown.Value)
                case 1 %xlsx Excel
                    base_name = ['APD_restitution_sequential_trace' num2str(app.ShowROIDropDown.Value)];
                    file_type = '.xlsx';
                    file_name = file_exists(app, base_name, file_type);
                    writetable(T,file_name);

                case 2 % csv comma delimited file
                    base_name = ['APD_restitution_sequential_trace' num2str(app.ShowROIDropDown.Value)];
                    file_type = '.csv';
                    file_name = file_exists(app, base_name, file_type);
                    writetable(T,file_name);

                case 3 % .m matlab file
                    base_name = ['APD_restitution_sequential_trace' num2str(app.ShowROIDropDown.Value)];
                    file_type = '.mat';
                    file_name = file_exists(app, base_name, file_type);
                    save(file_name,'T','-mat');


            end
            
        end

        % Button pushed function: ExportselectedAPtraceButton
        function ExportselectedAPtraceButtonPushed(app, event)
            %Export only the data regarding the last X% of AP at each frequency
            T = array2table(app.APD_freq_BCL_DI_trace_valid);
            T.Properties.VariableNames(1:end) = {'APD', 'freq', 'BCL', 'DI'};
            switch str2double(app.FileformatDropDown.Value)
                case 1 %xlsx Excel
                    base_name = ['APD_restitution_selectedAP_trace' num2str(app.ShowROIDropDown.Value)];
                    file_type = '.xlsx';
                    file_name = file_exists(app, base_name, file_type);
                    writetable(T,file_name);

                case 2 % csv comma delimited file
                    base_name = ['APD_restitution_selectedAP_trace' num2str(app.ShowROIDropDown.Value)];
                    file_type = '.csv';
                    file_name = file_exists(app, base_name, file_type);
                    writetable(T,file_name);

                case 3 % .m matlab file
                    base_name = ['APD_restitution_selectedAP_trace' num2str(app.ShowROIDropDown.Value)];
                    file_type = '.mat';
                    file_name = file_exists(app, base_name, file_type);
                    save(file_name,'T','-mat');


            end
            
        end

        % Button pushed function: ChangeFolderButtonSaveto
        function ChangeFolderButtonSavetoPushed(app, event)
            %Select different saving folder than default
            app.SavetoEditField.Value = uigetdir();
            app.SavetoEditField.Value = [app.SavetoEditField.Value '/'];
        end

        % Button pushed function: ExportmeantraceButton
        function ExportmeantraceButtonPushed(app, event)
            T = array2table(app.meanAPD_stdAPD_freq_BCL_DI_trace);
            T.Properties.VariableNames(1:end) = {'meanAPD', 'stdAPD', 'freq', 'BCL', 'DI', 'SEM_APD'};
            switch str2double(app.FileformatDropDown.Value)
                case 1 %xlsx Excel
                    base_name = ['APD_restitution_meanAPD_trace' num2str(app.ShowROIDropDown.Value)];
                    file_type = '.xlsx';
                    file_name = file_exists(app, base_name, file_type);
                    writetable(T,file_name);

                case 2 % csv comma delimited file
                    base_name = ['APD_restitution_meanAPD_trace' num2str(app.ShowROIDropDown.Value)];
                    file_type = '.csv';
                    file_name = file_exists(app, base_name, file_type);
                    writetable(T,file_name);

                case 3 % .m matlab file
                    base_name = ['APD_restitution_meanAPD_trace' num2str(app.ShowROIDropDown.Value)];
                    file_type = '.mat';
                    file_name = file_exists(app, base_name, file_type);
                    save(file_name,'T','-mat');


            end
            
        end

        % Menu selected function: SaveWorkspaceMenu
        function SaveWorkspaceMenuSelected(app, event)
            
            %Select different saving folder than default
            selected_folder = uigetdir();
            if selected_folder ~= 0
                app.SavetoEditField.Value = [selected_folder '/'];
                
                progress_bar = uiprogressdlg(app.APD_analysis_UIFigure,'Title','Saving','Indeterminate','on');
                % Create save file name by giving smallest non-existing number
                save_file = file_exists(app,'APD_rest_workspace','.mat');
                
                first_frame  = app.first_frame;
                file_info = app.file_info;
                ROI_handle = app.ROI_handle;
                ROI_signal = app.ROI_signal;
                ROI_time = app.ROI_time;
                signal_plot_handle = app.signal_plot_handle;
                fps = app.fps;
                channel_data = app.channel_data;
                BioPAC_plot_handle = app.BioPAC_plot_handle;
                min_freq = app.min_freq;
                max_freq = app.max_freq;
                num_steps = app.num_steps;
                frequencies = app.frequencies;
                stim_dur = app.stim_dur;
                stim_bcl = app.stim_bcl;
                stim_freq = app.stim_freq;
                freq_step = app.freq_step;
                stim_per_step = app.stim_per_step;
                stim_times = app.stim_times;
                APD_patches_handles = app.APD_patches_handles;
                APD_trace = app.APD_trace; 
                BCL_activation = app.BCL_activation;
                ACT = app.ACT;
                meanAPD_stdAPD_freq_BCL_DI_trace = app.meanAPD_stdAPD_freq_BCL_DI_trace;
                APD_freq_BCL_DI_trace_valid = app.APD_freq_BCL_DI_trace_valid; %trace with only the last X selected AP
                APD_freq_BCL_DI_trace = app.APD_freq_BCL_DI_trace; %trace with all AP delivered from a stimulus
                restitution_plot_handle = app.restitution_plot_handle;
                sif_header =    app.sif_header;
                total_recording_time = app.total_recording_time;
                
                save(save_file, ...
                'first_frame'  ,...
                'file_info' ,...
                'ROI_handle' ,...
                'ROI_signal' ,...
                'ROI_time' ,...
                'signal_plot_handle' ,...
                'fps' ,...
                'channel_data' ,...
                'BioPAC_plot_handle' ,...
                'min_freq' ,...
                'max_freq' ,...
                'num_steps' ,...
                'frequencies' ,...
                'stim_dur' ,...
                'stim_bcl' ,...
                'stim_freq' ,...
                'freq_step' ,...
                'stim_per_step' ,...
                'stim_times' ,...
                'APD_patches_handles' ,...
                'APD_trace' ,... 
                'BCL_activation' ,...
                'ACT' ,...
                'meanAPD_stdAPD_freq_BCL_DI_trace' ,...
                'APD_freq_BCL_DI_trace_valid' ,... 
                'APD_freq_BCL_DI_trace',... 
                'restitution_plot_handle' ,...
                'sif_header',...
                'total_recording_time',...
                '-mat','-v7.3');
    
                close(progress_bar)
            end
        end

        % Menu selected function: LoadWorkspaceMenu
        function LoadWorkspaceMenuSelected(app, event)
            % Select file
            [filename,filepath] = uigetfile('*.mat','Select the workspace file'); % Open file selector
            
            progress_bar = uiprogressdlg(app.APD_analysis_UIFigure,'Title','Loading','Indeterminate','on');
            
            set(app.TabGroup, 'Visible', 'on')
            
            app.SavetoEditField.Value = filepath;
            
            % Load
            load([filepath filename], ...
            'first_frame'  ,...
            'file_info' ,...
            'ROI_handle' ,...
            'ROI_signal' ,...
            'ROI_time' ,...
            'signal_plot_handle' ,...
            'fps' ,...
            'channel_data' ,...
            'BioPAC_plot_handle' ,...
            'min_freq' ,...
            'max_freq' ,...
            'num_steps' ,...
            'frequencies' ,...
            'stim_dur' ,...
            'stim_bcl' ,...
            'stim_freq' ,...
            'freq_step' ,...
            'stim_per_step' ,...
            'stim_times' ,...
            'APD_patches_handles' ,...
            'APD_trace' ,... 
            'BCL_activation' ,...
            'ACT' ,...
            'meanAPD_stdAPD_freq_BCL_DI_trace' ,...
            'APD_freq_BCL_DI_trace_valid' ,... 
            'APD_freq_BCL_DI_trace',...
            'sif_header',...
            'total_recording_time',...
            'restitution_plot_handle');
        
            app.first_frame = first_frame;
            app.file_info = file_info;
            app.ROI_handle = ROI_handle; 
            app.ROI_signal = ROI_signal;
            app.ROI_time = ROI_time;
            app.signal_plot_handle = signal_plot_handle;
            app.fps = fps;
            app.channel_data = channel_data;
            app.BioPAC_plot_handle = BioPAC_plot_handle;
            app.min_freq =  min_freq;
            app.max_freq = max_freq;
            app.num_steps = num_steps;
            app.frequencies = frequencies;
            app.stim_dur = stim_dur;
            app.stim_bcl = stim_bcl;
            app.stim_freq = stim_freq;
            app.freq_step = freq_step;
            app.stim_per_step = stim_per_step;
            app.stim_times = stim_times;
            app.APD_patches_handles = APD_patches_handles;
            app.APD_trace = APD_trace;
            app.BCL_activation = BCL_activation;
            app.ACT = ACT;
            app.meanAPD_stdAPD_freq_BCL_DI_trace = meanAPD_stdAPD_freq_BCL_DI_trace;
            app.APD_freq_BCL_DI_trace_valid = APD_freq_BCL_DI_trace_valid;
            app.APD_freq_BCL_DI_trace = APD_freq_BCL_DI_trace;
            app.restitution_plot_handle = restitution_plot_handle;
            app.sif_header = sif_header;
            app.total_recording_time = total_recording_time;

           
            % Plot first frame
            panel_display_position = get(app.panel_display_area, 'Position');
            app.main_display_axes = axes(app.panel_display_area, 'Units','Pixels','Position',[1,1,panel_display_position(3),panel_display_position(4)],'XTick',[],'YTick',[]);
            imshow(app.first_frame,'Parent',app.main_display_axes);
            
            
            % Plot ROI on frame
            if ~isempty(app.ROI_handle)
                cellfun(@(x) set(x,'Parent',app.main_display_axes),app.ROI_handle);
            end


            % Update ROI dropdowns
            update_ROI_dropdowns(app)
            
             % Plot ROI signal
            if ~isempty(app.signal_plot_handle)
                plot_ROI_signal(app,app.ShowROIDropDown.Value)
            end

            
            if ~isempty(app.channel_data)
                set([app.ShowBioPACDropDown app.SwitchBioPACShow_Hide],'Enable','on');
                app.SwitchBioPACShow_Hide;
            end
            
            
            if ~isempty(app.channel_data) && ~isempty(app.APD_trace)
                set([app.GetdataButton],'Enable','on');
            end
            
            
            %update tooltip
            tool_tip_update(app)


            % Enable other buttons
            set([app.ROIdropdown, app.deleteROI, app.DeleteallROIsButton, app.LoadROIsignalButton, app.save_ROI],'Enable','on');
            
        end

        % Close request function: APD_analysis_UIFigure
        function APD_analysis_UIFigureCloseRequest(app, event)
            close_check = uiconfirm(app.APD_analysis_UIFigure,'Are you sure you wish to close?','Confirm close','Icon','Warning');
            if length(close_check) == 2
                delete(app)
            end
        end

        % Value changed function: ErrorbartypeDropDown
        function ErrorbartypeDropDownValueChanged(app, event)
            value = app.ErrorbartypeDropDown.Value;
            
        end

        % Button pushed function: ManuallydefineparametersButton
        function ManuallydefineparametersButtonPushed(app, event)
            app.MinimumfrequencyHzEditField.Editable = 1;
            app.MaximumfrequencyHzEditField.Editable = 1;
            app.StimulusdurationmsEditField.Editable = 1;
            app.StimuliperstepEditField.Editable = 1;
            app.FrequencystepHzEditField.Editable = 1;
            app.StimulusTypeEditField.Editable = 1;
        end

        % Button pushed function: ParametersdefinedButton
        function ParametersdefinedButtonPushed(app, event)
            app.BioPAC_path_file = ['placeholder.fits'];
            load_BioPAC(app);
            
            set([app.ShowBioPACDropDown app.SwitchBioPACShow_Hide],'Enable','on');
            
            if ~isempty(app.ROI_APD.Value)
                set([app.GetdataButton],'Enable','on');
            end
            
            app.SwitchBioPACShow_Hide;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.APD_analysis_UIFigure = uifigure('Visible', 'off');
            app.APD_analysis_UIFigure.Name = 'UI Figure';
            app.APD_analysis_UIFigure.CloseRequestFcn = createCallbackFcn(app, @APD_analysis_UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.APD_analysis_UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadfileMenu = uimenu(app.FileMenu);
            app.LoadfileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadfileMenuSelected, true);
            app.LoadfileMenu.Text = 'Load file';
            app.WorkspaceMenu = uimenu(app.FileMenu);
            app.WorkspaceMenu.Text = 'Workspace';
            app.SaveWorkspaceMenu = uimenu(app.WorkspaceMenu);
            app.SaveWorkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveWorkspaceMenuSelected, true);
            app.SaveWorkspaceMenu.Text = 'Save Workspace';
            app.LoadWorkspaceMenu = uimenu(app.WorkspaceMenu);
            app.LoadWorkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadWorkspaceMenuSelected, true);
            app.LoadWorkspaceMenu.Text = 'Load Workspace';
            app.HelpMenu = uimenu(app.FileMenu);
            app.HelpMenu.Text = 'Help';
            app.TabGroup = uitabgroup(app.APD_analysis_UIFigure);
            app.ViewframeandROIsTab = uitab(app.TabGroup);
            app.ViewframeandROIsTab.Title = 'View frame and ROIs';
            app.GridLayout = uigridlayout(app.ViewframeandROIsTab);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.select_ROI = uibutton(app.GridLayout2, 'push');
            app.select_ROI.ButtonPushedFcn = createCallbackFcn(app, @select_ROIButtonPushed, true);
            app.select_ROI.Tooltip = {'Select region of interest. You will need to load the signal with the ''Load ROI signal from sif'' button'};
            app.select_ROI.Text = 'Select ROI';
            app.ROI_type = uidropdown(app.GridLayout2);
            app.ROI_type.Items = {'2', '4', '10', '20', '30', '40', '50', '75', '100', '125', '150', '200'};
            app.ROI_type.Tooltip = {'Shape and size of ROI to be selected'};
            app.ROI_type.Value = '2';
            app.deleteROI = uibutton(app.GridLayout2, 'push');
            app.deleteROI.ButtonPushedFcn = createCallbackFcn(app, @delete_ROI, true);
            app.deleteROI.Enable = 'off';
            app.deleteROI.Tooltip = {'Delete selected ROI.'};
            app.deleteROI.Text = 'Delete ROI';
            app.ROIdropdown = uidropdown(app.GridLayout2);
            app.ROIdropdown.Items = {'ROI 1'};
            app.ROIdropdown.ItemsData = {'1'};
            app.ROIdropdown.Enable = 'off';
            app.ROIdropdown.Tooltip = {'ROI selector. The ROI selected here will be deleted by the button delete ROI'};
            app.ROIdropdown.Value = '1';
            app.DeleteallROIsButton = uibutton(app.GridLayout2, 'push');
            app.DeleteallROIsButton.ButtonPushedFcn = createCallbackFcn(app, @delete_all_ROIs, true);
            app.DeleteallROIsButton.Enable = 'off';
            app.DeleteallROIsButton.Tooltip = {'Delete all ROIs and handels.'};
            app.DeleteallROIsButton.Text = 'Delete all ROIs';
            app.save_ROI = uibutton(app.GridLayout2, 'push');
            app.save_ROI.ButtonPushedFcn = createCallbackFcn(app, @save_ROIButtonPushed, true);
            app.save_ROI.Enable = 'off';
            app.save_ROI.Tooltip = {'Save position of ROIs to load on another file.'};
            app.save_ROI.Text = 'Save ROIs 💾';
            app.load_ROI = uibutton(app.GridLayout2, 'push');
            app.load_ROI.ButtonPushedFcn = createCallbackFcn(app, @load_ROIButtonPushed, true);
            app.load_ROI.Tooltip = {'Load ROI position (the signal will not be loaded. Use save workspace to save the signals)'};
            app.load_ROI.Text = 'Load ROIs';
            app.LoadROIsignalButton = uibutton(app.GridLayout2, 'push');
            app.LoadROIsignalButton.ButtonPushedFcn = createCallbackFcn(app, @LoadROIsignalButtonPushed, true);
            app.LoadROIsignalButton.Enable = 'off';
            app.LoadROIsignalButton.Tooltip = {'Load  signal from the selected ROI. This can take several minutes.'};
            app.LoadROIsignalButton.Text = 'Load ROI signal';
            app.SETCOLOURMAPSATURATIONPanel = uipanel(app.GridLayout2);
            app.SETCOLOURMAPSATURATIONPanel.Title = 'SET COLOUR MAP SATURATION';
            app.GridLayout11 = uigridlayout(app.SETCOLOURMAPSATURATIONPanel);
            app.MaxLabel = uilabel(app.GridLayout11);
            app.MaxLabel.Text = 'Max (%)';
            app.MaxColourmap = uieditfield(app.GridLayout11, 'numeric');
            app.MaxColourmap.ValueChangedFcn = createCallbackFcn(app, @MaxColourmapValueChanged, true);
            app.MaxColourmap.Value = 100;
            app.panel_display_area = uipanel(app.GridLayout);
            app.SignalprocessingandAPDTab = uitab(app.TabGroup);
            app.SignalprocessingandAPDTab.Title = 'Signal processing and APD';
            app.GridLayout3 = uigridlayout(app.SignalprocessingandAPDTab);
            app.ROI_signal_UIAxes = uiaxes(app.GridLayout3);
            xlabel(app.ROI_signal_UIAxes, 'time(s)')
            ylabel(app.ROI_signal_UIAxes, 'signal (a.u.)')
            app.ROI_signal_UIAxes.XTickLabelRotationMode = 'manual';
            app.ROI_signal_UIAxes.YTickLabelRotationMode = 'manual';
            app.ROI_signal_UIAxes.ZTickLabelRotationMode = 'manual';
            app.Panel_2 = uipanel(app.GridLayout3);
            app.GridLayout5 = uigridlayout(app.Panel_2);
            app.TabGroup2 = uitabgroup(app.GridLayout5);
            app.LoadBioPACTab = uitab(app.TabGroup2);
            app.LoadBioPACTab.Title = 'Load BioPAC';
            app.GridLayout6 = uigridlayout(app.LoadBioPACTab);
            app.ChangesearchfolderButton = uibutton(app.GridLayout6, 'push');
            app.ChangesearchfolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChangesearchfolderButtonPushed, true);
            app.ChangesearchfolderButton.Text = 'Change search folder';
            app.ManualfileselectionandimportButton = uibutton(app.GridLayout6, 'push');
            app.ManualfileselectionandimportButton.ButtonPushedFcn = createCallbackFcn(app, @ManualfileselectionandimportButtonPushed, true);
            app.ManualfileselectionandimportButton.Text = 'Manual file selection and import';
            app.SwitchBioPACShow_Hide = uiswitch(app.GridLayout6, 'slider');
            app.SwitchBioPACShow_Hide.Items = {'Hide', 'Show'};
            app.SwitchBioPACShow_Hide.ValueChangedFcn = createCallbackFcn(app, @SwitchBioPACShow_HideValueChanged, true);
            app.SwitchBioPACShow_Hide.Enable = 'off';
            app.SwitchBioPACShow_Hide.Value = 'Hide';
            app.ShowBioPACDropDown = uidropdown(app.GridLayout6);
            app.ShowBioPACDropDown.Items = {'ECGI', 'ECGII', 'ECGIII', 'Electrical stimulation', 'LED stimulation', 'Camera'};
            app.ShowBioPACDropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.ShowBioPACDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowBioPACDropDownValueChanged, true);
            app.ShowBioPACDropDown.Enable = 'off';
            app.ShowBioPACDropDown.Value = '1';
            app.AutomaticSearchFolderEditFieldLabel = uilabel(app.GridLayout6);
            app.AutomaticSearchFolderEditFieldLabel.Text = 'Automatic Search Folder';
            app.AutomaticSearchFolderEditField = uieditfield(app.GridLayout6, 'text');
            app.BioPACfileEditFieldLabel = uilabel(app.GridLayout6);
            app.BioPACfileEditFieldLabel.Text = 'BioPAC file';
            app.BioPACfileEditField = uieditfield(app.GridLayout6, 'text');
            app.BioPACfileEditField.Editable = 'off';
            app.BioPACfileEditField.Enable = 'off';
            app.MOVEBIOPACSIGNALPanel = uipanel(app.GridLayout6);
            app.MOVEBIOPACSIGNALPanel.Title = 'MOVE BIOPAC SIGNAL';
            app.GridLayout12 = uigridlayout(app.MOVEBIOPACSIGNALPanel);
            app.ReduceinYaxisButton = uibutton(app.GridLayout12, 'push');
            app.ReduceinYaxisButton.ButtonPushedFcn = createCallbackFcn(app, @ReduceinYaxisButtonPushed, true);
            app.ReduceinYaxisButton.Tooltip = {'Zoom out Y axis of BioPAC signal'};
            app.ReduceinYaxisButton.Text = 'Reduce in Y axis';
            app.MoveupButton = uibutton(app.GridLayout12, 'push');
            app.MoveupButton.ButtonPushedFcn = createCallbackFcn(app, @MoveupButtonPushed, true);
            app.MoveupButton.Tooltip = {'Move BioPAC trace upwards in the graph'};
            app.MoveupButton.Text = 'Move up';
            app.IncreaseinYaxisButton = uibutton(app.GridLayout12, 'push');
            app.IncreaseinYaxisButton.ButtonPushedFcn = createCallbackFcn(app, @IncreaseinYaxisButtonPushed, true);
            app.IncreaseinYaxisButton.Tooltip = {'Zoom in Y axis of BioPAC signal'};
            app.IncreaseinYaxisButton.Text = 'Increase in Y axis';
            app.MovedownButton = uibutton(app.GridLayout12, 'push');
            app.MovedownButton.ButtonPushedFcn = createCallbackFcn(app, @MovedownButtonPushed, true);
            app.MovedownButton.Tooltip = {'Move BioPAC trace downwards in the graph'};
            app.MovedownButton.Text = 'Move down';
            app.PACINGPROTOCOLINFORMATIONPanel = uipanel(app.GridLayout6);
            app.PACINGPROTOCOLINFORMATIONPanel.Title = 'PACING PROTOCOL INFORMATION';
            app.GridLayout13 = uigridlayout(app.PACINGPROTOCOLINFORMATIONPanel);
            app.StimulusTypeEditFieldLabel = uilabel(app.GridLayout13);
            app.StimulusTypeEditFieldLabel.Text = 'Stimulus Type';
            app.StimulusTypeEditField = uieditfield(app.GridLayout13, 'text');
            app.StimulusTypeEditField.Editable = 'off';
            app.MinimumfrequencyHzEditFieldLabel = uilabel(app.GridLayout13);
            app.MinimumfrequencyHzEditFieldLabel.Text = 'Minimum frequency (Hz)';
            app.MinimumfrequencyHzEditField = uieditfield(app.GridLayout13, 'text');
            app.MinimumfrequencyHzEditField.Editable = 'off';
            app.MaximumfrequencyHzEditFieldLabel = uilabel(app.GridLayout13);
            app.MaximumfrequencyHzEditFieldLabel.Text = 'Maximum frequency (Hz)';
            app.MaximumfrequencyHzEditField = uieditfield(app.GridLayout13, 'text');
            app.MaximumfrequencyHzEditField.Editable = 'off';
            app.FrequencystepHzEditFieldLabel = uilabel(app.GridLayout13);
            app.FrequencystepHzEditFieldLabel.Text = 'Frequency step (Hz)';
            app.FrequencystepHzEditField = uieditfield(app.GridLayout13, 'text');
            app.FrequencystepHzEditField.Editable = 'off';
            app.StimuliperstepEditFieldLabel = uilabel(app.GridLayout13);
            app.StimuliperstepEditFieldLabel.Text = 'Stimuli per step';
            app.StimuliperstepEditField = uieditfield(app.GridLayout13, 'text');
            app.StimuliperstepEditField.Editable = 'off';
            app.StimulusdurationmsEditFieldLabel = uilabel(app.GridLayout13);
            app.StimulusdurationmsEditFieldLabel.Text = 'Stimulus duration (ms)';
            app.StimulusdurationmsEditField = uieditfield(app.GridLayout13, 'text');
            app.StimulusdurationmsEditField.Editable = 'off';
            app.ManuallydefineparametersButton = uibutton(app.GridLayout6, 'push');
            app.ManuallydefineparametersButton.ButtonPushedFcn = createCallbackFcn(app, @ManuallydefineparametersButtonPushed, true);
            app.ManuallydefineparametersButton.Text = 'Manually define parameters';
            app.ParametersdefinedButton = uibutton(app.GridLayout6, 'push');
            app.ParametersdefinedButton.ButtonPushedFcn = createCallbackFcn(app, @ParametersdefinedButtonPushed, true);
            app.ParametersdefinedButton.Text = 'Parameters defined';
            app.TemporalFilteringTab = uitab(app.TabGroup2);
            app.TemporalFilteringTab.Title = 'Temporal Filtering';
            app.GridLayout8 = uigridlayout(app.TemporalFilteringTab);
            app.temporalfilter = uibutton(app.GridLayout8, 'push');
            app.temporalfilter.ButtonPushedFcn = createCallbackFcn(app, @temporalfilterButtonPushed, true);
            app.temporalfilter.Tooltip = {'Apply temporal selected filter to whole stack'};
            app.temporalfilter.Text = 'Apply temporal filter';
            app.TemporalFilterType = uidropdown(app.GridLayout8);
            app.TemporalFilterType.Items = {'Butterworth', 'FIR'};
            app.TemporalFilterType.ItemsData = {'1', '2'};
            app.TemporalFilterType.Tooltip = {'Filter type'};
            app.TemporalFilterType.Value = '1';
            app.CutofffrequencyHzLabel = uilabel(app.GridLayout8);
            app.CutofffrequencyHzLabel.Text = 'Cutoff frequency (Hz):';
            app.cutofffrequency = uieditfield(app.GridLayout8, 'numeric');
            app.cutofffrequency.Limits = [0 Inf];
            app.cutofffrequency.ValueChangedFcn = createCallbackFcn(app, @cutoff_limit, true);
            app.cutofffrequency.Tooltip = {'Frequency cut off for Butterworth filter'};
            app.cutofffrequency.Value = 30;
            app.FilterorderLabel = uilabel(app.GridLayout8);
            app.FilterorderLabel.Text = 'Filter order:';
            app.filterorder = uieditfield(app.GridLayout8, 'numeric');
            app.filterorder.Limits = [1 Inf];
            app.filterorder.Tooltip = {'Filter order for Butterworth'};
            app.filterorder.Value = 5;
            app.UndoButton = uibutton(app.GridLayout8, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.APDParametersTab = uitab(app.TabGroup2);
            app.APDParametersTab.Title = 'APD Parameters';
            app.GridLayout7 = uigridlayout(app.APDParametersTab);
            app.APDpercentageEditFieldLabel = uilabel(app.GridLayout7);
            app.APDpercentageEditFieldLabel.Text = 'APD percentage';
            app.APDpercentageEditField = uieditfield(app.GridLayout7, 'numeric');
            app.APDpercentageEditField.Tooltip = {'Percentage of repolarisation to compute APD end'};
            app.APDpercentageEditField.Value = 80;
            app.dfdt_maxpercentageEditFieldLabel = uilabel(app.GridLayout7);
            app.dfdt_maxpercentageEditFieldLabel.Enable = 'off';
            app.dfdt_maxpercentageEditFieldLabel.Text = 'dfdt_max percentage';
            app.dfdt_maxpercentageEditField = uieditfield(app.GridLayout7, 'numeric');
            app.dfdt_maxpercentageEditField.Enable = 'off';
            app.dfdt_maxpercentageEditField.Value = 100;
            app.RMPcalculationDropDownLabel = uilabel(app.GridLayout7);
            app.RMPcalculationDropDownLabel.Text = 'RMP calculation';
            app.rmpmethod_ROI = uidropdown(app.GridLayout7);
            app.rmpmethod_ROI.Items = {'-BCL/2 to BCL/2', 'Pre upstroke min', 'Post AP min', 'Avg( Pre Post min)'};
            app.rmpmethod_ROI.ItemsData = {'1', '2', '3', '4'};
            app.rmpmethod_ROI.Tooltip = {'Resting membrane potential estimation method. '};
            app.rmpmethod_ROI.Value = '1';
            app.ComputeallAPDsButton = uibutton(app.GridLayout7, 'push');
            app.ComputeallAPDsButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeallAPDsButtonPushed, true);
            app.ComputeallAPDsButton.Text = 'Compute all APDs';
            app.APDmsEditFieldLabel = uilabel(app.GridLayout7);
            app.APDmsEditFieldLabel.Text = 'APD (ms)';
            app.ROI_APD = uieditfield(app.GridLayout7, 'text');
            app.ROI_APD.Editable = 'off';
            app.dVdtmaxaumsEditFieldLabel = uilabel(app.GridLayout7);
            app.dVdtmaxaumsEditFieldLabel.Text = 'dV/dt max (a.u./ms)';
            app.ROI_dVdt = uieditfield(app.GridLayout7, 'text');
            app.ROI_dVdt.Editable = 'off';
            app.BCLmsEditFieldLabel = uilabel(app.GridLayout7);
            app.BCLmsEditFieldLabel.Text = 'BCL (ms)';
            app.ROI_BCL = uieditfield(app.GridLayout7, 'text');
            app.ROI_BCL.Editable = 'off';
            app.DeleteAPDsButton = uibutton(app.GridLayout7, 'push');
            app.DeleteAPDsButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAPDsButtonPushed, true);
            app.DeleteAPDsButton.Text = 'Delete APDs';
            app.TimesLabel = uilabel(app.GridLayout7);
            app.TimesLabel.Text = 'Time (s)';
            app.ROITime = uieditfield(app.GridLayout7, 'text');
            app.ROITime.Editable = 'off';
            app.MinprominenceEditFieldLabel = uilabel(app.GridLayout7);
            app.MinprominenceEditFieldLabel.Text = 'Min prominence';
            app.MinprominenceEditField = uieditfield(app.GridLayout7, 'numeric');
            app.MinprominenceEditField.Value = 0.2;
            app.ShowROIDropDownLabel = uilabel(app.GridLayout3);
            app.ShowROIDropDownLabel.Text = 'Show ROI';
            app.ShowROIDropDown = uidropdown(app.GridLayout3);
            app.ShowROIDropDown.Items = {'ROI 1'};
            app.ShowROIDropDown.ItemsData = {'1'};
            app.ShowROIDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowROIDropDownValueChanged, true);
            app.ShowROIDropDown.Value = '1';
            app.InvertsignalsButton = uibutton(app.GridLayout3, 'state');
            app.InvertsignalsButton.ValueChangedFcn = createCallbackFcn(app, @InvertsignalsButtonValueChanged, true);
            app.InvertsignalsButton.Text = 'Invert signals';
            app.APDrestitutioncurveTab = uitab(app.TabGroup);
            app.APDrestitutioncurveTab.Title = 'APD restitution curve';
            app.GridLayout4 = uigridlayout(app.APDrestitutioncurveTab);
            app.restitution_curve_UIAxes = uiaxes(app.GridLayout4);
            xlabel(app.restitution_curve_UIAxes, 'BCL (ms)')
            ylabel(app.restitution_curve_UIAxes, 'APD (ms)')
            app.restitution_curve_UIAxes.XTickLabelRotationMode = 'manual';
            app.restitution_curve_UIAxes.YTickLabelRotationMode = 'manual';
            app.restitution_curve_UIAxes.ZTickLabelRotationMode = 'manual';
            app.Panel_3 = uipanel(app.GridLayout4);
            app.GridLayout9 = uigridlayout(app.Panel_3);
            app.PLOTSRELATIVETOPACINGTIMESPanel = uipanel(app.GridLayout9);
            app.PLOTSRELATIVETOPACINGTIMESPanel.Title = 'PLOTS RELATIVE TO PACING TIMES';
            app.GridLayout16 = uigridlayout(app.PLOTSRELATIVETOPACINGTIMESPanel);
            app.APDsequentialallAPButton = uibutton(app.GridLayout16, 'push');
            app.APDsequentialallAPButton.ButtonPushedFcn = createCallbackFcn(app, @APDsequentialallAPButtonPushed, true);
            app.APDsequentialallAPButton.Enable = 'off';
            app.APDsequentialallAPButton.Text = 'APD sequential (all AP)';
            app.APDvsBCLmeananderrorButton = uibutton(app.GridLayout16, 'push');
            app.APDvsBCLmeananderrorButton.ButtonPushedFcn = createCallbackFcn(app, @APDvsBCLmeananderrorButtonPushed, true);
            app.APDvsBCLmeananderrorButton.Enable = 'off';
            app.APDvsBCLmeananderrorButton.Text = 'APD vs BCL (mean and error)';
            app.APDvsfrequencymeananderrorButton = uibutton(app.GridLayout16, 'push');
            app.APDvsfrequencymeananderrorButton.ButtonPushedFcn = createCallbackFcn(app, @APDvsfrequencymeananderrorButtonPushed, true);
            app.APDvsfrequencymeananderrorButton.Enable = 'off';
            app.APDvsfrequencymeananderrorButton.Text = 'APD vs frequency (mean and error)';
            app.APDvsDImeananderrorButton = uibutton(app.GridLayout16, 'push');
            app.APDvsDImeananderrorButton.ButtonPushedFcn = createCallbackFcn(app, @APDvsDImeananderrorButtonPushed, true);
            app.APDvsDImeananderrorButton.Enable = 'off';
            app.APDvsDImeananderrorButton.Text = 'APD vs DI (mean and error)';
            app.ErrorbartypeDropDownLabel = uilabel(app.GridLayout16);
            app.ErrorbartypeDropDownLabel.Enable = 'off';
            app.ErrorbartypeDropDownLabel.Text = 'Error bar type';
            app.ErrorbartypeDropDown = uidropdown(app.GridLayout16);
            app.ErrorbartypeDropDown.Items = {'Standard deviation', 'Standard Error'};
            app.ErrorbartypeDropDown.ItemsData = {'1', '2'};
            app.ErrorbartypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ErrorbartypeDropDownValueChanged, true);
            app.ErrorbartypeDropDown.Enable = 'off';
            app.ErrorbartypeDropDown.Value = '1';
            app.RESTITUTIONPARAMETERSPanel = uipanel(app.GridLayout9);
            app.RESTITUTIONPARAMETERSPanel.Title = 'RESTITUTION PARAMETERS';
            app.GridLayout17 = uigridlayout(app.RESTITUTIONPARAMETERSPanel);
            app.GetdataButton = uibutton(app.GridLayout17, 'push');
            app.GetdataButton.ButtonPushedFcn = createCallbackFcn(app, @GetdataButtonPushed, true);
            app.GetdataButton.Enable = 'off';
            app.GetdataButton.Tooltip = {'Pull APD data from protocol action potentials. APDs have to be computed and BioPAC data has to be loaded.'};
            app.GetdataButton.Text = 'Get data';
            app.NumberofAPtouseeachfromeachstepasaofthetotalAPinastepLabel = uilabel(app.GridLayout17);
            app.NumberofAPtouseeachfromeachstepasaofthetotalAPinastepLabel.Text = {'Number of AP to use each from each step as a % of '; 'the total AP in a step.'};
            app.UselastLabel = uilabel(app.GridLayout17);
            app.UselastLabel.Text = 'Use last (%)';
            app.UselastEditField = uieditfield(app.GridLayout17, 'numeric');
            app.UselastEditField.Value = 50;
            app.IgnorelossofcaptureSwitch = uiswitch(app.GridLayout17, 'slider');
            app.IgnorelossofcaptureLabel = uilabel(app.GridLayout17);
            app.IgnorelossofcaptureLabel.Text = 'Ignore loss of capture:';
            app.EXPORTDATAPanel = uipanel(app.GridLayout9);
            app.EXPORTDATAPanel.Title = 'EXPORT DATA';
            app.GridLayout18 = uigridlayout(app.EXPORTDATAPanel);
            app.ExportfulltraceButton = uibutton(app.GridLayout18, 'push');
            app.ExportfulltraceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportfulltraceButtonPushed, true);
            app.ExportfulltraceButton.Text = 'Export full trace';
            app.ExportselectedAPtraceButton = uibutton(app.GridLayout18, 'push');
            app.ExportselectedAPtraceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportselectedAPtraceButtonPushed, true);
            app.ExportselectedAPtraceButton.Text = 'Export selected AP trace';
            app.SavetoEditFieldLabel = uilabel(app.GridLayout18);
            app.SavetoEditFieldLabel.Text = 'Save to';
            app.SavetoEditField = uieditfield(app.GridLayout18, 'text');
            app.ChangeFolderButtonSaveto = uibutton(app.GridLayout18, 'push');
            app.ChangeFolderButtonSaveto.ButtonPushedFcn = createCallbackFcn(app, @ChangeFolderButtonSavetoPushed, true);
            app.ChangeFolderButtonSaveto.Tooltip = {'Select folder to where files will be exported.'};
            app.ChangeFolderButtonSaveto.Text = 'Change Folder';
            app.FileformatDropDownLabel = uilabel(app.GridLayout18);
            app.FileformatDropDownLabel.Text = 'File format';
            app.FileformatDropDown = uidropdown(app.GridLayout18);
            app.FileformatDropDown.Items = {'.xlsx (Excel)', '.csv', '.m (Matlab)'};
            app.FileformatDropDown.ItemsData = {'1', '2', '3'};
            app.FileformatDropDown.Value = '1';
            app.ExportmeantraceButton = uibutton(app.GridLayout18, 'push');
            app.ExportmeantraceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportmeantraceButtonPushed, true);
            app.ExportmeantraceButton.Text = 'Export mean trace';
            app.tool_tip = uieditfield(app.APD_analysis_UIFigure, 'text');
            app.tool_tip.Editable = 'off';
            app.tool_tip.Visible = 'off';
            % Show the figure after all components are created
            app.APD_analysis_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APD_restitution

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.APD_analysis_UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.APD_analysis_UIFigure)
        end
    end
end