classdef analyse_optical_mapping < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        opticalmappingUIFigure          matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadfileMenu                    matlab.ui.container.Menu
        LoadfilepartiallyMenu           matlab.ui.container.Menu
        DefineregionsMenu               matlab.ui.container.Menu
        RegionsMenu                     matlab.ui.container.Menu
        SaveRegionsMenu                 matlab.ui.container.Menu
        LoadRegionsMenu                 matlab.ui.container.Menu
        QuickloadMenu                   matlab.ui.container.Menu
        QuickloadpartiallyMenu          matlab.ui.container.Menu
        workspace                       matlab.ui.container.Menu
        saveworkspace                   matlab.ui.container.Menu
        loadworkspace                   matlab.ui.container.Menu
        APDrestitutionanalysisMenu      matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        RestartAPPMenu                  matlab.ui.container.Menu
        SettingsMenu                    matlab.ui.container.Menu
        ColourmapMenu                   matlab.ui.container.Menu
        HotCmap                         matlab.ui.container.Menu
        GrayscaleCmap                   matlab.ui.container.Menu
        JetCmap                         matlab.ui.container.Menu
        InterpolationMenu               matlab.ui.container.Menu
        NearestMenu                     matlab.ui.container.Menu
        BilinearMenu                    matlab.ui.container.Menu
        RegionsChosenMenu               matlab.ui.container.Menu
        MatchRegion1SizeMenu            matlab.ui.container.Menu
        ConfirmExtractBackgroundMenu    matlab.ui.container.Menu
        AcceptCrop                      matlab.ui.container.Menu
        CancelCrop                      matlab.ui.container.Menu
        LengthChosenMenu                matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        ViewingTab                      matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        PixelInfoButton                 matlab.ui.control.Button
        DeletereferencenormButton       matlab.ui.control.Button
        saveimage_2                     matlab.ui.control.Button
        EXTRACTBACKGROUNDPanel          matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        LoadareaButton                  matlab.ui.control.Button
        SaveareaButton                  matlab.ui.control.Button
        RemoveareaButton                matlab.ui.control.Button
        KeepareaButton                  matlab.ui.control.Button
        AssistedareasegmentationButton  matlab.ui.control.Button
        undolast_Viewing                matlab.ui.control.Button
        VISUALISEWAVEFRONTPanel         matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        ShowHideWavefrontButton         matlab.ui.control.StateButton
        ComputeWavefrontButton          matlab.ui.control.Button
        ThresholdWavefront              matlab.ui.control.NumericEditField
        ThresholdLabel                  matlab.ui.control.Label
        SETCOLOURMAPSATURATIONPanel     matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        MaxColourmap                    matlab.ui.control.NumericEditField
        MaxLabel                        matlab.ui.control.Label
        invertdata                      matlab.ui.control.StateButton
        NORMALISATIONButtonGroup        matlab.ui.container.ButtonGroup
        GridLayout7                     matlab.ui.container.GridLayout
        ReferencenormalisationButton    matlab.ui.control.StateButton
        globalmeannormalise             matlab.ui.control.StateButton
        timeaverage                     matlab.ui.control.StateButton
        localnormalise                  matlab.ui.control.StateButton
        globalnormalise                 matlab.ui.control.StateButton
        nonormalise                     matlab.ui.control.StateButton
        pickframe                       matlab.ui.control.Button
        nextframe                       matlab.ui.control.Button
        previousframe                   matlab.ui.control.Button
        playmovie                       matlab.ui.control.Button
        setframerate                    matlab.ui.control.NumericEditField
        FrameratefpsEditFieldLabel      matlab.ui.control.Label
        ProcessingTab                   matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        stim_length                     matlab.ui.control.NumericEditField
        RemoveArtefactFramesButton      matlab.ui.control.Button
        ROTATEMIRRORANDCROPPanel        matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        CropButton                      matlab.ui.control.StateButton
        MirrorButton                    matlab.ui.control.Button
        rotate_counterclockwise         matlab.ui.control.Button
        rotate_clockwise                matlab.ui.control.Button
        DeleteallROIsButton             matlab.ui.control.Button
        undolast                        matlab.ui.control.Button
        REMOVEBIASSHIFTPanel            matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        subStepEditField                matlab.ui.control.NumericEditField
        subStepEditFieldLabel           matlab.ui.control.Label
        subWindowEditField              matlab.ui.control.NumericEditField
        subWindowEditFieldLabel         matlab.ui.control.Label
        PolynomialDegree                matlab.ui.control.NumericEditField
        PolynomialdegreeLabel           matlab.ui.control.Label
        StepMethod3                     matlab.ui.control.NumericEditField
        StepEditFieldLabel              matlab.ui.control.Label
        WindowMethod3                   matlab.ui.control.NumericEditField
        WindowEditFieldLabel            matlab.ui.control.Label
        ApplyWINDOWButton               matlab.ui.control.Button
        ApplySLOWButton                 matlab.ui.control.Button
        ApplyRemoveBias                 matlab.ui.control.Button
        FILTERINGPanel                  matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        PeakfindingDropDown             matlab.ui.control.DropDown
        PeakfindingDropDownLabel        matlab.ui.control.Label
        mindistance                     matlab.ui.control.NumericEditField
        MindistancemsEditFieldLabel     matlab.ui.control.Label
        minheight                       matlab.ui.control.NumericEditField
        MinheightEditFieldLabel         matlab.ui.control.Label
        BinSpinner                      matlab.ui.control.Spinner
        BinSpinnerLabel                 matlab.ui.control.Label
        SELECTROIFIRSTLabel             matlab.ui.control.Label
        averagebeats                    matlab.ui.control.Button
        binning                         matlab.ui.control.Button
        KernelsizeLabel                 matlab.ui.control.Label
        filterorder                     matlab.ui.control.NumericEditField
        FilterorderLabel                matlab.ui.control.Label
        cutofffrequency                 matlab.ui.control.NumericEditField
        CutofffrequencyHzLabel          matlab.ui.control.Label
        TemporalFilterType              matlab.ui.control.DropDown
        GaussiansigmaLabel              matlab.ui.control.Label
        gaussiansigma                   matlab.ui.control.NumericEditField
        spatialkernelsize               matlab.ui.control.DropDown
        spatialFilterType               matlab.ui.control.DropDown
        temporalfilter                  matlab.ui.control.Button
        spatialfilter                   matlab.ui.control.Button
        clipdata                        matlab.ui.control.Button
        ROIdropdown                     matlab.ui.control.DropDown
        deleteROI                       matlab.ui.control.Button
        loadregions                     matlab.ui.control.Button
        saveregions                     matlab.ui.control.Button
        signalregiontype                matlab.ui.control.DropDown
        showsignals                     matlab.ui.control.Button
        hidesignal                      matlab.ui.control.Button
        signalregion                    matlab.ui.control.Button
        ROIpropertiesTab                matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        ROI_Actt                        matlab.ui.control.EditField
        ActivationtimemsLabel           matlab.ui.control.Label
        ROI_BCL                         matlab.ui.control.EditField
        BCLmsEditFieldLabel             matlab.ui.control.Label
        ROI_dVdt                        matlab.ui.control.EditField
        dVdtmaxaumsEditFieldLabel       matlab.ui.control.Label
        ROI_APD                         matlab.ui.control.EditField
        APDmsEditFieldLabel             matlab.ui.control.Label
        ComputeAPDBCLanddVdtmaxButton   matlab.ui.control.Button
        APDsettingsPanel                matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        DesiredAP100allEditField        matlab.ui.control.NumericEditField
        DesiredAP100allEditFieldLabel   matlab.ui.control.Label
        rmpmethod_ROI                   matlab.ui.control.DropDown
        RMPcalculationDropDownLabel_2   matlab.ui.control.Label
        dfdt_maxpercentageEditField     matlab.ui.control.NumericEditField
        dfdt_maxpercentageEditFieldLabel  matlab.ui.control.Label
        APDpercentageEditField          matlab.ui.control.NumericEditField
        APDpercentageEditFieldLabel_2   matlab.ui.control.Label
        APDAxes                         matlab.ui.control.UIAxes
        SelectROIDropDown               matlab.ui.control.DropDown
        SelectROIDropDownLabel          matlab.ui.control.Label
        ActivationMapTab                matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        mapisolines                     matlab.ui.control.StateButton
        IsochroneSeparationmsEditField  matlab.ui.control.NumericEditField
        IsochroneSeparationmsEditFieldLabel  matlab.ui.control.Label
        mapisochronesbutton             matlab.ui.control.StateButton
        Label                           matlab.ui.control.Label
        mapmeans                        matlab.ui.control.Button
        FILTERINGPanel_2                matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        undofiltering                   matlab.ui.control.Button
        gaussiansigma_maps              matlab.ui.control.NumericEditField
        GaussiansigmaLabel_maps         matlab.ui.control.Label
        spatialkernelsize_maps          matlab.ui.control.DropDown
        KernelsizeLabel_maps            matlab.ui.control.Label
        spatialFilterType_maps          matlab.ui.control.DropDown
        spatialfilter_maps              matlab.ui.control.Button
        SETLIMITSPanel                  matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        newzeromap                      matlab.ui.control.Button
        upperylimit                     matlab.ui.control.NumericEditField
        UpperYlimitEditFieldLabel       matlab.ui.control.Label
        lowerylimit                     matlab.ui.control.NumericEditField
        LowerYlimitEditFieldLabel       matlab.ui.control.Label
        UIAxesHistogram                 matlab.ui.control.UIAxes
        rmpmethod                       matlab.ui.control.DropDown
        RMPcalculationDropDownLabel     matlab.ui.control.Label
        roimeans                        matlab.ui.control.Button
        cutoffpercentage                matlab.ui.control.NumericEditField
        CutoffpercentageEditFieldLabel  matlab.ui.control.Label
        apdpercentage                   matlab.ui.control.NumericEditField
        APDpercentageEditFieldLabel     matlab.ui.control.Label
        mappercentage                   matlab.ui.control.NumericEditField
        MethodpercentageLabel           matlab.ui.control.Label
        mapmethod                       matlab.ui.control.DropDown
        MethodLabel                     matlab.ui.control.Label
        maptype                         matlab.ui.control.DropDown
        TypeLabel                       matlab.ui.control.Label
        generatemap                     matlab.ui.control.Button
        BeatbyBeatMapsTab               matlab.ui.container.Tab
        GridLayout4_2                   matlab.ui.container.GridLayout
        nextframe_2                     matlab.ui.control.Button
        previousframe_2                 matlab.ui.control.Button
        playmovie_2                     matlab.ui.control.Button
        setframerate_2                  matlab.ui.control.NumericEditField
        FrameratefpsEditFieldLabel_2    matlab.ui.control.Label
        SETLIMITSPanel_3                matlab.ui.container.Panel
        GridLayout8_3                   matlab.ui.container.GridLayout
        upperylimit_bbb                 matlab.ui.control.NumericEditField
        UpperYlimitEditFieldLabel_3     matlab.ui.control.Label
        lowerylimit_bbb                 matlab.ui.control.NumericEditField
        LowerYlimitEditFieldLabel_3     matlab.ui.control.Label
        UIAxesHistogram_bbb             matlab.ui.control.UIAxes
        offsettime                      matlab.ui.control.NumericEditField
        OffsettimemsLabel               matlab.ui.control.Label
        mapisolines_2                   matlab.ui.control.StateButton
        EditField                       matlab.ui.control.NumericEditField
        EditFieldLabel_2                matlab.ui.control.Label
        mapisochronesbutton_2           matlab.ui.control.StateButton
        Label_2                         matlab.ui.control.Label
        mapmeans_2                      matlab.ui.control.Button
        roimeans_2                      matlab.ui.control.Button
        mapmethod_2                     matlab.ui.control.DropDown
        MethodLabel_2                   matlab.ui.control.Label
        maptype_2                       matlab.ui.control.DropDown
        TypeLabel_2                     matlab.ui.control.Label
        generatebeatmaps                matlab.ui.control.Button
        ConductionVelocityTab           matlab.ui.container.Tab
        GridLayout22                    matlab.ui.container.GridLayout
        IncludeROIButton                matlab.ui.control.StateButton
        IncludequiversButton            matlab.ui.control.StateButton
        CVoutlierexclusion              matlab.ui.control.StateButton
        CVROIAnglemean                  matlab.ui.control.Button
        CVAnglemean                     matlab.ui.control.Button
        AreasButton                     matlab.ui.control.Button
        isochrone_method                matlab.ui.control.DropDown
        IsochronesButton                matlab.ui.control.StateButton
        CVroimean                       matlab.ui.control.Button
        CVmean                          matlab.ui.control.Button
        timemax                         matlab.ui.control.NumericEditField
        TimemaxmsEditFieldLabel         matlab.ui.control.Label
        distancemax                     matlab.ui.control.NumericEditField
        DistancemaxmmEditFieldLabel     matlab.ui.control.Label
        CV_method                       matlab.ui.control.DropDown
        CV_map                          matlab.ui.control.Button
        InputScaleButton                matlab.ui.control.Button
        MeasurelengthButton             matlab.ui.control.Button
        SETLIMITSPanel_2                matlab.ui.container.Panel
        GridLayout8_2                   matlab.ui.container.GridLayout
        newzeromap_traces               matlab.ui.control.Button
        upperylimit_traces              matlab.ui.control.NumericEditField
        UpperYlimitEditFieldLabel_2     matlab.ui.control.Label
        lowerylimit_traces              matlab.ui.control.NumericEditField
        LowerYlimitEditFieldLabel_2     matlab.ui.control.Label
        UIAxesHistogram_traces          matlab.ui.control.UIAxes
        FittingSignalsTab               matlab.ui.container.Tab
        GridLayout23                    matlab.ui.container.GridLayout
        apdpercentage_2                 matlab.ui.control.NumericEditField
        APDpercentageEditFieldLabel_3   matlab.ui.control.Label
        APDmap_traces                   matlab.ui.control.StateButton
        localnormalise_traces           matlab.ui.control.StateButton
        activationmap_traces            matlab.ui.control.StateButton
        nonormalise_traces              matlab.ui.control.StateButton
        Baselinecalculation             matlab.ui.control.DropDown
        BaselinecalculationDropDownLabel  matlab.ui.control.Label
        Signalmaxpercentile             matlab.ui.control.NumericEditField
        SignalmaxpercentileEditFieldLabel  matlab.ui.control.Label
        signalthreshold                 matlab.ui.control.NumericEditField
        RawsignalthresholdEditFieldLabel  matlab.ui.control.Label
        dfdtthresholdframerate          matlab.ui.control.NumericEditField
        dfdtthresholdframerateEditFieldLabel  matlab.ui.control.Label
        FittracesButton                 matlab.ui.control.Button
        ImportBioPACTab                 matlab.ui.container.Tab
        GridLayout17                    matlab.ui.container.GridLayout
        BioPACfileEditField             matlab.ui.control.EditField
        BioPACfileEditFieldLabel        matlab.ui.control.Label
        ShowBioPACDropDown              matlab.ui.control.DropDown
        SwitchBioPACShow_Hide           matlab.ui.control.Switch
        ManualfileselectionandimportButton  matlab.ui.control.Button
        AutomaticfilesearchandimportButton  matlab.ui.control.Button
        ChangesearchfolderButton        matlab.ui.control.Button
        AutomaticSearchFolderEditField  matlab.ui.control.EditField
        AutomaticSearchFolderEditFieldLabel  matlab.ui.control.Label
        ExportTab                       matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        saveimage_ROI                   matlab.ui.control.Button
        SaveMovieImageadvancedButton    matlab.ui.control.Button
        ChangeFolderButtonSaveto        matlab.ui.control.Button
        SavetoEditField                 matlab.ui.control.EditField
        SavetoEditFieldLabel            matlab.ui.control.Label
        ExportSignalsPanel              matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        SaveBioPACButton                matlab.ui.control.Button
        SaveROIsButton                  matlab.ui.control.Button
        ExportStacktoWorkspaceButton    matlab.ui.control.Button
        Savelogfile                     matlab.ui.control.Button
        NotesTextArea                   matlab.ui.control.TextArea
        NotesTextAreaLabel              matlab.ui.control.Label
        saveimage                       matlab.ui.control.Button
        savemovie                       matlab.ui.control.Button
        HistoryTab                      matlab.ui.container.Tab
        GridLayout20                    matlab.ui.container.GridLayout
        history_field                   matlab.ui.control.TextArea
        tool_tip                        matlab.ui.control.TextArea
    end

    % Made by: Callum Zgierski-Johnston cjoh093@aucklanduni.ac.nz 20191017
    % Notes: If distributing need to include licences for code snippets or
    % replicate?
    properties (Access = private)
        %% Old figure size 1536,801. New 1920,995.
        
        region_app; % Region selector app reference
        beataveraging_app; % Averaging beats app reference
        segmentation_app; % Assisted segmentation app for selecting areas reference
        normalise_reference; % Normalise reference app reference
        pickframe_app; % Pick frame app reference
        pickrange_app; % Pick range app for loading files partially
        main_display_axes; % Main display axes for image
        signal_display_axes; % Signal display axes
        BioPAC_signal_display_axes
        figure_size
        plot_colours = [ 0    0.4470    0.7410;    0.8500    0.3250    0.0980;    0.9290    0.6940    0.1250;    0.4940    0.1840    0.5560;    0.4660    0.6740    0.1880;    0.3010    0.7450    0.9330;    0.6350    0.0780    0.1840];
        
    end
    
    properties (Access = public)
        file_info = struct('file','','path','','name','','type',''); % Selected file info
        % Loaded file data.
        data = struct('n_frames',[],'n_frames_full',[],'height',[],'width',[],'fps',[],'bit_depth',[],'regions',4,'image_stack',[],'rotation',3,'region_bounds',[]); % Loaded file data
        processed_data = struct('n_frames',[],'height',[],'width',[],'region_widths',[],'image_stack',[],'cropped',0,'signals',[],'beat_locations',[],'activation_map',[],'previous_stack',[],'previous_stack_bias',[],'frame_range',[1 1],'keep_area_poly',[], 'keep_area_poly_vertices',[],'fitinfo',[],'scale',1,'CV_map',[],'binning',1);
        advanced_data = struct('fit',[],'f_baseline',[],'f_max',[],'upstroke_start_ind',[],'upstroke_end_ind',[],'repol_end_ind',[],'upstroke_fit',[],'repol_fit',[]);
        averaging = struct('beat_locations',[],'processed_data_stack_raw',[],'processed_data_stack_averaged',[]);
        % Display structure. 
        % % Normalisation 1 = no normalisation 2 = global, 3 = local, 4 -
        % mean local, 5-RGB wavefront, 6-time avg, 7-reference
        % normalisation, 8 = fit traces normalisation
        % renorm (1) = global, (2) = local, (3) = global mean, (4) = time
        % average, (5) = reference normalisation, (6) = fit traces local
        display = struct('fps',15,'current_frame',1,'image_handle',[],'normalisation',1,'inversion',0,'renorm',ones(6,1),'signal_display',0,'BioPAC_signal_display',0,'ROI_handle',[],'plot_handle',[],'plot_handle_BioPAC',[],'ROI_mask',[],'colourbar_handle',gobjects(0),'map',0, 'colour_map',hot(256),'quiver_handle',[],'isochrone_handle',[]);
        settings = struct('quick_load',struct('regions',1,'background',1,'roi',1,'select_file_from_quick_load', 0)); % Quick load orientation/reshape, background and ROIs
        ui_resume
        first_processed_frame
        max_value_ACT
        max_value_APD
        log_file
        notes
        history = struct('current','0','old','0');
        range = struct('start_frame',0, 'end_frame',0)
        old_file_info;
        cancel_pickrange;
        channel_data;
        BioPAC_file;
        BioPAC_path_file;
        local_normalisation_computed;
        local_normalisation_traces_computed;
        quick_load_partially;
        reference_stack;
        wait;
        spikes;
        activation_times;
        save_movie_app;
        rotation_angle;
        crop_rectangle;
        region_rectangles; %  Info of regions chosen
        
    end
    
    methods (Access = public)
        
        function tool_tip_update(app)
            try
                full_split_path = regexp([app.file_info.path,app.file_info.file],'/','split'); % Mac separator
                file_name_with_short_path = [full_split_path{end-2},'/', full_split_path{end-1},'/', full_split_path{end}];
            catch 
                full_split_path = regexp([app.file_info.path,app.file_info.file],'\','split'); % windows separator
                file_name_with_short_path = [full_split_path{end-2},'\', full_split_path{end-1},'\', full_split_path{end}];
            end
                
            if isempty(app.processed_data.n_frames)
                app.tool_tip.Value = sprintf('File name: %s\t\tImage size: %dx%dx%d\t\tFrame rate: %.3f fps\t\tBit depth: %d\t\tRegions: %d\t\tCurrent frame: %d\t\tStart time: %.1f s\t\tEnd time: %.1f s',file_name_with_short_path,app.data.width,app.data.height,app.data.n_frames,app.data.fps,app.data.bit_depth,app.data.regions,app.display.current_frame,(app.processed_data.frame_range(1)-1)/app.data.fps,(app.processed_data.frame_range(2)-1)/app.data.fps);
            else
                app.tool_tip.Value = sprintf('File name: %s\t\tImage size: %dx%dx%d\t\tFrame rate: %.3f fps\t\tBit depth: %d\t\tRegions: %d\t\tCurrent frame: %d\t\tStart time: %.1f s\t\tEnd time: %.1f s',file_name_with_short_path,app.processed_data.width,app.processed_data.height,app.processed_data.n_frames,app.data.fps,app.data.bit_depth,app.data.regions,app.display.current_frame,(app.processed_data.frame_range(1)-1)/app.data.fps,(app.processed_data.frame_range(2)-1)/app.data.fps);
            end
        end
        
        function update_view_signal(app)
            if length(size(app.processed_data.image_stack{app.display.normalisation}))==3
                set(app.display.image_handle,'CData',app.processed_data.image_stack{app.display.normalisation}(:,:,app.display.current_frame));
            elseif length(size(app.processed_data.image_stack{app.display.normalisation}))==4
                set(app.display.image_handle,'CData',app.processed_data.image_stack{app.display.normalisation}(:,:,:,app.display.current_frame));
            end
            min_cmap = min(app.processed_data.image_stack{app.display.normalisation}(:),[],'omitnan');
            if app.display.normalisation == 4
                max_cmap = 1;
            else
                max_cmap = max(app.processed_data.image_stack{app.display.normalisation}(:));
            end
            
            app.display.image_handle.Parent.CLim = [min_cmap max_cmap*app.MaxColourmap.Value/100 ];

            app.display.image_handle.Parent.Colormap = app.display.colour_map;


            app.display.map = 0;
            if isvalid(app.display.colourbar_handle)
                colorbar(app.display.colourbar_handle,'off')
            end
            if (~isempty(app.processed_data.signals)) && (app.display.normalisation ~=6) %not do if time average (normalisation=6) is selected or there are no signals
                cla(app.signal_display_axes);
                app.display.plot_handle = [];
                app.display.plot_handle = cellfun(@(x,y) plot(x,y,'Parent',app.signal_display_axes),app.processed_data.signals(:,1),app.processed_data.signals(:,app.display.normalisation+1));
                xlim(app.signal_display_axes, [app.processed_data.signals{end,1}(1),app.processed_data.signals{end,1}(end)])
                if ~app.display.signal_display
                    set(app.display.plot_handle,'Visible','off');
                end
            end


        end
        
        function initialize_buttons(app)
            %set([app.SettingsMenu],'Enable','off');
            set(app.averagebeats,'Enable','off')
            set(app.SELECTROIFIRSTLabel,'Enable','on')
            set(app.ROIdropdown,'Enable','off')
            set(app.deleteROI,'Enable','off')
            set(app.gaussiansigma,'Enable','off')
            set(app.spatialfilter_maps,'Enable','off')
            set(app.roimeans,'Enable','off')
            set(app.gaussiansigma_maps,'Enable','off')
            set(app.undofiltering,'Enable','off')
            set(app.GaussiansigmaLabel,'Enable','off')
            set(app.showsignals,'Enable', 'off')
            set(app.hidesignal,'Enable', 'off')
            set(app.saveregions,'Enable','off')
            set(app.nonormalise, 'Value', true)
            set(app.localnormalise, 'Value', false)
            set(app.globalnormalise, 'Value', false)
            set(app.globalmeannormalise, 'Value', false)
            
            app.invertdata.Value = 0;
            app.local_normalisation_computed = 0;
            app.quick_load_partially = 0;
            
        end
        
        function update_histogram_map(app,type)
            % 0 - activation_map, 1 - CV map
            if ~exist('type','var')
                map = app.processed_data.activation_map;
            else
                switch type
                    case 0
                        map = app.processed_data.activation_map;
                    case 1
                        map = app.processed_data.CV_map;
                end
            end


            histogram(app.UIAxesHistogram,map);
            histogram(app.UIAxesHistogram_traces,map);
            histogram(app.UIAxesHistogram_bbb,map);

            app.UIAxesHistogram.XLim = [max(0,min(map(:))),max(map(:))];
            app.UIAxesHistogram_traces.XLim = [max(0,min(map(:))),max(map(:))];
            app.UIAxesHistogram_bbb.XLim = [max(0,min(map(:))),max(map(:))];
        end
        
        function check_inversion(app)
            maximum_stack = max(app.data.image_stack(:));
            corner_pxl = app.data.image_stack(1,1,1)/maximum_stack;
            if corner_pxl > 0.8 %data is inverted, so invert back
                app.data.image_stack = maximum_stack - app.data.image_stack;
            end
        end
        
        function save_log_file(app, filename)
            row_notes = length(app.notes);
            notes = cell(row_notes+1,2);
            notes(2:end,1) = app.notes;
            notes(1,1) = {'Notes: '};
            active_file_name = mfilename("fullpath");
            [active_file_path,~,~] = fileparts(active_file_name); 
            
            [flag,git_log] = system(sprintf('TERM=ansi git -C "%s" log -1',active_file_path)); %MAC command
            if flag == 1
                [~,git_log] = system(sprintf('git -C "%s" log -1',active_file_path)); %Use windows command instead if flag shows error (1)
                git_log_lines = splitlines(git_log);
                current_commit =  git_log_lines{1};
                date_commit =  git_log_lines{3};
            else
                git_log_lines = splitlines(git_log);
                current_commit =  git_log_lines{2};
                date_commit =  git_log_lines{4};
                
            end

            if isempty(date_commit)
                current_commit = 'unknown';
                date_commit = date;
            end

            full_history = cell(length(app.history_field.Value)+1,2);
            full_history(1,1) = {'Full history: '};
            full_history(2:end,1) = app.history_field.Value;
            
            
            app.log_file = [{'file '}, [app.file_info.path,app.file_info.name];
                {'Current git commit: '}, current_commit;
                {'Date of git commit: '}, date_commit;
                {'Current frame '}, app.display.current_frame;
                {'start and end times'}, [(app.processed_data.frame_range(1)-1)/app.data.fps,(app.processed_data.frame_range(2)-1)/app.data.fps];
                {'Normalization (1-No, 2-Global, 3-local, 4-globalmeannormalise, 5-time average) '}, app.display.normalisation;
                {'Inverted (0-No, 1-Yes) '}, app.display.inversion;
                {'Filtering history '}, app.history.current;
                notes;
                full_history];
            
            writecell(app.log_file,filename)
            
        end
        
        function load_selected_file(app,range,rangeType)
            
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Loading data','Indeterminate','on');
            app.history_field.Value = {['File Loaded ', app.file_info.path,app.file_info.file]};
           
            
            if app.file_info.file ~= 0 % check if a file was selected
                % If have already been examining data, reinitialises variables and windows
                if ~isempty(app.data.image_stack)
                    set(app.TabGroup,'Visible','off');
                    app.initialize_BioPAC_data
                    % Check if colorbar exists and remove
                    if isvalid(app.display.colourbar_handle)
                        colorbar(app.display.colourbar_handle,'off');
                    end
                    % Check if signal axes exists and hide
                    if app.display.signal_display
                        app.hide_signals(app);
                    end
                    app.data = struct('n_frames',[],'n_frames_full',[],'height',[],'width',[],'fps',[],'bit_depth',[],'regions',4,'image_stack',[],'rotation',3,'region_bounds',[]); % Loaded file data
                    app.processed_data = struct('n_frames',[],'height',[],'width',[],'region_widths',[],'image_stack',[],'cropped',0,'signals',[],'beat_locations',[],'activation_map',[],'previous_stack',[],'previous_stack_bias',[],'frame_range',[1 1],'keep_area_poly',[], 'keep_area_poly_vertices',[],'fitinfo',[],'scale',1,'CV_map',[],'binning',1);
                    app.averaging = struct('beat_locations',[],'processed_data_stack_raw',[],'processed_data_stack_averaged',[]);
                    app.display = struct('fps',app.display.fps,'current_frame',1,'image_handle',[],'normalisation',1,'inversion',0,'renorm',ones(6,1),'signal_display',0,'BioPAC_signal_display',0,'ROI_handle',[],'plot_handle',[],'plot_handle_BioPAC',[],'ROI_mask',[],'colourbar_handle',gobjects(0),'map',0, 'colour_map',hot(256));
                    if app.settings.quick_load.select_file_from_quick_load ==  0
                        %app.settings = struct('quick_load',struct('regions',1,'background',1,'roi',1,'select_file_from_quick_load',0 ));
                        app.settings.quick_load.regions = 1;
                        app.settings.quick_load.background = 1;
                        app.settings.quick_load.roi = 1;
                        app.settings.quick_load.select_file_from_quick_load = 0;
                        
                    else
                        app.settings.quick_load.background = 1;
                        app.settings.quick_load.roi = 1;
                        app.settings.quick_load.select_file_from_quick_load = 1;
                    end

                    
                end
                
                app.display.normalisation = 1;
                
                
                [~, app.file_info.name, app.file_info.type] = fileparts(app.file_info.file); % Find file type


                if strcmp(app.file_info.type,'.sif') % If SIF load
                    
                    % Load .sif with MAC compatible function. Loads all the info
                    %of the file as well as all the frames (aleady
                    %reshaped, permuted and flipud
                    try
                        loaded_sif = sifreadnk([app.file_info.path,app.file_info.file],range,rangeType);
                    catch ME
                        errordlg(sprintf(['Error: there is a problem with the selected SIF file. Error code:',ME.message], 'SIF load error')) % Display error warning window if something went wrong when loading the data
                    end
                    app.data.n_frames_full = loaded_sif.kineticLength; %Number of frames of the whole recording without cuting
                    if loaded_sif.kineticLength ~= loaded_sif.scannedFrames
                        %warndlg(['WARNING: Not all frames have been able to be loaded. Loaded frames: ', num2str(loaded_sif.scannedFrames), ' Expected frames: ', num2str(loaded_sif.kineticLength)])
                        loaded_sif.kineticLength = loaded_sif.scannedFrames;
                    end
                    
                    app.data.n_frames = loaded_sif.kineticLength; % Number of frames
                    if app.data.n_frames > 0
                        if isempty(range)
                            app.processed_data.frame_range(1) = 0;
                            app.processed_data.frame_range(2) = app.data.n_frames;
                        else
                            app.processed_data.frame_range(1) = range(1);
                            app.processed_data.frame_range(2) = range(2);
                        end
                        app.data.height = loaded_sif.height;          % Height of the aquired image
                        app.data.width = loaded_sif.width;           % Width of the aquired image
                        app.data.fps = 1/loaded_sif.stackCycleTime;            % Frames per second (fps)
                        if app.data.fps == Inf
                            fps_int = inputdlg('Enter fps:','Frame rate',[1 35],{'100'});
                            app.data.fps = str2double(fps_int(1));
                        end
                        app.data.bit_depth = 12;                               % Set bit depth - can't seem to get from file currently | From the new MAC-compatible function, this may be the data in info.dataType
                        app.data.image_stack = loaded_sif.imageData;           % Store all frames in the correct variable
                        
                        %Create axes and display first frame only
                        if ~isempty(app.main_display_axes)
                            delete(app.main_display_axes);
                            app.main_display_axes=[];
                        end
                        app.main_display_axes = axes(app.opticalmappingUIFigure, 'Units','Pixels','Position',[1,app.figure_size(4)-floor(1.2*app.figure_size(4)/2)-40,app.figure_size(3),floor(1.2*app.figure_size(4)/2)],'XTick',[],'YTick',[]);
                        imshow(app.data.image_stack(:,:,1)./max(app.data.image_stack(:)),'Parent',app.main_display_axes);
                        
                        % Update tool tip
                        tool_tip_update(app);
                        % Enable load data and region selector | For now do not enable load data since the data is already all loaded
                        set([app.DefineregionsMenu],'Enable', 'on');
                        % Set default path to look for BioPAC files (ideally the structure of folders should be ../yyyy.mm.dd/BioPAC files/.. for the biopac files and
                        % ../yyyy.mm.dd/Recordings/.. for the OM recordings
                        path_parts = regexp(app.file_info.path,'/','split');
                        search_folder = string(join(path_parts(1:end-2),'/'));
                        app.AutomaticSearchFolderEditField.Value = strcat(search_folder, '/BioPAC files/');
                        app.SavetoEditField.Value = app.file_info.path;
                        
                    end

                elseif strcmp(app.file_info.type,'.fits') % If SIF load
                    % Load .fits header
                    image_metadata = fitsinfo([app.file_info.path,app.file_info.file]);
                    app.data.height = image_metadata.PrimaryData.Size(1);          % Height of the aquired image                 
                    app.data.width = image_metadata.PrimaryData.Size(2);           % Width of the aquired image

                    if isempty(range)
                        % Load .fits file
                        app.data.image_stack = fitsread([app.file_info.path,app.file_info.file]);

                        % Check whether multipart file 
                        if ~isempty(image_metadata.PrimaryData.Keywords{image_metadata.PrimaryData.Keywords(:,1)=="USERTXT1",2})

                            % Other files
                            [~,file_name,~] = fileparts(app.file_info.file);
                            file_list = dir([app.file_info.path,file_name,'_X*.fits']);
                            for k = 1:length(file_list)
                                tmp = fitsread([app.file_info.path,file_list(k).name]);
                                app.data.image_stack(:,:,end+1:end+size(tmp,3)) = tmp;
                            end

                        end

                        app.data.n_frames = size(app.data.image_stack,3); % Number of frames
                        app.processed_data.frame_range(1) = 0;
                        app.processed_data.frame_range(2) = app.data.n_frames;
                    else
                        app.processed_data.frame_range(1) = range(1);
                        app.processed_data.frame_range(2) = range(2);
                        
                        % Load .fits file
                        app.data.image_stack = fitsread([app.file_info.path,app.file_info.file],PixelRegion={[1,app.data.height],[1,app.data.width],[range(1),range(2)]});
                        app.data.n_frames = size(app.data.image_stack,3);
                    end
                    
                    app.data.image_stack = flip(app.data.image_stack,1);

                    app.data.fps = 1/image_metadata.PrimaryData.Keywords{1==(strcmp(image_metadata.PrimaryData.Keywords(:,1),'KCT')),2};            % Frames per second (fps)
                    if app.data.fps == Inf
                        fps_int = inputdlg('Enter fps:','Frame rate',[1 35],{'100'});
                        app.data.fps = str2double(fps_int(1));
                    end
                    app.data.bit_depth = 16;                               % Set bit depth - can't seem to get from file currently | From the new MAC-compatible function, this may be the data in info.dataType

                    %Create axes and display first frame only
                    if ~isempty(app.main_display_axes)
                        delete(app.main_display_axes);
                        app.main_display_axes=[];
                    end
                    app.main_display_axes = axes(app.opticalmappingUIFigure, 'Units','Pixels','Position',[1,app.figure_size(4)-floor(1.2*app.figure_size(4)/2)-40,app.figure_size(3),floor(1.2*app.figure_size(4)/2)],'XTick',[],'YTick',[]);
                    imshow(app.data.image_stack(:,:,1)./max(app.data.image_stack(:)),'Parent',app.main_display_axes);

                    % Update tool tip
                    tool_tip_update(app);
                    % Enable load data and region selector | For now do not enable load data since the data is already all loaded
                    set([app.DefineregionsMenu],'Enable', 'on');
                    % Set default path to look for BioPAC files (ideally the structure of folders should be ../yyyy.mm.dd/BioPAC files/.. for the biopac files and
                    % ../yyyy.mm.dd/Recordings/.. for the OM recordings
                    path_parts = regexp(app.file_info.path,'/','split');
                    search_folder = string(join(path_parts(1:end-2),'/'));
                    app.AutomaticSearchFolderEditField.Value = strcat(search_folder, '/BioPAC files/');
                    app.SavetoEditField.Value = app.file_info.path;


                elseif strcmp(app.file_info.type, '.mat') % If mat load %TODO% make more general
                    warning('off');
                    load([app.file_info.path,app.file_info.file],'RegisteredImage','new_image','new_image_1','new_image_2','stack');
                    warning('on');
                    if exist('RegisteredImage','var')
                        app.data.image_stack = RegisteredImage;
                        app.data.n_frames = size(RegisteredImage,3);
                        app.processed_data.n_frames = app.data.n_frames;
                    elseif exist('new_image','var')
                        app.data.image_stack = new_image;
                        app.data.n_frames = size(new_image,3);
                        app.processed_data.n_frames = app.data.n_frames;
                    elseif exist('new_image_1','var')
                        app.data.image_stack = new_image_1;
                        app.data.n_frames = size(new_image_1,3);
                        app.processed_data.n_frames = app.data.n_frames;
                    elseif exist('new_image_2','var')
                        app.data.image_stack = new_image_2;
                        app.data.n_frames = size(new_image_2,3);
                        app.processed_data.n_frames = app.data.n_frames;
                    elseif exist('stack','var')
                        app.data.image_stack = stack;
                        app.data.n_frames = size(stack,3);
                        app.processed_data.n_frames = app.data.n_frames;
                    end
                    
                    app.data.height = size(app.data.image_stack,1);
                    app.data.width = size(app.data.image_stack,2);
                    app.processed_data.height = size(app.data.image_stack,1);
                    app.processed_data.width = size(app.data.image_stack,2);
                    app.processed_data.frame_range(1) = 0;
                    app.processed_data.frame_range(2) = app.data.n_frames;
                    fps_int = inputdlg('Enter fps:','Frame rate',[1 35],{'100'});
                    % Add fps to log
                    app.history_field.Value{length(app.history_field.Value)+1,1} = ['FPS: ', fps_int{1}];
                    app.data.fps = str2double(fps_int(1));
                    app.data.bit_depth = 12; % Set bit depth - can't seem to get from file currently
                    
                    
                    % Check if data is inverted and if it is, invert back so define regions and segmentation assist work properly
                    check_inversion(app)
                    
                    %Create axes and display figure
                    if ~isempty(app.main_display_axes)
                        delete(app.main_display_axes);
                        app.main_display_axes=[];
                    end
                    app.main_display_axes = axes(app.opticalmappingUIFigure, 'Units','Pixels','Position',[1,app.figure_size(4)-floor(1.2*app.figure_size(4)/2)-40,app.figure_size(3),floor(1.2*app.figure_size(4)/2)],'XTick',[],'YTick',[]);
                    first_frame = app.data.image_stack(:,:,1); % storing the first frame because otherwise, when doing max(image_stack(:)) it does the max for all the stack and not jsut the first frame, and max(image_stack(:,:,1)) is doing max of each column
                    imshow(first_frame./max(first_frame(:)),'Parent',app.main_display_axes);
                    
                    % Update tool tip
                    tool_tip_update(app);
                    % Enable load data and region selector
                    set([app.DefineregionsMenu],'Enable','on');
                    
                elseif strcmp(app.file_info.type, '.dat')
                    %Load data
                    [loaded_dat, header, ~] = dat_reader([app.file_info.path,app.file_info.file],range,rangeType);
                    app.data.image_stack = single(loaded_dat);
                    app.data.n_frames = length(loaded_dat(1,1,:));
                    if app.data.n_frames > 0
                        app.processed_data.frame_range(2) = app.data.n_frames;
                        app.data.height = header.sizeY;          % Height of the aquired image
                        app.data.width = header.sizeX;           % Width of the aquired image
                        app.data.fps = 10*header.acquisitionFrequency;            % Frames per second (fps) x10 due to unknown issue
                        app.data.bit_depth = 12;                               % No idea about this one
                    end
                    %Create axes and display first frame only
                    if ~isempty(app.main_display_axes)
                        delete(app.main_display_axes);
                        app.main_display_axes=[];
                    end
                    app.main_display_axes = axes(app.opticalmappingUIFigure, 'Units','Pixels','Position',[1,app.figure_size(4)-floor(1.2*app.figure_size(4)/2)-40,app.figure_size(3),floor(1.2*app.figure_size(4)/2)],'XTick',[],'YTick',[]);
                    imshow(app.data.image_stack(:,:,1)./max(app.data.image_stack(:,:,1),[],'all'),'Parent',app.main_display_axes);
                    
                    % Update tool tip
                    tool_tip_update(app);
                    % Enable load data and region selector | For now do not enable load data since the data is already all loaded
                    set([app.DefineregionsMenu],'Enable', 'on');

                elseif strcmp(app.file_info.type, '.tif') || strcmp(app.file_info.type, '.tiff')
                    % Load data
                    file = [app.file_info.path,app.file_info.file];
                    warning('off')
                    %[stack,~] = tiffread2(file);
                    stack = tiffreadVolume(file);
                    warning('on')

                    %new_image = zeros(size(stack(1).data,1),size(stack(1).data,2),size(stack,2));
                    new_image = zeros(size(stack));

                    % Set frame rate
                    fps_int = inputdlg('Enter fps:','Frame rate',[1 35],{'100'});
                    % Add fps to log
                    app.history_field.Value{length(app.history_field.Value)+1,1} = ['FPS: ', fps_int{1},'.'];
                    app.data.fps = str2double(fps_int(1));
                    app.data.bit_depth = 12; % Set bit depth - can't seem to get from file currently

                    if size(stack,3) == 1
                        % If loaded a single image, presume it is part of multiple tiffs and
                        % converts into a stack
                        underscore_indices = regexp(file,'_');
                        file_base = file(1:underscore_indices(end));
                        file_list = natsortfiles(dir([file_base '*.tiff']));
                        new_image = zeros(size(stack,1),size(stack,2),size(file_list,1));
                        for k = 1:size(file_list,1)
                            clearvars stack;
                            %[stack,~] = tiffread2([file_list(k).folder '\' file_list(k).name]);
                            stack = imread([file_list(k).folder '\' file_list(k).name]);
                            new_image(:,:,k) = stack;
                        end
                    else
                        new_image = stack;
                        % Case if loaded an image stack
                        for k = 1:size(stack,2)
                           % new_image(:,:,k) = stack(k).data;
                            %new_image = cat(3,new_image,stack(k).data);%stack(k).data;
                        end
                    end

                    app.data.image_stack = single(new_image);
                    app.data.n_frames = size(new_image,3);
                    app.processed_data.n_frames = app.data.n_frames;

                    app.data.height = size(app.data.image_stack,1);
                    app.data.width = size(app.data.image_stack,2);
                    app.processed_data.height = size(app.data.image_stack,1);
                    app.processed_data.width = size(app.data.image_stack,2);
                    app.processed_data.frame_range(1) = 0;
                    app.processed_data.frame_range(2) = app.data.n_frames;
                    
                    %Create axes and display figure
                    if ~isempty(app.main_display_axes)
                        delete(app.main_display_axes);
                        app.main_display_axes=[];
                    end
                    app.main_display_axes = axes(app.opticalmappingUIFigure, 'Units','Pixels','Position',[1,app.figure_size(4)-floor(1.2*app.figure_size(4)/2)-40,app.figure_size(3),floor(1.2*app.figure_size(4)/2)],'XTick',[],'YTick',[]);
                    first_frame = app.data.image_stack(:,:,1); % storing the first frame because otherwise, when doing max(image_stack(:)) it does the max for all the stack and not jsut the first frame, and max(image_stack(:,:,1)) is doing max of each column
                    imshow(first_frame./max(first_frame(:)),'Parent',app.main_display_axes);
                    
                    % Update tool tip
                    tool_tip_update(app);
                    % Enable load data and region selector
                    set([app.DefineregionsMenu],'Enable','on');
                end

                close(progress_bar);
                if app.settings.quick_load.select_file_from_quick_load ==  0 %if not loading file from quick load, show pop up to ask if want to define regions or just continue
                    msg = 'Do you want to define regions or continue?';
                    title = 'Define Regions';
                    selection = uiconfirm(app.opticalmappingUIFigure,msg,title,'Options',{'Define regions','Load regions','Continue'},'DefaultOption',1);
                    switch selection
                        case 'Define regions' % Define regions
                            app.define_regions
                            set([app.SettingsMenu],'Enable','on');
                            set(app.RegionsMenu,'Enable','on');
                            app.history_field.Value{length(app.history_field.Value)+1,1} = 'Regions defined';
                            app.settings.quick_load.regions = 1;
                            
                        case 'Load regions'
                            LoadRegionsMenuSelected(app);
                            app.history_field.Value{length(app.history_field.Value)+1,1} = 'Regions Loaded';
                            app.settings.quick_load.regions = 1;
                        case 'Continue'
                            app.settings.quick_load.regions = 0;
                            app.history_field.Value{length(app.history_field.Value)+1,1} = 'Regions not defined';
                            app.processed_data.image_stack{app.display.normalisation} = app.data.image_stack;
                            app.processed_data.height = app.data.height;
                            app.processed_data.width = app.data.width;
                            app.processed_data.n_frames = app.data.n_frames;
                            
                            app.first_processed_frame = app.processed_data.image_stack{1}(:,:,1);
                            set([app.FileMenu],'Enable','on');
                            set([app.SettingsMenu],'Enable','on');
                            set([app.QuickloadMenu],'Enable','on');
                            % Make tabs visible
                            int = get(app.TabGroup,'Position');
                            set(app.TabGroup,'Position',[int(1), int(2), int(3), floor(app.figure_size(4)./3)])
                            set(app.TabGroup,'Visible','on')
                            set(app.saveworkspace,'Enable','on')
                            set(app.RegionsMenu,'Enable','on')
                            set(app.LoadRegionsMenu,'Enable','on')
                            
                            cla(app.main_display_axes);
                            app.display.image_handle = imshow(app.processed_data.image_stack{app.display.normalisation}(:,:,app.display.current_frame)./max(app.processed_data.image_stack{app.display.normalisation}(1:app.processed_data.height*app.processed_data.width)),'Parent',app.main_display_axes);
                            app.display.image_handle.Parent.CLim = [0,1];
                    end
                    app.initialize_buttons
                end


            else %if no new file is selected, just restore the info from before so you an choose regions again from the old file
                app.file_info = app.old_file_info;
            end
            
            
            figure(app.opticalmappingUIFigure);
            
        end
        
        function select_file(app)
            if app.file_info.file ~= 0 %if there is a file name already loaded, save it in a variable in case the pop-up window is closed without selecting a file
                app.old_file_info = app.file_info;
            end
            if ismac == 1
                [app.file_info.file , app.file_info.path] = uigetfile({'*.*','All Files (*.*)'},'File selector');
            else
                [app.file_info.file , app.file_info.path] = uigetfile({'*.sif','Andor files (*.sif)';'*.fits','FITS files (*.fits)';'*.dat','Photometrics files (*.dat)';'*.mat','MAT-Files (*.mat)';'*.tif; *.tiff','Tiff files (*.tif, *.tiff)';'*.*','All Files (*.*)'},'File selector');
            end

            figure(app.opticalmappingUIFigure);
        end
    end
    
    methods (Access = private)
        % Note when having 3 regions assume that it comes from panoramic
        % system. Mapping is as follows. From top to bottom view CB,AC,AB.
        % Goes to CBBAAB following rotation and cropping
        function apply_regions(app)
            app.processed_data.n_frames = app.data.n_frames;
            % Set cropped image size
            if app.data.rotation == 0 || app.data.rotation == 2 % I atria at bottom or top
                app.processed_data.height = max(app.data.region_bounds(:,5));
                app.processed_data.width = sum(app.data.region_bounds(:,6));
                app.processed_data.region_heights = app.data.region_bounds(:,5);
                app.processed_data.region_widths = app.data.region_bounds(:,6);
            else % if atria on the left side or the right side
                app.processed_data.height = max(app.data.region_bounds(:,6));
                app.processed_data.width = sum(app.data.region_bounds(:,5));
                app.processed_data.region_heights = app.data.region_bounds(:,6);
                app.processed_data.region_widths = app.data.region_bounds(:,5);
            end
            
            
            % Initialise processed image stack
            app.processed_data.image_stack{app.display.normalisation} = zeros(app.processed_data.height, app.processed_data.width, app.processed_data.n_frames);
            app.processed_data.image_stack{app.display.normalisation}(:) = min(app.data.image_stack(:));
            
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Applying regions','Indeterminate','on');
            if app.data.regions ~= 4 %For other than 4 views
                start = 1;
                for k = 1:app.data.regions
                    if app.data.regions == 3 && k == 1
                        app.processed_data.image_stack{app.display.normalisation}(1+floor((app.processed_data.height-app.processed_data.region_heights(k))/2):floor((app.processed_data.height-app.processed_data.region_heights(k))/2)+app.processed_data.region_heights(k),start:start+app.processed_data.region_widths(k)-1,:) = fliplr(rot90(app.data.image_stack(app.data.region_bounds(k,1):app.data.region_bounds(k,3),app.data.region_bounds(k,2):app.data.region_bounds(k,4),:),app.data.rotation));
                    else
                        app.processed_data.image_stack{app.display.normalisation}(1+floor((app.processed_data.height-app.processed_data.region_heights(k))/2):floor((app.processed_data.height-app.processed_data.region_heights(k))/2)+app.processed_data.region_heights(k),start:start+app.processed_data.region_widths(k)-1,:) = rot90(app.data.image_stack(app.data.region_bounds(k,1):app.data.region_bounds(k,3),app.data.region_bounds(k,2):app.data.region_bounds(k,4),:),app.data.rotation);
                    end
                    start=start+app.processed_data.region_widths(k);
                end
                
                % Flip to match viewing direction
                if app.data.regions == 3
                    app.processed_data.image_stack{app.display.normalisation} = fliplr(app.processed_data.image_stack{app.display.normalisation});
                end
            elseif app.data.regions == 4 %for 4 views
                start = 1;
                for k = 1:app.data.regions
                    if  k == 1 % first region just rotate counterclockwise 90 deg
                        app.processed_data.image_stack{app.display.normalisation}(1+floor((app.processed_data.height-app.processed_data.region_heights(k))/2):floor((app.processed_data.height-app.processed_data.region_heights(k))/2)+app.processed_data.region_heights(k),start:start+app.processed_data.region_widths(k)-1,:) = rot90(app.data.image_stack(app.data.region_bounds(k,1):app.data.region_bounds(k,3),app.data.region_bounds(k,2):app.data.region_bounds(k,4),:));
                    elseif k == 2 || k == 4 % second and fourth region flip left-right, flip up-down, and rotate counterclockwise 90 deg
                        app.processed_data.image_stack{app.display.normalisation}(1+floor((app.processed_data.height-app.processed_data.region_heights(k))/2):floor((app.processed_data.height-app.processed_data.region_heights(k))/2)+app.processed_data.region_heights(k),start:start+app.processed_data.region_widths(k)-1,:) = fliplr(rot90(fliplr(flipud(app.data.image_stack(app.data.region_bounds(k,1):app.data.region_bounds(k,3),app.data.region_bounds(k,2):app.data.region_bounds(k,4),:)))));
                    elseif k == 3 % third region flip up-down and rotate 90 deg counterclockwise
                        app.processed_data.image_stack{app.display.normalisation}(1+floor((app.processed_data.height-app.processed_data.region_heights(k))/2):floor((app.processed_data.height-app.processed_data.region_heights(k))/2)+app.processed_data.region_heights(k),start:start+app.processed_data.region_widths(k)-1,:) = fliplr(rot90(fliplr(app.data.image_stack(app.data.region_bounds(k,1):app.data.region_bounds(k,3),app.data.region_bounds(k,2):app.data.region_bounds(k,4),:))));
                    end
                    start=start+app.processed_data.region_widths(k);
                end
            end
            
            
            close(progress_bar)
            
            cla(app.main_display_axes);
            app.display.image_handle = imshow(app.processed_data.image_stack{app.display.normalisation}(:,:,app.display.current_frame)./max(app.processed_data.image_stack{app.display.normalisation}(1:app.processed_data.height*app.processed_data.width)),'Parent',app.main_display_axes);
            
            app.display.image_handle.Parent.CLim = [0,1];
            
            app.first_processed_frame = app.processed_data.image_stack{1}(:,:,1); %saving the first frame without inversion to use for automatic segmentation because if the data is later inverted the segmentation code will not work
            
            
            % Make tabs visible
            int = get(app.TabGroup,'Position');
            set(app.TabGroup,'Position',[int(1), int(2), int(3), floor(app.figure_size(4)./3)])
            set(app.TabGroup,'Visible','on')
            set(app.saveworkspace,'Enable','on')
        end
        
        
        function average_signal = time_signal(app, normalisation, signal_num)
            temp_stack = app.processed_data.image_stack{normalisation}.*app.display.ROI_mask{signal_num};
            average_signal = squeeze(mean(temp_stack,[1,2],'omitnan'));
        end
        
        function apply_signal_masks(app,signal_num)
            % Create signals
            %app.processed_data.signals{signal_num,1} = linspace(0,(size(app.processed_data.image_stack{1},3)-1)*(1/app.data.fps),size(app.processed_data.image_stack{1},3));%0:1/app.data.fps:(size(app.processed_data.image_stack{1},3)-1)/app.data.fps;
            app.processed_data.signals{signal_num,1} = 0: 1/app.data.fps: (size(app.processed_data.image_stack{1},3)-1)/app.data.fps;

            app.processed_data.signals{signal_num,2} = time_signal(app,1,signal_num);
            
            % Check if global or local normalisation are current and add
            % them if so.
            if ~app.display.renorm(1) %global norm
                app.processed_data.signals{signal_num,3} = time_signal(app,2,signal_num);
            end
            if ~app.display.renorm(2) %local norm
                app.processed_data.signals{signal_num,4} = time_signal(app,3,signal_num);
            end
            if ~app.display.renorm(3) %global mean norm
                app.processed_data.signals{signal_num,5} = time_signal(app,4,signal_num);
            end
            try
                if ~app.display.renorm(5) %reference norm
                    app.processed_data.signals{signal_num,8} = time_signal(app,7,signal_num);
                end
            catch
                disp('Warning: Reference normalisation not found. Resetting reference normalisation stack to empty.')
                app.reference_stack = [];
                app.display.renorm(5) = 1;
            end
        end
        
        function file_name = file_exists(app,suffix,filetype)
            file_name = [app.SavetoEditField.Value  app.file_info.name '_' suffix '_1' filetype];
            if exist(file_name, 'file')
                % Get number of files:
                file_list     = dir([app.SavetoEditField.Value  app.file_info.name '_' suffix '_*' filetype]);
                file_string     = lower(sprintf('%s*', file_list.name));
                file_num     = sscanf(file_string, [[lower(app.file_info.name) '_' lower(suffix) '_'], '%d', filetype, '*']);
                new_num   = max(file_num) + 1;
                file_name = [app.SavetoEditField.Value app.file_info.name '_' suffix '_' num2str(new_num) filetype];
                %file_name = [app.file_info.path app.file_info.name '_' suffix '_' num2str(new_num) filetype];
            end
        end
        
        
        function dir_name = dir_exists(app,suffix)
            dir_name = [app.SavetoEditField.Value  app.file_info.name '_' suffix '_1'];
            if exist(dir_name, 'dir')
                % Get number of folder with the same name:
                dir_list     = dir([app.file_info.path  app.file_info.name '_' suffix '_*']);
                dir_string     = sprintf('%s*', dir_list.name);
                dir_num     = sscanf(dir_string, [[app.file_info.name '_' suffix '_'], '%d']);
                new_num   = max(dir_num) + 1;
                dir_name = [app.SavetoEditField.Value app.file_info.name '_' suffix '_' num2str(new_num)];
            end
            
        end
        
        
        function update_map_view(app)
            % update view of the act/APD map
            switch str2double(app.maptype.Value)
                case 1 % Activation map max
                    max_value = app.max_value_ACT;
                    colourbar_string = 'Activation time (ms)';
                case 2 % APD map max
                    max_value = app.max_value_APD;
                    colourbar_string = sprintf('APD%d (ms)',app.apdpercentage.Value);
            end
            
            % Display map
            if ~isempty(app.display.quiver_handle)
                delete(app.display.quiver_handle)
            end

            set(app.display.image_handle,'CData',app.processed_data.activation_map);
            app.display.image_handle.Parent.CLim = [0,max_value];
            % Set first value to black in colour map so that NaN are black.
            app.display.image_handle.Parent.Colormap = [0 0 0; jet(256)];
            if isvalid(app.display.colourbar_handle)
                colorbar(app.display.colourbar_handle,'off')
            end
            app.display.colourbar_handle = colorbar(app.display.image_handle.Parent);
            app.display.colourbar_handle.Label.String = colourbar_string;
            app.display.colourbar_handle.Label.FontSize = app.display.colourbar_handle.Label.FontSize+2;
            app.display.map = 1;
            app.upperylimit.Value = app.display.colourbar_handle.Limits(2);
            app.lowerylimit.Value = app.display.colourbar_handle.Limits(1);
        end
        
        function BioPAC_file = search_BioPAC_file(app)
            % BioPAC file finder
            OM_file = app.file_info.name; % 'XXh-YYm-ZZs'
            
            time_stamp = regexp(OM_file,'[\d\.]+','match');
            time_stamp = str2double(time_stamp); % [XX YY ZZ]
            % Convert timestamp to absolute time (seconds)
            OM_absolute_time = time_stamp(1)*3600 + time_stamp(2)*60 + time_stamp(3);
            
            file_list  = dir(strcat(app.AutomaticSearchFolderEditField.Value,'*T*.acq')); %find Acqknowledge files
            file_list = file_list(2:end); % not take into account the first file which is t
            num_files = length(file_list);
            
            list_absolute_times = zeros(num_files,1);
            
            for k=1:num_files
                % compute absolute time for each file
                time_stamp = regexp(file_list(k).name,'[\d*]+','match');
                time_stamp = str2double(time_stamp);
                time_stamp = time_stamp(end-2:end);
                list_absolute_times(k) = time_stamp(1)*3600 + time_stamp(2)*60 + time_stamp(3);
            end
            
            time_diff = list_absolute_times - OM_absolute_time;
            
            idx = find(time_diff < 0 );
            idx = idx(end); % Take the BioPAC recording that started before the OM recording (ideally that's the one)
            
            BioPAC_file = file_list(idx).name;
        end
        
        function load_BioPAC(app)
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Loading BioPAC file and synchronizing with OM signal','Indeterminate','on');
            file_name = app.BioPAC_path_file;
            
            BioPAC = load_acq(file_name,0);


            chan_num = length(BioPAC.data(1,:)); %number of channels
            num_samples = length(BioPAC.data(:,1)); %length of acquisition in samples
            
            %Get channel names
            chan_names = struct2cell(BioPAC.hdr.per_chan_data);
            chan_names = chan_names(3,1,:);
            
            %get units
            chan_units = struct2cell(BioPAC.hdr.per_chan_data);
            chan_units = chan_units(8,1,:);
            
            %Find at which position (channel) each particular measurement is stored
            app.channel_data.local_perf.idx = find(strcmp(chan_names,'Local Perfusion'));
            app.channel_data.elec_stim.idx = find(strcmp(chan_names,'Electrical Stimulation'));
            app.channel_data.ECG1.idx = find(strcmp(chan_names,'ECG I'));
            if isempty(app.channel_data.ECG1.idx)
                app.channel_data.ECG1.idx = find(strcmp(chan_names,'ECG'));
            end
            app.channel_data.ECG2.idx = find(strcmp(chan_names,'ECG II'));
            app.channel_data.ECG3.idx = find(strcmp(chan_names,'ECG III'));
            app.channel_data.aort_press.idx = find(strcmp(chan_names,'Aortic Pressure'));
            app.channel_data.aort_press.idx = find(strcmp(chan_names,'Perfusion Pressure'));
            app.channel_data.aort_flow.idx = find(strcmp(chan_names,'Aortic Flow'));
            app.channel_data.temp.idx = find(strcmp(chan_names,'Temperature'));
            app.channel_data.camera.idx = find(strcmp(chan_names,'Camera Exposure'));
            app.channel_data.LED.idx = find(strcmp(chan_names,'LED'));
            app.channel_data.LED.idx = find(strcmp(chan_names,'LED Trigger'));
            app.channel_data.force.idx = find(strcmp(chan_names,'Force Transducer'));
            app.channel_data.motor1.idx = find(strcmp(chan_names,'Motor Trigger 1'));
            app.channel_data.motor2.idx = find(strcmp(chan_names,'Motor Trigger 2'));
            
            
            %Get units of the measurements
%             app.channel_data.local_perf.units = chan_units{app.channel_data.local_perf.idx};
%             app.channel_data.elec_stim.units = chan_units{app.channel_data.elec_stim.idx};
%             app.channel_data.ECG1.units = chan_units{app.channel_data.ECG1.idx};
%             app.channel_data.ECG2.units = chan_units{app.channel_data.ECG2.idx};
%             app.channel_data.ECG3.units = chan_units{app.channel_data.ECG3.idx};
%             app.channel_data.aort_press.units = chan_units{app.channel_data.aort_press.idx};
%             app.channel_data.aort_flow.units = chan_units{app.channel_data.aort_flow.idx};
%             app.channel_data.temp.units = chan_units{app.channel_data.temp.idx};
%             app.channel_data.camera.units = chan_units{app.channel_data.camera.idx};
            
             %Store data vector if the measurement is registered
            if ~isempty(app.channel_data.local_perf.idx)
                app.channel_data.local_perf.units = chan_units{app.channel_data.local_perf.idx};
                app.channel_data.local_perf.data = BioPAC.data(:,app.channel_data.local_perf.idx);
            end
            if ~isempty(app.channel_data.elec_stim.idx)
                app.channel_data.elec_stim.units = chan_units{app.channel_data.elec_stim.idx};
                app.channel_data.elec_stim.data = BioPAC.data(:,app.channel_data.elec_stim.idx);
            end
            if ~isempty(app.channel_data.ECG1.idx)
                app.channel_data.ECG1.units = chan_units{app.channel_data.ECG1.idx};
                app.channel_data.ECG1.data = BioPAC.data(:,app.channel_data.ECG1.idx);
            end
            if ~isempty(app.channel_data.ECG2.idx)
                app.channel_data.ECG2.units = chan_units{app.channel_data.ECG2.idx};
                app.channel_data.ECG2.data = BioPAC.data(:,app.channel_data.ECG2.idx);
            end
            if ~isempty(app.channel_data.ECG3.idx)
                app.channel_data.ECG3.units = chan_units{app.channel_data.ECG3.idx};
                app.channel_data.ECG3.data = BioPAC.data(:,app.channel_data.ECG3.idx);
            end
            if ~isempty(app.channel_data.aort_press.idx)
                app.channel_data.aort_press.units = chan_units{app.channel_data.aort_press.idx};
                app.channel_data.aort_press.data = BioPAC.data(:,app.channel_data.aort_press.idx);
            end
            if ~isempty(app.channel_data.aort_flow.idx)
                app.channel_data.aort_flow.units = chan_units{app.channel_data.aort_flow.idx};
                app.channel_data.aort_flow.data = BioPAC.data(:,app.channel_data.aort_flow.idx);
            end
            if ~isempty(app.channel_data.temp.idx)
                app.channel_data.temp.units = chan_units{app.channel_data.temp.idx};
                app.channel_data.temp.data = BioPAC.data(:,app.channel_data.temp.idx);
            end
            if ~isempty(app.channel_data.camera.idx)
                app.channel_data.camera.units = chan_units{app.channel_data.camera.idx};
                app.channel_data.camera.data = BioPAC.data(:,app.channel_data.camera.idx);
            end
            if ~isempty(app.channel_data.LED.idx)
                app.channel_data.LED.units = chan_units{app.channel_data.LED.idx};
                app.channel_data.LED.data = BioPAC.data(:,app.channel_data.LED.idx);
            end
            if ~isempty(app.channel_data.force.idx)
                app.channel_data.force.units = chan_units{app.channel_data.force.idx};
                app.channel_data.force.data = BioPAC.data(:,app.channel_data.force.idx);
            end
            if ~isempty(app.channel_data.motor1.idx)
                app.channel_data.motor1.units = chan_units{app.channel_data.motor1.idx};
                app.channel_data.motor1.data = BioPAC.data(:,app.channel_data.motor1.idx);
            end
            if ~isempty(app.channel_data.motor2.idx)
                app.channel_data.motor2.units = chan_units{app.channel_data.motor2.idx};
                app.channel_data.motor2.data = BioPAC.data(:,app.channel_data.motor2.idx);
            end
            
            app.channel_data.sampling_time = BioPAC.hdr.graph.sample_time*1e-3; %from ms to s
            sampling_rate = 1/app.channel_data.sampling_time;
            %time = linspace(0,app.channel_data.sampling_time*(num_samples-1), num_samples); %Total Acq time vector in s
            time = 0:app.channel_data.sampling_time:app.channel_data.sampling_time*(num_samples-1);
            %% Extract data only during camera exposure ON
            %Case where camera is off at the beginning of BioPAC recording
            
            try
                camera_ON = find(app.channel_data.camera.data > 0.5);
                recording_start_notcut = camera_ON(1);
                recording_end_notcut = camera_ON(end);
            catch
                Fs = sampling_rate;
                t =  0:app.channel_data.sampling_time:app.channel_data.sampling_time*(num_samples-1);
                x = app.channel_data.camera.data;
%                 fc = 5;
%                 Wn = (2/Fs)*fc;
%                 b = fir1(40,Wn,'low',kaiser(41,3));
%                 
%                 camera_exposure_filtered = filter(b,1,x);
% 
%                 init_point = floor(size(x,1)/2);
%                 end_point = floor(size(x,1)/2) + 1e4;
%                 x_mid = mean(camera_exposure_filtered(init_point:end_point));
%                 
%                 x_start = mean(camera_exposure_filtered(1:1e4));
%                 
%                 thresh = mean([x_mid, x_start]);
% 
%                 camera_ON = find(camera_exposure_filtered > thresh);
%                 recording_start_notcut = camera_ON(1);
%                 recording_end_notcut = camera_ON(end);

                % generate a square signal of duration of the recording
                % and perform cross correlation with the camera exposure
                % signal
                

                duration = (app.processed_data.frame_range(2)-1)/app.data.fps; %s
                square_signal = ones(1, length(x))*0;
                square_signal(1:duration*Fs) = 1;
                
                s1 = abs(x);
                %s1(s1>0.004) = 0.004;
                s1= s1/max(s1);
                s2 = square_signal;
                
                % Cross correlation
                [C21,lag21] = xcorr(s2,s1);
                C21 = C21/max(C21);
                
                [~,I21] = max(C21); %Max of Xcorrelation
                t21 = lag21(I21);

                recording_start_notcut = -t21;
                recording_end_notcut = -t21 + duration*Fs;


            end
            
%             if ~isempty(app.channel_data.camera.idx)
%                 app.channel_data.camera.data = app.channel_data.camera.data(recording_start_notcut:recording_end_notcut);
%             end
%             num_samples_recording = length(app.channel_data.camera.data);
            
            
            %app.channel_data.time_recording = linspace(0,app.channel_data.sampling_time*num_samples_recording, num_samples_recording);
            
            if ~(recording_start_notcut == 1) %The camera is not active when the Acq starts recording (nothing missing in the beginning)
                app.channel_data.time_recording = time - (recording_start_notcut-1)*app.channel_data.sampling_time;
            elseif recording_start_notcut == 1 && ~(recording_end_notcut == num_samples) %camera already active when Acq starts and is off when Acq stops = align with end time
                app.channel_data.time_recording = time + ((app.data.n_frames_full-1)/app.data.fps - time(recording_end_notcut-1));
            end
            
            
            % Take into account that the data could have been clipped
            % Calculate start and ending times using the current frame range
            camera_start_time = (app.processed_data.frame_range(1)-1)/app.data.fps;
            %If the data was clipped the time is shifted so it is aligned with the movie time
            app.channel_data.time_recording = app.channel_data.time_recording -  camera_start_time; %app.channel_data.time_recording(start_delay_idx);
%             
%             recording_start = recording_start_notcut + start_delay_idx;
%             recording_end = recording_start_notcut + end_delay_dx;
%             if isempty(recording_end)
%                 recording_end = num_samples_recording;
%                 app.channel_data.time_recording
%             end
%             
%             if ~isempty(app.channel_data.local_perf.idx)
%                 app.channel_data.local_perf.data = app.channel_data.local_perf.data(recording_start:recording_end);
%             end
%             if ~isempty(app.channel_data.elec_stim.idx)
%                 app.channel_data.elec_stim.data = app.channel_data.elec_stim.data(recording_start:recording_end);
%             end
%             if ~isempty(app.channel_data.ECG1.idx)
%                 app.channel_data.ECG1.data = app.channel_data.ECG1.data(recording_start:recording_end);
%                 %                 app.channel_data.ECG1.data = smoothdata(app.channel_data.ECG1.data,'gaussian', sampling_rate/0.03);
%                 app.channel_data.ECG1.data = app.channel_data.ECG1.data - min(app.channel_data.ECG1.data);
%                 app.channel_data.ECG1.data = app.channel_data.ECG1.data ./max(app.channel_data.ECG1.data);
%             end
%             if ~isempty(app.channel_data.ECG2.idx)
%                 app.channel_data.ECG2.data = app.channel_data.ECG2.data(recording_start:recording_end);
%                 %                 app.channel_data.ECG2.data = smoothdata(app.channel_data.ECG2.data,'gaussian', sampling_rate/0.03);
%                 app.channel_data.ECG2.data = app.channel_data.ECG2.data - min(app.channel_data.ECG2.data);
%                 app.channel_data.ECG2.data = app.channel_data.ECG2.data ./max(app.channel_data.ECG2.data);
%             end
%             if ~isempty(app.channel_data.ECG3.idx)
%                 app.channel_data.ECG3.data = app.channel_data.ECG3.data(recording_start:recording_end);
%                 %                 app.channel_data.ECG3.data = smoothdata(app.channel_data.ECG3.data,'gaussian', sampling_rate/0.03);
%                 app.channel_data.ECG3.data = app.channel_data.ECG3.data - min(app.channel_data.ECG3.data);
%                 app.channel_data.ECG3.data = app.channel_data.ECG3.data ./max(app.channel_data.ECG3.data);
%             end
%             if ~isempty(app.channel_data.aort_press.idx)
%                 app.channel_data.aort_press.data = app.channel_data.aort_press.data(recording_start:recording_end);
%             end
%             if ~isempty(app.channel_data.aort_flow.idx)
%                 app.channel_data.aort_flow.data = app.channel_data.aort_flow.data(recording_start:recording_end);
%             end
%             if ~isempty(app.channel_data.temp.idx)
%                 app.channel_data.temp.data = app.channel_data.temp.data(recording_start:recording_end);
%             end
%             if ~isempty(app.channel_data.LED.idx)
%                 app.channel_data.LED.data = app.channel_data.LED.data(recording_start:recording_end);
%             end
        close(progress_bar)
        end
        
        function update_BioPAC(app)
            app.load_BioPAC;
            app.SwitchBioPACShow_HideValueChanged;
        end
        
        function initialize_BioPAC_data(app)
            set(app.display.plot_handle_BioPAC,'Visible','off');
            set(app.BioPAC_signal_display_axes,'Visible','off');
            set(app.ShowBioPACDropDown,'Enable','off');
            app.BioPAC_signal_display_axes = [];
            app.BioPACfileEditField.Value = '';
            app.channel_data = [];
            app.display.BioPAC_signal_display = 0;
            app.SwitchBioPACShow_Hide.Value = 'Hide';
            if app.display.map == 1
                set(app.main_display_axes,'Position',[1,app.figure_size(4)-floor(1.2*app.figure_size(4)/2)-40,0.95*app.figure_size(3),floor(1.2*app.figure_size(4)/2)]);
            else
                set(app.main_display_axes,'Position',[1,app.figure_size(4)-floor(1.2*app.figure_size(4)/2)-40,app.figure_size(3),floor(1.2*app.figure_size(4)/2)]);
            end
            
        end
    
    
        function quick_load_partially_reference(app)
            % Open subApp to load reference recording and pass processed stack (regions and background) to main app. I'm using a subApp so I can use all loading functions
            % as we have them here but avoiding deleting file info and stack info from working file.
            
            
        end
        
        function rotate_stack_and_update(app)
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title',['Rotating ', num2str(app.rotation_angle)],'Indeterminate','on');
            
            % Store data for undo
            app.processed_data.previous_stack = app.processed_data.image_stack{1};
            app.history.old = app.history.current;
            
            %Rotate all available stacks stack
            len_norm = length(app.processed_data.image_stack);
            for k=1:len_norm
                if ~isempty(app.processed_data.image_stack{k})
                    app.processed_data.image_stack{k} = imrotate(app.processed_data.image_stack{k},app.rotation_angle);
                end
            end
            
            %Update height and width info
            h_old = app.processed_data.height;
            w_old = app.processed_data.width;
            
            app.processed_data.width = h_old;
            app.processed_data.height = w_old;
            
            tool_tip_update(app)
            
            %Update image handle
            cla(app.main_display_axes);
            app.display.image_handle = imshow(app.processed_data.image_stack{app.display.normalisation}(:,:,app.display.current_frame)./max(app.processed_data.image_stack{app.display.normalisation}(1:app.processed_data.height*app.processed_data.width)),'Parent',app.main_display_axes);
            
            
            %Update ROI location


            %Update view and ROI signals
            update_view_signal(app)
            
            close(progress_bar)
            
            %save to history
            app.history_field.Value{length(app.history_field.Value)+1,1} = ['Rotate', num2str(app.rotation_angle)];
            app.history.current = [app.history.current, sprintf('Rotate = %0.1f', app.rotation_angle)];
        end


        function plot_CV_map(app)
            % Plot CV map
            %save for map filtering
            max_value = max(app.processed_data.CV_map(:),[],'omitnan');
            app.max_value_ACT = max_value;
            colourbar_string = 'Conduction Velocity (mm/ms)';


            set(app.display.image_handle,'CData',app.processed_data.CV_map(:,:,3));
            app.display.image_handle.Parent.CLim = [0,max_value];
            % Set first value to black in colour map so that NaN are black.
            app.display.image_handle.Parent.Colormap = [0 0 0; jet(256)];
            if isvalid(app.display.colourbar_handle)
                colorbar(app.display.colourbar_handle,'off')
            end
            app.display.colourbar_handle = colorbar(app.display.image_handle.Parent);
            app.display.colourbar_handle.Label.String = colourbar_string;
            app.display.colourbar_handle.Label.FontSize = app.display.colourbar_handle.Label.FontSize+2;
            app.display.map = 1;
            app.upperylimit_traces.Value = app.display.colourbar_handle.Limits(2);
            app.lowerylimit_traces.Value = app.display.colourbar_handle.Limits(1);

            update_histogram_map(app,1)

            % Add quivers
            if isfield(app.display,"quiver_handle") && ~isempty(app.display.quiver_handle)
                delete(app.display.quiver_handle)
            end

            hold(app.display.image_handle.Parent, 'on');
            quiver_spacing = 10;
            quiver_rows = 1:quiver_spacing:app.processed_data.height;
            quiver_cols = 1:quiver_spacing:app.processed_data.width;
            [quiver_rows,quiver_cols]=meshgrid(quiver_rows,quiver_cols);
            quiver_rows=reshape(quiver_rows,[size(quiver_rows,1)*size(quiver_rows,2),1]);
            quiver_cols=reshape(quiver_cols,[size(quiver_cols,1)*size(quiver_cols,2),1]);
            app.display.quiver_handle = quiver(app.display.image_handle.Parent,quiver_cols,quiver_rows,app.processed_data.CV_map(sub2ind(size(app.processed_data.CV_map),quiver_rows,quiver_cols,2*ones(size(quiver_rows)))),app.processed_data.CV_map(sub2ind(size(app.processed_data.CV_map),quiver_rows,quiver_cols,ones(size(quiver_rows)))),'m');
            hold(app.display.image_handle.Parent, 'off');
        end

        
        function plot_isochrones(app, map_data, isochrone_values,isoline)
            hold(app.display.image_handle.Parent, 'on');
            if exist('app.display.quiver_handle','var')
                delete(app.display.quiver_handle)
            end
            if ~exist('isochrone_values','var') || isempty('isochrone_values')
                switch isoline
                    case 0
                        set(app.display.image_handle,'CData',zeros(size(map_data)));
                        [~,app.display.isochrone_handle] = contourf(map_data,"ShowText",true,'Parent',app.main_display_axes);
                    case 1
                        [~,app.display.isochrone_handle] = contour(map_data,"ShowText",true,'Parent',app.main_display_axes);
                end
            else
                switch isoline
                    case 0
                        set(app.display.image_handle,'CData',zeros(size(map_data)));
                        [~,app.display.isochrone_handle] = contourf(map_data,[-0.01,isochrone_values,max(map_data(:))],"ShowText",true,'Parent',app.main_display_axes); %[0.01,isochrone_values,max(map_data(:))]
                        set(app.display.colourbar_handle,'Ticks',[-0.01,isochrone_values,max(map_data(:))]);
                    case 1
                        %[~,app.display.isochrone_handle] = contour(map_data,[-0.01,isochrone_values,max(map_data(:))],'k',"ShowText",true,'Parent',app.main_display_axes); %[0.01,isochrone_values,max(map_data(:))]
                        [~,app.display.isochrone_handle] = contour(map_data,[-0.01,isochrone_values,max(map_data(:))],'k','Parent',app.main_display_axes); %[0.01,isochrone_values,max(map_data(:))]
                end
            end
            hold(app.display.image_handle.Parent, 'off');
        end

        function openVideoPlayer(app)
            % Frames per second for playback
            fps = app.display.fps;

            % Set videoData
            videoData = 255*app.processed_data.image_stack{app.display.normalisation}./max(app.processed_data.image_stack{app.display.normalisation}(:));
            
            % Get data size
            [H,W,N] = size(videoData);

            currentFrame = 1;

            %% Create Window
            fig = uifigure('Name','Video Player',...
                'Position',[100 100 W+40 H+120]);

            fig.CloseRequestFcn = @closePlayer;

            %% Axes
            ax = uiaxes(fig,...
                'Position',[20 80 W H]);
            ax.XTick = [];
            ax.YTick = [];
            axis(ax,'image')

            %% Display first frame
            imgHandle = image(ax, videoData(:,:,1));
            colormap(ax, app.display.colour_map);

            %% Slider
            slider = uislider(fig,...
                'Position',[20 60 W 3],...
                'Limits',[1 N],...
                'Value',1);

            %% Buttons
            btnWidth = 70;
            spacing = 20;
            yPos = 20;

            btnBack = uibutton(fig,'Text','⏪',...
                'Position',[20 yPos btnWidth 30]);

            btnPlay = uibutton(fig,'Text','▶',...
                'Position',[20+btnWidth+spacing yPos btnWidth 30]);

            btnStop = uibutton(fig,'Text','⏹',...
                'Position',[20+2*(btnWidth+spacing) yPos btnWidth 30]);

            btnForward = uibutton(fig,'Text','⏩',...
                'Position',[20+3*(btnWidth+spacing) yPos btnWidth 30]);

            %% Timer
            playTimer = timer( ...
                'ExecutionMode','fixedRate',...
                'Period',round(1/fps,3),...
                'TimerFcn',@playFrame);

            %% Callbacks

            btnPlay.ButtonPushedFcn = @(~,~) start(playTimer);
            btnStop.ButtonPushedFcn = @(~,~) stop(playTimer);
            btnBack.ButtonPushedFcn = @(~,~) stepBackward;
            btnForward.ButtonPushedFcn = @(~,~) stepForward;
            slider.ValueChangingFcn = @(s,e) scrubFrame(round(e.Value));

            %% ---- Nested Functions ----

            function playFrame(~,~)
                if currentFrame < N
                    currentFrame = currentFrame + 1;
                else
                    stop(playTimer);
                    return
                end

                updateFrame;
            end

            function stepForward
                stop(playTimer);
                currentFrame = min(currentFrame+1,N);
                updateFrame;
            end

            function stepBackward
                stop(playTimer);
                currentFrame = max(currentFrame-1,1);
                updateFrame;
            end

            function scrubFrame(val)
                stop(playTimer);
                currentFrame = min(max(val,1),N);
                updateFrame;
            end

            function updateFrame
                imgHandle.CData = videoData(:,:,currentFrame);

                slider.Value = currentFrame;
                drawnow limitrate
            end

            function closePlayer(~,~)
                try
                    stop(playTimer);
                    delete(playTimer);
                catch
                end
                delete(fig);
            end
        end
        
        function r = createROI(app,type,position,number)
            switch type
                case 'images.roi.Rectangle'
                    r = drawrectangle(app.main_display_axes,'Position', position,'Color',app.plot_colours(rem(number,7),:),'Label',num2str(number),'InteractionsAllowed','none');
                case 'images.roi.Polygon'
                    r = drawpolygon(app.main_display_axes, 'Position', position,'Color',app.plot_colours(rem(number,7),:),'Label',num2str(number),'InteractionsAllowed','none');
                case 'images.roi.Ellipse'
                    r = drawellipse(app.main_display_axes, 'Position', position,'Color',app.plot_colours(rem(number,7),:),'Label',num2str(number),'InteractionsAllowed','none');
                case 'images.roi.Freehand'
                    r = drawfreehand(app.main_display_axes, 'Position', position,'Color',app.plot_colours(rem(number,7),:),'Label',num2str(number),'InteractionsAllowed','none');
                otherwise
                    error('Unhandled ROI type: %s', type);
            end
        end
        
        function delay = align_cell_signals(app,signal, seed_signal, interp_factor)
            beat_signal = interp(signal,interp_factor);
            [~,~,delay] = alignsignals(seed_signal,beat_signal);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set component sizes relative to screen. Need to check at some point the range of sizes
            % that work
            set(app.TabGroup,'Visible','off')
            drawnow
            set(app.opticalmappingUIFigure,'WindowState','maximized');
            drawnow
            pause(2)
            app.figure_size = get(app.opticalmappingUIFigure,'position');
            
            set(app.tool_tip,'Position',[app.figure_size(1) app.figure_size(4)-17 app.figure_size(3) 20]);
            set(app.LoadfileMenu,'Enable','on')
        end

        % Close request function: opticalmappingUIFigure
        function opticalmappingUIFigureCloseRequest(app, event)
            close_check = uiconfirm(app.opticalmappingUIFigure,'Are you sure you wish to close?','Confirm close','Icon','Warning');
            if length(close_check) == 2
                
                delete(app.region_app)
                delete(app.beataveraging_app)
                delete(app.pickframe_app)
                delete(app)
            end
        end

        % Menu selected function: LoadfileMenu
        function load_full_file(app, event)
            range = [];
            rangeType = 1; %load full file
            select_file(app) %open file selector
            if isequal(app.file_info.file,0)
                app.file_info = app.old_file_info;
            else
                load_selected_file(app, range, rangeType); %load file selected
            end
            
        end

        % Menu selected function: LoadfilepartiallyMenu
        function Load_file_partially(app, event)
            select_file(app) % open file selector
            % Once file is selected, go to secondary app to read header (to know how long the recording is) and allow loading a particular time-frame
            if app.file_info.file ~= 0 % check if a file was selected. Open secondary app only if file was selected.
                app.pickrange_app = open_file_partially(app);
                uiwait(app.pickrange_app.pickrangeUIFigure);
                if app.cancel_pickrange == 1
                    app.file_info = app.old_file_info;
                else
                    
                    range = [app.range.start_frame, app.range.end_frame];
                    rangeType = 2; % Although in the subApp it is specified by time, there is already a conversion to frames performed in there. So rangeType is set to frame number.
                    load_selected_file(app,range, rangeType); %load file selected
                end
                
            else %if no new file is selected, just restore the info from before so you an choose regions again from the old file
                app.file_info = app.old_file_info;
            end
        end

        % Menu selected function: DefineregionsMenu
        function define_regions(app, event)
            % Function to define the number of regions and their positions
            % Pop up asking number of regions and direction of top
            app.region_app = region_parameters(app);
            uiwait(app.region_app.region_parameters_UIFigure)
            
            %% Create ROIs for regions
            first_frame = app.data.image_stack(:,:,1);
            image_mode = mode(first_frame(:));
            multiplier = 1.2;
            binary_image = imbinarize(first_frame./max(first_frame(:)),multiplier*image_mode/max(first_frame(:)));
            binary_stats = regionprops('table',binary_image,'Area','BoundingBox');
            
            % Check enough regions identified
            k=1;
            while (size(binary_stats,1) < app.data.regions) && (k<10)
                multiplier = multiplier*0.95;
                binary_image = imbinarize(first_frame./max(first_frame(:)),multiplier*image_mode/max(first_frame(:)));
                binary_stats = regionprops('table',binary_image,'Area','BoundingBox');
                k=k+1;
            end
            if k == 10
                multiplier = 1.2;
                binary_image = imbinarize(first_frame./max(first_frame(:)),multiplier*image_mode/max(first_frame(:)));
                binary_stats = regionprops('table',binary_image,'Area','BoundingBox');
                for l = 1:app.data.regions-size(binary_stats,1)
                    binary_stats(end+1,:) = binary_stats(end,:);
                end
            end
            app.region_rectangles = cell(app.data.regions,1);
            color_rectangles = ['r','g','b','y'];
            labels_rectangles = ['1','2','3','4'];
            for k = 1:app.data.regions
                [~, largest_region_index] = max(binary_stats.Area);
                % Add boundary around bounding box
                rectangle_initial_position = binary_stats.BoundingBox(largest_region_index,:);
                border = floor(0.05*min([app.data.width,app.data.height]));
                rectangle_initial_position(1) = max([1,rectangle_initial_position(1)-border]);
                rectangle_initial_position(2) = max([1,rectangle_initial_position(2)-border]);
                rectangle_initial_position(3) = min([ceil(app.data.width-rectangle_initial_position(1)),rectangle_initial_position(3)+2*border]);
                rectangle_initial_position(4) = min([ceil(app.data.height-rectangle_initial_position(2)),rectangle_initial_position(4)+2*border]);
                app.region_rectangles{k} = drawrectangle('Position',rectangle_initial_position,'Color',color_rectangles(k),'Label',labels_rectangles(k),'Parent',app.main_display_axes);
                binary_stats(largest_region_index,:) = [];
            end
            set([app.FileMenu],'Enable','off');
            set([app.SettingsMenu],'Enable','off');
            set(app.RegionsChosenMenu,'visible','on')
            set(app.MatchRegion1SizeMenu,'visible','on')
            
            uiwait(app.opticalmappingUIFigure)
            
            app.data.region_bounds = zeros(app.data.regions,6);
            for k = 1:app.data.regions
                % Save region bounds [x1 y1 x2 y2 w h]
                temp = app.region_rectangles{k}.Position;
                app.data.region_bounds(k,1) = ceil(temp(2));
                app.data.region_bounds(k,2) = ceil(temp(1));
                app.data.region_bounds(k,3) = floor(temp(2)+temp(4));
                app.data.region_bounds(k,4) = floor(temp(1)+temp(3));
                app.data.region_bounds(k,5) = app.data.region_bounds(k,3) - app.data.region_bounds(k,1) + 1;
                app.data.region_bounds(k,6) = app.data.region_bounds(k,4) - app.data.region_bounds(k,2) + 1;
            end
            
            % Sort bounds based on original order
            app.data.region_bounds = sortrows(app.data.region_bounds,2);
            % If 3 regions switch order
            if app.data.regions == 3
                if app.data.rotation == 3 % Atrial side on the left
                    temp_bounds = app.data.region_bounds;
                    app.data.region_bounds(2,:) = temp_bounds(3,:);
                    app.data.region_bounds(3,:) = temp_bounds(2,:);
                elseif app.data.rotation == 1 % Atrial side on the right
                    temp_bounds = app.data.region_bounds;
                    app.data.region_bounds(1,:) = temp_bounds(3,:);
                    app.data.region_bounds(2,:) = temp_bounds(1,:);
                    app.data.region_bounds(3,:) = temp_bounds(2,:);
                end
            end
            
            if app.data.regions == 4
                if app.data.rotation == 3 % Atrial side on the left
                    temp_bounds = app.data.region_bounds;
                    app.data.region_bounds(2,:) = temp_bounds(2,:);
                    app.data.region_bounds(3,:) = temp_bounds(4,:);
                    app.data.region_bounds(4,:) = temp_bounds(3,:);
                elseif app.data.rotation == 1 % Atrial side on the right
                    temp_bounds = app.data.region_bounds;
                    app.data.region_bounds(1,:) = temp_bounds(4,:);
                    app.data.region_bounds(2,:) = temp_bounds(3,:);
                    app.data.region_bounds(3,:) = temp_bounds(1,:);
                    app.data.region_bounds(4,:) = temp_bounds(2,:);
                end
            end
            
            % Crop and rotate as appropriate
            %app.processed_data.n_frames = app.data.n_frames;
            app.processed_data.cropped = 1;
            apply_regions(app);
            set([app.FileMenu],'Enable','on');
            set([app.SettingsMenu],'Enable','on');
            set([app.QuickloadMenu],'Enable','on');
            set([app.QuickloadpartiallyMenu],'Enable','on');
            set(app.LoadRegionsMenu,'Enable','off');          
            
        end

        % Menu selected function: RegionsChosenMenu
        function regions_chosen(app, event)
            uiresume(app.opticalmappingUIFigure)
            set(app.RegionsChosenMenu,'visible','off')
            set(app.MatchRegion1SizeMenu,'visible','off')
        end

        % Menu selected function: MatchRegion1SizeMenu
        function match_region_1_size(app, event)
            % Set all other regions to same size as region 1
            for k = 2:length(app.region_rectangles)
                app.region_rectangles{k}.Position(3:4) = app.region_rectangles{1}.Position(3:4);
            end
        end

        % Menu selected function: QuickloadMenu
        function quick_load(app, event)
            
            %TODO% tidy up loading of ROIs. Having to use a number of
            %hacks.
            % Error if a data set hasn't already been loaded.
            if isempty(app.processed_data.image_stack)
                uialert(app.opticalmappingUIFigure,'Must have a data set already loaded','Error')
            else
                % Save regions, background and ROIs based on user settings
                if (app.settings.quick_load.regions == 1) && (app.processed_data.cropped == 1)
                    regions = app.data.region_bounds;
                    num_regions = app.data.regions;
                    rotation = app.data.rotation;
                end
                if app.settings.quick_load.background == 1
                    %TODO% add save/load of background
                end
                if (app.settings.quick_load.roi == 1) && ~isempty(app.display.ROI_mask)
                    % Create non-ui figure image to store rectangles
                    h = figure;
                    h.Visible = 'off';
                    ax = axes(h);
                    for k = 1:length(app.display.ROI_handle)
                        roi_handles{k} = copyobj(app.display.ROI_handle{k},ax);
                    end
                    roi_masks = app.display.ROI_mask;
                else
                    roi_handles = [];
                end
                % Current normalisation/inversion
                norm = app.display.normalisation;
                inversion = app.display.inversion;


                %current colour map
                colourmap = app.display.colour_map;
                fps = app.display.fps;
                % Hide signals window during loading
                app.hide_signals(app);
                app.settings.quick_load.select_file_from_quick_load = 1;
                
                %store appfile info in case no file has been selected
                old_file_info_file = app.file_info.file;
                % Select new file
                if app.quick_load_partially == 0
                    app.load_full_file(app);
                else
                    app.Load_file_partially(app);
                end
                
                %                 if strcmp(app.file_info.file,old_file_info_file) == 0 % check if a different file was selected
                progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Quick-loading','Indeterminate','on');
                %Reset filtering history
                app.history = struct('current','0','old','0');
                
                %Reset Notes field
                app.NotesTextArea.Value = {''};
                
                
                app.display.colour_map = colourmap;
                % Load and apply regions, background and ROIs based on user settings
                if app.settings.quick_load.regions == 1
                    app.data.region_bounds = regions;
                    app.data.regions = num_regions;
                    app.data.rotation = rotation;
                    app.processed_data.cropped = 1;
                    %app.load_data(app);
                    apply_regions(app)
                else
                    app.processed_data.image_stack{app.display.normalisation} = app.data.image_stack;
                    app.processed_data.height = app.data.height;
                    app.processed_data.width = app.data.width;
                    cla(app.main_display_axes);
                    app.display.image_handle = imshow(app.processed_data.image_stack{app.display.normalisation}(:,:,app.display.current_frame)./max(app.processed_data.image_stack{app.display.normalisation}(:,:,app.display.current_frame),[],'all'),'Parent',app.main_display_axes);
                    app.display.image_handle.Parent.CLim = [0,1];
                    app.first_processed_frame = app.processed_data.image_stack{1}(:,:,1); %saving the first frame without inversion to use for automatic segmentation because if the data is later inverted the segmentation code will not work
                    % Make tabs visible
                    int = get(app.TabGroup,'Position');
                    set(app.TabGroup,'Position',[int(1), int(2), int(3), floor(app.figure_size(4)./3)])
                    set(app.TabGroup,'Visible','on')
                    set(app.saveworkspace,'Enable','on')
                end
                
                
                if app.settings.quick_load.background == 1
                    %TODO% add save/load of background
                end
                
                if app.settings.quick_load.roi == 1 && ~isempty(roi_handles)
                    for k = 1:length(roi_handles)
                        app.display.ROI_handle{k} = copyobj(roi_handles{k},app.main_display_axes);
                    end
                    app.display.ROI_mask = roi_masks;
                    app.display.signal_display = 1;
                    
                    
                    %cellfun(@(x) set(x,'Parent',app.main_display_axes),app.display.ROI_handle);
                    
                    % Create signals
                    for k = 1:length(roi_handles)
                        apply_signal_masks(app, k);
                    end
                    
                    % Plot signal on axes
                    if ~app.display.signal_display
                        set(app.main_display_axes,'Position',[1,app.figure_size(4)-floor(1.2*app.figure_size(4)/2)-40,0.7*app.figure_size(3),floor(1.2*app.figure_size(4)/2)]);
                        if isempty(app.signal_display_axes)
                            app.signal_display_axes = axes(app.opticalmappingUIFigure, 'Units','Pixels','Position',[0.74*app.figure_size(3),app.figure_size(4)-floor(1.1*app.figure_size(4)/2)-40,0.25*app.figure_size(3),floor(1.0*app.figure_size(4)/2)]);
                            hold(app.signal_display_axes, 'on');
                            xlabel('Time (s)','Parent',app.signal_display_axes);
                            ylabel('Signal (a.u.)','Parent',app.signal_display_axes);
                        end
                        set(app.signal_display_axes,'Visible','on');
                        app.display.signal_display = 1;
                    end
                    
                    %Plot signals
                    app.display.plot_handle = cellfun(@(x,y) plot(x,y,'Parent',app.signal_display_axes),app.processed_data.signals(:,1),app.processed_data.signals(:,app.display.normalisation+1));
                    
                    app.show_signals(app);
                    
                    figure(app.opticalmappingUIFigure);
                end
                
                % In case inversion status is 1, invert data
                if inversion == 1
                    invert(app);
                end


                app.display.fps = fps;
                
                app.display.normalisation = norm;
                % Display averaged data
                switch app.display.normalisation
                    case 1
                        app.change_normalisation_none(app);
                    case 2
                        app.change_normalisation_global(app);
                    case 3
                        app.change_normalisation_local(app);
                    case 4
                        app.change_normalisation_global_mean(app);
                    case 5
                        app.change_normalisation_timeaverage(app);
                end
                app.display.renorm(5) = 1;
                app.reference_stack =[];
                
                
                close(progress_bar)
                %else %if no new file is selected, just restore the info from before so you an choose regions again from the old file
                %   app.file_info = old_file_info;
                %                 end
                
            end
            app.settings.quick_load.select_file_from_quick_load = 0;
            
            
        end

        % Button pushed function: nextframe
        function next_frame(app, event)
            app.display.current_frame = min(app.processed_data.n_frames,app.display.current_frame+1);
            app.tool_tip_update;
            app.update_view_signal;
        end

        % Menu selected function: RestartAPPMenu
        function RestartAPPMenuSelected(app, event)
            close_check = uiconfirm(app.opticalmappingUIFigure,'Are you sure you wish to restart the APP?','Confirm close','Icon','Warning');
            if length(close_check) == 2
                app.opticalmappingUIFigure.Visible = 'off';
                % Open 2nd instance of app
                analyse_optical_mapping();
                % Delete old instance
                delete(app.opticalmappingUIFigure)
            end
        end

        % Button pushed function: playmovie
        function play_movie(app, event)
            % Play movie - when animations are possible with AppDesigner incorporate into current axes
            
            if ~isdeployed
                h = implay(app.processed_data.image_stack{app.display.normalisation}./max(app.processed_data.image_stack{app.display.normalisation}(:)),app.display.fps);
                h.Visual.ColorMap.Map = app.display.colour_map;
            else
                openVideoPlayer(app)
            end
        end

        % Value changed function: setframerate
        function set_fps(app, event)
            app.display.fps = app.setframerate.Value;
        end

        % Value changed function: nonormalise
        function change_normalisation_none(app, event)
            % add remove min for this and global -> also add un normalised button and signal.
            
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Changing to no normalisation','Indeterminate','on');
            app.display.normalisation = 1;
            app.globalnormalise.Value = 0;
            app.localnormalise.Value = 0;
            app.globalmeannormalise.Value = 0;
            app.timeaverage.Value = 0;
            app.ShowHideWavefrontButton.Value = 0;
            app.ReferencenormalisationButton.Value = 0;
            
            update_view_signal(app)
            close(progress_bar)
            app.history_field.Value{length(app.history_field.Value)+1,1} = 'Changed to no normalisation';
        end

        % Value changed function: globalnormalise
        function change_normalisation_global(app, event)
            
            
            app.display.normalisation = 2;
            app.nonormalise.Value = 0;
            app.globalmeannormalise.Value = 0;
            app.localnormalise.Value = 0;
            app.timeaverage.Value = 0;
            app.ShowHideWavefrontButton.Value = 0;
            app.ReferencenormalisationButton.Value = 0;
            
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Normalising','Indeterminate','on');
            
            if app.display.renorm(1)
                app.processed_data.image_stack{app.display.normalisation} = app.processed_data.image_stack{1}-min(app.processed_data.image_stack{1}(:));
                app.processed_data.image_stack{app.display.normalisation} = app.processed_data.image_stack{app.display.normalisation}./max(app.processed_data.image_stack{app.display.normalisation}(:));
                app.display.renorm(1) = 0;
                
                %Redo signals
                if ~isempty(app.processed_data.signals)
                    for k = 1:size(app.processed_data.signals,1)
                        app.processed_data.signals{k,app.display.normalisation+1} = time_signal(app,app.display.normalisation,k);
                    end
                end
                
            end
            update_view_signal(app)
            close(progress_bar)
            app.history_field.Value{length(app.history_field.Value)+1,1} = 'Changed to global normalisation';
        end

        % Value changed function: localnormalise
        function change_normalisation_local(app, event)
            
            
            app.display.normalisation = 3;
            app.globalnormalise.Value = 0;
            app.nonormalise.Value = 0;
            app.globalmeannormalise.Value = 0;
            app.timeaverage.Value = 0;
            app.ShowHideWavefrontButton.Value = 0;
            app.ReferencenormalisationButton.Value = 0;
            
            app.local_normalisation_computed = 1;
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Normalising','Indeterminate','on');
            if app.display.renorm(2)
                app.processed_data.image_stack{app.display.normalisation} = app.processed_data.image_stack{1}-min(app.processed_data.image_stack{1},[],3);
                app.processed_data.image_stack{app.display.normalisation} = app.processed_data.image_stack{app.display.normalisation}./max(app.processed_data.image_stack{app.display.normalisation},[],3);
                app.processed_data.image_stack{app.display.normalisation}(isnan(app.processed_data.image_stack{app.display.normalisation})) = 0;
                app.display.renorm(2) = 0;
                
                %Redo signals
                if ~isempty(app.processed_data.signals)
                    for k = 1:size(app.processed_data.signals,1)
                        app.processed_data.signals{k,app.display.normalisation+1} = time_signal(app,app.display.normalisation,k);
                    end
                end
                
            end
            update_view_signal(app)
            close(progress_bar)
            app.history_field.Value{length(app.history_field.Value)+1,1} = 'Changed to local normalisation';
        end

        % Value changed function: globalmeannormalise
        function change_normalisation_global_mean(app, event)
            
            
            app.display.normalisation = 4;
            app.globalnormalise.Value = 0;
            app.nonormalise.Value = 0;
            app.timeaverage.Value = 0;
            app.localnormalise.Value = 0;
            app.ShowHideWavefrontButton.Value = 0;
            app.ReferencenormalisationButton.Value = 0;
            
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Normalising','Indeterminate','on');
            if app.display.renorm(3)
                app.processed_data.image_stack{app.display.normalisation} = app.processed_data.image_stack{1}-min(app.processed_data.image_stack{1},[],3);
                local_maxes = max(app.processed_data.image_stack{app.display.normalisation},[],3);
                local_maxes(local_maxes==0) = NaN; % Set all areas with no amplitude to NaN (only occurs in areas that have been removed)
                app.processed_data.image_stack{app.display.normalisation} = app.processed_data.image_stack{app.display.normalisation}./max(local_maxes,[],'all','omitnan');%./mean(local_maxes(:),'omitnan');
                app.processed_data.image_stack{app.display.normalisation}(isnan(app.processed_data.image_stack{app.display.normalisation})) = 0;
                app.display.renorm(3) = 0;
                
                %Redo signals
                if ~isempty(app.processed_data.signals)
                    for k = 1:size(app.processed_data.signals,1)
                        app.processed_data.signals{k,app.display.normalisation+1} = time_signal(app,app.display.normalisation,k);
                    end
                end
                
            end

            update_view_signal(app)
            close(progress_bar)
            app.history_field.Value{length(app.history_field.Value)+1,1} = 'Changed to global mean normalisation';
            
        end

        % Value changed function: timeaverage
        function change_normalisation_timeaverage(app, event)
            
            
            app.display.normalisation = 6;
            app.globalnormalise.Value = 0;
            app.nonormalise.Value = 0;
            app.globalmeannormalise.Value = 0;
            app.localnormalise.Value = 0;
            app.ShowHideWavefrontButton.Value = 0;
            app.ReferencenormalisationButton.Value = 0;
            if app.display.renorm(2)
                uialert(app.opticalmappingUIFigure,'Local normalisation must be updated first','Error')
                app.display.normalisation = 1;
                app.nonormalise.Value = 1;
                app.timeaverage.Value = 0;
            elseif app.display.renorm(4)
                app.processed_data.image_stack{app.display.normalisation} = mean(app.processed_data.image_stack{3},3);
            end
            update_view_signal(app)
            app.history_field.Value{length(app.history_field.Value)+1,1} = 'Changed to time average';
        end

        % Value changed function: ReferencenormalisationButton
        function change_normalisation_reference(app, event)
            
            value = app.ReferencenormalisationButton.Value;
            app.display.normalisation = 7;
            app.globalnormalise.Value = 0;
            app.nonormalise.Value = 0;
            app.globalmeannormalise.Value = 0;
            app.localnormalise.Value = 0;
            app.timeaverage.Value = 0;
            app.ShowHideWavefrontButton.Value = 0;
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Transfering normalisation','Indeterminate','on');
            if isempty(app.reference_stack)
                progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Transfering normalisation','Indeterminate','on');
                app.display.renorm(5) = 0;
                %Open subAPP
                app.normalise_reference = open_reference_recording(app);
                app.wait = 1;
                while app.wait == 1
                    uiwait(app.normalise_reference.normalise_referenceUIFigure);
                end
                %Redo signals
                if ~isempty(app.processed_data.signals)
                    for k = 1:size(app.processed_data.signals,1)
                        app.processed_data.signals{k,app.display.normalisation+1} = time_signal(app,app.display.normalisation,k);
                    end
                end
                
                
            elseif app.display.renorm(5) && ~app.spikes
                app.display.renorm(5) = 0;
                
                %% Extract baseline for each stack
                reference_baseline = app.reference_stack - min(app.reference_stack,[],3);
                working_stack_baseline = app.processed_data.image_stack{1} - min(app.processed_data.image_stack{1},[],3);
                
                %% Find local maxima for each pixel
                local_maxes_reference = max(reference_baseline,[],3);
                local_maxes_working = max(working_stack_baseline,[],3);
                %%
                local_max = max(local_maxes_reference, local_maxes_working);
                %local_max(local_max==0) = NaN;
                %% Normalize
                app.processed_data.image_stack{7} = working_stack_baseline./local_max;
                app.processed_data.image_stack{7}(isnan(app.processed_data.image_stack{7})) = 0;
                %Redo signals
                if ~isempty(app.processed_data.signals)
                    for k = 1:size(app.processed_data.signals,1)
                        app.processed_data.signals{k,app.display.normalisation+1} = time_signal(app,app.display.normalisation,k);
                    end
                end
                
            elseif app.display.renorm(5) && app.spikes
                 app.display.renorm(5) = 0;
                 working_stack = app.processed_data.image_stack{1};
                 reference_stack = app.reference_stack;
                 
                 % De-invert working stack (assuming it is inverted) TODO: check for inversion to de-invert
                 working_stack = max(working_stack(:))-working_stack;
                 reference_stack = max(reference_stack(:))-reference_stack;

                 %clip all values of the working stack that are higher than the maximum of the reference stack (as they are not inverted, the spikes of the LED are positive
                 %spikes)
                 reference_stack = reference_stack - min(reference_stack,[],3);
                 working_stack = working_stack - min(working_stack,[],3);
                 size_stack = size(working_stack);
                 max_reference_pixel = max(reference_stack,[],3);
                 mean_reference_pixel = mean(reference_stack,3);
                 for k=1:size_stack(1)
                    for l=1:size_stack(2)
                       pixel_signal = working_stack(k,l,:);
                       pixel_signal(pixel_signal>max_reference_pixel(k,l)) = mean_reference_pixel(k,l);
                       working_stack(k,l,:) = pixel_signal;
                    end
                 end 
                 
                 %now invert stacks
                 working_stack = max(working_stack(:))-working_stack;
                 reference_stack = max(reference_stack(:))-reference_stack;

                 %% Extract baseline for each stack using the min of the reference
                 working_stack = working_stack - min(reference_stack,[],3);
                 reference_stack = reference_stack - min(reference_stack,[],3);
            
                 %% Find local maxima for each pixel
                 local_maxes_reference = max(reference_stack,[],3);
                 local_maxes_working = max(working_stack,[],3);
                 %%
                 local_max = max(local_maxes_reference, local_maxes_working);
                 %local_max(local_max==0) = NaN;
                 %% Normalize
                 normalized_stack = working_stack./local_max;
                 normalized_stack(isnan(normalized_stack)) = 0;
                 normalized_stack = normalized_stack - min(normalized_stack,[],3);
                 app.processed_data.image_stack{7} = normalized_stack./max(normalized_stack,[],3);
                  %Redo signals
                if ~isempty(app.processed_data.signals)
                    for k = 1:size(app.processed_data.signals,1)
                        app.processed_data.signals{k,app.display.normalisation+1} = time_signal(app,app.display.normalisation,k);
                    end
                end

            end
         
            close(progress_bar)
            update_view_signal(app)
            app.history_field.Value{length(app.history_field.Value)+1,1} = 'Changed to reference normalisation';


        end

        % Value changed function: invertdata
        function invert(app, event)
            app.display.inversion = app.invertdata.Value; % save state for quick load
            %value = app.invertdata.Value;
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Inverting','Indeterminate','on');
            for k = 1:length(app.processed_data.image_stack)
                app.processed_data.image_stack{k} = max(app.processed_data.image_stack{k}(:)) - app.processed_data.image_stack{k};
                % Recalculate all signals
                if app.display.signal_display
                    for l = 1:size(app.processed_data.signals,1)
                        app.processed_data.signals{l,2} = time_signal(app,1,l);
                        if ~app.display.renorm(1)
                            app.processed_data.signals{l,3} = time_signal(app,2,l);
                        end
                        if ~app.display.renorm(2)
                            app.processed_data.signals{l,4} = time_signal(app,3,l);
                        end
                        if ~app.display.renorm(3)
                            app.processed_data.signals{l,5} = time_signal(app,4,l);
                        end
                    end
                end
            end
            
            % Apply mask area if there is one
            if ~isempty(app.processed_data.keep_area_poly)
                % Mask no normalisation
                app.processed_data.image_stack{1} = app.processed_data.image_stack{1}.*app.processed_data.keep_area_poly;
                
                % Recalculate normalisation that is displayed
                app.display.renorm = ones(size(app.display.renorm));
                switch app.display.normalisation
                    case 1
                        app.change_normalisation_none(app);
                    case 2
                        app.change_normalisation_global(app);
                    case 3
                        app.change_normalisation_local(app);
                    case 4
                        app.change_normalisation_global_mean(app);
                    case 5
                        app.change_normalisation_local(app);
                        app.change_normalisation_timeaverage(app);
                end
            end


            update_view_signal(app)
            close(progress_bar)
            app.history_field.Value{length(app.history_field.Value)+1,1} = 'Signal inverted';
        end

        % Button pushed function: KeepareaButton
        function keep_area(app, event)
            app.processed_data.previous_stack = app.processed_data.image_stack{1};
            app.processed_data.previous_keep_area_poly = app.processed_data.keep_area_poly;
            
            % Draw a keep area for every region
            if ~isfield(app.processed_data,'keep_area_poly') || isempty(app.processed_data.keep_area_poly)
                app.processed_data.keep_area_poly = ones(app.processed_data.height,app.processed_data.width);
            end
            
            area_poly = zeros(app.processed_data.height,app.processed_data.width);
            
            for k =1:app.data.regions
                h{k} = drawpolygon('Parent',app.main_display_axes);
                poly_mask = poly2mask(h{k}.Position(:,1),h{k}.Position(:,2),app.processed_data.height,app.processed_data.width);
                area_poly = area_poly + poly_mask;
            end
            
            app.processed_data.keep_area_poly = app.processed_data.keep_area_poly .* area_poly;
            
            % Delete draw polygons
            cellfun(@delete,h);
            
            % Convert zeros in poly to NaN
            app.processed_data.keep_area_poly(app.processed_data.keep_area_poly==0) = NaN;
            
            % Mask no normalisation
            app.processed_data.image_stack{1} = app.processed_data.image_stack{1}.*app.processed_data.keep_area_poly;
            
            % Recalculate normalisation that is displayed
            app.display.renorm = ones(size(app.display.renorm));
            switch app.display.normalisation
                case 1
                    app.change_normalisation_none(app);
                case 2
                    app.change_normalisation_global(app);
                case 3
                    app.change_normalisation_local(app);
                case 4
                    app.change_normalisation_global_mean(app);
                case 5
                    app.change_normalisation_local(app);
                    app.change_normalisation_timeaverage(app);
            end
        end

        % Button pushed function: RemoveareaButton
        function remove_area(app, event)
            app.processed_data.previous_stack = app.processed_data.image_stack{1};
            app.processed_data.previous_keep_area_poly = app.processed_data.keep_area_poly;
            
            % Keep area for conversion to double
            if ~isfield(app.processed_data,'keep_area_poly') || isempty(app.processed_data.keep_area_poly)
                app.processed_data.keep_area_poly = ones(app.processed_data.height,app.processed_data.width);
            end
            
            h = drawpolygon('Parent',app.main_display_axes);
            poly_mask = ~poly2mask(h.Position(:,1),h.Position(:,2),app.processed_data.height,app.processed_data.width);
            delete(h)
            
            
            app.processed_data.keep_area_poly = app.processed_data.keep_area_poly .* poly_mask;
            
            % Convert zeros in poly to NaN
            app.processed_data.keep_area_poly(app.processed_data.keep_area_poly==0) = NaN;
            
            % Mask no normalisation
            app.processed_data.image_stack{1} = app.processed_data.image_stack{1}.*app.processed_data.keep_area_poly;
            
            % Recalculate normalisation that is displayed
            app.display.renorm = zeros(1,4);
            app.display.renorm(2) = 1; % just renormalise global normalisations. Others are not affected by removing a piece of the image.
            app.display.renorm(4) = 1;
            switch app.display.normalisation
                case 1
                    app.change_normalisation_none(app);
                case 2
                    app.change_normalisation_global(app);
                case 3
                    app.change_normalisation_local(app);
                case 4
                    app.change_normalisation_global_mean(app);
                case 5
                    app.change_normalisation_timeaverage(app);
            end
            
        end

        % Button pushed function: SaveareaButton
        function save_area(app, event)
            save_file = file_exists(app,'area','.mat');
            keep_area_poly = app.processed_data.keep_area_poly_vertices; %This saves the mask - loading will be backwards compatible with mask or polygons
            save(save_file,'keep_area_poly','-mat');
            app.history_field.Value{length(app.history_field.Value)+1,1} = 'Background area saved';
        end

        % Button pushed function: LoadareaButton
        function load_area(app, event)
            app.processed_data.previous_stack = app.processed_data.image_stack{1};
            app.processed_data.previous_keep_area_poly = app.processed_data.keep_area_poly;
            
            [area_file,area_path] = uigetfile({'*.mat';'*.*'},'Area selector'); % Open ROI selector
            load([area_path area_file], 'keep_area_poly');
            
            if iscell(keep_area_poly)
                app.processed_data.keep_area_poly_vertices =cell(length(keep_area_poly),1); %load polygon positions
                for k =1:length(keep_area_poly)
                    app.processed_data.keep_area_poly_vertices{k} = drawpolygon(app.main_display_axes, 'Position', keep_area_poly{k});
                end
                
                set([app.FileMenu],'Enable','off');
                set([app.SettingsMenu],'Enable','off');
                set([app.TabGroup],'visible','off');
                set(app.ConfirmExtractBackgroundMenu,'visible','on')
                
                uiwait(app.opticalmappingUIFigure)
                progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Extracting Background','Indeterminate','on');
                set([app.FileMenu],'Enable','on');
                set([app.SettingsMenu],'Enable','on');
                set([app.TabGroup],'visible','on');
                set(app.ConfirmExtractBackgroundMenu,'visible','off')
                
                %convert polygons to mask and apply
                mask = false(app.processed_data.height, app.processed_data.width); % Initialize final mask
                for k =1:length(app.processed_data.keep_area_poly_vertices)
                    poly_k = app.processed_data.keep_area_poly_vertices{k};
                    poly_k_mask = poly2mask(poly_k.Position(:,1), poly_k.Position(:,2), app.processed_data.height, app.processed_data.width);
                    mask = mask | poly_k_mask;
                    delete(app.processed_data.keep_area_poly_vertices{k})
                end
                app.processed_data.keep_area_poly_vertices = keep_area_poly;
                
                app.processed_data.keep_area_poly = mask;
                
            else 
                app.processed_data.keep_area_poly = keep_area_poly;
            end
            
            
            % Mask no normalisation
            app.processed_data.image_stack{1} = app.processed_data.image_stack{1}.*app.processed_data.keep_area_poly;
            
            % Recalculate normalisation that is displayed
            app.display.renorm = [1, 1, 1, 1 ,1]; %changed this
            
            switch app.display.normalisation
                case 1
                    app.change_normalisation_none(app);
                case 2
                    app.change_normalisation_global(app);
                case 3
                    app.change_normalisation_local(app);
                case 4
                    app.change_normalisation_global_mean(app);
                case 5
                    app.change_normalisation_timeaverage(app);
            end

            figure(app.opticalmappingUIFigure);
            app.history_field.Value{length(app.history_field.Value)+1,1} = 'Background area loaded';
        end

        % Button pushed function: signalregion
        function select_signal(app, event)
            % Select polygon and add mean of these signals over time to
            % signals cell array
            if strcmp(app.signalregiontype.Value,'Rectangle')
                app.display.ROI_handle{end+1} = drawrectangle('Parent',app.main_display_axes,'Color',app.plot_colours(1+rem(length(app.display.ROI_handle),7),:),'Label',num2str(1+length(app.display.ROI_handle)),'InteractionsAllowed','none');
                app.display.ROI_mask{end+1} = createMask(app.display.ROI_handle{end},app.processed_data.height,app.processed_data.width);
            elseif strcmp(app.signalregiontype.Value,'Polygon')
                app.display.ROI_handle{end+1} = drawpolygon('Parent',app.main_display_axes,'Color',app.plot_colours(1+rem(length(app.display.ROI_handle),7),:),'Label',num2str(1+length(app.display.ROI_handle)),'InteractionsAllowed','none');
                app.display.ROI_mask{end+1} = poly2mask(app.display.ROI_handle{end}.Position(:,1),app.display.ROI_handle{end}.Position(:,2),app.processed_data.height,app.processed_data.width);
            else
                square_size = str2num(app.signalregiontype.Value);
                
                roi_point = drawpoint('Parent',app.main_display_axes); %use mouse to select a point ROI
                point_pos = round(get(roi_point,'Position')); %extract coordinates of the point ROI
                delete(roi_point); %delete the point roi marker

                if square_size == 1
                    xmin = point_pos(1);
                    ymin =  point_pos(2);
                else
                    xmin = point_pos(1) - square_size/2;
                    ymin =  point_pos(2) - square_size/2;
                end
                width = square_size;
                height = square_size;

                app.display.ROI_handle{end+1} = drawrectangle('Parent',app.main_display_axes,'Position', [xmin ymin width height], 'Color',app.plot_colours(rem(1+length(app.display.ROI_handle),7),:),'Label',num2str(1+length(app.display.ROI_handle)),'InteractionsAllowed','none');
                app.display.ROI_mask{end+1} = createMask(app.display.ROI_handle{end},app.processed_data.height,app.processed_data.width);
                
            end
            app.display.ROI_mask{end} = double(app.display.ROI_mask{end});
            app.display.ROI_mask{end}(app.display.ROI_mask{end}==0) = NaN;
            
            % Create signals
            apply_signal_masks(app, size(app.processed_data.signals,1)+1);
            
            % Plot signal on axes
            if ~app.display.signal_display
                set(app.main_display_axes,'Position',[1,app.figure_size(4)-floor(1.2*app.figure_size(4)/2)-40,0.7*app.figure_size(3),floor(1.2*app.figure_size(4)/2)]);
                if isempty(app.signal_display_axes)
                    app.signal_display_axes = axes(app.opticalmappingUIFigure, 'Units','Pixels','Position',[0.74*app.figure_size(3),app.figure_size(4)-floor(1.1*app.figure_size(4)/2)-40,0.25*app.figure_size(3),floor(1.0*app.figure_size(4)/2)],'Color', 'none');
                    hold(app.signal_display_axes, 'on');
                    xlabel('Time (s)','Parent',app.signal_display_axes);
                    ylabel('Signal (a.u.)','Parent',app.signal_display_axes);
                end
                set(app.signal_display_axes,'Visible','on');
                app.display.signal_display = 1;
            end
            
            % Update plot command for right normalisation.
            app.display.plot_handle(end+1) = plot(app.processed_data.signals{end,1}, app.processed_data.signals{end,app.display.normalisation+1},'Parent',app.signal_display_axes);
            xlim(app.signal_display_axes, [app.processed_data.signals{end,1}(1),app.processed_data.signals{end,1}(end)])
            
            % Enable selecting and deleting ROI
            set(app.ROIdropdown,'Enable','on');
            set(app.deleteROI,'Enable','on');
            
            num_ROI = num2cell(1:length(app.display.ROI_handle));
            % Update ROI dropdown
            app.ROIdropdown.Items = cellfun(@(x) sprintf('ROI %d',x),num_ROI,'UniformOutput',false);
            app.ROIdropdown.ItemsData = num_ROI;
            
            app.SelectROIDropDown.Items = cellfun(@(x) sprintf('ROI %d',x),num_ROI,'UniformOutput',false);
            app.SelectROIDropDown.ItemsData = num_ROI;
            
            
            set(app.averagebeats,'Enable','on')
            set(app.SELECTROIFIRSTLabel,'Visible','off')
            set(app.showsignals,'Enable', 'on')
            set(app.hidesignal,'Enable', 'on')
            set(app.saveregions,'Enable','on')
            
            app.history_field.Value{length(app.history_field.Value)+1,1} = [app.signalregiontype.Value, ' ROI selected. Number: ', int2str(num_ROI{end}),'. Vertices: ', mat2str(app.display.ROI_handle{end}.Vertices,3)];
        end

        % Button pushed function: hidesignal
        function hide_signals(app, event)
            app.display.signal_display = 0;
            for k = 1:length(app.display.ROI_handle)
                set(app.display.ROI_handle{k},'Visible','off');
            end
            set(app.display.plot_handle,'Visible','off');
            set(app.signal_display_axes,'Visible','off');
            try
                if ~app.display.BioPAC_signal_display
                    if app.display.map == 1
                        set(app.main_display_axes,'Position',[1,app.figure_size(4)-floor(1.2*app.figure_size(4)/2)-40,0.95*app.figure_size(3),floor(1.2*app.figure_size(4)/2)]);
                    else
                        set(app.main_display_axes,'Position',[1,app.figure_size(4)-floor(1.2*app.figure_size(4)/2)-40,app.figure_size(3),floor(1.2*app.figure_size(4)/2)]);
                    end
                end
                
            catch
                %in case of loading an old workspace with no biopac signal struct
                app.display.BioPAC_signal_display = 0;
            end
                
        end

        % Button pushed function: showsignals
        function show_signals(app, event)
            app.display.signal_display = 1;
            for k = 1:length(app.display.ROI_handle)
                set(app.display.ROI_handle{k},'Visible','on');
            end
            set(app.display.plot_handle,'Visible','on');
            set(app.signal_display_axes,'Visible','on');
            if app.display.map == 1
                set(app.main_display_axes,'Position',[1,app.figure_size(4)-floor(1.2*app.figure_size(4)/2)-40,0.67*app.figure_size(3),floor(1.2*app.figure_size(4)/2)]);
            else
                set(app.main_display_axes,'Position',[1,app.figure_size(4)-floor(1.2*app.figure_size(4)/2)-40,0.7*app.figure_size(3),floor(1.2*app.figure_size(4)/2)]);
            end
        end

        % Button pushed function: clipdata
        function clip_data(app, event)
            app.processed_data.previous_stack = app.processed_data.image_stack{1};
            app.processed_data.previous_signals = app.processed_data.signals;
            
            % Input new time range
            new_time_range = inputdlg({'Start time (s)','End time (s)'},'Clip times:',[1 35],{'0',sprintf('%.3f',(size(app.processed_data.image_stack{1},3)-1)/app.data.fps)});
            
            % New frame range only if didn't cancel dialogue box
            if isempty(new_time_range)
                new_frame_range(1) = 1;
                new_frame_range(2) = size(app.processed_data.image_stack{1},3);
            else
                new_frame_range(1) = max(1 + ceil(str2double(new_time_range{1}).*app.data.fps),1);
                new_frame_range(2) = min(1 + ceil(str2double(new_time_range{2}).*app.data.fps),size(app.processed_data.image_stack{1},3));
                app.display.current_frame = 1;
            end
            
            % Store a copy of unnormalised image stack then clear stacks
            % and reassign section
            temp_stack = app.processed_data.image_stack{1};
            app.processed_data.image_stack = {};
            app.processed_data.image_stack{1} = temp_stack(:,:,new_frame_range(1):new_frame_range(2));
            
            % Clear signals and set that all normalisations still need to be
            % done
            num_sig = size(app.processed_data.signals,1);
            app.processed_data.signals = [];
            app.display.renorm = ones(size(app.display.renorm));
            app.processed_data.n_frames = size(app.processed_data.image_stack{1},3);
            
            % Save start and end frame range taking into account previous
            % start and end frames
            app.processed_data.frame_range(2) = app.processed_data.frame_range(1) - 1 + new_frame_range(2);
            app.processed_data.frame_range(1) = app.processed_data.frame_range(1) + new_frame_range(1) - 1;
            
            %Re-load BioPAC with updated cuts if BioPAC is loaded
            if ~isempty(app.channel_data)
                app.update_BioPAC;
            end
            
            % Recreate signals
            for k = 1:num_sig
                apply_signal_masks(app, k);
            end
            
            % Display averaged data
            switch app.display.normalisation
                case 1
                    app.change_normalisation_none(app);
                case 2
                    app.change_normalisation_global(app);
                case 3
                    app.change_normalisation_local(app);
                case 4
                    app.change_normalisation_global_mean(app);
                case 5
                    app.change_normalisation_timeaverage(app);
            end
            
            app.tool_tip_update;

            app.history_field.Value{length(app.history_field.Value)+1,1} = ['Clip time. Start: ',new_time_range{1},' s. End: ',new_time_range{2},' s.'];
        end

        % Button pushed function: deleteROI
        function delete_ROI(app, event)
            ROI_to_delete = app.ROIdropdown.Value;
            
            delete(app.display.ROI_handle{ROI_to_delete});
            app.display.ROI_handle(ROI_to_delete) = [];
            app.display.ROI_mask(ROI_to_delete) = [];
            app.processed_data.signals(ROI_to_delete,:) = [];
            app.display.plot_handle = [];
            
            % Clear axes
            cla(app.signal_display_axes);
            
            %Plot signals
            app.display.plot_handle = cellfun(@(x,y) plot(x,y,'Parent',app.signal_display_axes),app.processed_data.signals(:,1),app.processed_data.signals(:,app.display.normalisation+1));
            
            %Update ROI colour and label
            for k = 1:length(app.display.ROI_handle)
                set(app.display.ROI_handle{k},'Color',app.plot_colours(rem(k,7),:));
                set(app.display.ROI_handle{k},'Label',num2str(k));
            end
            
            num_ROI = num2cell(1:length(app.display.ROI_handle));
            % Update ROI dropdown
            app.ROIdropdown.Items = cellfun(@(x) sprintf('ROI %d',x),num_ROI,'UniformOutput',false);
            app.ROIdropdown.ItemsData = num_ROI;
            
            app.SelectROIDropDown.Items = cellfun(@(x) sprintf('ROI %d',x),num_ROI,'UniformOutput',false);
            app.SelectROIDropDown.ItemsData = num_ROI;

            app.history_field.Value{length(app.history_field.Value)+1,1} = ['ROI ',int2str(ROI_to_delete),' deleted.'];
        end

        % Button pushed function: saveregions
        function save_regions(app, event)
            save_file = file_exists(app,'ROI','.mat');
            roi_handles = app.display.ROI_handle;
            ROI_masks = app.display.ROI_mask;
            save(save_file,'roi_handles','ROI_masks','-mat');
        end

        % Button pushed function: loadregions
        function load_regions(app, event)
            [ROI_file,ROI_path] = uigetfile({'*.mat';'*.*'},'ROI selector'); % Open ROI selector
            load([ROI_path ROI_file], 'roi_handles', 'ROI_masks');
            if ~isempty(app.display.ROI_handle)
                cellfun(@delete, app.display.ROI_handle);
                cla(app.signal_display_axes);
                delete(app.display.plot_handle);
                app.display.ROI_handle = [];
                app.display.plot_handle = [];
                app.display.ROI_mask = [];
                app.processed_data.signals = [];
            end
            
            app.display.ROI_handle = roi_handles;
            app.display.ROI_mask = ROI_masks;
            
            cellfun(@(x) set(x,'Parent',app.main_display_axes),app.display.ROI_handle);
            
            % Create signals
            for k = 1:length(roi_handles)
                apply_signal_masks(app, k);
            end
            
            % Plot signal on axes
            if ~app.display.signal_display
                set(app.main_display_axes,'Position',[1,app.figure_size(4)-floor(1.2*app.figure_size(4)/2)-40,0.7*app.figure_size(3),floor(1.2*app.figure_size(4)/2)]);
                if isempty(app.signal_display_axes)
                    app.signal_display_axes = axes(app.opticalmappingUIFigure, 'Units','Pixels','Position',[0.74*app.figure_size(3),app.figure_size(4)-floor(1.1*app.figure_size(4)/2)-40,0.25*app.figure_size(3),floor(1.0*app.figure_size(4)/2)]);
                    hold(app.signal_display_axes, 'on');
                    xlabel('Time (s)','Parent',app.signal_display_axes);
                    ylabel('Signal (a.u.)','Parent',app.signal_display_axes);
                end
                set(app.signal_display_axes,'Visible','on');
                app.display.signal_display = 1;
            end

            % Add history
            app.history_field.Value{length(app.history_field.Value)+1,1} = ['Regions loaded from: ',ROI_file,'.'];
            
            
            %Plot signals
            app.display.plot_handle = cellfun(@(x,y) plot(x,y,'Parent',app.signal_display_axes),app.processed_data.signals(:,1),app.processed_data.signals(:,app.display.normalisation+1));
            set(app.averagebeats,'Enable','on')
            set(app.SELECTROIFIRSTLabel,'Visible','off')
            set(app.showsignals,'Enable', 'on')
            set(app.hidesignal,'Enable', 'on')
            set(app.saveregions,'Enable','on')
            figure(app.opticalmappingUIFigure);
        end

        % Button pushed function: savemovie
        function save_movie(app, event)
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Saving','Indeterminate','on');
            %Colour map
            cMap = app.display.colour_map;
            %TODO% Calcium imaging use cMap winter.
            movie_stack = (app.processed_data.image_stack{app.display.normalisation} - min(app.processed_data.image_stack{app.display.normalisation}(:)))/max(app.processed_data.image_stack{app.display.normalisation}(:));
            movie_stack(movie_stack>app.MaxColourmap.Value/100) = 1;
            movie_stack(isnan(movie_stack)) = 0;
            movie_stack = double(movie_stack); % Explicit set as double in case load single when doing motion correction
            
            time_range = [(app.processed_data.frame_range(1)-1)/app.data.fps,(app.processed_data.frame_range(2)-1)/app.data.fps];
            filename = sprintf('movie_%.1f-%.1f',time_range(1), time_range(2));
            movie_file = file_exists(app,filename,'.avi');
            log_file_name =  file_exists(app,['log_file_' filename],'.dat');
            save_log_file(app,log_file_name);
            
            output_video = VideoWriter(movie_file);
            
            output_video.FrameRate = app.display.fps;
            
            % Save video
            open(output_video);
            for k = 1:app.processed_data.n_frames
                frame = im2frame(1+255*movie_stack(:,:,k),cMap);
                f_timestamp = insertText(frame.cdata, [ceil(app.processed_data.width/2), ceil(0.98*app.processed_data.height)],sprintf('t = %.2f s', (k-1)/app.data.fps),'AnchorPoint','Center','fontsize',8);%16);
                frame.cdata = f_timestamp(:,:,1);
                writeVideo(output_video,frame);
                clear frame
            end
            close(output_video)
            close(progress_bar)
        end

        % Button pushed function: saveimage, saveimage_2
        function save_image(app, event)
            %Create file name
            if app.display.map == 1
                if (isfield(app.display,"quiver_handle") && ~isempty(app.display.quiver_handle) && isvalid(app.display.quiver_handle))% || (isfield(app.display,"isochrone_values") && ~isempty(app.display.isochrone_values))
                    picture_file = file_exists(app,'conduction_velocity','.png');
                    log_file_name = file_exists(app,'log_file_conduction_velocity','.dat');
                    save_log_file(app,log_file_name);

                elseif str2double(app.maptype.Value) == 1
                    picture_file = file_exists(app,'activation_map','.png');
                    log_file_name = file_exists(app,'log_file_activation_map','.dat');
                    save_log_file(app,log_file_name);
                    
                elseif str2double(app.maptype.Value) == 2
                    picture_file = file_exists(app,sprintf('apd%d_map',app.apdpercentage.Value),'.png');
                    log_file_name = file_exists(app,sprintf('log_file_apd%d_map',app.apdpercentage.Value),'.dat');
                    save_log_file(app,log_file_name);

                elseif str2double(app.maptype.Value) == 4
                    picture_file = file_exists(app,sprintf('repolarisation%d_map',app.apdpercentage.Value),'.png');
                    log_file_name = file_exists(app,sprintf('log_file_repolarisation%d_map',app.apdpercentage.Value),'.dat');
                    save_log_file(app,log_file_name);
                end
            else
                %TODO% update so have normalisation
                picture_file = file_exists(app,sprintf('frame-%d',app.display.current_frame),'.png');
                log_file_name = file_exists(app,sprintf('log_file_frame-%d',app.display.current_frame),'.dat');
                save_log_file(app,log_file_name);
            end
            
            
            % Create non-ui figure image to save
            h = figure;
            h.Visible = 'off';
            ax = gca;
            if app.display.map
                % Plot
                if (isfield(app.display,"quiver_handle") && ~isempty(app.display.quiver_handle) && isvalid(app.display.quiver_handle))% || (isfield(app.display,"isochrone_values") && ~isempty(app.display.isochrone_values))
                    imshow(app.processed_data.CV_map(:,:,3),[app.lowerylimit_traces.Value app.upperylimit_traces.Value],'InitialMagnification',ceil(100*700/app.processed_data.height));
                    image_data = app.processed_data.CV_map(:,:,3);
                    if app.IncludequiversButton.Value == 1
                        hold(ax, 'on');
                        quiver_spacing = 10;
                        quiver_rows = 1:quiver_spacing:app.processed_data.height;
                        quiver_cols = 1:quiver_spacing:app.processed_data.width;
                        [quiver_rows,quiver_cols]=meshgrid(quiver_rows,quiver_cols);
                        quiver_rows=reshape(quiver_rows,[size(quiver_rows,1)*size(quiver_rows,2),1]);
                        quiver_cols=reshape(quiver_cols,[size(quiver_cols,1)*size(quiver_cols,2),1]);
                        quiver_handle = quiver(ax,quiver_cols,quiver_rows,app.processed_data.CV_map(sub2ind(size(app.processed_data.CV_map),quiver_rows,quiver_cols,2*ones(size(quiver_rows)))),app.processed_data.CV_map(sub2ind(size(app.processed_data.CV_map),quiver_rows,quiver_cols,ones(size(quiver_rows)))),'m');
                        hold(ax, 'off');
                    end
                    if app.IncludeROIButton.Value == 1
                        hold on
                        for k = 1:length(app.display.ROI_handle)
                            copyobj(app.display.ROI_handle{k},ax)
                            %drawrectangle('Position',app.display.ROI_handle{k}.Position, 'Color', app.display.ROI_handle{k}.Color,'Label',app.display.ROI_handle{k}.Label,'InteractionsAllowed','none');
                        end
                    end
                else
                    imshow(app.processed_data.activation_map,[app.lowerylimit.Value app.upperylimit.Value],'InitialMagnification',ceil(100*700/app.processed_data.height),'Interpolation', app.display.image_handle.Interpolation);
                    image_data = app.processed_data.activation_map;
                end

                if isfield(app.display,"isochrone_values") && ~isempty(app.display.isochrone_values)
                    hold on
                    contour(app.processed_data.activation_map,[-0.01,app.display.isochrone_values,max(app.processed_data.activation_map(:))],'k'); %[0.01,isochrone_values,max(map_data(:))]
                end

                % Set colormap and bar
                h.Colormap = [0 0 0; jet(256)];
                hc = colorbar(h.Children);
                
                if (isfield(app.display,"quiver_handle") && ~isempty(app.display.quiver_handle) && isvalid(app.display.quiver_handle))% || (isfield(app.display,"isochrone_values") && ~isempty(app.display.isochrone_values))
                    hc.Label.String = 'Conduction velocity (mm/ms)';
                else
                    switch str2double(app.maptype.Value)
                        case 1
                            hc.Label.String = 'Activation time (ms)';
                        case 2
                            hc.Label.String = sprintf('APD%d (ms)',app.apdpercentage.Value);
                    end
                end


            else
                if app.display.normalisation == 1
                    colour_map = gray(256);
                else
                    colour_map = app.display.colour_map;
                end
                current_frame = app.processed_data.image_stack{app.display.normalisation}(:,:,app.display.current_frame);
                max_current_frame = max(current_frame(:));
                min_current_frame = min(current_frame(:));
                cmap_limits = [min_current_frame max_current_frame*app.MaxColourmap.Value/100 ];
                imshow(current_frame./max_current_frame, cmap_limits,'InitialMagnification',ceil(100*700/app.processed_data.height));
                image_data = current_frame;
                colormap(colour_map)
                
                
            end
            
            % Save displayed image
            exportgraphics(h,picture_file,'Resolution',300);
            writematrix(image_data,[picture_file(1:end-4),'.csv'])
            delete(h)
        end

        % Button pushed function: averagebeats
        function average_beats(app, event)
            % Store unnormalised data for undo
            app.processed_data.previous_stack = app.processed_data.image_stack{1};
            
            % Number of signals
            num_sig = size(app.processed_data.signals,1);
            
            min_height = app.minheight.Value/100; % convert to fraction
            min_distance = app.mindistance.Value/1000; % convert to seconds
            
            signal = app.processed_data.signals{1,app.display.normalisation+1};
            time_vector = app.processed_data.signals{1,1};
            dfdt = diff(signal)./time_vector(2);
            switch app.PeakfindingDropDown.Value
                case 'dfdt'
                    [~, app.averaging.beat_locations] = findpeaks(dfdt,'MinPeakHeight',min_height*(max(dfdt)-mean(dfdt)),'MinPeakDistance',(min_distance/time_vector(2)));
                case 'Peaks'
                    [~, app.averaging.beat_locations] = findpeaks(signal,'MinPeakHeight',min_height*(max(signal)-min(signal))+min(signal),'MinPeakDistance',(min_distance/time_vector(2)));
                    app.averaging.beat_locations = app.averaging.beat_locations - round(mean(diff(app.averaging.beat_locations))/10);
            end
            number_of_beats = length(app.averaging.beat_locations);
            app.activation_times = nan(1,number_of_beats);
            
            delta = 10; %points around the 'dfdtmax' to be used for interpolation
            interp_points = 1000; %number of points to use for the fine grid to get interpolated data
            for m = 1:number_of_beats
                idx_max = app.averaging.beat_locations(m);
                start_ind = max([idx_max-delta,1]);
                end_ind = min([idx_max+delta,length(time_vector)-1]);
                time_fine_grid = linspace(time_vector(start_ind), time_vector(end_ind), interp_points); %generate fine grid for interpolation in ms
                dfdt_interpolated = interp1(time_vector(start_ind: end_ind), dfdt(start_ind: end_ind), time_fine_grid, 'spline'); %interpolate around the previously found start index
                [~,idx_max_interpolated] = max(dfdt_interpolated); % find new dfdt max
                app.activation_times(m) = time_fine_grid(idx_max_interpolated); %activation time of that beat in s
            end
            
            [~, app.averaging.beat_locations] = min(abs(app.activation_times -time_vector.'));

            % Store old fps
            fps = app.data.fps;
            
            % Substract 10% of mean interbeat interval from locations to ensure start of AP is
            % included
            app.averaging.beat_locations = app.averaging.beat_locations - ceil(0.2*mean(diff(app.averaging.beat_locations)));
            if app.averaging.beat_locations(1) < 1
                app.averaging.beat_locations = app.averaging.beat_locations + 1 - app.averaging.beat_locations(1);
            end
            app.beataveraging_app = beat_averaging(app);
            uiwait(app.beataveraging_app.beat_averaging_UIFigure)
            
            app.history.current = [app.history.current, 'average_beats'];
            
            
            % Recreate signals
            for k = 1:num_sig
                apply_signal_masks(app, k);
            end
            
            % Display averaged data
            switch app.display.normalisation
                case 1
                    app.change_normalisation_none(app);
                case 2
                    app.change_normalisation_global(app);
                case 3
                    app.change_normalisation_local(app);
                case 4
                    app.change_normalisation_global_mean(app);
                case 5
                    app.change_normalisation_timeaverage(app);
            end
            
            set(app.generatemap,'Enable','on')
            app.history_field.Value{length(app.history_field.Value)+1,1} = 'Applied average beats';
            if fps ~= app.data.fps
                app.history_field.Value{length(app.history_field.Value)+1,1} = ['New fps: ', num2str(app.data.fps,6),'.'];
            end
        end

        % Button pushed function: temporalfilter
        function temporal_filtering(app, event)
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Filtering','Indeterminate','on');
            
            % Store data for undo
            app.processed_data.previous_stack = app.processed_data.image_stack{1};
            app.history.old = app.history.current;
            app.processed_data.previous_signals = app.processed_data.signals;
            
            fc = app.cutofffrequency.Value;
            fs = app.data.fps;
            
            switch str2double(app.TemporalFilterType.Value)
                case 1
                    [b,a] = butter(app.filterorder.Value,fc/(fs/2),'low');
                    
                    app.history.current = [app.history.current, sprintf(' BUTTERWORTH(cutoff%d, order=%d)',app.cutofffrequency.Value, app.filterorder.Value) ];
                    app.history_field.Value{length(app.history_field.Value)+1,1} = ['Apply spatial filter: ', sprintf(' BUTTERWORTH(cutoff%d, order=%d)',app.cutofffrequency.Value, app.filterorder.Value)];
                case 2
                    a0 = [1, 1, 0, 0];
                    f0 = [0, fc, fc*1.25, fs/2]./(fs/2);
                    b = firpm(app.filterorder.Value, f0, a0);
                    a = 1;
                    app.history.current = [app.history.current, sprintf(' FIR(cutoff%d, order=%d)',app.cutofffrequency.Value, app.filterorder.Value) ];
                    app.history_field.Value{length(app.history_field.Value)+1,1} = ['Apply spatial filter: ',sprintf(' FIR(cutoff%d, order=%d)',app.cutofffrequency.Value, app.filterorder.Value)];
            end
            
            % Filter using a bidirectional filter so get zero phase offset
            app.processed_data.image_stack{1} = FiltFiltM(b,a,app.processed_data.image_stack{1},3);
            
            close(progress_bar);
            
            % Set that renormalisation is needed.
            app.display.renorm = ones(size(app.display.renorm));
            % Display filtered data
            switch app.display.normalisation
                case 1
                    app.change_normalisation_none(app);
                case 2
                    app.change_normalisation_global(app);
                case 3
                    app.change_normalisation_local(app);
                case 4
                    app.change_normalisation_global_mean(app);
                case 6
                    app.change_normalisation_timeaverage(app);
                case 7
                    app.change_normalisation_reference(app)
            end
        end

        % Button pushed function: spatialfilter
        function spatial_filtering(app, event)
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Filtering','Indeterminate','on');
            
            % Store data for undo
            app.processed_data.previous_stack = app.processed_data.image_stack{1};
            app.history.old = app.history.current;
            app.processed_data.previous_signals = app.processed_data.signals;

            switch str2double(app.spatialFilterType.Value)
                case 1 % Box linear filter
                    % Create kernel for spatial filter
                    kernel = ones(str2double(app.spatialkernelsize.Value))/(str2double(app.spatialkernelsize.Value)^2);
                    % Apply spatial filter
                    app.processed_data.image_stack{1} = convn(app.processed_data.image_stack{1},kernel,'same');
                    if ~isempty(app.reference_stack)
                        app.reference_stack = conv(app.reference_stack,kernel,'same');
                    end
                    app.history.current = [app.history.current, sprintf(' BOX(%cx%c)',app.spatialkernelsize.Value,app.spatialkernelsize.Value)];
                    app.history_field.Value{length(app.history_field.Value)+1,1} = ['Apply spatial filter: ',sprintf(' BOX(%cx%c)',app.spatialkernelsize.Value,app.spatialkernelsize.Value)];
                    
                case 2 % Gaussian filter
                    % Create gaussian kernel for spatial filter
                    sigma = app.gaussiansigma.Value;
                    N = str2double(app.spatialkernelsize.Value);
                    [x,y]=meshgrid(fix(-N/2):fix(N/2), fix(-N/2):fix(N/2));
                    kernel = (1/(sigma^2*2*pi))*exp(-(x.^2+y.^2)./(2*sigma^2));
                    % Apply spatial filter
                    app.processed_data.image_stack{1} = convn(app.processed_data.image_stack{1},kernel,'same');
                    if ~isempty(app.reference_stack)
                        app.reference_stack = conv(app.reference_stack,kernel,'same');
                    end
                    
                    app.history.current = [app.history.current, sprintf(' GAUSS(%cx%c, sigma=%0.1f)',app.spatialkernelsize.Value,app.spatialkernelsize.Value, sigma)];
                    app.history_field.Value{length(app.history_field.Value)+1,1} = ['Apply spatial filter: ',sprintf(' GAUSS(%cx%c, sigma=%0.1f)',app.spatialkernelsize.Value,app.spatialkernelsize.Value, sigma)];
                    
                case 3 % Median filter
                    kernel_size = str2double(app.spatialkernelsize.Value);
                    app.processed_data.image_stack{1} = medfilt3(app.processed_data.image_stack{1},[kernel_size,kernel_size,1]);
                    if ~isempty(app.reference_stack)
                        app.reference_stack = medfilt3(app.reference_stack,[kernel_size,kernel_size,1]);
                    end
                    
                    app.history.current = [app.history.current, sprintf(' MEDIAN(%cx%c)',app.spatialkernelsize.Value,app.spatialkernelsize.Value)];
                    app.history_field.Value{length(app.history_field.Value)+1,1} = ['Apply spatial filter: ',sprintf(' MEDIAN(%cx%c)',app.spatialkernelsize.Value,app.spatialkernelsize.Value)];
                    
            end
            
            
            close(progress_bar)
            
            % Set that renormalisation is needed.
            app.display.renorm = ones(size(app.display.renorm));
            % Display filtered data
            switch app.display.normalisation
                case 1
                    app.change_normalisation_none(app);
                case 2
                    app.change_normalisation_global(app);
                case 3
                    app.change_normalisation_local(app);
                case 4
                    app.change_normalisation_global_mean(app);
                case 6
                    app.change_normalisation_timeaverage(app);
                case 7
                    app.change_normalisation_reference(app)
            end
            
        end

        % Value changed function: spatialFilterType
        function spatial_Filter_Type(app, event)
            filterType = app.spatialFilterType.Value;
            switch str2double(filterType)
                case 1 % Box linear filter
                    set(app.gaussiansigma,'Enable', 'off')
                    set(app.GaussiansigmaLabel,'Enable', 'off')
                case 2 % Gaussian filter
                    set(app.gaussiansigma,'Enable', 'on')
                    set(app.GaussiansigmaLabel,'Enable', 'on')
                case 3
                    set(app.gaussiansigma,'Enable', 'off')
                    set(app.GaussiansigmaLabel,'Enable', 'off')
            end
        end

        % Value changed function: cutofffrequency
        function cutoff_limit(app, event)
            value = app.cutofffrequency.Value;
            if value > 0.5*app.data.fps
                app.cutofffrequency.Value = app.data.fps/2;
            end
        end

        % Button pushed function: newzeromap
        function new_zero_map(app, event)
            new_zero = inputdlg('Enter new zero value:','Zero input',[1 35],{'0'});
            
            app.processed_data.activation_map = app.processed_data.activation_map - str2double(new_zero(1));
            start_common = mode(app.processed_data.activation_map(:));
            % Add 60 ms to mode assuming all conduction should be done
            % by the time most of the tissue excited
            max_value = min([max(app.processed_data.activation_map(:)) start_common+60]);
            set(app.display.image_handle,'CData',app.processed_data.activation_map);
            app.display.image_handle.Parent.CLim = [0,max_value];
            caxis(app.display.image_handle.Parent,[0,max_value]);
            app.upperylimit.Value = app.display.colourbar_handle.Limits(2);
            app.lowerylimit.Value = app.display.colourbar_handle.Limits(1);
            
            update_histogram_map(app)

            app.history_field.Value{length(app.history_field.Value)+1,1} = ['New zero: ',new_zero{1},' ms.'];
        end

        % Button pushed function: generatemap
        function generate_map(app, event)
            %% Add check for local normalisation and for only a single beat. Do when switching to tab!
            
            if isempty(app.averaging.beat_locations)
                answer = uiconfirm(app.opticalmappingUIFigure,'You should average beats first if the recording has more than one beat. Are you sure that you want to generate a map?','Generate map: average beats','Options',{'Yes','No'},'Icon','Warning');
                if app.local_normalisation_computed == 0
                    answer = 'No';
                    warning = uiconfirm(app.opticalmappingUIFigure,'Local normalisation not detected. Please normalise first.','Generate map: local normalisation','Options',{'Ok'},'Icon','Warning');
                end
            else
                answer = 'Yes';
            end

            if isempty(app.processed_data.signals) % only allow generating the map if a ROI is selected. Otherwise there is no time vector
                answer = 'No';
                uialert(app.opticalmappingUIFigure,'You need to select a ROI to compute the map.','Map cannot be computed');
            end

            previous_normalisation = app.display.normalisation;
            
            if strcmp(answer, 'Yes')
                %progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Calculating map','Indeterminate','on');
                progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Calculating map','Message','Please wait...','Cancelable','on');
                
                % Set current normalisation button to off
                switch app.display.normalisation
                    case 1
                        app.nonormalise.Value = 0;
                    case 2
                        app.globalnormalise.Value = 0;
                    case 3
                        app.localnormalise.Value = 0;
                    case 4
                        app.timeaverage.Value = 0;
                end

                time = app.processed_data.signals{1,1}; %get time vector
                interp_points = 1000; %number of points to use for the fine grid to get interpolated data

                rmp_time = 0.005; % time prior to upstroke to calculate RMP until.

                % Find start of AP point based on method
                switch str2double(app.mapmethod.Value)
                    % dfdt
                    case 1
                        % Find dfdt
                        dfdt = diff(app.processed_data.image_stack{3},1,3)/(1/app.data.fps);
                        dfdt(dfdt==0) = NaN;
                        % Normalise dfdt
                        dfdt = dfdt-min(dfdt,[],3);
                        dfdt = dfdt./max(dfdt,[],3);
                        
                        % Find start indices
                        start_indices = findfirst(dfdt>=app.mappercentage.Value/100,3,1,'first');
%                         [~,start_indices] = max(dfdt,[],3);
                        start_indices(start_indices==0) = NaN; %TODO% update rationale for this
                        
                        activation_times = nan(app.processed_data.height,app.processed_data.width);
                        time = app.processed_data.signals{1,1}; %get time vector
                        delta = 10; %points around the 'dfdtmax' to be used for interpolation
                        interp_points = 1000; %number of points to use for the fine grid to get interpolated data
                        for m = 1:app.processed_data.height
                            progress_bar.Value = m/app.processed_data.height;
                            for n = 1:app.processed_data.width
                                idx_max = start_indices(m,n);
                                
                                if ~isnan(idx_max) && idx_max ~= 1
                                    start_index = idx_max - delta;
                                    end_index = idx_max + delta;
                                    if start_index < 1
                                        start_index = 1;
                                    end
                                    if end_index > (length(time)-1)
                                        end_index = (length(time)-1);
                                    end
                                    
                                    dfdt_mn = dfdt(m,n,:); %get the dfdt of the particular pixel
                                    dfdt_mn = reshape(dfdt_mn, 1, length(dfdt_mn));
                                    time_fine_grid = linspace(time(start_index), time(end_index), interp_points); %generate fine grid for interpolation in ms
                                    dfdt_mn_interpolated = interp1(time(start_index: end_index), dfdt_mn(start_index: end_index), time_fine_grid, 'spline'); %interpolate around the previously found start index
                                    [~,idx_max_interpolated] = max(dfdt_mn_interpolated); % find new dfdt max
                                    activation_times(m,n) = 1000*time_fine_grid(idx_max_interpolated); %transofrm to ms
                                end
                            end
                        end
                        
                    %Peak    
                    case 2
                        %find index of peak (defined as maximum)
%                         [~,start_indices] = max(app.processed_data.image_stack{3},[],3);
% % %                         start_indices = findfirst(app.processed_data.image_stack{3}>=app.mappercentage.Value/100,3,1,'first');
% % %                         start_indices(start_indices==0) = NaN; %just in case
% % %                         %Interpolate around index of peak
% % %                         time = app.processed_data.signals{1,1}; %get time vector
% % %                         delta = 50; %points around the 'peak' to be used for interpolation
% % %                         interp_points = 1000; %number of points to use for the fine grid to get interpolated data
% % %                         activation_times = nan(app.processed_data.height,app.processed_data.width);
% % %                         for m = 1:app.processed_data.height
% % %                             progress_bar.Value = m/app.processed_data.height;
% % %                             for n = 1:app.processed_data.width
% % %                                 idx_max = start_indices(m,n);
% % %                                 if idx_max > delta && idx_max+delta < length(time)
% % %                                     signal_mn = app.processed_data.image_stack{3}(m,n,:); %get the signal of the particular pixel
% % %                                     signal_mn = reshape(signal_mn, 1, length(signal_mn));
% % %                                     time_fine_grid = linspace(time(idx_max - delta), time(idx_max + delta), interp_points); %generate fine grid for interpolation in ms
% % %                                     signal_mn_interpolated = interp1(time(idx_max-delta: idx_max + delta), signal_mn(idx_max-delta: idx_max + delta), time_fine_grid, 'spline'); %interpolate around the previously found start index
% % %                                     [~,idx_max_interpolated] = max(signal_mn_interpolated); % find new dfdt max
% % %                                     activation_times(m,n) = 1000*time_fine_grid(idx_max_interpolated); %transofrm to ms
% % %                                 end
% % %                             end
% % %                         end


                        activation_times = nan(app.processed_data.height,app.processed_data.width); 
                        [~,activation_indices]=  max(app.processed_data.image_stack{3},[],3);
                        time = app.processed_data.signals{1,1}; %get time vector
                        
                        for m = 1:app.processed_data.height
                            progress_bar.Value = m/app.processed_data.height;
                            for n = 1:app.processed_data.width
                                max_index = activation_indices(m,n);
                                delta1 = 10;
                                delta2 = 10;
                                if max_index ~= 1
                                    signal_mn = app.processed_data.image_stack{3}(m,n,:); %get the signal of the particular pixel
                                    signal_mn = reshape(signal_mn, 1, length(signal_mn));
                                    if max_index - delta1 <= 0
                                        delta1 = max_index - 1;
                                    elseif max_index + delta2 > length(time)
                                        delta2 = length(time)- max_index;
                                    else
                                        %disp([max_index,delta1, delta2])
                                        polynomial_fit = polyfit(time(max_index-delta1:max_index+delta2),signal_mn(max_index-delta1:max_index+delta2),2); %fit a polynomial order 6 around the peak
                                        time_fine_grid = linspace(time(max_index-delta1),time(max_index+delta2),10000);
                                    end
                                    poly_evaluated = polyval(polynomial_fit, time_fine_grid);
                                    [~,max_index_poly] = max(poly_evaluated);
                                    activation_times(m,n) = time_fine_grid(max_index_poly);
                                else
                                    activation_times(m,n) = NaN;
                                end
                            end
                        end

                        activation_times = 1000*activation_times;

                    % Cross correlation
                    case 3
                        rmp_time = 0.01; % 10 ms for cross-correlation as peak seems to be identified later
                        
                        % Use centre pixel of ROI as seed pixel
                        roi_mask = app.display.ROI_mask{1};
                        roi_mask(isnan(roi_mask)) = 0;
                        roi_props = regionprops(roi_mask);
                        seed_signal = squeeze(app.processed_data.image_stack{3}(floor(roi_props.Centroid(2)),floor(roi_props.Centroid(1)),:));

                        %Find start of seed for offset for start_indices -
                        %use dfdt
                        dfdt_seed = diff(seed_signal)/(1/app.data.fps);
                        dfdt_seed = dfdt_seed-min(dfdt_seed);
                        dfdt_seed = dfdt_seed./max(dfdt_seed);

                        seed_start_index = find(dfdt_seed>=app.mappercentage.Value/100,1,'first');

                        % Interpolate seed signal to increase resolution
                        % (10 fold increase)
                        interp_factor = 10;
                        seed_signal = interp(seed_signal,interp_factor);
                        
                        activation_times = nan(app.processed_data.height,app.processed_data.width);
                        dt = 1/app.data.fps; % time step
                        
                        % Loop through all pixels
                        for m = 1:app.processed_data.height
                            progress_bar.Value = m/app.processed_data.height;
                            for n = 1:app.processed_data.width
                                if (mean(app.processed_data.image_stack{3}(m,n,:)) ~= 0) && ~(anynan(app.processed_data.image_stack{3}(m,n,:)))                                    
                                    beat_signal = interp(squeeze(app.processed_data.image_stack{3}(m,n,:)),interp_factor);
                                    [~,~,delay] = alignsignals(seed_signal,beat_signal);
                                    % Activation time in ms
                                    activation_times(m,n) = 1000*delay*dt/interp_factor;
                                end
                            end
                        end
                        activation_times = activation_times - min(activation_times(:));

                        % Used for APD restitution calculation.
                        start_indices = round(activation_times/(1000*dt));
                        index_shift = seed_start_index - start_indices(floor(roi_props.Centroid(2)),floor(roi_props.Centroid(1)));
                        start_indices = start_indices + index_shift;
                        % Instead just use max point as used for assessing
                        % repolarisation
                        %[~,start_indices] = max(app.processed_data.image_stack{3},[],3);

                        % Midpoint of rise
                    case 4
                        % Get initial prediction of start from cross
                        % correlation
                        % Use centre pixel of ROI as seed pixel
                        roi_mask = app.display.ROI_mask{1};
                        roi_mask(isnan(roi_mask)) = 0;
                        roi_props = regionprops(roi_mask);
                        seed_signal = squeeze(app.processed_data.image_stack{3}(floor(roi_props.Centroid(2)),floor(roi_props.Centroid(1)),:));

                         %Find start of seed for offset for start_indices -
                        %use dfdt
                        dfdt_seed = diff(seed_signal)/(1/app.data.fps);
                        dfdt_seed = dfdt_seed-min(dfdt_seed);
                        dfdt_seed = dfdt_seed./max(dfdt_seed);

                        seed_start_index = find(dfdt_seed>=app.mappercentage.Value/100,1,'first');
                        
                        activation_times = nan(app.processed_data.height,app.processed_data.width);
                        dt = 1/app.data.fps; % time step

                        interp_factor = 10;
                        
                        max_V = max(app.processed_data.image_stack{3},[],3);

                        % Loop through all pixels
                        for m = 1:app.processed_data.height
                            progress_bar.Value = m/app.processed_data.height;
                            for n = 1:app.processed_data.width
                                if (mean(app.processed_data.image_stack{3}(m,n,:)) ~= 0) && ~(anynan(app.processed_data.image_stack{3}(m,n,:)))                                    
                                    beat_signal = squeeze(app.processed_data.image_stack{3}(m,n,:));
                                    [~,~,delay] = alignsignals(seed_signal,beat_signal);
                                    % Start index
                                    start_index = seed_start_index + delay;

                                    % Find RMP
                                    switch str2double(app.rmpmethod.Value)
                                        case 1 % Calculate resting membrane potential from start to 5 ms before AP upstroke.
                                            resting_V = mean(app.processed_data.image_stack{3}(m,n,1:(start_index-ceil(0.005*app.data.fps))),'omitnan');
                                        case 2 % Calculate resting membrane potential from start to 5 ms before AP upstroke plus from when this value is reached after AP to end
                                            init_rmp = mean(app.processed_data.image_stack{3}(m,n,1:(start_index-ceil(0.005*app.data.fps))),'omitnan');
                                            cross_index = min([start_index + find(app.processed_data.image_stack{3}(k,l,start_index:end)<=init_rmp,1) - 1, app.processed_data.n_frames]); % Find when signal goes below rmp
                                            resting_V = mean(app.processed_data.image_stack{3}(m,n,[1:(start_index-ceil(0.005*app.data.fps)), cross_index:end]),'omitnan');
                                        case 3 % Calculate resting membrane potential from start to 5 ms before AP upstroke plus from minimum post AP plus 50 ms
                                            [~,min_postap_loc] = min(app.processed_data.image_stack{3}(m,n,start_index:end)); % Calculate location of min
                                            min_postap_loc = min_postap_loc+start_index-1;
                                            end_min_loc = min([min_postap_loc+ceil(0.05*app.data.fps), app.processed_data.n_frames]);
                                            resting_V = mean(app.processed_data.image_stack{3}(m,n,[1:(start_index-ceil(0.005*app.data.fps)), min_postap_loc:end_min_loc]),'omitnan');
                                        case 4 % Set resting membrane potential to minimum potential
                                            resting_V = min(app.processed_data.image_stack{3}(m,n,:));
                                    end

                                    % Mid rise value
                                    cross_V = (max_V(m,n) + resting_V)/2;

                                    % Find crossing index with
                                    % interpolation
                                    beat_signal = interp(squeeze(app.processed_data.image_stack{3}(m,n,:)),interp_factor);

                                    % Find crossing
                                    cross_index = find(beat_signal >= cross_V,1,"first");

                                    activation_times(m,n) = 1000*cross_index*dt/interp_factor;

                                end
                            end
                        end

                        % Used for APD restitution calculation.
                        start_indices = round(activation_times/(1000*dt));


                        activation_times = activation_times - min(activation_times(:));


                end

                % Final processing depends on map type of interest
                switch str2double(app.maptype.Value)
                    % Activation timings
                    case 1 %TODO% apply some averaging to reduce effect of noise
                        % Find most common start point
                        %                         start_common = mode(start_indices(:));
                        % Set start point for atria assuming AV delay of 50 ms
                        % plus 10 ms for conduction. Also assume that entire
                        % heart will be excited within 60 ms of mode %TODO%
                        % check this with cases of slow conduction
                        %                         activation_index_start = min([1, start_common - ceil(0.06*app.data.fps)]);
                        %                         activation_index_end = min([max(start_indices(:)) start_common+ceil(0.06*app.data.fps)]) - activation_index_start;
                        %
                        %                         %% Normalise to start value
%                         app.processed_data.activation_map = start_indices-activation_index_start;
%                         app.processed_data.activation_map(app.processed_data.activation_map<0) = 0;
%                         
%                         % Convert to ms
%                         app.processed_data.activation_map = 1000*app.processed_data.activation_map./app.data.fps;
%                          max_value = 1000*activation_index_end./app.data.fps;

                        app.processed_data.activation_map = activation_times;
                        %save for map filtering
                        max_value = max(activation_times(:),[],'omitnan');
                        app.max_value_ACT = max_value;
                        colourbar_string = 'Activation time (ms)';

                        % Add to log
                        app.history_field.Value{length(app.history_field.Value)+1,1} = ['Activation time map generated. Method: ',app.mapmethod.Items{str2num(app.mapmethod.Value)},'. Method percentage: ',int2str(app.mappercentage.Value),'.'];

                        % APD
                    case 2 %TODO% decide if take average of top values to lower effect of noise
                        % Find peak and resting membrane voltages. Speed up
                        % resting membrane V calculation. Generate matrices of
                        % unique start indices? %TODO% convert to cellfuns?
                        
                        max_V = max(app.processed_data.image_stack{3},[],3);
                        APD = nan(app.processed_data.height,app.processed_data.width);
                        %% Find resting membrane voltage for each pixel
%                         repol_indices = nan(app.processed_data.height,app.processed_data.width);
                        for k = 1:app.processed_data.height
                            progress_bar.Value = k/app.processed_data.height;
                            if progress_bar.CancelRequested
                                break
                            end
                            
                            for l = 1:app.processed_data.width
                                if ~isnan(start_indices(k,l))
                                    Vm_signal = reshape(app.processed_data.image_stack{3}(k,l,:), 1, length(app.processed_data.image_stack{3}(k,l,:)));
                                    switch str2double(app.rmpmethod.Value)
                                        case 1 % Calculate resting membrane potential from start to 5 ms before AP upstroke.
                                            resting_V = mean(app.processed_data.image_stack{3}(k,l,1:(start_indices(k,l)-ceil(0.005*app.data.fps))),'omitnan');
                                        case 2 % Calculate resting membrane potential from start to 5 ms before AP upstroke plus from when this value is reached after AP to end
                                            init_rmp = mean(app.processed_data.image_stack{3}(k,l,1:(start_indices(k,l)-ceil(0.005*app.data.fps))),'omitnan');
                                            cross_index = min([start_indices(k,l) + find(app.processed_data.image_stack{3}(k,l,start_indices(k,l):end)<=init_rmp,1) - 1, app.processed_data.n_frames]); % Find when signal goes below rmp
                                            resting_V = mean(app.processed_data.image_stack{3}(k,l,[1:(start_indices(k,l)-ceil(0.005*app.data.fps)), cross_index:end]),'omitnan');
                                        case 3 % Calculate resting membrane potential from start to 5 ms before AP upstroke plus from minimum post AP plus 50 ms
                                            [~,min_postap_loc] = min(app.processed_data.image_stack{3}(k,l,start_indices(k,l):end)); % Calculate location of min
                                            min_postap_loc = min_postap_loc+start_indices(k,l)-1;
                                            end_min_loc = min([min_postap_loc+ceil(0.05*app.data.fps), app.processed_data.n_frames]);
                                            resting_V = mean(app.processed_data.image_stack{3}(k,l,[1:(start_indices(k,l)-ceil(0.005*app.data.fps)), min_postap_loc:end_min_loc]),'omitnan');
                                        case 4 % Set resting membrane potential to minimum potential
                                            resting_V = min(app.processed_data.image_stack{3}(k,l,:));
                                    end
                                    amp_V = ((100-app.apdpercentage.Value)/100).*(max_V(k,l)-resting_V)+resting_V;
                                    repol_index= start_indices(k,l) + min([find(app.processed_data.image_stack{3}(k,l,start_indices(k,l)+1:end)<=amp_V,1,'first'),length(app.processed_data.image_stack{3}(k,l,start_indices(k,l):end))]);
                                    pre_repol_index = repol_index - 1;
                                    if repol_index <= length(time) 
                                        time_fine_grid = linspace(time(pre_repol_index), time(repol_index), interp_points); %generate fine grid for interpolation in ms
                                        Vm_kl_interpolated = interp1(time(pre_repol_index: repol_index), Vm_signal(pre_repol_index: repol_index), time_fine_grid, 'linear');
                                        repol_index_interpolated =  min([ find(Vm_kl_interpolated<=amp_V,1,'first'), length(time_fine_grid)]);
                                        repol_time = time_fine_grid(repol_index_interpolated);
                                        if strcmp(app.mapmethod.Value,'3')
                                            APD(k,l) = 1000*repol_time - (activation_times(k,l) + 1000*time(index_shift));
                                        else
                                            APD(k,l) = 1000*repol_time - activation_times(k,l);
                                        end
                                    else
                                        APD(k,l) = NaN;
                                    end


                                end
                            end
                        end
                        
                        % Durations
                        app.processed_data.activation_map = APD;
                        max_value = 10*ceil(max(app.processed_data.activation_map(:))/10);
                        %save for map filtering
                        app.max_value_APD = max_value;
                        colourbar_string = sprintf('APD%d (ms)',app.apdpercentage.Value);
                        
                        % Add to log
                        app.history_field.Value{length(app.history_field.Value)+1,1} = ['APD',int2str(app.apdpercentage.Value),' map generated. Method: ',app.mapmethod.Items{str2num(app.mapmethod.Value)},'. Method percentage: ',int2str(app.mappercentage.Value),'. RMP method: ',char(app.rmpmethod.Items(str2double(app.rmpmethod.Value))),'.'];

                    %df/dt Max
                    case 3
                        % Find dfdt
                        dfdt = diff(app.processed_data.image_stack{previous_normalisation},1,3)/(1/app.data.fps);
                        dfdt(dfdt==0) = NaN;
                        dfdt_max = max(dfdt,[],3);

                        app.processed_data.activation_map = dfdt_max;
                        max_value = max(app.processed_data.activation_map(:));
                        colourbar_string = 'Peak df/dt';

                        % Add to log
                        app.history_field.Value{length(app.history_field.Value)+1,1} = ['Peak dfdt map generated.'];

                    %AP amplitude
                    case 4

                        %% Find resting membrane voltage for each pixel
                        max_V = max(app.processed_data.image_stack{previous_normalisation},[],3);
                        AP_amplitude = nan(app.processed_data.height,app.processed_data.width);
                        
                        for k = 1:app.processed_data.height
                            progress_bar.Value = k/app.processed_data.height;
                            if progress_bar.CancelRequested
                                break
                            end
                            delta = 10;
                            for l = 1:app.processed_data.width
                                if ~isnan(start_indices(k,l)) && start_indices(k,l)+delta < length(time) && start_indices(k,l)>delta
                                    switch str2double(app.rmpmethod.Value)
                                        case 1 % Calculate resting membrane potential from start to 5 ms before AP upstroke.
                                            resting_V = mean(app.processed_data.image_stack{previous_normalisation}(k,l,1:(start_indices(k,l)-ceil(0.005*app.data.fps))),'omitnan');
                                        case 2 % Calculate resting membrane potential from start to 5 ms before AP upstroke plus from when this value is reached after AP to end
                                            init_rmp = mean(app.processed_data.image_stack{3}(k,l,1:(start_indices(k,l)-ceil(0.005*app.data.fps))),'omitnan');
                                            cross_index = min([start_indices(k,l) + find(app.processed_data.image_stack{3}(k,l,start_indices(k,l):end)<=init_rmp,1) - 1, app.processed_data.n_frames]); % Find when signal goes below rmp
                                            resting_V = mean(app.processed_data.image_stack{previous_normalisation}(k,l,[1:(start_indices(k,l)-ceil(0.005*app.data.fps)), cross_index:end]),'omitnan');
                                        case 3 % Calculate resting membrane potential from start to 5 ms before AP upstroke plus from minimum post AP plus 50 ms
                                            [~,min_postap_loc] = min(app.processed_data.image_stack{3}(k,l,start_indices(k,l):end)); % Calculate location of min
                                            min_postap_loc = min_postap_loc+start_indices(k,l)-1;
                                            end_min_loc = min([min_postap_loc+ceil(0.05*app.data.fps), app.processed_data.n_frames]);
                                            resting_V = mean(app.processed_data.image_stack{previous_normalisation}(k,l,[1:(start_indices(k,l)-ceil(0.005*app.data.fps)), min_postap_loc:end_min_loc]),'omitnan');
                                        case 4 % Set resting membrane potential to minimum potential
                                            resting_V = min(app.processed_data.image_stack{previous_normalisation}(k,l,:));
                                    end
                                    AP_amplitude(k,l) = max_V(k,l)-resting_V;
                                     
                                end
                            end
                        end

                        app.processed_data.activation_map = AP_amplitude;
                        max_value = max(AP_amplitude(:));
                        %save for map filtering
                        app.max_value_APD = max_value;
                        colourbar_string = 'AP amplitude';

                        % Add to log
                        app.history_field.Value{length(app.history_field.Value)+1,1} = ['AP amplitude map generated.'];

                    % Number of peaks
                    case 5
                        map_peaks = nan(app.processed_data.height,app.processed_data.width);
                        for k = 1:app.processed_data.height
                            progress_bar.Value = k/app.processed_data.height;
                            if progress_bar.CancelRequested
                                break
                            end
                            
                            for l = 1:app.processed_data.width
                                signal = squeeze(app.processed_data.image_stack{3}(k,l,:));
                                if ~all(signal ==0) && ~all(isnan(signal))
                                    pks = findpeaks(signal,'MinPeakHeight', min(signal)+0.7*(max(signal) - mean(signal)), 'MinPeakDistance', (0.05*app.data.fps), 'MinPeakProminence', 0.3*(max(signal) - mean(signal)));
                                    map_peaks(k,l) = length(pks);
                                end
                            end
                        end
                        app.processed_data.activation_map = map_peaks;
                        max_value = max(app.processed_data.activation_map(:));
                        colourbar_string = 'Number of peaks';

                        % Add to log
                        app.history_field.Value{length(app.history_field.Value)+1,1} = ['Number of peaks map generated.'];
                        
                    case 6 % Repolarization time
                        %Repolarization time
                        %Compute activation map
                        
                        %Compute APD
                        % Find peak and resting membrane voltages. Speed up
                        % resting membrane V calculation. Generate matrices of
                        % unique start indices? %TODO% convert to cellfuns?
                        max_V = max(app.processed_data.image_stack{3},[],3);
                        %% Find resting membrane voltage for each pixel
                        repol_time = nan(app.processed_data.height,app.processed_data.width);
                        for k = 1:app.processed_data.height
                            progress_bar.Value = k/app.processed_data.height;
                            if progress_bar.CancelRequested
                                break
                            end
                            
                            for l = 1:app.processed_data.width
                                if ~isnan(start_indices(k,l))
                                    Vm_signal = reshape(app.processed_data.image_stack{3}(k,l,:), 1, length(app.processed_data.image_stack{3}(k,l,:)));
                                    switch str2double(app.rmpmethod.Value)
                                        case 1 % Calculate resting membrane potential from start to 5 ms before AP upstroke.
                                            resting_V = mean(app.processed_data.image_stack{3}(k,l,1:(start_indices(k,l)-ceil(0.005*app.data.fps))),'omitnan');
                                        case 2 % Calculate resting membrane potential from start to 5 ms before AP upstroke plus from when this value is reached after AP to end
                                            init_rmp = mean(app.processed_data.image_stack{3}(k,l,1:(start_indices(k,l)-ceil(0.005*app.data.fps))),'omitnan');
                                            cross_index = min([start_indices(k,l) + find(app.processed_data.image_stack{3}(k,l,start_indices(k,l):end)<=init_rmp,1) - 1, app.processed_data.n_frames]); % Find when signal goes below rmp
                                            resting_V = mean(app.processed_data.image_stack{3}(k,l,[1:(start_indices(k,l)-ceil(0.005*app.data.fps)), cross_index:end]),'omitnan');
                                        case 3 % Calculate resting membrane potential from start to 5 ms before AP upstroke plus from minimum post AP plus 50 ms
                                            [~,min_postap_loc] = min(app.processed_data.image_stack{3}(k,l,start_indices(k,l):end)); % Calculate location of min
                                            min_postap_loc = min_postap_loc+start_indices(k,l)-1;
                                            end_min_loc = min([min_postap_loc+ceil(0.05*app.data.fps), app.processed_data.n_frames]);
                                            resting_V = mean(app.processed_data.image_stack{3}(k,l,[1:(start_indices(k,l)-ceil(0.005*app.data.fps)), min_postap_loc:end_min_loc]),'omitnan');
                                        case 4 % Set resting membrane potential to minimum potential
                                            resting_V = min(app.processed_data.image_stack{3}(k,l,:));
                                    end
                                    amp_V = ((100-app.apdpercentage.Value)/100).*(max_V(k,l)-resting_V)+resting_V;
                                    repol_index = start_indices(k,l) + min([find(app.processed_data.image_stack{3}(k,l,start_indices(k,l)+1:end)<=amp_V,1,'first'),length(app.processed_data.image_stack{3}(k,l,start_indices(k,l):end))]);
                                    pre_repol_index = repol_index - 1;
                                    if repol_index <= length(time)
                                        time_fine_grid = linspace(time(pre_repol_index), time(repol_index), interp_points); %generate fine grid for interpolation in ms
                                        Vm_kl_interpolated = interp1(time(pre_repol_index: repol_index), Vm_signal(pre_repol_index: repol_index), time_fine_grid, 'linear');
                                        repol_index_interpolated =  min([ find(Vm_kl_interpolated<=amp_V,1,'first'), length(time_fine_grid)]);
                                        repol_time(k,l) = 1000*time_fine_grid(repol_index_interpolated);
                                    end
                                end
                            end
                        end
                       
                        app.processed_data.activation_map = repol_time;
                        max_value = max(app.processed_data.activation_map(:));
                        colourbar_string = 'Repolarization Map';

                        % Add to log
                        app.history_field.Value{length(app.history_field.Value)+1,1} = ['Repolarisation',int2str(app.apdpercentage.Value),' map generated. Method: ',app.mapmethod.Items{str2num(app.mapmethod.Value)},'. Method percentage: ',int2str(app.mappercentage.Value),'. RMP method: ',char(app.rmpmethod.Items(str2double(app.rmpmethod.Value))),'.'];
                    
                        
                    % Mean signal
                    case 7
                        % Find dfdt
                        mean_signal = mean(app.processed_data.image_stack{previous_normalisation},3,'omitnan');

                        app.processed_data.activation_map = mean_signal;
                        max_value = max(app.processed_data.activation_map(:));
                        colourbar_string = 'Mean signal';

                        % Add to log
                        app.history_field.Value{length(app.history_field.Value)+1,1} = ['Mean signal map generated.'];

                    % 20%-80% upstroke velocity calculation UPTOHERE
                    case 8
                        max_V = max(app.processed_data.image_stack{3},[],3);
                        rise_time = nan(app.processed_data.height,app.processed_data.width);

                        %% Find resting membrane voltage for each pixel
                        for k = 1:app.processed_data.height
                            progress_bar.Value = k/app.processed_data.height;
                            if progress_bar.CancelRequested
                                break
                            end

                            for l = 1:app.processed_data.width
                                if ~isnan(start_indices(k,l))
                                    Vm_signal = squeeze(app.processed_data.image_stack{3}(k,l,:));
                                    switch str2double(app.rmpmethod.Value)
                                        case 1 % Calculate resting membrane potential from start to 5 ms before AP upstroke.
                                            resting_V = mean(app.processed_data.image_stack{3}(k,l,1:(start_indices(k,l)-ceil(0.005*app.data.fps))),'omitnan');
                                        case 2 % Calculate resting membrane potential from start to 5 ms before AP upstroke plus from when this value is reached after AP to end
                                            init_rmp = mean(app.processed_data.image_stack{3}(k,l,1:(start_indices(k,l)-ceil(0.005*app.data.fps))),'omitnan');
                                            cross_index = min([start_indices(k,l) + find(app.processed_data.image_stack{3}(k,l,start_indices(k,l):end)<=init_rmp,1) - 1, app.processed_data.n_frames]); % Find when signal goes below rmp
                                            resting_V = mean(app.processed_data.image_stack{3}(k,l,[1:(start_indices(k,l)-ceil(0.005*app.data.fps)), cross_index:end]),'omitnan');
                                        case 3 % Calculate resting membrane potential from start to 5 ms before AP upstroke plus from minimum post AP plus 50 ms
                                            [~,min_postap_loc] = min(app.processed_data.image_stack{3}(k,l,start_indices(k,l):end)); % Calculate location of min
                                            min_postap_loc = min_postap_loc+start_indices(k,l)-1;
                                            end_min_loc = min([min_postap_loc+ceil(0.05*app.data.fps), app.processed_data.n_frames]);
                                            resting_V = mean(app.processed_data.image_stack{3}(k,l,[1:(start_indices(k,l)-ceil(0.005*app.data.fps)), min_postap_loc:end_min_loc]),'omitnan');
                                        case 4 % Set resting membrane potential to minimum potential
                                            resting_V = min(app.processed_data.image_stack{3}(k,l,:));
                                    end
                                    
                                    % Find 20% and 80% values and then the
                                    % index they first occur at
                                    V_20 = 0.2.*(max_V(k,l)-resting_V)+resting_V;
                                    V_80 = 0.8.*(max_V(k,l)-resting_V)+resting_V;
                                    V_20_index= min([find(Vm_signal>=V_20,1,'first'),length(Vm_signal)]);
                                    V_80_index= min([find(Vm_signal>=V_80,1,'first'),length(Vm_signal)]);
                                    
                                    % Assumes constant frame rate (I don't
                                    % think this is an issue)
                                    if V_80_index <= length(Vm_signal) 
                                        time_fine_grid = linspace(time(max([1,V_20_index-1])), time(V_80_index), interp_points); %generate fine grid for interpolation in ms
                                        Vm_kl_interpolated = interp1(time(max([1,V_20_index-1]): V_80_index), Vm_signal(max([1,V_20_index-1]): V_80_index), time_fine_grid, 'linear');
                                        V_20_index_interpolated =  min([find(Vm_kl_interpolated>=V_20,1,'first'), length(time_fine_grid)]);
                                        V_80_index_interpolated =  min([find(Vm_kl_interpolated>=V_80,1,'first'), length(time_fine_grid)]);

                                        V80_20_time = time_fine_grid(V_80_index_interpolated) - time_fine_grid(V_20_index_interpolated);
                                        rise_time(k,l) = 1000*V80_20_time;
                                    else
                                        rise_time(k,l) = NaN;
                                    end


                                end
                            end
                        end
                        
                        % Durations
                        app.processed_data.activation_map = rise_time;
                        max_value = 10*ceil(max(app.processed_data.activation_map(:))/10);

                        colourbar_string = 'Rise time (80-20) (ms)';
                        
                        % Add to log
                        app.history_field.Value{length(app.history_field.Value)+1,1} = ['Rise time (80-20) map generated. Method: ',app.mapmethod.Items{str2num(app.mapmethod.Value)},'. Method percentage: ',int2str(app.mappercentage.Value),'. RMP method: ',char(app.rmpmethod.Items(str2double(app.rmpmethod.Value))),'.'];

                end

                % Display map
                if isfield(app.display,'quiver_handle') && ~isempty(app.display.quiver_handle)
                    delete(app.display.quiver_handle)
                end

                set(app.display.image_handle,'CData',app.processed_data.activation_map);
                app.display.image_handle.Parent.CLim = [0,max_value];
                % Set first value to black in colour map so that NaN are black.
                app.display.image_handle.Parent.Colormap = [0 0 0; jet(256)];
                if isvalid(app.display.colourbar_handle)
                    colorbar(app.display.colourbar_handle,'off')
                end
                app.display.colourbar_handle = colorbar(app.display.image_handle.Parent);
                app.display.colourbar_handle.Label.String = colourbar_string;
                app.display.colourbar_handle.Label.FontSize = app.display.colourbar_handle.Label.FontSize+2;
                app.display.map = 1;
                app.upperylimit.Value = app.display.colourbar_handle.Limits(2);
                app.lowerylimit.Value = app.display.colourbar_handle.Limits(1);
                
                set(app.spatialFilterType_maps,'Enable','on')
                set(app.spatialfilter_maps,'Enable','on')
                set(app.spatialkernelsize_maps,'Enable','on')
                set(app.KernelsizeLabel,'Enable', 'on')
                set(app.GaussiansigmaLabel_maps, 'Enable', 'on')
                set(app.gaussiansigma_maps,'Enable','on')
                set(app.roimeans,'Enable','on')
                set(app.mapmeans,'Enable','on')
                
                update_histogram_map(app)
                
                close(progress_bar);
            end
        end

        % Button pushed function: undofiltering
        function undo_filtering_map(app, event)
            current_map = app.processed_data.activation_map;
            app.processed_data.activation_map = app.processed_data.previous_map;
            app.processed_data.previous_map = current_map;
            
            update_map_view(app)
            update_histogram_map(app)
        end

        % Value changed function: ROIdropdown, SelectROIDropDown
        function ROI_dropdown_color_change(app, event)
            app.ROIdropdown.FontColor = app.plot_colours(rem(app.ROIdropdown.Value,7),:);
        end

        % Button pushed function: mapmeans
        function map_means(app, event)
            %Convert the values over/below the limits to NaN so they don't compute to mean and mode
            thresholded_map = app.processed_data.activation_map;
            thresholded_map(thresholded_map > app.upperylimit.Value) = NaN; %app.upperylimit.Value;
            thresholded_map(thresholded_map < app.lowerylimit.Value) = NaN; %app.lowerylimit.Value;
            map_mean = mean(thresholded_map(:),'omitnan')
            map_mean_sem = std(thresholded_map(:), "omitnan")/sqrt(length(thresholded_map(:)) - sum(isnan(thresholded_map(:))))
            map_median = median(thresholded_map(:),'omitnan')
            map_percentiles = prctile(thresholded_map(:),[5,95],'all');
            
            message = sprintf('Mean: %.1f, SEM: %.1f\tMedian: %.1f\nPercentiles: 5th: %.1f\t95th: %.1f\n', map_mean, map_mean_sem, map_median,map_percentiles(1),map_percentiles(2));
            uialert(app.opticalmappingUIFigure,message,'Map mean values','Icon','')
        end

        % Value changed function: lowerylimit, upperylimit
        function change_map_limits(app, event)
            ymax = app.upperylimit.Value;
            ymin = app.lowerylimit.Value;
            
            caxis(app.display.image_handle.Parent,[ymin,ymax]);
        end

        % Button pushed function: roimeans
        function roi_means(app, event)
            roi_values = cellfun(@(x) app.processed_data.activation_map.*x,app.display.ROI_mask,'UniformOutput',false);
            roi_means = cellfun(@(x) mean(x(:)),roi_values,'UniformOutput',false,'omitnan');
            roi_medians = cellfun(@(x) median(x(:),'omitnan'),roi_values,'UniformOutput',false);
            roi_5th = cellfun(@(x) prctile(x(:),5,'all'),roi_values,'UniformOutput',false);
            roi_95th = cellfun(@(x) prctile(x(:),95,'all'),roi_values,'UniformOutput',false);
            icon_array = zeros(length(app.display.ROI_mask),1,3);
            icon_array(:,1,:) = app.plot_colours(1:length(app.display.ROI_mask),:);
            icon_array = repelem(icon_array,floor(512/length(app.display.ROI_mask)),1,1);
            icon_array = repmat(icon_array,[1,512,1]);
            roi_message = cellfun(@(x,y,z,a) sprintf('Mean: %.1f\Median: %.1f\nPercentiles: 5th: %.1f\t95th: %.1f\n',x,y,z,a),roi_means,roi_medians,roi_5th,roi_95th,'UniformOutput',false);
            uialert(app.opticalmappingUIFigure,strjoin(roi_message),'ROI mean values','Icon',icon_array);
        end

        % Button pushed function: previousframe
        function previous_frame(app, event)
            app.display.current_frame = max(1,app.display.current_frame-1);
            app.tool_tip_update;
            app.update_view_signal;
        end

        % Button pushed function: undolast_Viewing
        function undo_last_viewing(app, event)
            app.processed_data.keep_area_poly = app.processed_data.previous_keep_area_poly;
            app.undo
        end

        % Button pushed function: spatialfilter_maps
        function spatial_filtering_maps(app, event)
            % Store original stack before filtering it
            %             original_stack = app.processed_data.image_stack{1};
            app.processed_data.previous_map = app.processed_data.activation_map;
            
            % Call spatial filter of the non-mapped data with the parameters of this Tab
            
            switch str2double(app.spatialFilterType_maps.Value)
                case 4
                    app.processed_data.activation_map = colfilt(app.processed_data.activation_map, [str2double(app.spatialkernelsize_maps.Value), str2double(app.spatialkernelsize_maps.Value)], 'sliding', @mode);
                    app.update_map_view
                otherwise
                    app.spatialkernelsize.Value = app.spatialkernelsize_maps.Value;
                    app.spatialFilterType.Value = app.spatialFilterType_maps.Value;
                    app.gaussiansigma.Value = app.gaussiansigma_maps.Value;
                    spatial_filtering(app);
                    generate_map(app)
            end
            %             app.processed_data.image_stack{1} = original_stack;
            % Re-do map
            
            set(app.undofiltering,'Enable','on')
            
            update_histogram_map(app)
                               
            
        end

        % Button pushed function: pickframe
        function frame_pick(app, event)
            app.pickframe_app = pick_frame(app);
            uiwait(app.pickframe_app.pickframeUIFigure);
            app.tool_tip_update;
            app.update_view_signal;
        end

        % Menu selected function: saveworkspace
        function save_workspace(app, event)
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Saving','Indeterminate','on');
            % Create save file name by giving smallest non-existing number
            save_file = file_exists(app,'workspace','.mat');
            
            % Intermediate variables for saving
            file_info_int = app.file_info;
            % Remove original data stack  to save space-> in future likely remove this
            % structure entirely. %TODO%
            data_int = app.data;
            data_int.image_stack = [];
            processed_data_int = app.processed_data;
            % Remove normalisations and just reapply on load to limit file
            % size.
            if length(processed_data_int.image_stack) >=8
                processed_data_int.image_stack([2:7,9:end]) = [];
            else
                processed_data_int.image_stack(2:end) = [];
            end
            averaging_int = app.averaging;
            display_int = app.display;
            display_int.renorm = ones(5,1);
            settings_int = app.settings;
            command_history = app.history_field;
            advanced_data_int = app.advanced_data;


            % Save to mat file - save or just renormalise after?
            save(save_file,'file_info_int','data_int','processed_data_int','averaging_int','display_int','settings_int','command_history','advanced_data_int','-mat','-v7.3');
        
            close(progress_bar);
        end

        % Menu selected function: loadworkspace
        function load_workspace(app, event)
            % Select file
            [filename,filepath] = uigetfile('*.mat','Select the workspace file'); % Open file selector
            
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Loading','Indeterminate','on');
            
            app.SavetoEditField.Value = filepath;

            % Load
            load([filepath filename],'file_info_int','data_int','processed_data_int','averaging_int','display_int','settings_int','command_history','advanced_data_int');
            if exist('file_info_int','var')
                % Hide colour bar, will re add if map is selected
                if isvalid(app.display.colourbar_handle)
                    colorbar(app.display.colourbar_handle,'off')
                end
                
                % Copy handle for figure and signal into int variables
                display_int.image_handle = app.display.image_handle;
                display_int.plot_handle = app.display.plot_handle;
                
                % Load data into app structures
                app.file_info = file_info_int;
                app.file_info.path = filepath;
                app.data = data_int;
                app.processed_data = processed_data_int;
                app.averaging = averaging_int;
                app.display = display_int;
                if exist('advanced_data_int','var')
                    app.advanced_data = advanced_data_int;
                end

                try
                    app.history_field.Value = {['Loading workspace ',filepath, filename]};
                    for k=1:length(command_history.Value)
                        app.history_field.Value{length(app.history_field.Value)+1,1} = command_history.Value{k,1};
                    end
                catch
                    app.history_field.Value = {'Loaded Workspace without previous command history'};
                end
                
                % Take into account old workspace files
                if ~isfield(app.processed_data,'frame_range')
                    app.processed_data.frame_range = [1 size(app.processed_data.image_stack{1},3)];
                end
                if ~isfield(app.processed_data,'binning')
                    app.processed_data.binning = 1;
                end
                if exist('settings_int','var')
                    app.settings = settings_int;
                else
                    app.settings = struct('quick_load',struct('regions',1,'background',1,'roi',1)); % Quick load orientation/reshape, background and ROIs
                end
                
                % Clear loaded data
                clear('file_info_int','data_int','processed_data_int','averaging_int','display_int','settings_int');
                
                % Update file info
                app.tool_tip_update;
                
                % Create axes and image handle if they don't already exist
                if isempty(app.main_display_axes)
                    app.main_display_axes = axes(app.opticalmappingUIFigure, 'Units','Pixels','Position',[1,app.figure_size(4)-floor(1.2*app.figure_size(4)/2)-40,app.figure_size(3),floor(1.2*app.figure_size(4)/2)],'XTick',[],'YTick',[]);
                    app.display.image_handle = imshow(app.processed_data.image_stack{1}(:,:,1)./max(app.processed_data.image_stack{1}(1:app.processed_data.height*app.processed_data.width)),'Parent',app.main_display_axes);
                    app.display.image_handle.Parent.CLim = [0,1];
                end
                int = get(app.TabGroup,'Position');
                set(app.TabGroup,'Position',[int(1), int(2), int(3), floor(app.figure_size(4)./3)])
                set(app.TabGroup,'Visible','on')
                set(app.saveworkspace,'Enable','on')
                
                % Calculate ROIs if present
                if ~isempty(app.display.ROI_handle)
                    cellfun(@(x) set(x,'Parent',app.main_display_axes),app.display.ROI_handle);
                end
                
                % Display or hide signals window
                if app.display.signal_display == 1
                    % Create signal display axes if they don't already exist
                    set(app.main_display_axes,'Position',[1,app.figure_size(4)-floor(1.2*app.figure_size(4)/2)-40,0.7*app.figure_size(3),floor(1.2*app.figure_size(4)/2)]);
                    if isempty(app.signal_display_axes)
                        app.signal_display_axes = axes(app.opticalmappingUIFigure, 'Units','Pixels','Position',[0.74*app.figure_size(3),app.figure_size(4)-floor(1.1*app.figure_size(4)/2)-40,0.25*app.figure_size(3),floor(1.0*app.figure_size(4)/2)]);
                        hold(app.signal_display_axes, 'on');
                        xlabel('Time (s)','Parent',app.signal_display_axes);
                        ylabel('Signal (a.u.)','Parent',app.signal_display_axes);
                    end
                    set(app.signal_display_axes,'Visible','on');
                    app.show_signals(app);
                else
                    app.hide_signals(app);
                end
                %Set colormap to hot 
                app.display.colour_map = hot(256);
                
                % Display map
                if app.display.map == 1
                    app.change_normalisation_local;
                    app.generate_map(app);
                    % Display current normalisation
                else
                    switch app.display.normalisation
                        case 1
                            app.change_normalisation_none(app);
                            app.nonormalise.Value = 1;
                        case 2
                            app.change_normalisation_global(app);
                            app.globalnormalise.Value = 1;
                        case 3
                            app.change_normalisation_local(app);
                            app.localnormalise.Value = 1;
                        case 4
                            app.change_normalisation_global_mean(app);
                            app.globalmeannormalise.Value = 1;
                        case 5
                            app.change_normalisation_local(app);
                            app.change_normalisation_timeaverage(app);
                            app.timeaverage.Value = 1;
                    end
                end
                %TODO% update so that any values in GUI are updated
                app.first_processed_frame = app.processed_data.image_stack{1}(:,:,1); %saving the first frame without inversion to use for automatic segmentation because if the data is later inverted the segmentation code will not work
                
                %check inversion of first processed frame
                maximum_stack = max(app.first_processed_frame,[],'all');
                corner_pxl = app.first_processed_frame(1,1,1)/maximum_stack;
                if corner_pxl > 0.8 %data is inverted, so invert back
                    app.first_processed_frame = maximum_stack - app.first_processed_frame;
                end

            end
            figure(app.opticalmappingUIFigure);
            set([app.FileMenu],'Enable','on');
            set([app.SettingsMenu],'Enable','on');
            set([app.QuickloadMenu],'Enable','on');
            set([app.QuickloadpartiallyMenu],'Enable','on');
            set(app.LoadRegionsMenu,'Enable','off')
            close(progress_bar)
        end

        % Button pushed function: undolast
        function undo(app, event)
            % Temporarily store current to then move it into previous_stack
            int_stack = app.processed_data.image_stack{1};
            current_history = app.history.current;
            
            app.processed_data.image_stack{1} = app.processed_data.previous_stack;
            app.processed_data.previous_stack = int_stack;
            
            app.history.current = app.history.old;
            app.history.old = current_history;
            
            %update tooltip
            app.processed_data.height = size(app.processed_data.image_stack{1},1);
            app.processed_data.width =  size(app.processed_data.image_stack{1},2);
            tool_tip_update(app)
            %Update image handle
            cla(app.main_display_axes);
            app.display.image_handle = imshow(app.processed_data.image_stack{1}(:,:,app.display.current_frame)./max(app.processed_data.image_stack{1}(1:app.processed_data.height*app.processed_data.width)),'Parent',app.main_display_axes);
            
            % Restore signals if they have been changed
            if ~isempty(app.processed_data.previous_signals)
                app.processed_data.signals = app.processed_data.previous_signals;
            end

            % Recalculate displayed normalisation
            app.display.renorm = ones(size(app.display.renorm));
            switch app.display.normalisation
                case 1
                    app.change_normalisation_none(app);
                case 2
                    app.change_normalisation_global(app);
                case 3
                    app.change_normalisation_local(app);
                case 4
                    app.change_normalisation_global_mean(app);
                case 5
                    app.change_normalisation_timeaverage(app);
            end
            app.history_field.Value{length(app.history_field.Value)+1,1} = 'Undo last processing step.';
        end

        % Button pushed function: AssistedareasegmentationButton
        function Assisted_area_segmentation(app, event)
            app.processed_data.previous_stack = app.processed_data.image_stack{1};
            app.processed_data.previous_keep_area_poly = app.processed_data.keep_area_poly;
            
            app.processed_data.keep_area_poly = ones(size(app.processed_data.image_stack{1}));
            
            app.segmentation_app = segmentationAssist(app);
            app.ui_resume = false;
            while app.ui_resume == false
                try
                    uiwait(app.segmentation_app.segmentationAssistUIFigure)
                catch
                    break
                end
                
            end
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Transfering mask','Indeterminate','on');
            % Mask no normalisation
            app.processed_data.image_stack{1} = app.processed_data.image_stack{1}.*app.processed_data.keep_area_poly;
            app.history_field.Value{length(app.history_field.Value)+1,1} = 'Background extracted with asisted area segmentation';
            
            % Recalculate normalisation that is displayed
            app.display.renorm = [1, 1, 1, 1 ,1];
            switch app.display.normalisation
                case 1
                    %app.change_normalisation_none(app);
                    update_view_signal(app)
                case 2
                    app.change_normalisation_global(app);
                case 3
                    app.display.renorm(2) = 0;
                    %app.change_normalisation_local(app);
                    app.processed_data.image_stack{3} = app.processed_data.image_stack{3}.*app.processed_data.keep_area_poly;
                    update_view_signal(app)
                    
                case 4
                    app.change_normalisation_global_mean(app);
                case 5
                    app.change_normalisation_local(app);
                    app.change_normalisation_timeaverage(app);
            end
            close(progress_bar)
            
        end

        % Menu selected function: HotCmap
        function Hot_cmap(app, event)
            app.display.colour_map = hot(256);
            update_view_signal(app)
            app.HotCmap.Checked = "on";
            app.GrayscaleCmap.Checked = "off";
            app.JetCmap.Checked = "off";
        end

        % Menu selected function: GrayscaleCmap
        function Grayscale_cmap(app, event)
            app.display.colour_map = gray(256);
            update_view_signal(app)
            app.HotCmap.Checked = "off";
            app.GrayscaleCmap.Checked = "on";
            app.JetCmap.Checked = "off";
        end

        % Menu selected function: JetCmap
        function Jet_cmap(app, event)
            app.display.colour_map = jet(256);
            update_view_signal(app)
            app.HotCmap.Checked = "off";
            app.GrayscaleCmap.Checked = "off";
            app.JetCmap.Checked = "on";
        end

        % Value changed function: NotesTextArea
        function notes_text(app, event)
            app.notes = app.NotesTextArea.Value;
            
        end

        % Button pushed function: Savelogfile
        function save_log_button(app, event)
            %file_id = fopen('notes.txt','wt');
            %fprintf(file_id, app.notes);
            %fclose(file_id);
            %writecell(app.notes,'notes.dat') %% TODO: CHANGE FOLDER WHERE IT IS SAVED, CURRENTLY IT SAVES TO THE CURRENT FOLDER, SET SO IT SAVES TO THE FOLDER WHERE THE OPEN FILE IS.
            filename = file_exists(app,'log_file','.dat');
            save_log_file(app,filename)
        end

        % Button pushed function: ApplyRemoveBias
        function Apply_remove_bias_shift(app, event)
            % Fits a polynomial of order app.Polynomialdegree.Value to each pixel signal and substracts it from the pixel signal. This is automatically done by the matlab
            % function detrend.
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Removing bias shift from the signal','Message','This step can take several minutes...','Cancelable','on');
            
            app.processed_data.previous_stack = app.processed_data.image_stack{1};
            app.history.old = app.history.current;
            
            poly_degree = app.PolynomialDegree.Value; %app.Polynomialdegree.Value;
            
            size_stack = size(app.processed_data.image_stack{1});
            
            for m = 1:size_stack(1)
                progress_bar.Message = [' This step can take several minutes. Processing ', num2str(round(m/size_stack(1)*100)), '%'];
                progress_bar.Value = m/size_stack(1);
                
                for n = 1:size_stack(2)
                    signal_pxl = squeeze(app.processed_data.image_stack{1}(m,n,:));
                    if ~all(signal_pxl ==0) && ~all(isnan(signal_pxl))
                        if progress_bar.CancelRequested
                            break
                        end
                        
                        signal_pxl_detrend = detrend(signal_pxl, poly_degree);
                        app.processed_data.image_stack{1}(m,n,:) = reshape(signal_pxl_detrend, 1,1 ,size_stack(3));
                    end
                end
            end
            
            if ~progress_bar.CancelRequested
                
                app.history.current = [app.history.current, sprintf(' RemoveBias(n=%c)',poly_degree)];
                % Set that renormalisation is needed.
                app.display.renorm = ones(size(app.display.renorm));
                % Display filtered data
                switch app.display.normalisation
                    case 1
                        app.change_normalisation_none(app);
                    case 2
                        app.change_normalisation_global(app);
                    case 3
                        app.change_normalisation_local(app);
                    case 4
                        app.change_normalisation_global_mean(app);
                    case 5
                        app.change_normalisation_timeaverage(app);
                end
            else
                app.processed_data.image_stack{1} = app.processed_data.previous_stack;
            end
            close(progress_bar)
            
        end

        % Button pushed function: ApplySLOWButton
        function ApplySLOWButtonPushed(app, event)
            % Uses the matlab function msbackadj to extract the baseline of a signal with peaks by means of a window-based method.
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Removing bias shift from the signal','Message','This step can take several minutes...','Cancelable','on');
            
            app.processed_data.previous_stack = app.processed_data.image_stack{1};
            app.history.old = app.history.current;
            
            window_size = app.subWindowEditField.Value;
            step_size = app.subStepEditField.Value;
            
            size_stack = size(app.processed_data.image_stack{1});
            
            time = app.processed_data.signals{1}';
            for m = 1:size_stack(1)
                progress_bar.Message = [' This step can take several minutes. Processing ', num2str(round(m/size_stack(1)*100)), '%'];
                progress_bar.Value = m/size_stack(1);
                
                for n = 1:size_stack(2)
                    signal_pxl = squeeze(app.processed_data.image_stack{1}(m,n,:));
                    if ~all(signal_pxl ==0) && ~all(isnan(signal_pxl))
                        if progress_bar.CancelRequested
                            break
                        end
                        signal_pxl_detrend = msbackadj(time, signal_pxl ,'WindowSize',window_size,'StepSize',step_size);
                        signal_pxl_detrend = signal_pxl_detrend - min(signal_pxl_detrend);
                        signal_pxl_detrend = signal_pxl_detrend/max(signal_pxl_detrend);
                        app.processed_data.image_stack{1}(m,n,:) = reshape(signal_pxl_detrend, 1,1 ,size_stack(3));
                    end
                end
                
            end
            
            if ~progress_bar.CancelRequested
                
                app.history.current = [app.history.current, sprintf(' RemoveBiasSlow')];
                % Set that renormalisation is needed.
                app.display.renorm = ones(size(app.display.renorm));
                % Display filtered data
                switch app.display.normalisation
                    case 1
                        app.change_normalisation_none(app);
                    case 2
                        app.change_normalisation_global(app);
                    case 3
                        app.change_normalisation_local(app);
                    case 4
                        app.change_normalisation_global_mean(app);
                    case 5
                        app.change_normalisation_timeaverage(app);
                end
            else
                app.processed_data.image_stack{1} = app.processed_data.previous_stack;
            end
            close(progress_bar)
        end

        % Button pushed function: ApplyWINDOWButton
        function ApplyWINDOWButtonPushed(app, event)
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Removing bias shift from the signal','Message','This step can take several minutes...','Cancelable','on');
            
            app.processed_data.previous_stack = app.processed_data.image_stack{1};
            app.history.old = app.history.current;
            
            subwindow_size = app.subWindowEditField.Value;
            substep_size = app.subStepEditField.Value;
            
            box_size = app.WindowMethod3.Value;
            step_size = app.StepMethod3.Value;
            
            % Compute how many horizontal and vertical steps i will move the box
            %[height, width, kinetic_length] = size(app.processed_data.image_stack{1});
            [height, width, ~] = size(app.processed_data.image_stack{1});
            width_steps = floor(width/step_size);
            height_steps = floor(height/step_size);
            time = app.processed_data.signals{1}';
            
            for h = 1:height_steps-1
                progress_bar.Message = [' This step can take several minutes. Processing ', num2str(round(h/(height_steps-1)*100)), '%'];
                progress_bar.Value = h/(height_steps-1);
                
                for w = 1:width_steps-1
                    if progress_bar.CancelRequested
                        break
                    end
                    % Extract the signals inside the box
                    w_left=  1 + (w-1)*step_size;
                    w_right = w_left-1 + box_size;
                    
                    h_up = 1 +  (h-1)*step_size;
                    h_bottom = h_up-1 + box_size;
                    box_signals = app.processed_data.image_stack{1}( h_up:h_bottom, w_left:w_right, :);
                    if ~all(isnan(box_signals),'all') && ~all(box_signals ==0,'all')
                        % Mean signal inside the box
                        box_mean = squeeze(mean(box_signals,[1,2],'omitnan'));
                        
                        %remove_baseline shift
                        box_signal_detrend = msbackadj(time, box_mean ,'WindowSize',subwindow_size,'StepSize',substep_size);
                        
                        %get the baseline points by
                        baseline = box_mean - box_signal_detrend;
                        
                        %Expand the baseline signal into 3D array for later substraction
                        baseline3D = permute(repmat(baseline,1, box_size, box_size), [3,2,1]);
                        
                        %remove baseline to individual pixels
                        box_signals = box_signals - baseline3D;
                        
                        % update singals into the stack
                        app.processed_data.image_stack{1}(h_up:h_bottom, w_left:w_right , :) = box_signals;
                        
                        %mask out the borders again
                        app.processed_data.image_stack{1} = app.processed_data.image_stack{1}.*app.processed_data.keep_area_poly;
                    end
                end
            end
            
            if ~progress_bar.CancelRequested
                
                app.history.current = [app.history.current, sprintf(' RemoveBiasWindow')];
                % Set that renormalisation is needed.
                app.display.renorm = ones(size(app.display.renorm));
                % Display filtered data
                switch app.display.normalisation
                    case 1
                        app.change_normalisation_none(app);
                    case 2
                        app.change_normalisation_global(app);
                    case 3
                        app.change_normalisation_local(app);
                    case 4
                        app.change_normalisation_global_mean(app);
                    case 5
                        app.change_normalisation_timeaverage(app);
                end
            else
                app.processed_data.image_stack{1} = app.processed_data.previous_stack;
            end
            close(progress_bar)


        end

        % Value changed function: MaxColourmap
        function MaxColourmapValueChanged(app, event)
            update_view_signal(app)
            
        end

        % Button pushed function: ComputeWavefrontButton
        function ComputeWavefrontButtonPushed(app, event)
            if app.history.current == '0';
                warndlg(['WARNING: No filtering or detrending has been detected. It is strongly encouraged to process the data first.'])
            end
            
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Generating wavefront data','Message','Please wait...','Cancelable','on');
            
            
            % Check if global normalisation is available. Normalise otherwise %%%not working like this. COthRRECTION: do it always so if there was a filtering step this
            % is taken into account
            app.change_normalisation_global(app);
            
            
            % Check that data is inverted. Invert otherwise.
            if app.invertdata.Value == 0
                app.invertdata.Value = 1;
                invert(app)
            end
            
            stack = app.processed_data.image_stack{2};
            %Generate RGB stack to be able to show the wavefront in red
            rgb_stack = repmat(stack, [1,1,1,3]);
            rgb_stack = permute(rgb_stack, [1 2 4 3]);
            
            size_stack = size(stack);
            %Go through every pixl in the non-rgb image that is not NaN and check min and max. Then set the threshold at say 80 of the difference. Then find the time-frames at which the signal is above this threshold. At the frames where it's above the threshold set the value of the rgb to [1 0 0]
            for m=1:size_stack(1)
                progress_bar.Value = m/size_stack(1);
                if progress_bar.CancelRequested
                    break
                end
                for n=1:size_stack(2)
                    signal_pxl = squeeze(stack(m,n,:));
                    if ~all(signal_pxl ==0) && ~all(isnan(signal_pxl))
                        
                        
                        %detrend signal or signal should be detrend already- include a tick box to do detrending if not done already?
                        %signal_pxl = detrend(signal_pxl, 6);
                        
                        max_pxl = max(signal_pxl);
                        min_pxl = min(signal_pxl);
                        range = max_pxl - min_pxl;
                        threshold_max = min_pxl + app.ThresholdWavefront.Value/100*range;
                        
                        wavefront_max = (signal_pxl>threshold_max);
                        
                        red = repmat([1 0 0], [1, 1, 1, sum(wavefront_max)]);
                        red = permute(red, [1,3,2,4]);
                        
                        rgb_stack(m,n,:,wavefront_max) = red;


                    end
                end
            end
            if ~progress_bar.CancelRequested
                app.processed_data.image_stack{5} = rgb_stack;
                
                app.display.normalisation = 5;
                
                % Set global normalise button to unchecked and activate the show/hide button
                app.globalnormalise.Value = 0;
                set(app.ShowHideWavefrontButton,'Enable','on')
                app.ShowHideWavefrontButton.Value = 1;
                update_view_signal(app)
            end
            
            close(progress_bar)
            
        end

        % Value changed function: ShowHideWavefrontButton
        function ShowHideWavefrontButtonValueChanged(app, event)
            value = app.ShowHideWavefrontButton.Value;
            if value == 1
                app.display.normalisation = 5;
                app.nonormalise.Value = 0;
                app.globalnormalise.Value = 0;
                app.localnormalise.Value = 0;
                app.timeaverage.Value = 0;
                update_view_signal(app)
            else
                app.display.colour_map = gray(256);
                app.display.normalisation = 2;
                app.nonormalise.Value = 0;
                app.globalnormalise.Value = 1;
                app.localnormalise.Value = 0;
                app.timeaverage.Value = 0;
                update_view_signal(app)
            end
        end

        % Value changed function: maptype
        function maptypeValueChanged(app, event)
            value = app.maptype.Value;
            %             if value ==3
            %                 set(app.generatemap,'Enable','on')
            %                 set(app.average_first_label,'Visible','off')
            %             else
            %                 set(app.generatemap,'Enable','off')
            %                 set(app.average_first_label,'Visible','on')
            %             end
        end

        % Callback function
        function ComputeROIpropertiesButtonPushed(app, event)
            % get ROI signal
            time = app.processed_data.signals{app.SelectROIDropDown.Value, 1}';
            signal_ROI = app.processed_data.signals{app.SelectROIDropDown.Value, 4};
            signal_ROI_norm = signal_ROI - min(signal_ROI);
            signal_ROI_norm = signal_ROI_norm./max(signal_ROI_norm);
            % Find peaks
            [~, times] = findpeaks(sgolayfilt(double(signal_ROI_norm),2,21),time, 'MinPeakProminence', 0.3);
            if length(times) == 1
                app.ROIbcl_old.Value = time(end);
                app.stdBCL.Value = 0;
            else
                app.ROIbcl_old.Value = mean(diff(times)*1000);
                app.stdBCL.Value = std(diff(times)*1000);
            end


        end

        % Menu selected function: SaveRegionsMenu
        function SaveRegionsMenuSelected(app, event)
            % Save number of regions
            save_file = file_exists(app,'regions','.mat');
            number_of_regions = app.data.regions;
            region_bounds = app.data.region_bounds;
            rotation = app.data.rotation;
            
            save(save_file,'number_of_regions','region_bounds',"rotation");
            
        end

        % Menu selected function: LoadRegionsMenu
        function LoadRegionsMenuSelected(app, event)
            [region_file,region_path] = uigetfile({'*.mat';'*.*'},'Region selector'); % Open Region selector
            region_data = load([region_path region_file]);
            
            app.data.regions = region_data.number_of_regions;
            app.data.region_bounds = region_data.region_bounds;
            app.data.rotation = region_data.rotation;
            
            
%             %Apply transformation and apply regions
%             % If 3 regions switch order
%             if app.data.regions == 3
%                 if app.data.rotation == 3
%                     temp_bounds = app.data.region_bounds;
%                     app.data.region_bounds(2,:) = temp_bounds(3,:);
%                     app.data.region_bounds(3,:) = temp_bounds(2,:);
%                 elseif app.data.rotation == 1
%                     temp_bounds = app.data.region_bounds;
%                     app.data.region_bounds(1,:) = temp_bounds(3,:);
%                     app.data.region_bounds(2,:) = temp_bounds(1,:);
%                     app.data.region_bounds(3,:) = temp_bounds(2,:);
%                 end
%             end
            
            % Crop and rotate as appropriate
            %app.processed_data.n_frames = app.data.n_frames;
            app.processed_data.cropped = 1;
            apply_regions(app);
            set([app.FileMenu],'Enable','on');
            set([app.SettingsMenu],'Enable','on');
            set([app.QuickloadMenu],'Enable','on');
            set([app.RegionsMenu],'Enable','on');
            set([app.LoadRegionsMenu],'Enable','off');
            set([app.SaveRegionsMenu],'Enable','on');
            set([app.DefineregionsMenu],'Enable','off');
        end

        % Callback function
        function ComputeMotionCorrectedImageButtonPushed(app, event)
            % Generate necessary directories
            MotCorr_dir_name = dir_exists(app,'MotionCorrection');
            mkdir(MotCorr_dir_name);
            mkdir(MotCorr_dir_name, 'MatchedPoints');
            mkdir(MotCorr_dir_name, 'VTKOutput');


        end

        % Button pushed function: AutomaticfilesearchandimportButton
        function AutomaticfilesearchandimportButtonPushed(app, event)
            app.BioPAC_file = search_BioPAC_file(app);
            app.BioPAC_path_file = [app.AutomaticSearchFolderEditField.Value app.BioPAC_file];
            load_BioPAC(app)
            app.BioPACfileEditField.Value = [app.AutomaticSearchFolderEditField.Value app.BioPAC_file];
            
            BioPAC_signal = app.ShowBioPACDropDown.Value;
            set([app.ShowBioPACDropDown],'Enable','on')
            
            app.SwitchBioPACShow_Hide;
            
        end

        % Value changed function: ShowBioPACDropDown
        function ShowBioPACDropDownValueChanged(app, event)
            
            app.SwitchBioPACShow_HideValueChanged;
            
            
        end

        % Value changed function: SwitchBioPACShow_Hide
        function SwitchBioPACShow_HideValueChanged(app, event)
            BioPAC_signal = app.ShowBioPACDropDown.Value;
            
            if app.SwitchBioPACShow_Hide.Value == 'Show'
                % Plot signal on axes
                if ~app.display.BioPAC_signal_display || isempty(app.BioPAC_signal_display_axes)
                    % Move OM display axis to one side to make space for the signal plot
                    set(app.main_display_axes,'Position',[1,app.figure_size(4)-floor(1.2*app.figure_size(4)/2)-40, 0.7*app.figure_size(3),floor(1.2*app.figure_size(4)/2)]);
                    
                    % If signal axes are empty, generate them.
                    if isempty(app.BioPAC_signal_display_axes)
                        app.BioPAC_signal_display_axes = axes(app.opticalmappingUIFigure, 'Units','Pixels','Position',[0.74*app.figure_size(3),app.figure_size(4)-floor(1.1*app.figure_size(4)/2)-40,0.25*app.figure_size(3),floor(1.0*app.figure_size(4)/2)],'Color','none');
                        xlabel('Time (s)','Parent',app.BioPAC_signal_display_axes);
                        ylabel('Signal (a.u.)','Parent',app.BioPAC_signal_display_axes);
                    end
                    set(app.BioPAC_signal_display_axes,'Visible','on');
                    app.display.BioPAC_signal_display = 1;
                end
                
                empty_signal = zeros(1,length(app.channel_data.ECG1.data));
                
                if ~isempty(app.channel_data.LED.idx) && ~isempty(app.channel_data.ECG2.idx) %LED and ECG1,2,3 (mouse in panoramic system)
                    channels_signal = {app.channel_data.ECG1.data, app.channel_data.ECG2.data, app.channel_data.ECG3.data, app.channel_data.elec_stim.data, app.channel_data.LED.data, app.channel_data.camera.data, empty_signal, empty_signal, empty_signal};
                
                elseif ~isempty(app.channel_data.LED.idx) && isempty(app.channel_data.ECG2.idx) %LED but no ECG2 (mouse system)
                    channels_signal = {app.channel_data.ECG1.data, empty_signal ,empty_signal, app.channel_data.elec_stim.data, app.channel_data.LED.data, app.channel_data.camera.data , empty_signal, empty_signal, empty_signal};
                
%                 elseif ~isempty(app.channel_data.force.data) %mechanical pacing (no LED)
%                     force = app.channel_data.force.data - min(app.channel_data.force.data);
%                     force = force/max(force)-0.5;
%                     
%                     motor1 = app.channel_data.motor1.data - min(app.channel_data.motor1.data);
%                     motor1 = motor1/max(motor1)-0.5;
%                     
%                     motor2 = app.channel_data.motor2.data - min(app.channel_data.motor2.data);
%                     motor2 = motor2/max(motor2)-0.5;
%                     
%                     channels_signal = {app.channel_data.ECG1.data, app.channel_data.ECG2.data, app.channel_data.ECG3.data, app.channel_data.elec_stim.data,empty_signal , app.channel_data.camera.data, force, motor1, motor2};
%  
                else %Standard rabbit
                    channels_signal = {app.channel_data.ECG1.data, app.channel_data.ECG2.data, app.channel_data.ECG3.data, app.channel_data.elec_stim.data,0 , app.channel_data.camera.data, 0, 0, 0};
                end
                app.display.plot_handle_BioPAC = plot(app.channel_data.time_recording, channels_signal{str2num(BioPAC_signal)}*5+8.5,'r','Parent',app.BioPAC_signal_display_axes);
                %app.display.plot_handle_BioPAC = plot(app.channel_data.time_recording, channels_signal{str2num(BioPAC_signal)}*1.5+8.5,'r','Parent',app.BioPAC_signal_display_axes);
                set(app.BioPAC_signal_display_axes,'Color','none')
                ylim(app.BioPAC_signal_display_axes, [0,10.2])
                xlim(app.BioPAC_signal_display_axes, [0,(app.processed_data.frame_range(2)-1)/app.data.fps - (app.processed_data.frame_range(1)-1)/app.data.fps])
                set(app.BioPAC_signal_display_axes,'YTickLabel',[])
                
            elseif app.SwitchBioPACShow_Hide.Value == 'Hide'
                app.display.BioPAC_signal_display = 0;
                set(app.display.plot_handle_BioPAC,'Visible','off');
                set(app.BioPAC_signal_display_axes,'Visible','off');
                if ~app.display.signal_display
                    if app.display.map == 1
                        set(app.main_display_axes,'Position',[1,app.figure_size(4)-floor(1.2*app.figure_size(4)/2)-40,0.95*app.figure_size(3),floor(1.2*app.figure_size(4)/2)]);
                    else
                        set(app.main_display_axes,'Position',[1,app.figure_size(4)-floor(1.2*app.figure_size(4)/2)-40,app.figure_size(3),floor(1.2*app.figure_size(4)/2)]);
                    end
                end
                
            end
        end

        % Button pushed function: ManualfileselectionandimportButton
        function ManualfileselectionandimportButtonPushed(app, event)
            [app.BioPAC_file , BioPAC_path] = uigetfile({'*.*'},'File selector');
            app.BioPAC_path_file = [BioPAC_path app.BioPAC_file];
            load_BioPAC(app);
            app.BioPACfileEditField.Value = [app.AutomaticSearchFolderEditField.Value app.BioPAC_file];
            
            set([app.ShowBioPACDropDown],'Enable','on');
            
            app.SwitchBioPACShow_Hide;
        end

        % Button pushed function: ChangesearchfolderButton
        function ChangesearchfolderButtonPushed(app, event)
            app.AutomaticSearchFolderEditField.Value = uigetdir();
            app.AutomaticSearchFolderEditField.Value = [app.AutomaticSearchFolderEditField.Value '/'];
        end

        % Menu selected function: QuickloadpartiallyMenu
        function QuickloadpartiallyMenuSelected(app, event)
            app.quick_load_partially = 1;
            app.quick_load(app);
            app.quick_load_partially = 0;
            
        end

        % Button pushed function: SaveBioPACButton
        function SaveBioPACButtonPushed(app, event)
            %Export the struct of the BioPAC data, including all channels and time (everything trimmed to the recording time according to camera exposure)
            BioPAC_struct = app.channel_data;
            file_name = file_exists(app,'BioPAC_signals','.mat');
            save(file_name,'-struct','BioPAC_struct')
        end

        % Button pushed function: SaveROIsButton
        function SaveROIsButtonPushed(app, event)
            %Export ROI signals including time [and save the image of the heart with the ROIs on it?]
            ROI_signals = app.processed_data.signals;
            file_name = file_exists(app,'ROI_signals','.mat');
            save(file_name,'ROI_signals')
            
            % Save displayed image with ROIs
            h = figure;
            h.Visible = 'off';
            ax = axes(h);
            for k = 1:length(app.display.ROI_handle)
                roi_handles{k} = copyobj(app.display.ROI_handle{k},ax);
            end
            set(roi_handles{1}, 'Visible', 'on')
            hold on
            
            current_frame = app.processed_data.image_stack{app.display.normalisation}(:,:,app.display.current_frame);
            current_frame_norm = current_frame./max(current_frame,[],'all');
            image_handle = imshow(current_frame_norm,'Parent',ax);
            
            min_cmap = min(current_frame_norm,[],'all');
            max_cmap = max(current_frame_norm,[],'all');
            image_handle.Parent.CLim = [min_cmap max_cmap*app.MaxColourmap.Value/100 ];
            image_handle.Parent.Colormap = app.display.colour_map;
            set(image_handle, 'Visible', 'on')

            picture_file = file_exists(app,sprintf('ROIs_frame-%d',app.display.current_frame),'.png');
            % Save displayed image
            exportgraphics(h,picture_file,'Resolution',300);
            delete(h)

            log_file_name = file_exists(app,sprintf('log_file_frame-%d',app.display.current_frame),'.dat');
            save_log_file(app,log_file_name);


        end

        % Button pushed function: ExportStacktoWorkspaceButton
        function ExportStacktoWorkspaceButtonPushed(app, event)
            
            stack = app.processed_data.image_stack{app.display.normalisation};
            assignin('base','stack',stack);

            map = app.processed_data.activation_map;
            assignin('base','map',map);
            
            CV_map = app.processed_data.CV_map;
            assignin('base','CV_map',CV_map);

            ROI_masks = app.display.ROI_mask;
            assignin('base','ROI_masks',ROI_masks);
        end

        % Button pushed function: DeleteallROIsButton
        function delete_all_ROI(app, event)
            app.display.ROI_handle = [];
            app.display.ROI_mask = [];
            app.processed_data.signals = [];
            app.display.plot_handle = [];
            
            % Clear axes
            cla(app.signal_display_axes);

            num_ROI = num2cell(1:length(app.display.ROI_handle));
            % Update ROI dropdown
            app.ROIdropdown.Items = cellfun(@(x) sprintf('ROI %d',x),num_ROI,'UniformOutput',false);
            app.ROIdropdown.ItemsData = num_ROI;
            
            app.SelectROIDropDown.Items = cellfun(@(x) sprintf('ROI %d',x),num_ROI,'UniformOutput',false);
            app.SelectROIDropDown.ItemsData = num_ROI;
            
            cla(app.main_display_axes);
            app.display.image_handle = imshow(app.processed_data.image_stack{app.display.normalisation}(:,:,app.display.current_frame)./max(app.processed_data.image_stack{app.display.normalisation}(1:app.processed_data.height*app.processed_data.width)),'Parent',app.main_display_axes);
            app.display.image_handle.Parent.CLim = [0,1];
            update_view_signal(app)

            % Add to log file
            app.history_field.Value{length(app.history_field.Value)+1,1} = 'All ROI deleted.';
        end

        % Value changed function: TemporalFilterType
        function TemporalFilterTypeValueChanged(app, event)
            value = app.TemporalFilterType.Value;
            
            switch str2double(app.TemporalFilterType.Value)
                case 1
                    app.filterorder.Value = 5;
                case 2
                    app.filterorder.Value = 50;
            end
           
        end

        % Button pushed function: ComputeAPDBCLanddVdtmaxButton
        function ComputeAPDBCLanddVdtmaxButtonPushed(app, event)
            fps = app.data.fps;
            APD_percentage = app.APDpercentageEditField.Value;
            
            % get ROI signal
            time = app.processed_data.signals{str2double(string(app.SelectROIDropDown.Value)), 1}';
            signal = app.processed_data.signals{str2double(string(app.SelectROIDropDown.Value)), app.display.normalisation+1};
            % normalise signal and find number of AP and at which times the peak is (approx)
            signal_norm = signal - min(signal);
            signal_norm = signal_norm./max(signal_norm);
            % Find AP peaks
            [~, times] = findpeaks(sgolayfilt(double(signal_norm),2,21),time, 'MinPeakProminence', 0.2);
            [~, indices_peaks] = findpeaks(sgolayfilt(double(signal_norm),2,21), 'MinPeakProminence', 0.2);
            % Compute first approximation of BCL based on the peaks
            bcl_list = diff(times);
            % add a last bcl value for the last AP if the last AP could not have an associated BCL
            if length(bcl_list) < length(times)
                bcl_list = [bcl_list', time(end) - times(end)];
            end
            app.ROI_BCL.Value = num2str(bcl_list);            
            
            %calculate dfdt
            dfdt = diff(signal); %/(1/fps);
            
            
            % normalise dfdt
            dfdt = dfdt-min(dfdt);
            dfdt = dfdt./max(dfdt);

            if app.DesiredAP100allEditField.Value ~= 100 
                peak1 = app.DesiredAP100allEditField.Value;
                peak100 = app.DesiredAP100allEditField.Value;
                APD = zeros(1);
                dVdtmax = zeros(1);
                resting_V = zeros(1);
            else %if 100 is the value of this field, there will be a loop to calculate all APD from all AP
                peak1 = 1;
                peak100 = length(times);
                APD = zeros(1,length(times));
                activation_time = zeros(1,length(times));
                repol_time = zeros(1, length(times));
                dVdtmax =  zeros(1, length(times));
                resting_V = zeros(1, length(times));

            end
            k = 1;
            for peak = peak1:peak100
                
                % Find RMP and APD
                %RMP is mean V of BCL/2 to 5ms before AP upstroke to when this value is crossed to BCL/2 (or end) #select wanted peak to analyze

                if length(times) == 1 % if only 1 peak take the whole window
                    init_index = 1;
                    end_index = length(time)-1;
                    bcl = time(end);
                else
                    bcl = bcl_list(peak); 

                    % Find interval of AP in indices
                    init_index = max([find(time >= (times(peak) - bcl/1.5), 1), 1]) ;
                    end_index = min([find(time >= (times(peak) + bcl/1.5), 1),length(time)-1]);
                end
                % Find upstroke index within the interval
                end_index_for_upstroke = min([find(time >= (times(peak) + bcl/2), 1),length(time)-1]);
                upstroke_index = init_index + find(dfdt(init_index:end_index_for_upstroke)>=max(dfdt(init_index:end_index_for_upstroke)),1)-1;
                %interpolation to find accurate activation time
                interp_points = 1000;
                delta = 10;
                
                lower_bound_interp = max([upstroke_index - delta,1]);
                
                upper_bound_interp = min([upstroke_index + delta,length(time)-1]);
                
                time_fine_grid = linspace(time(lower_bound_interp), time(upper_bound_interp), interp_points); %generate fine grid for interpolation in ms
                dfdt_interpolated = interp1(time(lower_bound_interp: upper_bound_interp), dfdt(lower_bound_interp: upper_bound_interp), time_fine_grid, 'spline'); %interpolate around the previously found start index
                [dfdtmax,idx_max_interpolated] = max(dfdt_interpolated); % find new dfdt max
                activation_time(peak) = 1000*time_fine_grid(idx_max_interpolated); %transofrm to ms
                
                % compute RMP from before and after upstroke
                init_RMP = mean(signal(init_index:(upstroke_index-ceil(0.005*fps))),'omitnan');
                cross_index = min([upstroke_index + find(signal(upstroke_index:end_index)<=init_RMP,1) - 1, end_index]);
                end_RMP = mean(signal(cross_index:end_index),'omitnan');
                
                % compute total RMP
                switch str2double(app.rmpmethod_ROI.Value)
                    case 1 %use mean of previous and after AP
                        resting_V(peak) = mean([init_RMP, end_RMP]);
                    case 2 %use minimum value pre upstroke
                        resting_V(peak) = min(signal(init_index:upstroke_index));
                    case 3 % use minimum value after AP
                        next_peak_index = min([find(time >= (times(peak) + bcl), 1),length(time)-1]);
                        resting_V(peak) = min(signal(upstroke_index: next_peak_index));
                    case 4 % take the average of min before upstroke and min after AP
                        pre_min =  min(signal(init_index:upstroke_index));
                        next_peak_index = min([find(time >= (times(peak) + bcl), 1),length(time)-1]);
                        post_min = min(signal(upstroke_index: next_peak_index));
                        resting_V(peak) = mean([pre_min, post_min]);
                end
                
                %overwrite V_max to just take into account the V_max within the given time window and compute amplitude
                V_max = max(signal(init_index:end_index));
                amp_V = ((100-APD_percentage)/100).*(V_max-resting_V(peak))+resting_V(peak);
                %Find index where the AP has recovered the given percentage (or if it didnt, take the last index)
                %repol_index = upstroke_index + min([find(signal(upstroke_index+1:end_index)<=amp_V,1,'first'),length(signal(upstroke_index:end_index))]);
                repol_index = indices_peaks(peak) + min([find(signal(indices_peaks(peak)+1:end_index)<=amp_V,1,'first'),length(signal(indices_peaks(peak):end_index))]);
                pre_repol_index = repol_index - 1;
                time_fine_grid = linspace(time(pre_repol_index), time(repol_index), interp_points); %generate fine grid for interpolation in ms
                Vm_interpolated = interp1(time(pre_repol_index: repol_index), signal(pre_repol_index: repol_index), time_fine_grid, 'linear');
                repol_index_interpolated =  min([ find(Vm_interpolated<=amp_V,1,'first'), length(time_fine_grid)]);
                
                repol_time(peak) = time_fine_grid(repol_index_interpolated);
                APD(k) = 1000*repol_time(peak) - activation_time(peak);
                dVdtmax(k) = dfdtmax/fps;
                k = k +1;
            end


            %Write output APD, dVdt and BCL
            app.ROI_APD.Value = num2str(APD);
            app.ROI_dVdt.Value = num2str(dVdtmax);
            app.ROI_Actt.Value = num2str(activation_time);
            if app.DesiredAP100allEditField.Value == 100 
                bcl_list = [diff(activation_time), time(end)*1000 - times(end)*1000];
                app.ROI_BCL.Value = num2str(bcl_list);
            else
                app.ROI_BCL.Value = num2str(bcl_list(peak)*1000);
            end
            % Plot AP and APD limits on the APDAxes
            APDAxes_handle = plot(app.APDAxes, time*1000, signal);
            for peak=peak1:peak100
                xline(app.APDAxes, activation_time(peak),'g--');
                xline(app.APDAxes, repol_time(peak)*1000, 'r--');
                %yline(app.APDAxes, resting_V(peak),'b:');
                onethird = (repol_time(peak)*1000 - activation_time(peak))/3;
                line(app.APDAxes, [activation_time(peak)-onethird, repol_time(peak)*1000+onethird], [resting_V(peak), resting_V(peak)], 'Color','b', 'LineStyle', ':');
            end
            
            if app.DesiredAP100allEditField.Value ~= 100 
                xlim(app.APDAxes,[time(init_index)*1000,time(end_index)*1000]);
            else
                xlim(app.APDAxes,[time(1)*1000,time(end)*1000]);
            end
            disp(['ROI', num2str(app.SelectROIDropDown.Value)])
            disp(['BCL ', num2str(mean(1000./bcl_list)), ' Hz (NOT NECESSARILY EQUAL TO PACING FREQUENCY'])
            disp(['APD', num2str(APD_percentage)])
            disp(APD')

        end

        % Button pushed function: DeletereferencenormButton
        function DeletereferencenormButtonPushed(app, event)
            app.reference_stack = [];
            app.display.renorm(5) = 1;
            app.history_field.Value{length(app.history_field.Value)+1,1} = 'Reference normalisation deleted';
        end

        % Button pushed function: ChangeFolderButtonSaveto
        function ChangeFolderButtonSavetoPushed(app, event)
            app.SavetoEditField.Value = uigetdir();
            app.SavetoEditField.Value = [app.SavetoEditField.Value '/'];
        end

        % Menu selected function: ConfirmExtractBackgroundMenu
        function ConfirmExtractBackgroundMenuSelected(app, event)
            uiresume(app.opticalmappingUIFigure)
            set(app.ConfirmExtractBackgroundMenu,'visible','off')
        end

        % Value changed function: SavetoEditField
        function SavetoEditFieldValueChanged(app, event)
value = app.SavetoEditField.Value;

        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            web('https://www.iekm.uniklinik-freiburg.de/gitlab/IEKM/optical-mapping-analysis/-/wikis/home')
        end

        % Button pushed function: SaveMovieImageadvancedButton
        function SaveMovieImageadvancedButtonPushed(app, event)
            app.save_movie_app = timestamp_save_movie(app);
            uiwait(app.save_movie_app.timestamp_save_movieUIFigure)
        end

        % Button pushed function: rotate_clockwise
        function rotate_clockwisePushed(app, event)
            app.rotation_angle = -90;
            rotate_stack_and_update(app)
            
        end

        % Button pushed function: rotate_counterclockwise
        function rotate_counterclockwisePushed(app, event)
            app.rotation_angle = +90;
            rotate_stack_and_update(app)
        end

        % Button pushed function: MirrorButton
        function MirrorButtonPushed(app, event)
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Mirroring image','Indeterminate','on');
            
            % Store data for undo
            app.processed_data.previous_stack = app.processed_data.image_stack{1};
            app.history.old = app.history.current;
            
            %Rotate all available stacks stack
            len_norm = length(app.processed_data.image_stack);
            for k=1:len_norm
                if ~isempty(app.processed_data.image_stack{k})
                    app.processed_data.image_stack{k} = fliplr(app.processed_data.image_stack{k});
                end
            end
            
            
            %Update image handle
            cla(app.main_display_axes);
            app.display.image_handle = imshow(app.processed_data.image_stack{app.display.normalisation}(:,:,app.display.current_frame)./max(app.processed_data.image_stack{app.display.normalisation}(1:app.processed_data.height*app.processed_data.width)),'Parent',app.main_display_axes);
            
            
            %Update ROI location


            %Update view and ROI signals
            update_view_signal(app)
            
            close(progress_bar)
            
            %save to history
            app.history_field.Value{length(app.history_field.Value)+1,1} = 'Mirrored';
            app.history.current = [app.history.current, sprintf('Image mirrored')];
        end

        % Value changed function: CropButton
        function CropButtonValueChanged(app, event)
            % 1. Change button to pressed while cropping region is being drawn
            app.CropButton.Value = 1; 
            set([app.AcceptCrop,app.CancelCrop],'Visible','on');
            set([app.TabGroup,app.FileMenu, app.SettingsMenu], 'Visible','off')
            
            % 2. Draw rectangle allowing interaction
            app.crop_rectangle = drawrectangle('Parent',app.main_display_axes, 'InteractionsAllowed', 'All');


        end

        % Menu selected function: CancelCrop
        function CancelCropMenuSelected(app, event)
            app.CropButton.Value = 0; 
            set([app.AcceptCrop,app.CancelCrop],'Visible','off');
            set([app.TabGroup,app.FileMenu, app.SettingsMenu], 'Visible','on')
            delete(app.crop_rectangle);
        end

        % Menu selected function: AcceptCrop
        function AcceptCropMenuSelected(app, event)
            app.CropButton.Value = 0; 
            set([app.AcceptCrop,app.CancelCrop],'Visible','off');
            set([app.TabGroup,app.FileMenu, app.SettingsMenu], 'Visible','on')
            
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Cropping image','Indeterminate','on');
            
            % Store data for undo
            app.processed_data.previous_stack = app.processed_data.image_stack{1};
            app.history.old = app.history.current;
            
            %For all normalisation views
            len_norm = length(app.processed_data.image_stack);
            x = floor(app.crop_rectangle.Position(1));
            y = floor(app.crop_rectangle.Position(2));
            dx = floor(app.crop_rectangle.Position(3));
            dy = floor(app.crop_rectangle.Position(4));
            
            if x < 1
                x=1;
            end
            if x+dx > app.processed_data.width
                dx = app.processed_data.width - x-1;
            end    
            if y < 1
                y = 1;
            end    
            if y+dy > app.processed_data.height
                dy = app.processed_data.height- y-1;
            end


            for k=1:len_norm
                if ~isempty(app.processed_data.image_stack{k})
                    app.processed_data.image_stack{k} = app.processed_data.image_stack{k}(y:y+dy, x:x+dx,:);
                end
            end


            delete(app.crop_rectangle);
            
            %update tooltip
            app.processed_data.height = size(app.processed_data.image_stack{1},1);
            app.processed_data.width =  size(app.processed_data.image_stack{1},2);
            tool_tip_update(app)
            
            %Update image handle
            cla(app.main_display_axes);
            app.display.image_handle = imshow(app.processed_data.image_stack{app.display.normalisation}(:,:,app.display.current_frame)./max(app.processed_data.image_stack{app.display.normalisation}(1:app.processed_data.height*app.processed_data.width)),'Parent',app.main_display_axes);
            
            
            %Update ROI location


            %Update view and ROI signals
            update_view_signal(app)
            close(progress_bar)
            
            %save to history
            app.history_field.Value{length(app.history_field.Value)+1,1} = 'Cropped';
            app.history.current = [app.history.current, sprintf('Image cropped')];
        end

        % Menu selected function: APDrestitutionanalysisMenu
        function APDrestitutionanalysisMenuSelected(app, event)
            APD_restitution()
        end

        % Button pushed function: binning
        function bin_image(app, event)
            % Bin image using imresize - increased res but doesn't use
            % summing
            app.processed_data.previous_stack = app.processed_data.image_stack{1};
            app.processed_data.previous_signals = app.processed_data.signals;
            
            % Store a copy of unnormalised image stack then clear stacks
            % and bin new stack
            temp_stack = app.processed_data.image_stack{1};
            app.processed_data.image_stack = {};
            

            %Store ROI data
            if ~isempty(app.display.ROI_handle)
                roiPositions = cellfun(@(r) r.Position, app.display.ROI_handle, 'UniformOutput', false);
                roiTypes = cellfun(@(r) class(r), app.display.ROI_handle, 'UniformOutput', false);
            end

            for k = 1:app.processed_data.n_frames
                app.processed_data.image_stack{1}(:,:,k) = imresize(temp_stack(:,:,k),1/app.BinSpinner.Value,'bilinear');
            end
            if isempty(app.processed_data.binning)
                app.processed_data.binning = 1;
            end

            % Update sizes
            app.processed_data.width = size(app.processed_data.image_stack{1},2);
            app.processed_data.height = size(app.processed_data.image_stack{1},1);
            app.processed_data.binning = app.processed_data.binning*app.BinSpinner.Value;
            
            % Clear signals and set that all normalisations still need to be
            % done
            num_sig = size(app.processed_data.signals,1);
            app.processed_data.signals = [];
            app.display.renorm = ones(size(app.display.renorm));
            app.processed_data.n_frames = size(app.processed_data.image_stack{1},3);    
            
            % Delete signals
            app.display.ROI_mask = [];
            app.processed_data.signals = [];
            app.display.plot_handle = [];
            
            % Clear axes
            cla(app.signal_display_axes);

            % Clear axes
            cla(app.main_display_axes);
            app.display.image_handle = imshow(app.processed_data.image_stack{1}(:,:,app.display.current_frame)./max(app.processed_data.image_stack{1}(1:app.processed_data.height*app.processed_data.width)),'Parent',app.main_display_axes);
            app.display.image_handle.Parent.CLim = [0,1];
            
            % Display averaged data
            switch app.display.normalisation
                case 1
                    app.change_normalisation_none(app);
                case 2
                    app.change_normalisation_global(app);
                case 3
                    app.change_normalisation_local(app);
                case 4
                    app.change_normalisation_global_mean(app);
                case 5
                    app.change_normalisation_timeaverage(app);
            end
            
            if exist('roiPositions','var')
                % Restore signals
                % Resize ROI
                app.display.ROI_handle = cellfun(@(type, pos,num) app.createROI(type, pos / app.BinSpinner.Value, num), roiTypes, roiPositions, num2cell(1:length(roiTypes)), 'UniformOutput', false);
                app.display.ROI_mask = cellfun(@(roi_handle) createMask(roi_handle,app.processed_data.height,app.processed_data.width),app.display.ROI_handle,'UniformOutput', false);

                % Create signals
                for k = 1:length(app.display.ROI_handle)
                    apply_signal_masks(app, k);
                end

                % Plot signal on axes
                if ~app.display.signal_display
                    set(app.main_display_axes,'Position',[1,app.figure_size(4)-floor(1.2*app.figure_size(4)/2)-40,0.7*app.figure_size(3),floor(1.2*app.figure_size(4)/2)]);
                    if isempty(app.signal_display_axes)
                        app.signal_display_axes = axes(app.opticalmappingUIFigure, 'Units','Pixels','Position',[0.74*app.figure_size(3),app.figure_size(4)-floor(1.1*app.figure_size(4)/2)-40,0.25*app.figure_size(3),floor(1.0*app.figure_size(4)/2)]);
                        hold(app.signal_display_axes, 'on');
                        xlabel('Time (s)','Parent',app.signal_display_axes);
                        ylabel('Signal (a.u.)','Parent',app.signal_display_axes);
                    end
                    set(app.signal_display_axes,'Visible','on');
                    app.display.signal_display = 1;
                end


                %Plot signals
                app.display.plot_handle = cellfun(@(x,y) plot(x,y,'Parent',app.signal_display_axes),app.processed_data.signals(:,1),app.processed_data.signals(:,app.display.normalisation+1));
                set(app.averagebeats,'Enable','on')
                set(app.SELECTROIFIRSTLabel,'Visible','off')
                set(app.showsignals,'Enable', 'on')
                set(app.hidesignal,'Enable', 'on')
                set(app.saveregions,'Enable','on')
                figure(app.opticalmappingUIFigure);
            end

            app.tool_tip_update;
            app.history_field.Value{length(app.history_field.Value)+1,1} = [int2str(app.BinSpinner.Value), 'x', int2str(app.BinSpinner.Value), ' binning.'];
        end

        % Button pushed function: FittracesButton
        function fit_traces(app, event)
            % Fit traces to the data based on the approach from
            % https://www.ncbi.nlm.nih.gov/pmc/articles/PMC4551126/. Note
            % this either needs good signal quality, averaging, or spatial
            % and temporal filtering prior

            % Progress bar
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Fitting traces');

            % Find number of beats based on recorded signal for assessing
            % pixel signal quality
            signal_pixnorm = app.processed_data.signals{1,app.display.normalisation+1};
            time_vector = app.processed_data.signals{1,1};
            dfdt = diff(signal_pixnorm)./time_vector(2);
            [~, beat_locations] = findpeaks(dfdt,'MinPeakHeight',0.5*(max(dfdt)-mean(dfdt)),'MinPeakDistance',(0.05*1/time_vector(2)));
            signal_n_beats = length(beat_locations);
        
            % If don't have pixel-by-pixel normalisation than apply it
            if app.local_normalisation_computed ~= 1
                app.processed_data.image_stack{3} = app.processed_data.image_stack{1}-min(app.processed_data.image_stack{1},[],3);
                app.processed_data.image_stack{3} = app.processed_data.image_stack{3}./max(app.processed_data.image_stack{3},[],3);
                app.processed_data.image_stack{3}(isnan(app.processed_data.image_stack{3})) = 0;
            end

            %% Define thresholds
            dfdt_threshold = app.data.fps*app.dfdtthresholdframerate.Value/100; % Think this is versatile but not certain yet
            signal_threshold = app.signalthreshold.Value; % threshold in signal change, more than 1%?

            %% Initialise stacks
            app.processed_data.image_stack{8} = nan(size(app.processed_data.image_stack{1}));
            app.advanced_data.fit = nan(app.processed_data.height,app.processed_data.width,signal_n_beats);
            app.advanced_data.f_baseline =  app.advanced_data.fit;
            app.advanced_data.f_max =  app.advanced_data.fit;
            app.advanced_data.upstroke_start_ind =  app.advanced_data.fit;
            app.advanced_data.upstroke_end_ind =  app.advanced_data.fit;
            app.advanced_data.repol_end_ind =  app.advanced_data.fit;
            app.advanced_data.upstroke_fit = cell(app.processed_data.height,app.processed_data.width,signal_n_beats);
            app.advanced_data.repol_fit = cell(app.processed_data.height,app.processed_data.width,signal_n_beats);

 
            %% Find AP upstroke for each pixel 
            for k = 1:size(app.processed_data.image_stack{3},1)
                progress_bar.Value = k/size(app.processed_data.image_stack{3},1);
                for l = 1:size(app.processed_data.image_stack{3},2)
                    signal_pixnorm = squeeze(app.processed_data.image_stack{3}(k,l,:));
                    signal = squeeze(app.processed_data.image_stack{1}(k,l,:));
                    dfdt = diff(signal_pixnorm)/time_vector(2);
                    dfdt = movmean(dfdt,round(app.data.fps/25)); % Helps to remove noise and focus on upstroke as 25 as roughly the duration of upstroke
                    possible_locs = find(dfdt > dfdt_threshold); % Find areas with change over threshold
                    possible_locs = sort([possible_locs; 1+possible_locs(diff(possible_locs) == 2)]); % Expand areas if only one point not above threshold

                    % Don't do further processing if no possible locations
                    if ~isempty(possible_locs)
                        break_points = [0; find(diff(possible_locs)~=1);length(possible_locs)]; %find breaks between groupings
                        upstrokes = mat2cell(possible_locs,diff(break_points),1); % form cell array with one cell for each upstroke

                        % Remove noise by discarding areas not above signal
                        % threshold
                        remove_locs = [];
                        for m = 1:length(upstrokes)
                            % Expand signal_subset due to the movmean filter
                            signal_subset = signal(max(1,upstrokes{m}(1)-round(app.data.fps/25)):min(upstrokes{m}(end)+1+round(app.data.fps/25),length(signal)));
                            if (100*range(signal_subset)/min(signal_subset)) < signal_threshold
                                remove_locs = [remove_locs, m];
                            end
                        end
                        upstrokes(remove_locs) = [];

                        % Additional processing if number of beats matches
                        % those from ROI
                        if (length(upstrokes) == signal_n_beats) & ((signal_n_beats-1)<=length(find(diff(cell2mat(upstrokes))>(0.8*size(app.processed_data.image_stack{1},3)/(signal_n_beats+1))))) & (upstrokes{end}(1)<(length(signal)-25))
                            % Raw signal
                            raw_signal = squeeze(app.processed_data.image_stack{1}(k,l,:));

                            for m = 1:signal_n_beats
                                if m ~= signal_n_beats
                                    signal_subset = raw_signal(upstrokes{m}(end):upstrokes{m+1}(1));
                                else
                                    signal_subset = raw_signal(upstrokes{m}(end):end);
                                end

                                % Histogram of signal subset
                                if strcmp(app.Baselinecalculation.Value,'Histogram')
                                    [bin_num, bin_edge] = discretize(signal_subset,20);
                                    histogram_peak = floor(median(bin_num));
                                    start_hist = max(1,histogram_peak-4); % 4 bins to the left of the peak
                                    end_hist = min(length(bin_edge),histogram_peak+5); % +5 instead of 4 to get end of bin value
                                    pd = fitdist(signal_subset((bin_edge(start_hist)<=signal_subset) & (signal_subset <=bin_edge(end_hist))),'Normal');
                                    f_baseline = pd.mu;
                                elseif strcmp(app.Baselinecalculation.Value, 'Prior')
                                    f_baseline = signal_subset(1);
                                elseif strcmp(app.Baselinecalculation.Value, 'Prior/Post')
                                    f_baseline = mean([signal_subset(1),signal_subset(end)]);
                                end
                                f_max = prctile(signal_subset,app.Signalmaxpercentile.Value);
                                
                                % Check percentage swing
                                if ((f_max-f_baseline)/f_max) > 0.003 %Great than 0.3% than continue
                                    % Fit upstroke
                                    upstroke_fit = polyfit(time_vector(max([1,upstrokes{m}(1)-2]):upstrokes{m}(end)+2),raw_signal(max([1,upstrokes{m}(1)-2]):upstrokes{m}(end)+2),1);

                                    % Find intersection with baseline and
                                    % max value
                                    upstroke_baseline_ind = find(polyval(upstroke_fit,time_vector)>=f_baseline,1,'first');
                                    upstroke_max_ind = find(polyval(upstroke_fit,time_vector)>=f_max,1,'first');

                                    % Redo signal subset using upstroke
                                    % max index
                                    if m ~= signal_n_beats
                                        signal_subset = raw_signal(upstroke_max_ind:upstrokes{m+1}(1));
                                    else
                                        signal_subset = raw_signal(upstroke_max_ind:end);
                                    end

                                    % Fit repolarisation
                                    repol_end_ind = find(signal_subset(1:end-1)<=(f_baseline+0.05*(f_max-f_baseline))&signal_subset(2:end)<=(f_baseline+0.05*(f_max-f_baseline)),1,'first'); %Find first 2 consecutive values below threshold

                                    % fit at least 10 points for safety
                                    repol_end_ind = min([max([repol_end_ind,10],[],'omitnan'),length(signal_subset)]);

                                    repol_fit = polyfit(time_vector(upstroke_max_ind:(upstroke_max_ind+repol_end_ind-1)),signal_subset(1:repol_end_ind),4);

                                    repol_end_ind = upstroke_max_ind-1+find(polyval(repol_fit,time_vector(upstroke_max_ind:end))<=f_baseline,1,'first');
                                    % In case repol_fit increases again,
                                    % check that it has a value, if not use
                                    % old value
                                    if isempty(repol_end_ind)
                                        repol_end_ind = upstroke_max_ind-1 + find(signal_subset(1:end-1)<=(f_baseline+0.05*(f_max-f_baseline))&signal_subset(2:end)<=(f_baseline+0.05*(f_max-f_baseline)),1,'first'); %Find first 2 consecutive values below threshold
                                    end

                                    % Assigning values to processed data
                                    app.advanced_data.fit(k,l,m) = 1;
                                    app.advanced_data.f_baseline(k,l,m) = f_baseline;
                                    app.advanced_data.f_max(k,l,m) = f_max;
                                    app.advanced_data.upstroke_start_ind(k,l,m) = upstroke_baseline_ind;
                                    app.advanced_data.upstroke_end_ind(k,l,m) = upstroke_max_ind;
                                    app.advanced_data.repol_end_ind(k,l,m) = repol_end_ind;
                                    app.advanced_data.upstroke_fit{k,l,m} = upstroke_fit;
                                    app.advanced_data.repol_fit{k,l,m} = repol_fit;

                                    %% Generate new signal
                                    if m == 1
                                        app.processed_data.image_stack{8}(k,l,1:upstroke_baseline_ind) = polyval(upstroke_fit,time_vector(upstroke_baseline_ind)); % Baseline pre
                                    
                                    elseif m == signal_n_beats
                                        last_repol_ind = app.advanced_data.repol_end_ind(k,l,m-1);
                                        fit_between = polyfit([time_vector(last_repol_ind), time_vector(upstroke_baseline_ind)],[polyval(app.advanced_data.repol_fit{k,l,m-1},time_vector(last_repol_ind)), polyval(upstroke_fit,time_vector(upstroke_baseline_ind))],1);
                                        app.processed_data.image_stack{8}(k,l,last_repol_ind+1:upstroke_baseline_ind) = polyval(fit_between,time_vector(last_repol_ind+1:upstroke_baseline_ind)); % Baseline between
                                        app.processed_data.image_stack{8}(k,l,repol_end_ind:end) = polyval(repol_fit,time_vector(repol_end_ind)); % Baseline after

                                    else
                                        last_repol_ind = app.advanced_data.repol_end_ind(k,l,m-1);
                                        fit_between = polyfit([time_vector(last_repol_ind), time_vector(upstroke_baseline_ind)],[polyval(app.advanced_data.repol_fit{k,l,m-1},time_vector(last_repol_ind)), polyval(upstroke_fit,time_vector(upstroke_baseline_ind))],1);
                                        app.processed_data.image_stack{8}(k,l,last_repol_ind+1:upstroke_baseline_ind) = polyval(fit_between,time_vector(last_repol_ind+1:upstroke_baseline_ind)); % Baseline between

                                    end
                                    app.processed_data.image_stack{8}(k,l,upstroke_baseline_ind+1:upstroke_max_ind) = polyval(upstroke_fit,time_vector(upstroke_baseline_ind+1:upstroke_max_ind)); % Upstroke
                                    app.processed_data.image_stack{8}(k,l,upstroke_max_ind+1:repol_end_ind) = polyval(repol_fit,time_vector(upstroke_max_ind+1:repol_end_ind)); % Plateau/decay
                                end
                            end
                        end
                    end
                end
            end
            app.display.normalisation = 8;

            for k = 1:size(app.processed_data.signals,1)
                app.processed_data.signals{k,app.display.normalisation+1} = time_signal(app,app.display.normalisation,k);
            end
            change_normalisation_none_traces(app);
            close(progress_bar)
        end

        % Value changed function: nonormalise_traces
        function change_normalisation_none_traces(app, event)
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Changing to no normalisation - traces','Indeterminate','on');
            app.display.normalisation = 8;
            app.nonormalise.Value = 0;
            app.nonormalise_traces.Value = 1;
            app.globalnormalise.Value = 0;
            app.localnormalise.Value = 0;
            app.globalmeannormalise.Value = 0;
            app.timeaverage.Value = 0;
            app.localnormalise_traces.Value = 0;
            app.ShowHideWavefrontButton.Value = 0;
            app.ReferencenormalisationButton.Value = 0;
            
            update_view_signal(app)
            close(progress_bar)
            app.history_field.Value{length(app.history_field.Value)+1,1} = 'Changed to no normalisation - traces';
        end

        % Value changed function: localnormalise_traces
        function change_normalisation_local_traces(app, event)
            app.display.normalisation = 9;
            app.globalnormalise.Value = 0;
            app.nonormalise.Value = 0;
            app.globalmeannormalise.Value = 0;
            app.timeaverage.Value = 0;
            app.nonormalise_traces.Value = 0;
            app.ShowHideWavefrontButton.Value = 0;
            app.ReferencenormalisationButton.Value = 0;
            
            app.local_normalisation_traces_computed = 1;
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Normalising','Indeterminate','on');
            if app.display.renorm(6)
                app.processed_data.image_stack{app.display.normalisation} = app.processed_data.image_stack{8}-min(app.processed_data.image_stack{8},[],3);
                app.processed_data.image_stack{app.display.normalisation} = app.processed_data.image_stack{app.display.normalisation}./max(app.processed_data.image_stack{app.display.normalisation},[],3);
                app.processed_data.image_stack{app.display.normalisation}(isnan(app.processed_data.image_stack{app.display.normalisation})) = 0;
                app.display.renorm(6) = 0;
                
                %Redo signals
                if ~isempty(app.processed_data.signals)
                    for k = 1:size(app.processed_data.signals,1)
                        app.processed_data.signals{k,app.display.normalisation+1} = time_signal(app,app.display.normalisation,k);
                    end
                end
                
            end
            update_view_signal(app)
            close(progress_bar)
            app.history_field.Value{length(app.history_field.Value)+1,1} = 'Changed to local normalisation - traces';
        end

        % Value changed function: activationmap_traces
        function generate_activationmap_traces(app, event)
            app.nonormalise_traces.Value = 0;
            app.localnormalise_traces.Value = 0;

            activation_times_trace = app.advanced_data.upstroke_start_ind;
            activation_times_trace = activation_times_trace-min(activation_times_trace,[],[1,2],'omitnan');
            activation_times_trace = mean(activation_times_trace,3,'omitnan');
            activation_times_trace = 1000*activation_times_trace./app.data.fps;
            
            app.processed_data.activation_map = activation_times_trace;
            %save for map filtering
            max_value = max(activation_times_trace(:),[],'omitnan');
            app.max_value_ACT = max_value;
            colourbar_string = 'Activation time (ms)';

            if ~isempty(app.display.quiver_handle)
                delete(app.display.quiver_handle)
            end
            set(app.display.image_handle,'CData',app.processed_data.activation_map);
            app.display.image_handle.Parent.CLim = [0,max_value];
            % Set first value to black in colour map so that NaN are black.
            app.display.image_handle.Parent.Colormap = [0 0 0; jet(256)];
            if isvalid(app.display.colourbar_handle)
                colorbar(app.display.colourbar_handle,'off')
            end
            app.display.colourbar_handle = colorbar(app.display.image_handle.Parent);
            app.display.colourbar_handle.Label.String = colourbar_string;
            app.display.colourbar_handle.Label.FontSize = app.display.colourbar_handle.Label.FontSize+2;
            app.display.map = 1;
            app.upperylimit_traces.Value = app.display.colourbar_handle.Limits(2);
            app.lowerylimit_traces.Value = app.display.colourbar_handle.Limits(1);

            update_histogram_map(app)
        end

        % Value changed function: lowerylimit_traces, upperylimit_traces
        function change_map_limits_traces(app, event)
            ymax = app.upperylimit_traces.Value;
            ymin = app.lowerylimit_traces.Value;
            
            caxis(app.display.image_handle.Parent,[ymin,ymax]);
        end

        % Button pushed function: newzeromap_traces
        function new_zero_map_traces(app, event)
            new_zero = inputdlg('Enter new zero value:','Zero input',[1 35],{'0'});
            
            app.processed_data.activation_map = app.processed_data.activation_map - str2double(new_zero(1));
            start_common = mode(app.processed_data.activation_map(:));
            % Add 60 ms to mode assuming all conduction should be done
            % by the time most of the tissue excited
            max_value = min([max(app.processed_data.activation_map(:)) start_common+60]);
            set(app.display.image_handle,'CData',app.processed_data.activation_map);
            app.display.image_handle.Parent.CLim = [0,max_value];
            caxis(app.display.image_handle.Parent,[0,max_value]);
            app.upperylimit_traces.Value = app.display.colourbar_handle.Limits(2);
            app.lowerylimit_traces.Value = app.display.colourbar_handle.Limits(1);
            
            update_histogram_map(app)
        end

        % Button pushed function: MeasurelengthButton
        function measure_scale(app, event)
            set([app.FileMenu],'Enable','off');
            set([app.SettingsMenu],'Enable','off');
            set(app.LengthChosenMenu,'visible','on')

            h1 = drawline('Parent',app.main_display_axes);

            uiwait(app.opticalmappingUIFigure)
            
            length_mm = inputdlg('Enter length in mm:','Scale measurement');
            length_mm = str2num(length_mm{1});

            length_pixels = sqrt((h1.Position(2,1)-h1.Position(1,1))^2+(h1.Position(2,2)-h1.Position(1,2))^2)

            app.processed_data.scale = length_mm/length_pixels;

            message = sprintf('Scale: %.3f μm/pixel',1000*app.processed_data.scale);
            uialert(app.opticalmappingUIFigure,message,'Pixel Scale','Icon','')

            set([app.FileMenu],'Enable','on');
            set([app.SettingsMenu],'Enable','on');
            set([app.QuickloadMenu],'Enable','on');
            set([app.QuickloadpartiallyMenu],'Enable','on');

            % Add to log file 
            app.history_field.Value{length(app.history_field.Value)+1,1} = ['Scale: ', num2str(app.processed_data.scale,3) ,' μm/pixel.'];
        end

        % Menu selected function: LengthChosenMenu
        function LengthChosenMenuSelected(app, event)
            uiresume(app.opticalmappingUIFigure)
            set(app.LengthChosenMenu,'visible','off')
        end

        % Button pushed function: InputScaleButton
        function input_scale(app, event)
            scale = inputdlg('Enter scale in μm/pixel:','Scale');
            app.processed_data.scale = str2num(scale{1});

            % Add to log file
            app.history_field.Value{length(app.history_field.Value)+1,1} = ['Scale: ', scale{1},' μm/pixel.'];
        end

        % Button pushed function: CV_map
        function generate_CV_map(app, event)
            % Add an option to do from traces at some point?

            selection = uiconfirm(app.opticalmappingUIFigure,'Have you already generated the activation map?','Confirm CV map generation','Options',{'Yes','No'},'DefaultOption',1,'CancelOption', 2);
            
            app.processed_data.CV_map = nan(app.processed_data.height,app.processed_data.width,3); % x velocity, y velocity, total velocity
            switch selection
                case 'No'
                    return;
                case 'Yes'
                    progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Finding conduction velocity');
                    activation_map = app.processed_data.activation_map;

                    % Set sub-zero activation times to NaN and those
                    % greater than the max of the display
                    activation_map(activation_map<0) = nan;
                    activation_map(activation_map>app.display.image_handle.Parent.CLim(2)) = nan;

                    [row,col] = find(~isnan(activation_map));
                    % Use Barly method "Estimation of conduction velocity
                    % vector fields from epicardial mapping data"
                    if strcmp('Bayly Method',app.CV_method.Value)
                        dxy = 1000*app.distancemax.Value/app.processed_data.scale;
                        dt = app.timemax.Value;
                        % Loop over all activation map points
                        for k = 1:length(row)
                            % Find other active points within wavefront, first
                            % find those within dx of
                            progress_bar.Value = k/length(row);
                            wavefront = find(abs(row-row(k))<dxy & abs(col-col(k))<dxy);
                            activation_times = activation_map(sub2ind(size(activation_map),row(wavefront),col(wavefront)));
                            % Find those within dt
                            keep_indices = find(abs(activation_times - activation_map(row(k),col(k)))<dt);
                            wavefront = wavefront(keep_indices);
                            activation_times = activation_times(keep_indices);

                            if length(activation_times) >= 6
                                % Fit polynomial surface to wavefront T(x,y) = ax^2
                                % + by^2 + cxy + dx + ey + f
                                coefficients = [row(wavefront).^2, col(wavefront).^2, row(wavefront).*col(wavefront),row(wavefront),col(wavefront),ones(size(wavefront))];
                                fit_params = (coefficients'*coefficients)\(coefficients'*activation_times);

                                % Gradient vector components
                                Tx = 2*fit_params(1)*row(k) + fit_params(3)*col(k) + fit_params(4);
                                Ty = 2*fit_params(2)*col(k) + fit_params(3)*row(k) + fit_params(5);

                                % Conversion from gradient vector to velocity components in pixels/ms
                                app.processed_data.CV_map(row(k),col(k),1) = Tx/(Tx^2+Ty^2);
                                app.processed_data.CV_map(row(k),col(k),2) = Ty/(Tx^2+Ty^2);
                            end
                        end
                        % Find absolute CV
                        app.processed_data.CV_map(:,:,3) = sqrt(app.processed_data.CV_map(:,:,1).^2 + app.processed_data.CV_map(:,:,2).^2);

                        % Convert to mm/ms
                        app.processed_data.CV_map = app.processed_data.CV_map*app.processed_data.scale/1000;

                        % Remove outliers
                        [outlier_row, outlier_col] = find(app.processed_data.CV_map(:,:,3) > (mean(app.processed_data.CV_map(:,:,3),'all','omitnan') + 3*std(app.processed_data.CV_map(:,:,3),[],'all','omitnan')));
                        app.processed_data.CV_map(sub2ind(size(app.processed_data.CV_map),repmat(outlier_row,3,1),repmat(outlier_col,3,1),[ones(size(outlier_row));2*ones(size(outlier_row));3*ones(size(outlier_row))])) = NaN;

                        % Add to log
                        app.history_field.Value{length(app.history_field.Value)+1,1} = ['CV map generated. Method: Bayly Method. Max distance: ',num2str(app.distancemax.Value,3),' mm. Max time: ',num2str(app.timemax.Value,3),' ms.'];

                    elseif strcmp('Nearest Neighbour',app.CV_method.Value)
                        % 3 x 3 seeds for x and y CV weighting
                        weighting = struct('x',[-1,0,1;-1,0,1;-1,0,1],'y',[-1,-1,-1;0,0,0;1,1,1]);

                        % Remove indices at edge of image
                        map_size = size(activation_map);
                        del_ind = find(row == 1 | row == map_size(1) | col == 1 | col == map_size(2));
                        row(del_ind) = [];
                        col(del_ind) = [];

                        % Loops over all activation map points
                        for k = 1:length(row)
                            progress_bar.Value = k/length(row);
                            % Make submatrix of adjacent pixels
                            activation_times = activation_map([row(k)-1:row(k)+1],[col(k)-1:col(k)+1]);
                            
                            % Don't include if has NaN
                            if sum(isnan(activation_times(:))) == 0
                                mean_x_activation = sum(activation_times.*weighting.x,'all','omitnan')./sum(~isnan(activation_times).*abs(weighting.x),'all');
                                mean_y_activation = sum(activation_times.*weighting.y,'all','omitnan')./sum(~isnan(activation_times).*abs(weighting.y),'all');

                                % If activation time improbably short remove as
                                % likely just noise (currently limited to
                                % 1/10th of frame rate)
                                if abs(mean_x_activation) <= 1000/(10*app.data.fps)
                                    mean_x_activation = 0;
                                end
                                if abs(mean_y_activation) <= 1000/(10*app.data.fps)
                                    mean_y_activation = 0;
                                end

                                % Convert into CV in pixels/ms
                                app.processed_data.CV_map(row(k),col(k),1) = 1/mean_x_activation;
                                app.processed_data.CV_map(row(k),col(k),2) = 1/mean_y_activation;
                            end
                        end
                        % If there is no difference in activation time across the sub-matrix this causes an infinite conduction velocity so manually set to 0
                        app.processed_data.CV_map(app.processed_data.CV_map==Inf) = 0;

                        % Find absolute CV
                        app.processed_data.CV_map(:,:,3) = sqrt(app.processed_data.CV_map(:,:,1).^2 + app.processed_data.CV_map(:,:,2).^2);

                        % Convert to mm/ms
                        app.processed_data.CV_map = app.processed_data.CV_map*app.processed_data.scale/1000;

                        % Add to log
                        app.history_field.Value{length(app.history_field.Value)+1,1} = ['CV map generated. Method: nearest neighbour.'];

                    end

                    % Automatically remove outliers if button selected.
                    % Loosely based on https://academic.oup.com/cardiovascres/article/51/4/681/389351
                    % Don't do averaging though, just exclude if value is
                    % greater than mean + 2SD in local area. Local area
                    % defined as 1/2 distance used in Bayly calculation but
                    % input here as diameter rather than radius
                    % May lose some edge info as can't exclude nan
                    if app.CVoutlierexclusion.Value == 1
                        last_CV = nan(size(app.processed_data.CV_map(:,:,3)));

                        % Inclusion distance in odd pixels odd
                        dxy = 2*floor(ceil(1000*app.distancemax.Value/app.processed_data.scale)/2) + 1;

                        while(app.processed_data.CV_map(:,:,3) ~= last_CV)
                            local_mean = conv2(app.processed_data.CV_map(:,:,3),ones(dxy)./sum(ones(dxy),'all'),'same');
                            local_std = stdfilt(app.processed_data.CV_map(:,:,3),ones(dxy));
                            [outlier_indices_row, outlier_indices_col] = find(abs(app.processed_data.CV_map(:,:,3)-local_mean)>2*local_std);
                            app.processed_data.CV_map(outlier_indices_row,outlier_indices_col,:) = nan;
                            last_CV = app.processed_data.CV_map(:,:,3);
                        end
                    end

                    % Plot CV map
                    plot_CV_map(app)

                    close(progress_bar)
                    % - need to also have option to update when
                    % lim changes, how to update quivers?
            end
        end

        % Button pushed function: CVmean
        function CV_mean(app, event)
            map_mean = mean(app.processed_data.CV_map(:,:,3),'all','omitnan');
            map_median = median(app.processed_data.CV_map(:,:,3),'all','omitnan');
            map_std = std(app.processed_data.CV_map(:,:,3),0,'all','omitmissing');
            map_percentiles = prctile(app.processed_data.CV_map(:,:,3),[5,95],'all');

            % Mean taking into account directions
            directional_map_mean_x = mean(app.processed_data.CV_map(:,:,1),'all','omitnan');
            directional_map_mean_y = mean(app.processed_data.CV_map(:,:,2),'all','omitnan');
            directional_map_mean = sqrt(directional_map_mean_x^2 + directional_map_mean_y^2);
            directional_map_mode_x = mode(app.processed_data.CV_map(:,:,1),'all');
            directional_map_mode_y = mode(app.processed_data.CV_map(:,:,2),'all');
            directional_map_mode = sqrt(directional_map_mode_x^2 + directional_map_mode_y^2);
            
            message = sprintf('Absolute mean: %.3f mm/ms\n Median: %.3f mm/ms\n Absolute std: %.3f mm/ms\nAbsolute percentiles: 5th:%.3f 95th:%.3f \nDirectional mean: %.3f mm/ms\n Mode: %.3f mm/ms\n',map_mean,map_median,map_std,map_percentiles(1),map_percentiles(2),directional_map_mean,directional_map_mode);
            uialert(app.opticalmappingUIFigure,message,'CV map mean values','Icon','')

            % Add to log
            app.history_field.Value{length(app.history_field.Value)+1,1} = ['Mean CV values: ', message];
        end

        % Button pushed function: CVroimean
        function CV_roi_mean(app, event)
            roi_values = cellfun(@(x) app.processed_data.CV_map(:,:,3).*x,app.display.ROI_mask,'UniformOutput',false);
            roi_means = cellfun(@(x) mean(x(:),'omitnan'),roi_values,'UniformOutput',false);
            roi_stds = cellfun(@(x) std(x(:),0,'all','omitmissing'),roi_values,'UniformOutput',false);
            roi_medians = cellfun(@(x) median(x(:),'omitnan'),roi_values,'UniformOutput',false);
            roi_5th = cellfun(@(x) prctile(x(:),5,'all'),roi_values,'UniformOutput',false);
            roi_95th = cellfun(@(x) prctile(x(:),95,'all'),roi_values,'UniformOutput',false);

            % Means taking into account directions
            dir_roi_values_x = cellfun(@(x) app.processed_data.CV_map(:,:,1).*x,app.display.ROI_mask,'UniformOutput',false);
            dir_roi_values_y = cellfun(@(x) app.processed_data.CV_map(:,:,2).*x,app.display.ROI_mask,'UniformOutput',false);
            dir_roi_means_x = cellfun(@(x) mean(x(:)),dir_roi_values_x,'UniformOutput',false,'omitnan');
            dir_roi_means_y = cellfun(@(x) mean(x(:)),dir_roi_values_y,'UniformOutput',false,'omitnan');
            dir_roi_means = cellfun(@(x,y) sqrt(x^2+y^2),dir_roi_means_x,dir_roi_means_y,'UniformOutput',false');
            dir_roi_modes_x = cellfun(@(x) mode(x(:)),dir_roi_values_x,'UniformOutput',false);
            dir_roi_modes_y = cellfun(@(x) mode(x(:)),dir_roi_values_y,'UniformOutput',false);
            dir_roi_modes = cellfun(@(x,y) sqrt(x^2+y^2),dir_roi_modes_x,dir_roi_modes_y,'UniformOutput',false);

            icon_array = zeros(length(app.display.ROI_mask),1,3);
            icon_array(:,1,:) = app.plot_colours(1:length(app.display.ROI_mask),:);
            icon_array = repelem(icon_array,floor(512/length(app.display.ROI_mask)),1,1);
            icon_array = repmat(icon_array,[1,512,1]);
            roi_message = cellfun(@(x,y,z,a,b) sprintf('Mean: %.3f mm/ms\t STD: %.3f mm/ms\nMedian: %.3f\nPercentiles: 5th: %.3f\t95th: %.3f\n',x,y,z,a,b),roi_means,roi_stds,roi_medians,roi_5th,roi_95th,'UniformOutput',false);
            uialert(app.opticalmappingUIFigure,strjoin(roi_message),'CV ROI mean values','Icon',icon_array);

            % Add to log
            app.history_field.Value{length(app.history_field.Value)+1,1} = ['Mean CV values in ROI: ', roi_message{1}];

            roi_message = cellfun(@(x,y) sprintf('Directional mean: %.3f mm/ms\t Directional mode: %.3f mm/ms\n ',x,y),dir_roi_means,dir_roi_modes,'UniformOutput',false);
            uialert(app.opticalmappingUIFigure,strjoin(roi_message),'CV ROI directional mean values','Icon',icon_array);

            % Add to log
            app.history_field.Value{length(app.history_field.Value)+1,1} = ['Directional mean CV values in ROI: ', roi_message{1}];
        end

        % Value changed function: IsochronesButton
        function isochrones(app, event)
            % If isochrones button goes true then plot isochrones
            if app.IsochronesButton.Value == 1
                
                switch app.isochrone_method.Value
                    % If automatic mode then just do contour plot
                    case 'Automatic'
                        plot_isochrones(app,app.processed_data.CV_map(:,:,3),[],0);

                        % Add to log file
                        app.history_field.Value{length(app.history_field.Value)+1,1} = ['Plot isochrones: Automatic.'];
                    case 'Manual'
                        isochrone_value_string = inputdlg('Enter isochrone values separated by commas:','Isochrone Values');
                        isochrone_values = str2num(isochrone_value_string{1});

                        plot_isochrones(app,app.processed_data.CV_map(:,:,3),isochrone_values,0);

                        % Add to log file
                        app.history_field.Value{length(app.history_field.Value)+1,1} = ['Plot isochrones: Manual. Values: ',isochrone_value_string{1}];

                    case 'Mean ± SD'
                        CV_mean = mean(app.processed_data.CV_map(:,:,3),'all','omitnan');
                        CV_SD = std(app.processed_data.CV_map(:,:,3),[],'all','omitnan');
                        
                        isochrone_value_string = inputdlg(sprintf('Mean: %.2f, SD: %.2f\nEnter SD multipliers separated by commas:',CV_mean,CV_SD),'Isochrone Values');
                        isochrone_values = str2num(isochrone_value_string{1});
                        isochrone_values = CV_mean + CV_SD*isochrone_values;

                        plot_isochrones(app,app.processed_data.CV_map(:,:,3),isochrone_values,0);

                        % Add to log file
                        app.history_field.Value{length(app.history_field.Value)+1,1} = ['Plot isochrones: Mean ± SD. Values: ',isochrone_value_string{1}];
                    case 'Mode ± SD'
                        CV_mode = mode(app.processed_data.CV_map(:,:,3),'all');
                        CV_SD = std(app.processed_data.CV_map(:,:,3),[],'all','omitnan');
                        
                        isochrone_value_string = inputdlg(sprintf('Mean: %.2f, SD: %.2f\nEnter SD multipliers separated by commas:',CV_mode,CV_SD),'Isochrone Values');
                        isochrone_values = str2num(isochrone_value_string{1});
                        isochrone_values = CV_mode + CV_SD*isochrone_values;

                        plot_isochrones(app,app.processed_data.CV_map(:,:,3),isochrone_values,0);

                        % Add to log file
                        app.history_field.Value{length(app.history_field.Value)+1,1} = ['Plot isochrones: Mean ± SD. Values: ',isochrone_value_string{1}];
                end
                % If isochrones button goes false then show CV map again
            else
                % Plot CV map
                delete(app.display.isochrone_handle);

                % Delete any existing labels
                existing_labels = findall(app.main_display_axes,'Type','Text');
                delete(existing_labels);

                plot_CV_map(app)
            end
        end

        % Button pushed function: AreasButton
        function calculate_isochrone_areas(app, event)
            % Find continuous regions of CV between isochrone labels

            % Delete any existing labels
            existing_labels = findall(app.main_display_axes,'Type','Text');
            delete(existing_labels);

            % Isochrone levels
            levels = app.display.colourbar_handle.Ticks;
            % If isochrone doesn't include final level then add
            if levels(end) < max(app.processed_data.CV_map(:,:,3),[],'all')
                levels(end+1) = max(app.processed_data.CV_map(:,:,3),[],'all');
            end

            % Loop through levels
            for k = 1:length(levels)-1
                level_map = (levels(k) <= app.processed_data.CV_map(:,:,3)) & (levels(k+1) >= app.processed_data.CV_map(:,:,3));
                level_connected = bwconncomp(level_map);
                level_properties = regionprops(level_connected,"Area","Centroid");

                % Plot area at centroid of connected region
                for l = 1:size(level_properties,1)
                    text(level_properties(l).Centroid(1),level_properties(l).Centroid(2),sprintf("%.1f-%.1f: %d",levels(k),levels(k+1),level_properties(l).Area),'HorizontalAlignment','center','Parent',app.main_display_axes,'Color','white');
                end

            end

        end

        % Value changed function: mapisochronesbutton
        function map_isochrones(app, event)
            if app.mapisochronesbutton.Value == 1

                % Set isochrone values
                isochrone_values = app.lowerylimit.Value:app.IsochroneSeparationmsEditField.Value:app.upperylimit.Value;

                plot_isochrones(app,app.processed_data.activation_map,isochrone_values,0);

                % Add to log file
                app.history_field.Value{length(app.history_field.Value)+1,1} = ['Plot isochrones on map. Isochrone separation:' num2str(app.IsochroneSeparationmsEditField.Value),' ms.'];

            else
                % Plot map again
                delete(app.display.isochrone_handle);

                % Delete any existing labels
                existing_labels = findall(app.main_display_axes,'Type','Text');
                delete(existing_labels);

                set(app.display.image_handle,'CData',app.processed_data.activation_map);
                app.display.image_handle.Parent.CLim = [0,app.upperylimit.Value];
                % Set first value to black in colour map so that NaN are black.
                app.display.image_handle.Parent.Colormap = [0 0 0; jet(256)];
            end
        end

        % Button pushed function: saveimage_ROI
        function save_image_ROI(app, event)
            %Create file name
            picture_file = file_exists(app,sprintf('frame-%d',app.display.current_frame),'.png');
            log_file_name = file_exists(app,sprintf('log_file_frame-%d_ROI',app.display.current_frame),'.dat');
            save_log_file(app,log_file_name);

            
            % Create non-ui figure image to save
            h = figure;
            h.Visible = 'off';
            ax = gca;

            colour_map = app.display.colour_map;

            current_frame = app.processed_data.image_stack{app.display.normalisation}(:,:,app.display.current_frame);
            max_current_frame = max(current_frame(:));
            min_current_frame = min(current_frame(:));
            cmap_limits = [min_current_frame max_current_frame*app.MaxColourmap.Value/100 ];
            imshow(current_frame./max_current_frame, cmap_limits,'InitialMagnification',ceil(100*700/app.processed_data.height));
            colormap(colour_map)

            hold on
            for k = 1:length(app.display.ROI_handle)
                copyobj(app.display.ROI_handle{k},ax)
                %drawrectangle('Position',app.display.ROI_handle{k}.Position, 'Color', app.display.ROI_handle{k}.Color,'Label',app.display.ROI_handle{k}.Label,'InteractionsAllowed','none');
            end

    
            % Save displayed image
            exportgraphics(h,picture_file,'Resolution',300);
            delete(h)
        end

        % Value changed function: mapisolines
        function map_isolines(app, event)
            if app.mapisolines.Value == 1

                % Set isochrone values
                app.display.isochrone_values = app.lowerylimit.Value:app.IsochroneSeparationmsEditField.Value:app.upperylimit.Value;

                plot_isochrones(app,app.processed_data.activation_map,app.display.isochrone_values,1);

                % Add to log file
                app.history_field.Value{length(app.history_field.Value)+1,1} = ['Plot isolines on map. Isochrone separation:' num2str(app.IsochroneSeparationmsEditField.Value),' ms.'];

            else
                % Plot map again
                delete(app.display.isochrone_handle);
                app.display.isochrone_values = [];

                % Delete any existing labels
                existing_labels = findall(app.main_display_axes,'Type','Text');
                delete(existing_labels);

                set(app.display.image_handle,'CData',app.processed_data.activation_map);
                app.display.image_handle.Parent.CLim = [0,app.upperylimit.Value];
                % Set first value to black in colour map so that NaN are black.
                app.display.image_handle.Parent.Colormap = [0 0 0; jet(256)];
            end
            
        end

        % Menu selected function: NearestMenu
        function NearestMenuSelected(app, event)
            app.display.image_handle.Interpolation = 'nearest';
            app.NearestMenu.Checked = "on";
            app.BilinearMenu.Checked = "off";
        end

        % Menu selected function: BilinearMenu
        function BilinearMenuSelected(app, event)
            app.display.image_handle.Interpolation = 'bilinear';
            app.NearestMenu.Checked = "off";
            app.BilinearMenu.Checked = "on";
        end

        % Button pushed function: CVAnglemean
        function CV_angle_mean(app, event)
            % Convert to angles then create a list
            angles = atan(app.processed_data.CV_map(:,:,1)./app.processed_data.CV_map(:,:,2));
            angles_list = angles(~isnan(angles));

            % Find means and variance taking into account circular
            % statistics
            angle_mean = 90+circ_mean(angles_list)*180/pi;
            angle_median = 90+circ_median(angles_list)*180/pi;
            angle_std = sqrt(circ_var(angles_list))*180/pi;
            
            message = sprintf('Mean circular angle: %.1f%c\n Median: %.1f%c\n Standard deviation: %.1f%c\n',angle_mean,176,angle_median,176,angle_std,176);
            uialert(app.opticalmappingUIFigure,message,'CV map mean angles','Icon','')

            % Add to log
            app.history_field.Value{length(app.history_field.Value)+1,1} = ['Mean CV angles: ', message];
        end

        % Button pushed function: CVROIAnglemean
        function CV_angle_roi_mean(app, event)
            % Convert to angles then create a list
            angles = cellfun(@(x)atan(app.processed_data.CV_map(:,:,1)./app.processed_data.CV_map(:,:,2)).*x,app.display.ROI_mask,'UniformOutput',false);
            angles_list = cellfun(@(x) x(~isnan(x)),angles,'UniformOutput',false);

            % Find means and variance of ROI taking into account circular
            % statistics
            angle_mean = cellfun(@(x) 90+circ_mean(x)*180/pi,angles_list,'UniformOutput',false);
            angle_median = cellfun(@(x) 90+circ_median(x)*180/pi, angles_list,'UniformOutput',false);
            angle_std = cellfun(@(x) sqrt(circ_var(x))*180/pi,angles_list,'UniformOutput',false);
            
            icon_array = zeros(length(app.display.ROI_mask),1,3);
            icon_array(:,1,:) = app.plot_colours(1:length(app.display.ROI_mask),:);
            icon_array = repelem(icon_array,floor(512/length(app.display.ROI_mask)),1,1);
            icon_array = repmat(icon_array,[1,512,1]);

            roi_message = cellfun(@(x,y,z) sprintf('Mean circular angle: %.1f%c\n Median: %.1f%c\n Standard deviation: %.1f%c\n',x,176,y,176,z,176),angle_mean,angle_median,angle_std,'UniformOutput',false);
            uialert(app.opticalmappingUIFigure,strjoin(roi_message),'CV ROI mean angles','Icon',icon_array)

            % Add to log
            app.history_field.Value{length(app.history_field.Value)+1,1} = ['Mean CV angles in ROI: ', roi_message];

        end

        % Button pushed function: PixelInfoButton
        function PixelInfoButtonPushed(app, event)
            first_frame = app.processed_data.image_stack{1}(:,:,1);
            total_pixels = length(first_frame(:));
            foreground_pixels = sum(~isnan(first_frame),'all');

            pixel_message = sprintf('Foreground pixels: %d\n Total pixels: %d\n',foreground_pixels,total_pixels);
            uialert(app.opticalmappingUIFigure,pixel_message,'Pixel_info');

        end

        % Button pushed function: RemoveArtefactFramesButton
        function remove_optical_artefact_frames(app, event)
            dfdt = diff(app.processed_data.signals{end,app.display.normalisation+1});
            [~,pacing_locations] = findpeaks(dfdt,'MinPeakHeight',0.5*max(dfdt),'MinPeakDistance',0.025*app.data.fps); %at least 25 ms from other peaks
            
            frames_to_remove = [];
            for k = 1:length(pacing_locations)
                frames_to_remove = [frames_to_remove, max(pacing_locations(k)-ceil(0.1*(app.stim_length.Value/1000)*app.data.fps),1):min(pacing_locations(k)+ceil(1.1*(app.stim_length.Value/1000)*app.data.fps),length(app.processed_data.signals{end,app.display.normalisation+1}))]; %Expand by 10% at either end
            end

            intermediate_array = app.processed_data.image_stack{1};
            frames = 1:size(intermediate_array,3);
            end_array = app.processed_data.image_stack{1};
            intermediate_array(:,:,frames_to_remove) = [];
            frames(frames_to_remove) = [];
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Interpolating','Message','Please wait...','Cancelable','off');
            for k = 1:size(intermediate_array,1)
                progress_bar.Value = k/size(intermediate_array,1);
                for l = 1:size(intermediate_array,2)
                    end_array(k,l,frames_to_remove) = interp1(frames,squeeze(intermediate_array(k,l,:)),frames_to_remove,'spline');
                end
            end

            % Renew stack
            app.processed_data.image_stack = {};
            app.processed_data.image_stack{1} = end_array;
            
            % Clear signals and set that all normalisations still need to be
            % done
            num_sig = size(app.processed_data.signals,1);
            app.processed_data.signals = [];
            app.display.renorm = ones(size(app.display.renorm));
            
            % Recreate signals
            for k = 1:num_sig
                apply_signal_masks(app, k);
            end
            
            % Display averaged data
            switch app.display.normalisation
                case 1
                    app.change_normalisation_none(app);
                case 2
                    app.change_normalisation_global(app);
                case 3
                    app.change_normalisation_local(app);
                case 4
                    app.change_normalisation_global_mean(app);
                case 5
                    app.change_normalisation_timeaverage(app);
            end
            
            app.tool_tip_update;

            app.history_field.Value{length(app.history_field.Value)+1,1} = [sprintf('Removing optical artefacts. Cutoff: %f.',app.stim_length.Value)];

            close(progress_bar);
        end

        % Button pushed function: generatebeatmaps
        function generate_beat_maps(app, event)
            %% Find start of beats
            progress_bar = uiprogressdlg(app.opticalmappingUIFigure,'Title','Calculating map','Message','Please wait...','Cancelable','on');

            min_height = app.minheight.Value/100; % convert to fraction
            min_distance = app.mindistance.Value/1000; % convert to seconds

            signal = app.processed_data.signals{1,app.display.normalisation+1};
            time_vector = app.processed_data.signals{1,1};
            dfdt = diff(signal)./time_vector(2);
            switch app.PeakfindingDropDown.Value
                case 'dfdt'
                    [~, beat_locations] = findpeaks(dfdt,'MinPeakHeight',min_height*(max(dfdt)-mean(dfdt)),'MinPeakDistance',(min_distance/time_vector(2)));
                case 'Peaks'
                    [~, beat_locations] = findpeaks(signal,'MinPeakHeight',min_height*(max(signal)-min(signal))+min(signal),'MinPeakDistance',(min_distance/time_vector(2)));
                    beat_locations = app.averaging.beat_locations - round(mean(diff(beat_locations))/10);
            end
            
            beat_locations = beat_locations - round(app.offsettime.Value*app.data.fps/1000);
            beat_locations(beat_locations<1) = [];
            number_of_beats = length(beat_locations);

            % Find activation maps for all except the last beat
            app.processed_data.activation_map = nan(app.processed_data.height,app.processed_data.width,number_of_beats-1);

            % Use centre pixel of ROI as seed pixel
            roi_mask = app.display.ROI_mask{1};
            roi_mask(isnan(roi_mask)) = 0;
            roi_props = regionprops(roi_mask);
            dt = 1/app.data.fps; % time step
            for k = 1:number_of_beats-1
                progress_bar.Value = k/(number_of_beats-1);

                % Extract the current beat
                beat = app.processed_data.image_stack{3}(:, :, beat_locations(k):beat_locations(k+1));
                seed_signal = squeeze(beat(floor(roi_props.Centroid(2)),floor(roi_props.Centroid(1)),:));

                % Interpolate seed signal to increase resolution (10 fold increase)
                interp_factor = 10;
                seed_signal = interp(seed_signal,interp_factor);

                % Identify valid signals
                valid_pixels = ((mean(beat,3) ~= 0) & ~(any(isnan(beat), 3)));

                % Convert beats to array of signals
                beat_signals = reshape(beat, size(beat,1)*size(beat,2), size(beat,3));
                valid_index = find(valid_pixels); % 1D version

                % Convert array of signals to cell array of valid pixels
                valid_signals = num2cell(beat_signals(valid_index, :), 2); 

                delays = cellfun(@(signal) align_cell_signals(app,signal,seed_signal,interp_factor),valid_signals);

                activation_1d = nan(size(beat,1)*size(beat,2), 1);
                activation_1d(valid_index) = 1000 * delays * dt / interp_factor;

                app.processed_data.activation_map(:,:,k) = reshape(activation_1d,size(beat,1),size(beat,2)); 

                app.processed_data.activation_map(:,:,k) = app.processed_data.activation_map(:,:,k) - app.processed_data.activation_map(floor(roi_props.Centroid(2)),floor(roi_props.Centroid(1)),k) + app.offsettime.Value;
                % Need to tidy output...
            end

            % Display
            %save for map filtering
            max_value = max(app.processed_data.activation_map(:),[],'omitnan');
            app.max_value_ACT = max_value;
            colourbar_string = 'Activation time (ms)';

            if isfield(app.display,'quiver_handle') && ~isempty(app.display.quiver_handle)
                delete(app.display.quiver_handle)
            end

            set(app.display.image_handle,'CData',app.processed_data.activation_map(:,:,1));
            app.display.image_handle.Parent.CLim = [0,max_value];
            % Set first value to black in colour map so that NaN are black.
            app.display.image_handle.Parent.Colormap = [0 0 0; jet(256)];
            if isvalid(app.display.colourbar_handle)
                colorbar(app.display.colourbar_handle,'off')
            end
            app.display.colourbar_handle = colorbar(app.display.image_handle.Parent);
            app.display.colourbar_handle.Label.String = colourbar_string;
            app.display.colourbar_handle.Label.FontSize = app.display.colourbar_handle.Label.FontSize+2;
            app.display.map = 1;

            app.upperylimit_bbb.Value = app.display.colourbar_handle.Limits(2);
            app.lowerylimit_bbb.Value = app.display.colourbar_handle.Limits(1);

            update_histogram_map(app)


            % Add to log
            app.history_field.Value{length(app.history_field.Value)+1,1} = ['Beat by beat activation time maps gneratd using cross-correlation.'];

           close(progress_bar);
        end

        % Value changed function: lowerylimit_bbb, upperylimit_bbb
        function change_map_limits_bbb(app, event)
            ymax = app.upperylimit_bbb.Value;
            ymin = app.lowerylimit_bbb.Value;

            clim(app.display.image_handle.Parent,[ymin,ymax]);

        end

        % Button pushed function: nextframe_2
        function nextframe_bbb(app, event)
            app.display.current_frame = min(size(app.processed_data.activation_map,3),app.display.current_frame+1);
            app.tool_tip_update;
            set(app.display.image_handle,'CData',app.processed_data.activation_map(:,:,app.display.current_frame));
        end

        % Button pushed function: previousframe_2
        function previous_frame_bbb(app, event)
            app.display.current_frame = max(1,app.display.current_frame-1);
            app.tool_tip_update;
            set(app.display.image_handle,'CData',app.processed_data.activation_map(:,:,app.display.current_frame));            
        end

        % Button pushed function: playmovie_2
        function play_movie_bbb(app, event)
            h = implay(app.processed_data.activation_map,app.display.fps);
            h.Visual.ColorMap.Map = [0 0 0; jet(256)];

            % Enable user-defined colormap range
            h.Visual.ColorMap.UserRange = 1; 

            % Set minimum and maximum values
            h.Visual.ColorMap.UserRangeMin = app.lowerylimit_bbb.Value;
            h.Visual.ColorMap.UserRangeMax = app.upperylimit_bbb.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.opticalmappingUIFigure = uifigure('Visible', 'off');
            app.opticalmappingUIFigure.Name = 'Optical mapping analysis';
            app.opticalmappingUIFigure.CloseRequestFcn = createCallbackFcn(app, @opticalmappingUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.opticalmappingUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadfileMenu = uimenu(app.FileMenu);
            app.LoadfileMenu.MenuSelectedFcn = createCallbackFcn(app, @load_full_file, true);
            app.LoadfileMenu.Tooltip = {'Load file (.sif'; ' .dat'; ' .mat)'};
            app.LoadfileMenu.Enable = 'off';
            app.LoadfileMenu.Text = 'Load file';
            app.LoadfilepartiallyMenu = uimenu(app.FileMenu);
            app.LoadfilepartiallyMenu.MenuSelectedFcn = createCallbackFcn(app, @Load_file_partially, true);
            app.LoadfilepartiallyMenu.Tooltip = {'Load file partially by selecting starting and end of the file. It allows to pre-load ECG to accurately select start and end.'};
            app.LoadfilepartiallyMenu.Text = 'Load file partially';
            app.DefineregionsMenu = uimenu(app.FileMenu);
            app.DefineregionsMenu.MenuSelectedFcn = createCallbackFcn(app, @define_regions, true);
            app.DefineregionsMenu.Tooltip = {'Define regions to order and mirror panoramic OM files'};
            app.DefineregionsMenu.Enable = 'off';
            app.DefineregionsMenu.Text = 'Define regions';
            app.RegionsMenu = uimenu(app.FileMenu);
            app.RegionsMenu.Enable = 'off';
            app.RegionsMenu.Text = 'Regions';
            app.SaveRegionsMenu = uimenu(app.RegionsMenu);
            app.SaveRegionsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveRegionsMenuSelected, true);
            app.SaveRegionsMenu.Tooltip = {'Save order and mirroring info of the current file to load on another file later.'};
            app.SaveRegionsMenu.Text = 'Save Regions';
            app.LoadRegionsMenu = uimenu(app.RegionsMenu);
            app.LoadRegionsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadRegionsMenuSelected, true);
            app.LoadRegionsMenu.Tooltip = {'Load regions file to order and mirror accordingly panoramic files.'};
            app.LoadRegionsMenu.Text = 'Load Regions';
            app.QuickloadMenu = uimenu(app.FileMenu);
            app.QuickloadMenu.MenuSelectedFcn = createCallbackFcn(app, @quick_load, true);
            app.QuickloadMenu.Tooltip = {'Load another file with the same regions'; ' normalisation'; ' inversion and ROIs.'};
            app.QuickloadMenu.Enable = 'off';
            app.QuickloadMenu.Text = 'Quick load';
            app.QuickloadpartiallyMenu = uimenu(app.FileMenu);
            app.QuickloadpartiallyMenu.MenuSelectedFcn = createCallbackFcn(app, @QuickloadpartiallyMenuSelected, true);
            app.QuickloadpartiallyMenu.Tooltip = {'Load partially another file with the same regions'; ' normalisation'; ' inversion and ROIs.'};
            app.QuickloadpartiallyMenu.Enable = 'off';
            app.QuickloadpartiallyMenu.Text = 'Quick load partially';
            app.workspace = uimenu(app.FileMenu);
            app.workspace.Text = 'Workspace';
            app.saveworkspace = uimenu(app.workspace);
            app.saveworkspace.MenuSelectedFcn = createCallbackFcn(app, @save_workspace, true);
            app.saveworkspace.Tooltip = {'Save workspace file to work later at the saved state of workspace'};
            app.saveworkspace.Enable = 'off';
            app.saveworkspace.Text = 'Save';
            app.loadworkspace = uimenu(app.workspace);
            app.loadworkspace.MenuSelectedFcn = createCallbackFcn(app, @load_workspace, true);
            app.loadworkspace.Tooltip = {'Load workspace'};
            app.loadworkspace.Text = 'Load';
            app.APDrestitutionanalysisMenu = uimenu(app.FileMenu);
            app.APDrestitutionanalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @APDrestitutionanalysisMenuSelected, true);
            app.APDrestitutionanalysisMenu.Text = 'APD restitution analysis';
            app.HelpMenu = uimenu(app.FileMenu);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Tooltip = {'Open browser with Wiki page'};
            app.HelpMenu.Text = 'Help';
            app.RestartAPPMenu = uimenu(app.FileMenu);
            app.RestartAPPMenu.MenuSelectedFcn = createCallbackFcn(app, @RestartAPPMenuSelected, true);
            app.RestartAPPMenu.Text = 'Restart APP';
            app.SettingsMenu = uimenu(app.opticalmappingUIFigure);
            app.SettingsMenu.Enable = 'off';
            app.SettingsMenu.Text = 'Settings';
            app.ColourmapMenu = uimenu(app.SettingsMenu);
            app.ColourmapMenu.Text = 'Colour map';
            app.HotCmap = uimenu(app.ColourmapMenu);
            app.HotCmap.MenuSelectedFcn = createCallbackFcn(app, @Hot_cmap, true);
            app.HotCmap.Checked = 'on';
            app.HotCmap.Text = 'Hot';
            app.GrayscaleCmap = uimenu(app.ColourmapMenu);
            app.GrayscaleCmap.MenuSelectedFcn = createCallbackFcn(app, @Grayscale_cmap, true);
            app.GrayscaleCmap.Text = 'Grayscale';
            app.JetCmap = uimenu(app.ColourmapMenu);
            app.JetCmap.MenuSelectedFcn = createCallbackFcn(app, @Jet_cmap, true);
            app.JetCmap.Text = 'Jet';
            app.InterpolationMenu = uimenu(app.SettingsMenu);
            app.InterpolationMenu.Text = 'Interpolation';
            app.NearestMenu = uimenu(app.InterpolationMenu);
            app.NearestMenu.MenuSelectedFcn = createCallbackFcn(app, @NearestMenuSelected, true);
            app.NearestMenu.Checked = 'on';
            app.NearestMenu.Text = 'Nearest';
            app.BilinearMenu = uimenu(app.InterpolationMenu);
            app.BilinearMenu.MenuSelectedFcn = createCallbackFcn(app, @BilinearMenuSelected, true);
            app.BilinearMenu.Text = 'Bilinear';
            app.RegionsChosenMenu = uimenu(app.opticalmappingUIFigure);
            app.RegionsChosenMenu.MenuSelectedFcn = createCallbackFcn(app, @regions_chosen, true);
            app.RegionsChosenMenu.Visible = 'off';
            app.RegionsChosenMenu.Text = 'Regions Chosen';
            app.MatchRegion1SizeMenu = uimenu(app.opticalmappingUIFigure);
            app.MatchRegion1SizeMenu.MenuSelectedFcn = createCallbackFcn(app, @match_region_1_size, true);
            app.MatchRegion1SizeMenu.Visible = 'off';
            app.MatchRegion1SizeMenu.Text = 'Match Region 1 Size';
            app.ConfirmExtractBackgroundMenu = uimenu(app.opticalmappingUIFigure);
            app.ConfirmExtractBackgroundMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfirmExtractBackgroundMenuSelected, true);
            app.ConfirmExtractBackgroundMenu.Visible = 'off';
            app.ConfirmExtractBackgroundMenu.Text = 'Confirm Extract Background';
            app.AcceptCrop = uimenu(app.opticalmappingUIFigure);
            app.AcceptCrop.MenuSelectedFcn = createCallbackFcn(app, @AcceptCropMenuSelected, true);
            app.AcceptCrop.Visible = 'off';
            app.AcceptCrop.Text = 'Accept Crop';
            app.CancelCrop = uimenu(app.opticalmappingUIFigure);
            app.CancelCrop.MenuSelectedFcn = createCallbackFcn(app, @CancelCropMenuSelected, true);
            app.CancelCrop.Visible = 'off';
            app.CancelCrop.Text = 'Cancel Crop';
            app.LengthChosenMenu = uimenu(app.opticalmappingUIFigure);
            app.LengthChosenMenu.MenuSelectedFcn = createCallbackFcn(app, @LengthChosenMenuSelected, true);
            app.LengthChosenMenu.Visible = 'off';
            app.LengthChosenMenu.Text = 'Length Chosen';
            app.tool_tip = uitextarea(app.opticalmappingUIFigure);
            app.TabGroup = uitabgroup(app.opticalmappingUIFigure);
            app.ViewingTab = uitab(app.TabGroup);
            app.ViewingTab.Title = 'Viewing';
            app.GridLayout = uigridlayout(app.ViewingTab);
            app.FrameratefpsEditFieldLabel = uilabel(app.GridLayout);
            app.FrameratefpsEditFieldLabel.Text = 'Frame rate (fps)';
            app.setframerate = uieditfield(app.GridLayout, 'numeric');
            app.setframerate.ValueChangedFcn = createCallbackFcn(app, @set_fps, true);
            app.setframerate.Value = 15;
            app.playmovie = uibutton(app.GridLayout, 'push');
            app.playmovie.ButtonPushedFcn = createCallbackFcn(app, @play_movie, true);
            app.playmovie.Text = 'Play movie ';
            app.previousframe = uibutton(app.GridLayout, 'push');
            app.previousframe.ButtonPushedFcn = createCallbackFcn(app, @previous_frame, true);
            app.previousframe.Text = '◀ Previous';
            app.nextframe = uibutton(app.GridLayout, 'push');
            app.nextframe.ButtonPushedFcn = createCallbackFcn(app, @next_frame, true);
            app.nextframe.Text = 'Next ▶';
            app.pickframe = uibutton(app.GridLayout, 'push');
            app.pickframe.ButtonPushedFcn = createCallbackFcn(app, @frame_pick, true);
            app.pickframe.Text = 'Pick frame';
            app.NORMALISATIONButtonGroup = uibuttongroup(app.GridLayout);
            app.NORMALISATIONButtonGroup.Title = 'NORMALISATION';
            app.GridLayout7 = uigridlayout(app.NORMALISATIONButtonGroup);
            app.nonormalise = uibutton(app.GridLayout7, 'state');
            app.nonormalise.ValueChangedFcn = createCallbackFcn(app, @change_normalisation_none, true);
            app.nonormalise.Tooltip = {'Change to no-normalisation'};
            app.nonormalise.Text = 'No normalisation';
            app.nonormalise.Value = true;
            app.globalnormalise = uibutton(app.GridLayout7, 'state');
            app.globalnormalise.ValueChangedFcn = createCallbackFcn(app, @change_normalisation_global, true);
            app.globalnormalise.Tooltip = {'Change to normalisation by global maximum'};
            app.globalnormalise.Text = 'Global max normalisation';
            app.localnormalise = uibutton(app.GridLayout7, 'state');
            app.localnormalise.ValueChangedFcn = createCallbackFcn(app, @change_normalisation_local, true);
            app.localnormalise.Tooltip = {'Change to local (pixel-by-pixel) normalisation.'};
            app.localnormalise.Text = 'Local max normalisation';
            app.timeaverage = uibutton(app.GridLayout7, 'state');
            app.timeaverage.ValueChangedFcn = createCallbackFcn(app, @change_normalisation_timeaverage, true);
            app.timeaverage.Tooltip = {'Show average of each pixel over time'};
            app.timeaverage.Text = 'Time Average';
            app.globalmeannormalise = uibutton(app.GridLayout7, 'state');
            app.globalmeannormalise.ValueChangedFcn = createCallbackFcn(app, @change_normalisation_global_mean, true);
            app.globalmeannormalise.Tooltip = {'Removes local offset and then normalises by the global mean maxima.'};
            app.globalmeannormalise.Text = 'Global mean normalisation';
            app.ReferencenormalisationButton = uibutton(app.GridLayout7, 'state');
            app.ReferencenormalisationButton.ValueChangedFcn = createCallbackFcn(app, @change_normalisation_reference, true);
            app.ReferencenormalisationButton.Tooltip = {'Substracts baseline on a pixel-by-pixel basis and uses another file to find local max. Both recordings should have the heart in the same position.'};
            app.ReferencenormalisationButton.Text = 'Reference normalisation';
            app.invertdata = uibutton(app.GridLayout, 'state');
            app.invertdata.ValueChangedFcn = createCallbackFcn(app, @invert, true);
            app.invertdata.Tooltip = {'Invert signal'};
            app.invertdata.Text = 'Invert data';
            app.SETCOLOURMAPSATURATIONPanel = uipanel(app.GridLayout);
            app.SETCOLOURMAPSATURATIONPanel.Title = 'SET COLOUR MAP SATURATION';
            app.GridLayout11 = uigridlayout(app.SETCOLOURMAPSATURATIONPanel);
            app.MaxLabel = uilabel(app.GridLayout11);
            app.MaxLabel.Text = 'Max (%)';
            app.MaxColourmap = uieditfield(app.GridLayout11, 'numeric');
            app.MaxColourmap.ValueChangedFcn = createCallbackFcn(app, @MaxColourmapValueChanged, true);
            app.MaxColourmap.Value = 100;
            app.VISUALISEWAVEFRONTPanel = uipanel(app.GridLayout);
            app.VISUALISEWAVEFRONTPanel.Title = 'VISUALISE WAVEFRONT';
            app.GridLayout12 = uigridlayout(app.VISUALISEWAVEFRONTPanel);
            app.ThresholdLabel = uilabel(app.GridLayout12);
            app.ThresholdLabel.Text = 'Threshold (%)';
            app.ThresholdWavefront = uieditfield(app.GridLayout12, 'numeric');
            app.ThresholdWavefront.Value = 50;
            app.ComputeWavefrontButton = uibutton(app.GridLayout12, 'push');
            app.ComputeWavefrontButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeWavefrontButtonPushed, true);
            app.ComputeWavefrontButton.Tooltip = {'Changes to global normalisation (computes global norm if not available)'; ' thresholds at a pixel base'; ' and shows in red the upper thresholded values to visualise the wavefront. Strongly encouraged: spatial normalisation'; ' detrend.'};
            app.ComputeWavefrontButton.Text = 'Compute Wavefront';
            app.ShowHideWavefrontButton = uibutton(app.GridLayout12, 'state');
            app.ShowHideWavefrontButton.ValueChangedFcn = createCallbackFcn(app, @ShowHideWavefrontButtonValueChanged, true);
            app.ShowHideWavefrontButton.Enable = 'off';
            app.ShowHideWavefrontButton.Text = 'Show/Hide Wavefront';
            app.EXTRACTBACKGROUNDPanel = uipanel(app.GridLayout);
            app.EXTRACTBACKGROUNDPanel.Title = 'EXTRACT BACKGROUND';
            app.GridLayout14 = uigridlayout(app.EXTRACTBACKGROUNDPanel);
            app.undolast_Viewing = uibutton(app.GridLayout14, 'push');
            app.undolast_Viewing.ButtonPushedFcn = createCallbackFcn(app, @undo_last_viewing, true);
            app.undolast_Viewing.Tooltip = {'Undo last keep/remove area'};
            app.undolast_Viewing.Text = ' ⏎ Undo keep/remove area';
            app.AssistedareasegmentationButton = uibutton(app.GridLayout14, 'push');
            app.AssistedareasegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @Assisted_area_segmentation, true);
            app.AssistedareasegmentationButton.Tooltip = {'Open assisted area segmentation app to mask-out the background'};
            app.AssistedareasegmentationButton.Text = 'Assisted area segmentation';
            app.KeepareaButton = uibutton(app.GridLayout14, 'push');
            app.KeepareaButton.ButtonPushedFcn = createCallbackFcn(app, @keep_area, true);
            app.KeepareaButton.Enable = 'off';
            app.KeepareaButton.Text = '✔ Keep area';
            app.RemoveareaButton = uibutton(app.GridLayout14, 'push');
            app.RemoveareaButton.ButtonPushedFcn = createCallbackFcn(app, @remove_area, true);
            app.RemoveareaButton.Enable = 'off';
            app.RemoveareaButton.Text = '✖ Remove area';
            app.SaveareaButton = uibutton(app.GridLayout14, 'push');
            app.SaveareaButton.ButtonPushedFcn = createCallbackFcn(app, @save_area, true);
            app.SaveareaButton.Tooltip = {'Save background mask to file'};
            app.SaveareaButton.Text = '💾 Save area';
            app.LoadareaButton = uibutton(app.GridLayout14, 'push');
            app.LoadareaButton.ButtonPushedFcn = createCallbackFcn(app, @load_area, true);
            app.LoadareaButton.Tooltip = {'Load background mask file'};
            app.LoadareaButton.Text = 'Load area';
            app.saveimage_2 = uibutton(app.GridLayout, 'push');
            app.saveimage_2.ButtonPushedFcn = createCallbackFcn(app, @save_image, true);
            app.saveimage_2.Text = 'Save image';
            app.DeletereferencenormButton = uibutton(app.GridLayout, 'push');
            app.DeletereferencenormButton.ButtonPushedFcn = createCallbackFcn(app, @DeletereferencenormButtonPushed, true);
            app.DeletereferencenormButton.Tooltip = {'Delete reference normalisation.'};
            app.DeletereferencenormButton.Text = 'Delete reference norm.';
            app.PixelInfoButton = uibutton(app.GridLayout, 'push');
            app.PixelInfoButton.ButtonPushedFcn = createCallbackFcn(app, @PixelInfoButtonPushed, true);
            app.PixelInfoButton.Text = 'Pixel Info';
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.GridLayout2 = uigridlayout(app.ProcessingTab);
            app.signalregion = uibutton(app.GridLayout2, 'push');
            app.signalregion.ButtonPushedFcn = createCallbackFcn(app, @select_signal, true);
            app.signalregion.Tooltip = {'Select region of interest'};
            app.signalregion.Text = 'Select ROI';
            app.hidesignal = uibutton(app.GridLayout2, 'push');
            app.hidesignal.ButtonPushedFcn = createCallbackFcn(app, @hide_signals, true);
            app.hidesignal.Enable = 'off';
            app.hidesignal.Tooltip = {'Hide graph from ROI signals'};
            app.hidesignal.Text = 'Hide signals 🚫';
            app.showsignals = uibutton(app.GridLayout2, 'push');
            app.showsignals.ButtonPushedFcn = createCallbackFcn(app, @show_signals, true);
            app.showsignals.Enable = 'off';
            app.showsignals.Tooltip = {'Show signals from selected ROI'};
            app.showsignals.Text = 'Show signals 👁';
            app.signalregiontype = uidropdown(app.GridLayout2);
            app.signalregiontype.Items = {'Rectangle', 'Polygon', '1', '2', '4', '10', '20', '30', '40', '50'};
            app.signalregiontype.Tooltip = {'Shape and size of ROI to be selected'};
            app.signalregiontype.Value = 'Rectangle';
            app.saveregions = uibutton(app.GridLayout2, 'push');
            app.saveregions.ButtonPushedFcn = createCallbackFcn(app, @save_regions, true);
            app.saveregions.Enable = 'off';
            app.saveregions.Tooltip = {'Save position of ROIs to load on another file.'};
            app.saveregions.Text = 'Save ROIs 💾';
            app.loadregions = uibutton(app.GridLayout2, 'push');
            app.loadregions.ButtonPushedFcn = createCallbackFcn(app, @load_regions, true);
            app.loadregions.Tooltip = {'Load ROI file'};
            app.loadregions.Text = 'Load ROIs';
            app.deleteROI = uibutton(app.GridLayout2, 'push');
            app.deleteROI.ButtonPushedFcn = createCallbackFcn(app, @delete_ROI, true);
            app.deleteROI.Enable = 'off';
            app.deleteROI.Tooltip = {'Delete selected ROI.'};
            app.deleteROI.Text = 'Delete ROI';
            app.ROIdropdown = uidropdown(app.GridLayout2);
            app.ROIdropdown.Items = {'ROI 1'};
            app.ROIdropdown.ItemsData = {'1'};
            app.ROIdropdown.ValueChangedFcn = createCallbackFcn(app, @ROI_dropdown_color_change, true);
            app.ROIdropdown.Enable = 'off';
            app.ROIdropdown.Tooltip = {'ROI selector. The ROI selected here will be deleted by the button delete ROI'};
            app.ROIdropdown.Value = '1';
            app.clipdata = uibutton(app.GridLayout2, 'push');
            app.clipdata.ButtonPushedFcn = createCallbackFcn(app, @clip_data, true);
            app.clipdata.Tooltip = {'Clip frames (shorten movie)'};
            app.clipdata.Text = 'Clip time ✂';
            app.FILTERINGPanel = uipanel(app.GridLayout2);
            app.FILTERINGPanel.Title = 'FILTERING';
            app.GridLayout6 = uigridlayout(app.FILTERINGPanel);
            app.spatialfilter = uibutton(app.GridLayout6, 'push');
            app.spatialfilter.ButtonPushedFcn = createCallbackFcn(app, @spatial_filtering, true);
            app.spatialfilter.Tooltip = {'Apply selected spatial filter type to whole stack'};
            app.spatialfilter.Text = 'Apply spatial filter';
            app.temporalfilter = uibutton(app.GridLayout6, 'push');
            app.temporalfilter.ButtonPushedFcn = createCallbackFcn(app, @temporal_filtering, true);
            app.temporalfilter.Tooltip = {'Apply temporal selected filter to whole stack'};
            app.temporalfilter.Text = 'Apply temporal filter';
            app.spatialFilterType = uidropdown(app.GridLayout6);
            app.spatialFilterType.Items = {'Box linear filter', 'Gaussian filter', 'Median filter'};
            app.spatialFilterType.ItemsData = {'1', '2', '3'};
            app.spatialFilterType.ValueChangedFcn = createCallbackFcn(app, @spatial_Filter_Type, true);
            app.spatialFilterType.Tooltip = {'Select type of spatial filter'};
            app.spatialFilterType.Value = '1';
            app.spatialkernelsize = uidropdown(app.GridLayout6);
            app.spatialkernelsize.Items = {'3x3', '5x5', '7x7', '9x9'};
            app.spatialkernelsize.ItemsData = {'3', '5', '7', '9'};
            app.spatialkernelsize.Tooltip = {'Kernel size'};
            app.spatialkernelsize.Value = '3';
            app.gaussiansigma = uieditfield(app.GridLayout6, 'numeric');
            app.gaussiansigma.Limits = [0 Inf];
            app.gaussiansigma.Enable = 'off';
            app.gaussiansigma.Tooltip = {'Sigma for Gaussian'};
            app.gaussiansigma.Value = 0.5;
            app.GaussiansigmaLabel = uilabel(app.GridLayout6);
            app.GaussiansigmaLabel.Enable = 'off';
            app.GaussiansigmaLabel.Text = 'Gaussian sigma:';
            app.TemporalFilterType = uidropdown(app.GridLayout6);
            app.TemporalFilterType.Items = {'Butterworth', 'FIR'};
            app.TemporalFilterType.ItemsData = {'1', '2'};
            app.TemporalFilterType.ValueChangedFcn = createCallbackFcn(app, @TemporalFilterTypeValueChanged, true);
            app.TemporalFilterType.Value = '1';
            app.CutofffrequencyHzLabel = uilabel(app.GridLayout6);
            app.CutofffrequencyHzLabel.Text = 'Cutoff frequency (Hz):';
            app.cutofffrequency = uieditfield(app.GridLayout6, 'numeric');
            app.cutofffrequency.Limits = [0 Inf];
            app.cutofffrequency.ValueChangedFcn = createCallbackFcn(app, @cutoff_limit, true);
            app.cutofffrequency.Tooltip = {'Frequency cut off for Butterworth filter'};
            app.cutofffrequency.Value = 30;
            app.FilterorderLabel = uilabel(app.GridLayout6);
            app.FilterorderLabel.Text = 'Filter order:';
            app.filterorder = uieditfield(app.GridLayout6, 'numeric');
            app.filterorder.Limits = [1 Inf];
            app.filterorder.Tooltip = {'Filter order for Butterworth'};
            app.filterorder.Value = 5;
            app.KernelsizeLabel = uilabel(app.GridLayout6);
            app.KernelsizeLabel.Text = 'Kernel size:';
            app.binning = uibutton(app.GridLayout6, 'push');
            app.binning.ButtonPushedFcn = createCallbackFcn(app, @bin_image, true);
            app.binning.Text = 'Binning';
            app.averagebeats = uibutton(app.GridLayout6, 'push');
            app.averagebeats.ButtonPushedFcn = createCallbackFcn(app, @average_beats, true);
            app.averagebeats.Enable = 'off';
            app.averagebeats.Text = 'Average beats';
            app.SELECTROIFIRSTLabel = uilabel(app.GridLayout6);
            app.SELECTROIFIRSTLabel.Text = {''; 'SELECT ROI FIRST'};
            app.BinSpinnerLabel = uilabel(app.GridLayout6);
            app.BinSpinnerLabel.Text = 'Bin #';
            app.BinSpinner = uispinner(app.GridLayout6);
            app.BinSpinner.Limits = [1 Inf];
            app.BinSpinner.Value = 2;
            app.MinheightEditFieldLabel = uilabel(app.GridLayout6);
            app.MinheightEditFieldLabel.Text = 'Min height (%)';
            app.minheight = uieditfield(app.GridLayout6, 'numeric');
            app.minheight.Value = 50;
            app.MindistancemsEditFieldLabel = uilabel(app.GridLayout6);
            app.MindistancemsEditFieldLabel.Text = 'Min distance (ms)';
            app.mindistance = uieditfield(app.GridLayout6, 'numeric');
            app.mindistance.Value = 50;
            app.PeakfindingDropDownLabel = uilabel(app.GridLayout6);
            app.PeakfindingDropDownLabel.Text = 'Peak finding';
            app.PeakfindingDropDown = uidropdown(app.GridLayout6);
            app.PeakfindingDropDown.Items = {'dfdt', 'Peaks'};
            app.PeakfindingDropDown.Value = 'dfdt';
            app.REMOVEBIASSHIFTPanel = uipanel(app.GridLayout2);
            app.REMOVEBIASSHIFTPanel.Title = 'REMOVE BIAS SHIFT';
            app.GridLayout10 = uigridlayout(app.REMOVEBIASSHIFTPanel);
            app.ApplyRemoveBias = uibutton(app.GridLayout10, 'push');
            app.ApplyRemoveBias.ButtonPushedFcn = createCallbackFcn(app, @Apply_remove_bias_shift, true);
            app.ApplyRemoveBias.Tooltip = {'Removes bias shift of the signal by substracting the best fitting polynomial to each pixel-time signal.'};
            app.ApplyRemoveBias.Text = 'Apply FAST';
            app.ApplySLOWButton = uibutton(app.GridLayout10, 'push');
            app.ApplySLOWButton.ButtonPushedFcn = createCallbackFcn(app, @ApplySLOWButtonPushed, true);
            app.ApplySLOWButton.Enable = 'off';
            app.ApplySLOWButton.Text = 'Apply SLOW';
            app.ApplyWINDOWButton = uibutton(app.GridLayout10, 'push');
            app.ApplyWINDOWButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyWINDOWButtonPushed, true);
            app.ApplyWINDOWButton.Enable = 'off';
            app.ApplyWINDOWButton.Text = 'Apply WINDOW';
            app.WindowEditFieldLabel = uilabel(app.GridLayout10);
            app.WindowEditFieldLabel.Enable = 'off';
            app.WindowEditFieldLabel.Text = 'Window';
            app.WindowMethod3 = uieditfield(app.GridLayout10, 'numeric');
            app.WindowMethod3.Enable = 'off';
            app.WindowMethod3.Value = 5;
            app.StepEditFieldLabel = uilabel(app.GridLayout10);
            app.StepEditFieldLabel.Enable = 'off';
            app.StepEditFieldLabel.Text = 'Step';
            app.StepMethod3 = uieditfield(app.GridLayout10, 'numeric');
            app.StepMethod3.Enable = 'off';
            app.StepMethod3.Value = 5;
            app.PolynomialdegreeLabel = uilabel(app.GridLayout10);
            app.PolynomialdegreeLabel.Text = 'Polynomial degree';
            app.PolynomialDegree = uieditfield(app.GridLayout10, 'numeric');
            app.PolynomialDegree.Value = 4;
            app.subWindowEditFieldLabel = uilabel(app.GridLayout10);
            app.subWindowEditFieldLabel.Enable = 'off';
            app.subWindowEditFieldLabel.Text = 'subWindow';
            app.subWindowEditField = uieditfield(app.GridLayout10, 'numeric');
            app.subWindowEditField.Enable = 'off';
            app.subWindowEditField.Value = 0.1;
            app.subStepEditFieldLabel = uilabel(app.GridLayout10);
            app.subStepEditFieldLabel.Enable = 'off';
            app.subStepEditFieldLabel.Text = 'subStep';
            app.subStepEditField = uieditfield(app.GridLayout10, 'numeric');
            app.subStepEditField.Enable = 'off';
            app.subStepEditField.Value = 0.1;
            app.undolast = uibutton(app.GridLayout2, 'push');
            app.undolast.ButtonPushedFcn = createCallbackFcn(app, @undo, true);
            app.undolast.Tooltip = {'Undo last averaging or filtering operation'};
            app.undolast.Text = '⏎ Undo';
            app.DeleteallROIsButton = uibutton(app.GridLayout2, 'push');
            app.DeleteallROIsButton.ButtonPushedFcn = createCallbackFcn(app, @delete_all_ROI, true);
            app.DeleteallROIsButton.Tooltip = {'Delete all ROIs and handels.'};
            app.DeleteallROIsButton.Text = 'Delete all ROIs';
            app.ROTATEMIRRORANDCROPPanel = uipanel(app.GridLayout2);
            app.ROTATEMIRRORANDCROPPanel.Title = 'ROTATE, MIRROR AND CROP';
            app.GridLayout21 = uigridlayout(app.ROTATEMIRRORANDCROPPanel);
            app.rotate_clockwise = uibutton(app.GridLayout21, 'push');
            app.rotate_clockwise.ButtonPushedFcn = createCallbackFcn(app, @rotate_clockwisePushed, true);
            app.rotate_clockwise.Tooltip = {'Rotate recording 90 deg clockwise'};
            app.rotate_clockwise.Text = '↻';
            app.rotate_counterclockwise = uibutton(app.GridLayout21, 'push');
            app.rotate_counterclockwise.ButtonPushedFcn = createCallbackFcn(app, @rotate_counterclockwisePushed, true);
            app.rotate_counterclockwise.Tooltip = {'Rotate recording 90 deg counterclockwise'};
            app.rotate_counterclockwise.Text = '↺';
            app.MirrorButton = uibutton(app.GridLayout21, 'push');
            app.MirrorButton.ButtonPushedFcn = createCallbackFcn(app, @MirrorButtonPushed, true);
            app.MirrorButton.Tooltip = {'Mirror image along the vertical axis (flip left-right)'};
            app.MirrorButton.Text = 'Mirror';
            app.CropButton = uibutton(app.GridLayout21, 'state');
            app.CropButton.ValueChangedFcn = createCallbackFcn(app, @CropButtonValueChanged, true);
            app.CropButton.Text = 'Crop';
            app.RemoveArtefactFramesButton = uibutton(app.GridLayout2, 'push');
            app.RemoveArtefactFramesButton.ButtonPushedFcn = createCallbackFcn(app, @remove_optical_artefact_frames, true);
            app.RemoveArtefactFramesButton.Text = 'Remove Artefact Frames';
            app.stim_length = uieditfield(app.GridLayout2, 'numeric');
            app.stim_length.Tooltip = {'Optical stimulation length in ms'};
            app.stim_length.Value = 10;
            app.ROIpropertiesTab = uitab(app.TabGroup);
            app.ROIpropertiesTab.Title = 'ROI properties';
            app.GridLayout13 = uigridlayout(app.ROIpropertiesTab);
            app.SelectROIDropDownLabel = uilabel(app.GridLayout13);
            app.SelectROIDropDownLabel.Text = 'Select ROI';
            app.SelectROIDropDown = uidropdown(app.GridLayout13);
            app.SelectROIDropDown.Items = {'ROI 1'};
            app.SelectROIDropDown.ItemsData = {'1'};
            app.SelectROIDropDown.ValueChangedFcn = createCallbackFcn(app, @ROI_dropdown_color_change, true);
            app.SelectROIDropDown.Value = '1';
            app.APDsettingsPanel = uipanel(app.GridLayout13);
            app.APDsettingsPanel.Title = 'APD settings';
            app.GridLayout19 = uigridlayout(app.APDsettingsPanel);
            app.APDAxes = uiaxes(app.GridLayout19);
            xlabel(app.APDAxes, 'time (ms)')
            ylabel(app.APDAxes, 'signal (a.u.)')
            app.APDAxes.XTickLabelRotationMode = 'manual';
            app.APDAxes.YTickLabelRotationMode = 'manual';
            app.APDAxes.ZTickLabelRotationMode = 'manual';
            app.APDpercentageEditFieldLabel_2 = uilabel(app.GridLayout19);
            app.APDpercentageEditFieldLabel_2.Text = 'APD percentage';
            app.APDpercentageEditField = uieditfield(app.GridLayout19, 'numeric');
            app.APDpercentageEditField.Tooltip = {'Percentage of repolarisation to compute APD end'};
            app.APDpercentageEditField.Value = 80;
            app.dfdt_maxpercentageEditFieldLabel = uilabel(app.GridLayout19);
            app.dfdt_maxpercentageEditFieldLabel.Enable = 'off';
            app.dfdt_maxpercentageEditFieldLabel.Text = 'dfdt_max percentage';
            app.dfdt_maxpercentageEditField = uieditfield(app.GridLayout19, 'numeric');
            app.dfdt_maxpercentageEditField.Enable = 'off';
            app.dfdt_maxpercentageEditField.Value = 100;
            app.RMPcalculationDropDownLabel_2 = uilabel(app.GridLayout19);
            app.RMPcalculationDropDownLabel_2.Text = 'RMP calculation';
            app.rmpmethod_ROI = uidropdown(app.GridLayout19);
            app.rmpmethod_ROI.Items = {'-BCL/2 to BCL/2', 'Pre upstroke min', 'Post AP min', 'Avg( Pre Post min)'};
            app.rmpmethod_ROI.ItemsData = {'1', '2', '3', '4'};
            app.rmpmethod_ROI.Tooltip = {'Resting membrane potential estimation method. (1) takes the average before upstroke and average during diastole. (2) Takes the minimum before upstroke. (3) Takes the minimum after repolarisation. (4) Takes the average between the two minima (before and after AP). '};
            app.rmpmethod_ROI.Value = '1';
            app.DesiredAP100allEditFieldLabel = uilabel(app.GridLayout19);
            app.DesiredAP100allEditFieldLabel.Text = 'Desired AP (100 = all)';
            app.DesiredAP100allEditField = uieditfield(app.GridLayout19, 'numeric');
            app.DesiredAP100allEditField.Tooltip = {'Desired APD number to calculate APD from. Value has to be an integer.'};
            app.DesiredAP100allEditField.Value = 1;
            app.ComputeAPDBCLanddVdtmaxButton = uibutton(app.GridLayout13, 'push');
            app.ComputeAPDBCLanddVdtmaxButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeAPDBCLanddVdtmaxButtonPushed, true);
            app.ComputeAPDBCLanddVdtmaxButton.Tooltip = {'Compute APD and dVdtmax of the selected AP. If single AP is selected'; ' BCL is computed from the peak-to-peak interval to the following beat. If all AP are chosen'; ' the BCL is computed from the activation times.'};
            app.ComputeAPDBCLanddVdtmaxButton.Text = {'Compute APD, '; 'BCL, and dVdtmax'};
            app.APDmsEditFieldLabel = uilabel(app.GridLayout13);
            app.APDmsEditFieldLabel.Text = 'APD (ms)';
            app.ROI_APD = uieditfield(app.GridLayout13, 'text');
            app.ROI_APD.Editable = 'off';
            app.dVdtmaxaumsEditFieldLabel = uilabel(app.GridLayout13);
            app.dVdtmaxaumsEditFieldLabel.Text = 'dV/dt max (a.u./ms)';
            app.ROI_dVdt = uieditfield(app.GridLayout13, 'text');
            app.ROI_dVdt.Editable = 'off';
            app.BCLmsEditFieldLabel = uilabel(app.GridLayout13);
            app.BCLmsEditFieldLabel.Text = 'BCL (ms)';
            app.ROI_BCL = uieditfield(app.GridLayout13, 'text');
            app.ROI_BCL.Editable = 'off';
            app.ActivationtimemsLabel = uilabel(app.GridLayout13);
            app.ActivationtimemsLabel.Text = 'Activation time (ms)';
            app.ROI_Actt = uieditfield(app.GridLayout13, 'text');
            app.ROI_Actt.Editable = 'off';
            app.ActivationMapTab = uitab(app.TabGroup);
            app.ActivationMapTab.Title = 'Activation Map';
            app.GridLayout4 = uigridlayout(app.ActivationMapTab);
            app.generatemap = uibutton(app.GridLayout4, 'push');
            app.generatemap.ButtonPushedFcn = createCallbackFcn(app, @generate_map, true);
            app.generatemap.Tooltip = {'Generate activation/APD map (WARNING: needs average beats first)'};
            app.generatemap.Text = 'Generate map';
            app.TypeLabel = uilabel(app.GridLayout4);
            app.TypeLabel.Text = 'Type';
            app.maptype = uidropdown(app.GridLayout4);
            app.maptype.Items = {'Activation time', 'APD', 'df/dt max', 'AP amplitude', 'Number of peaks', 'Repolarization time', 'Mean signal', '20-80 rise speed'};
            app.maptype.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.maptype.ValueChangedFcn = createCallbackFcn(app, @maptypeValueChanged, true);
            app.maptype.Value = '1';
            app.MethodLabel = uilabel(app.GridLayout4);
            app.MethodLabel.Text = 'Method';
            app.mapmethod = uidropdown(app.GridLayout4);
            app.mapmethod.Items = {'df/dt', 'peak', 'cross-correlation', 'mid-point'};
            app.mapmethod.ItemsData = {'1', '2', '3', '4'};
            app.mapmethod.Value = '1';
            app.MethodpercentageLabel = uilabel(app.GridLayout4);
            app.MethodpercentageLabel.Text = 'Method percentage';
            app.mappercentage = uieditfield(app.GridLayout4, 'numeric');
            app.mappercentage.Limits = [0 100];
            app.mappercentage.Value = 100;
            app.APDpercentageEditFieldLabel = uilabel(app.GridLayout4);
            app.APDpercentageEditFieldLabel.Text = 'APD percentage';
            app.apdpercentage = uieditfield(app.GridLayout4, 'numeric');
            app.apdpercentage.Limits = [0 100];
            app.apdpercentage.Value = 80;
            app.CutoffpercentageEditFieldLabel = uilabel(app.GridLayout4);
            app.CutoffpercentageEditFieldLabel.Enable = 'off';
            app.CutoffpercentageEditFieldLabel.Text = 'Cutoff percentage';
            app.cutoffpercentage = uieditfield(app.GridLayout4, 'numeric');
            app.cutoffpercentage.Limits = [0 100];
            app.cutoffpercentage.Enable = 'off';
            app.roimeans = uibutton(app.GridLayout4, 'push');
            app.roimeans.ButtonPushedFcn = createCallbackFcn(app, @roi_means, true);
            app.roimeans.Enable = 'off';
            app.roimeans.Tooltip = {'Display mean of map values within ROI. Beware highly sensitive to noise.'};
            app.roimeans.Text = 'ROI means';
            app.RMPcalculationDropDownLabel = uilabel(app.GridLayout4);
            app.RMPcalculationDropDownLabel.Text = 'RMP calculation';
            app.rmpmethod = uidropdown(app.GridLayout4);
            app.rmpmethod.Items = {'Pre', 'Pre/post', 'Pre/post min', 'Min'};
            app.rmpmethod.ItemsData = {'1', '2', '3', '4'};
            app.rmpmethod.Tooltip = {'Resting membrane potential calculated only pre AP'; ' pre AP and post AP'; ' pre AP and post min'; ' or using the min value.'};
            app.rmpmethod.Value = '1';
            app.SETLIMITSPanel = uipanel(app.GridLayout4);
            app.SETLIMITSPanel.Title = 'SET LIMITS';
            app.GridLayout8 = uigridlayout(app.SETLIMITSPanel);
            app.UIAxesHistogram = uiaxes(app.GridLayout8);
            app.UIAxesHistogram.XTickLabelRotationMode = 'manual';
            app.UIAxesHistogram.YTick = [];
            app.UIAxesHistogram.YTickLabelRotationMode = 'manual';
            app.UIAxesHistogram.ZTickLabelRotationMode = 'manual';
            app.LowerYlimitEditFieldLabel = uilabel(app.GridLayout8);
            app.LowerYlimitEditFieldLabel.Text = 'Lower Y limit';
            app.lowerylimit = uieditfield(app.GridLayout8, 'numeric');
            app.lowerylimit.Limits = [0 Inf];
            app.lowerylimit.ValueChangedFcn = createCallbackFcn(app, @change_map_limits, true);
            app.UpperYlimitEditFieldLabel = uilabel(app.GridLayout8);
            app.UpperYlimitEditFieldLabel.Text = 'Upper Y limit';
            app.upperylimit = uieditfield(app.GridLayout8, 'numeric');
            app.upperylimit.Limits = [0 Inf];
            app.upperylimit.ValueChangedFcn = createCallbackFcn(app, @change_map_limits, true);
            app.newzeromap = uibutton(app.GridLayout8, 'push');
            app.newzeromap.ButtonPushedFcn = createCallbackFcn(app, @new_zero_map, true);
            app.newzeromap.Text = 'New zero';
            app.FILTERINGPanel_2 = uipanel(app.GridLayout4);
            app.FILTERINGPanel_2.Title = 'FILTERING';
            app.GridLayout9 = uigridlayout(app.FILTERINGPanel_2);
            app.spatialfilter_maps = uibutton(app.GridLayout9, 'push');
            app.spatialfilter_maps.ButtonPushedFcn = createCallbackFcn(app, @spatial_filtering_maps, true);
            app.spatialfilter_maps.Tooltip = {'Apply selected spatial filter type to whole stack'};
            app.spatialfilter_maps.Text = 'Apply spatial filter';
            app.spatialFilterType_maps = uidropdown(app.GridLayout9);
            app.spatialFilterType_maps.Items = {'Box linear filter', 'Gaussian filter', 'Median filter', 'Mode filter'};
            app.spatialFilterType_maps.ItemsData = {'1', '2', '3', '4'};
            app.spatialFilterType_maps.Enable = 'off';
            app.spatialFilterType_maps.Tooltip = {'Select type of spatial filter'};
            app.spatialFilterType_maps.Value = '1';
            app.KernelsizeLabel_maps = uilabel(app.GridLayout9);
            app.KernelsizeLabel_maps.Enable = 'off';
            app.KernelsizeLabel_maps.Text = 'Kernel size:';
            app.spatialkernelsize_maps = uidropdown(app.GridLayout9);
            app.spatialkernelsize_maps.Items = {'3x3', '5x5', '7x7', '9x9'};
            app.spatialkernelsize_maps.ItemsData = {'3', '5', '7', '9'};
            app.spatialkernelsize_maps.Tooltip = {'Kernel size'};
            app.spatialkernelsize_maps.Value = '3';
            app.GaussiansigmaLabel_maps = uilabel(app.GridLayout9);
            app.GaussiansigmaLabel_maps.Enable = 'off';
            app.GaussiansigmaLabel_maps.Text = 'Gaussian sigma:';
            app.gaussiansigma_maps = uieditfield(app.GridLayout9, 'numeric');
            app.gaussiansigma_maps.Limits = [0 Inf];
            app.gaussiansigma_maps.Enable = 'off';
            app.gaussiansigma_maps.Tooltip = {'Sigma for Gaussian'};
            app.gaussiansigma_maps.Value = 0.5;
            app.undofiltering = uibutton(app.GridLayout9, 'push');
            app.undofiltering.ButtonPushedFcn = createCallbackFcn(app, @undo_filtering_map, true);
            app.undofiltering.Enable = 'off';
            app.undofiltering.Tooltip = {'Undo last averaging or filtering operation'};
            app.undofiltering.Text = '⏎ Undo filtering';
            app.mapmeans = uibutton(app.GridLayout4, 'push');
            app.mapmeans.ButtonPushedFcn = createCallbackFcn(app, @map_means, true);
            app.mapmeans.Enable = 'off';
            app.mapmeans.Tooltip = {'Compute means of mapped data (either mean APD or mean ACT time). It takes into account the set limits.'};
            app.mapmeans.Text = 'Map means';
            app.Label = uilabel(app.GridLayout4);
            app.Label.Text = '';
            app.mapisochronesbutton = uibutton(app.GridLayout4, 'state');
            app.mapisochronesbutton.ValueChangedFcn = createCallbackFcn(app, @map_isochrones, true);
            app.mapisochronesbutton.Text = 'Isochrones';
            app.IsochroneSeparationmsEditFieldLabel = uilabel(app.GridLayout4);
            app.IsochroneSeparationmsEditFieldLabel.Text = 'Isochrone Separation (ms)';
            app.IsochroneSeparationmsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.IsochroneSeparationmsEditField.ValueDisplayFormat = '%.0f';
            app.IsochroneSeparationmsEditField.Value = 1;
            app.mapisolines = uibutton(app.GridLayout4, 'state');
            app.mapisolines.ValueChangedFcn = createCallbackFcn(app, @map_isolines, true);
            app.mapisolines.Text = 'Isolines';
            app.BeatbyBeatMapsTab = uitab(app.TabGroup);
            app.BeatbyBeatMapsTab.Title = 'Beat by Beat Maps';
            app.GridLayout4_2 = uigridlayout(app.BeatbyBeatMapsTab);
            app.generatebeatmaps = uibutton(app.GridLayout4_2, 'push');
            app.generatebeatmaps.ButtonPushedFcn = createCallbackFcn(app, @generate_beat_maps, true);
            app.generatebeatmaps.Tooltip = {'Generate activation/APD map (WARNING: needs average beats first)'};
            app.generatebeatmaps.Text = 'Generate maps';
            app.TypeLabel_2 = uilabel(app.GridLayout4_2);
            app.TypeLabel_2.Text = 'Type';
            app.maptype_2 = uidropdown(app.GridLayout4_2);
            app.maptype_2.Items = {'Activation time'};
            app.maptype_2.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.maptype_2.Value = '1';
            app.MethodLabel_2 = uilabel(app.GridLayout4_2);
            app.MethodLabel_2.Text = 'Method';
            app.mapmethod_2 = uidropdown(app.GridLayout4_2);
            app.mapmethod_2.Items = {'cross-correlation'};
            app.mapmethod_2.ItemsData = {'1', '2', '3', '4'};
            app.mapmethod_2.Value = '1';
            app.roimeans_2 = uibutton(app.GridLayout4_2, 'push');
            app.roimeans_2.Enable = 'off';
            app.roimeans_2.Tooltip = {'Display mean of map values within ROI. Beware highly sensitive to noise.'};
            app.roimeans_2.Text = 'ROI means';
            app.mapmeans_2 = uibutton(app.GridLayout4_2, 'push');
            app.mapmeans_2.Enable = 'off';
            app.mapmeans_2.Tooltip = {'Compute means of mapped data (either mean APD or mean ACT time). It takes into account the set limits.'};
            app.mapmeans_2.Text = 'Map means';
            app.Label_2 = uilabel(app.GridLayout4_2);
            app.Label_2.Text = '';
            app.mapisochronesbutton_2 = uibutton(app.GridLayout4_2, 'state');
            app.mapisochronesbutton_2.Text = 'Isochrones';
            app.EditFieldLabel_2 = uilabel(app.GridLayout4_2);
            app.EditFieldLabel_2.Text = 'Isochrone Separation (ms)';
            app.EditField = uieditfield(app.GridLayout4_2, 'numeric');
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField.Value = 1;
            app.mapisolines_2 = uibutton(app.GridLayout4_2, 'state');
            app.mapisolines_2.Text = 'Isolines';
            app.OffsettimemsLabel = uilabel(app.GridLayout4_2);
            app.OffsettimemsLabel.Text = 'Offset time (ms)';
            app.offsettime = uieditfield(app.GridLayout4_2, 'numeric');
            app.offsettime.Limits = [0 1000];
            app.offsettime.Value = 30;
            app.SETLIMITSPanel_3 = uipanel(app.GridLayout4_2);
            app.SETLIMITSPanel_3.Title = 'SET LIMITS';
            app.GridLayout8_3 = uigridlayout(app.SETLIMITSPanel_3);
            app.UIAxesHistogram_bbb = uiaxes(app.GridLayout8_3);
            app.UIAxesHistogram_bbb.XTickLabelRotationMode = 'manual';
            app.UIAxesHistogram_bbb.YTick = [];
            app.UIAxesHistogram_bbb.YTickLabelRotationMode = 'manual';
            app.UIAxesHistogram_bbb.ZTickLabelRotationMode = 'manual';
            app.LowerYlimitEditFieldLabel_3 = uilabel(app.GridLayout8_3);
            app.LowerYlimitEditFieldLabel_3.Text = 'Lower Y limit';
            app.lowerylimit_bbb = uieditfield(app.GridLayout8_3, 'numeric');
            app.lowerylimit_bbb.Limits = [0 Inf];
            app.lowerylimit_bbb.ValueChangedFcn = createCallbackFcn(app, @change_map_limits_bbb, true);
            app.UpperYlimitEditFieldLabel_3 = uilabel(app.GridLayout8_3);
            app.UpperYlimitEditFieldLabel_3.Text = 'Upper Y limit';
            app.upperylimit_bbb = uieditfield(app.GridLayout8_3, 'numeric');
            app.upperylimit_bbb.Limits = [0 Inf];
            app.upperylimit_bbb.ValueChangedFcn = createCallbackFcn(app, @change_map_limits_bbb, true);
            app.FrameratefpsEditFieldLabel_2 = uilabel(app.GridLayout4_2);
            app.FrameratefpsEditFieldLabel_2.Text = 'Frame rate (fps)';
            app.setframerate_2 = uieditfield(app.GridLayout4_2, 'numeric');
            app.setframerate_2.Value = 15;
            app.playmovie_2 = uibutton(app.GridLayout4_2, 'push');
            app.playmovie_2.ButtonPushedFcn = createCallbackFcn(app, @play_movie_bbb, true);
            app.playmovie_2.Text = 'Play movie ';
            app.previousframe_2 = uibutton(app.GridLayout4_2, 'push');
            app.previousframe_2.ButtonPushedFcn = createCallbackFcn(app, @previous_frame_bbb, true);
            app.previousframe_2.Text = '◀ Previous';
            app.nextframe_2 = uibutton(app.GridLayout4_2, 'push');
            app.nextframe_2.ButtonPushedFcn = createCallbackFcn(app, @nextframe_bbb, true);
            app.nextframe_2.Text = 'Next ▶';
            app.ConductionVelocityTab = uitab(app.TabGroup);
            app.ConductionVelocityTab.Tooltip = {''};
            app.ConductionVelocityTab.Title = 'Conduction Velocity';
            app.GridLayout22 = uigridlayout(app.ConductionVelocityTab);
            app.SETLIMITSPanel_2 = uipanel(app.GridLayout22);
            app.SETLIMITSPanel_2.Title = 'SET LIMITS';
            app.GridLayout8_2 = uigridlayout(app.SETLIMITSPanel_2);
            app.UIAxesHistogram_traces = uiaxes(app.GridLayout8_2);
            app.UIAxesHistogram_traces.YTick = [];
            app.LowerYlimitEditFieldLabel_2 = uilabel(app.GridLayout8_2);
            app.LowerYlimitEditFieldLabel_2.Text = 'Lower Y limit';
            app.lowerylimit_traces = uieditfield(app.GridLayout8_2, 'numeric');
            app.lowerylimit_traces.Limits = [0 Inf];
            app.lowerylimit_traces.ValueChangedFcn = createCallbackFcn(app, @change_map_limits_traces, true);
            app.UpperYlimitEditFieldLabel_2 = uilabel(app.GridLayout8_2);
            app.UpperYlimitEditFieldLabel_2.Text = 'Upper Y limit';
            app.upperylimit_traces = uieditfield(app.GridLayout8_2, 'numeric');
            app.upperylimit_traces.Limits = [0 Inf];
            app.upperylimit_traces.ValueChangedFcn = createCallbackFcn(app, @change_map_limits_traces, true);
            app.newzeromap_traces = uibutton(app.GridLayout8_2, 'push');
            app.newzeromap_traces.ButtonPushedFcn = createCallbackFcn(app, @new_zero_map_traces, true);
            app.newzeromap_traces.Text = 'New zero';
            app.MeasurelengthButton = uibutton(app.GridLayout22, 'push');
            app.MeasurelengthButton.ButtonPushedFcn = createCallbackFcn(app, @measure_scale, true);
            app.MeasurelengthButton.Text = 'Measure length';
            app.InputScaleButton = uibutton(app.GridLayout22, 'push');
            app.InputScaleButton.ButtonPushedFcn = createCallbackFcn(app, @input_scale, true);
            app.InputScaleButton.Text = 'Input Scale';
            app.CV_map = uibutton(app.GridLayout22, 'push');
            app.CV_map.ButtonPushedFcn = createCallbackFcn(app, @generate_CV_map, true);
            app.CV_map.Text = 'CV map';
            app.CV_method = uidropdown(app.GridLayout22);
            app.CV_method.Items = {'Bayly Method', 'Nearest Neighbour'};
            app.CV_method.Value = 'Bayly Method';
            app.DistancemaxmmEditFieldLabel = uilabel(app.GridLayout22);
            app.DistancemaxmmEditFieldLabel.Text = 'Distance max (mm)';
            app.distancemax = uieditfield(app.GridLayout22, 'numeric');
            app.distancemax.Value = 3;
            app.TimemaxmsEditFieldLabel = uilabel(app.GridLayout22);
            app.TimemaxmsEditFieldLabel.Text = 'Time max (ms)';
            app.timemax = uieditfield(app.GridLayout22, 'numeric');
            app.timemax.Value = 6;
            app.CVmean = uibutton(app.GridLayout22, 'push');
            app.CVmean.ButtonPushedFcn = createCallbackFcn(app, @CV_mean, true);
            app.CVmean.Text = 'CV mean';
            app.CVroimean = uibutton(app.GridLayout22, 'push');
            app.CVroimean.ButtonPushedFcn = createCallbackFcn(app, @CV_roi_mean, true);
            app.CVroimean.Text = 'CV ROI mean';
            app.IsochronesButton = uibutton(app.GridLayout22, 'state');
            app.IsochronesButton.ValueChangedFcn = createCallbackFcn(app, @isochrones, true);
            app.IsochronesButton.Text = 'Isochrones';
            app.isochrone_method = uidropdown(app.GridLayout22);
            app.isochrone_method.Items = {'Automatic', 'Manual', 'Mean ± SD', 'Mode ± SD'};
            app.isochrone_method.Value = 'Automatic';
            app.AreasButton = uibutton(app.GridLayout22, 'push');
            app.AreasButton.ButtonPushedFcn = createCallbackFcn(app, @calculate_isochrone_areas, true);
            app.AreasButton.Text = 'Areas';
            app.CVAnglemean = uibutton(app.GridLayout22, 'push');
            app.CVAnglemean.ButtonPushedFcn = createCallbackFcn(app, @CV_angle_mean, true);
            app.CVAnglemean.Text = 'Angle mean';
            app.CVROIAnglemean = uibutton(app.GridLayout22, 'push');
            app.CVROIAnglemean.ButtonPushedFcn = createCallbackFcn(app, @CV_angle_roi_mean, true);
            app.CVROIAnglemean.Text = 'Angle ROI mean';
            app.CVoutlierexclusion = uibutton(app.GridLayout22, 'state');
            app.CVoutlierexclusion.Tooltip = {'Exclusion of CV out of mean+_2SD in the local area'};
            app.CVoutlierexclusion.Text = 'Remove local outliers';
            app.IncludequiversButton = uibutton(app.GridLayout22, 'state');
            app.IncludequiversButton.Text = 'Include quivers';
            app.IncludequiversButton.Value = true;
            app.IncludeROIButton = uibutton(app.GridLayout22, 'state');
            app.IncludeROIButton.Text = 'Include ROI';
            app.FittingSignalsTab = uitab(app.TabGroup);
            app.FittingSignalsTab.Tooltip = {'Fitting procedure from Ken Wang - cardiac tissue slices paper'};
            app.FittingSignalsTab.Title = 'Fitting Signals';
            app.GridLayout23 = uigridlayout(app.FittingSignalsTab);
            app.FittracesButton = uibutton(app.GridLayout23, 'push');
            app.FittracesButton.ButtonPushedFcn = createCallbackFcn(app, @fit_traces, true);
            app.FittracesButton.Text = 'Fit traces';
            app.dfdtthresholdframerateEditFieldLabel = uilabel(app.GridLayout23);
            app.dfdtthresholdframerateEditFieldLabel.Text = 'dfdt threshold (% frame rate)';
            app.dfdtthresholdframerate = uieditfield(app.GridLayout23, 'numeric');
            app.dfdtthresholdframerate.Value = 10;
            app.RawsignalthresholdEditFieldLabel = uilabel(app.GridLayout23);
            app.RawsignalthresholdEditFieldLabel.Text = 'Raw signal threshold (%)';
            app.signalthreshold = uieditfield(app.GridLayout23, 'numeric');
            app.signalthreshold.Value = 1.5;
            app.SignalmaxpercentileEditFieldLabel = uilabel(app.GridLayout23);
            app.SignalmaxpercentileEditFieldLabel.Text = 'Signal max percentile';
            app.Signalmaxpercentile = uieditfield(app.GridLayout23, 'numeric');
            app.Signalmaxpercentile.Value = 95;
            app.BaselinecalculationDropDownLabel = uilabel(app.GridLayout23);
            app.BaselinecalculationDropDownLabel.Text = 'Baseline calculation';
            app.Baselinecalculation = uidropdown(app.GridLayout23);
            app.Baselinecalculation.Items = {'Histogram', 'Prior', 'Prior/Post'};
            app.Baselinecalculation.Value = 'Histogram';
            app.nonormalise_traces = uibutton(app.GridLayout23, 'state');
            app.nonormalise_traces.ValueChangedFcn = createCallbackFcn(app, @change_normalisation_none_traces, true);
            app.nonormalise_traces.Text = 'No normalisation';
            app.activationmap_traces = uibutton(app.GridLayout23, 'state');
            app.activationmap_traces.ValueChangedFcn = createCallbackFcn(app, @generate_activationmap_traces, true);
            app.activationmap_traces.Text = 'Activation time map';
            app.localnormalise_traces = uibutton(app.GridLayout23, 'state');
            app.localnormalise_traces.ValueChangedFcn = createCallbackFcn(app, @change_normalisation_local_traces, true);
            app.localnormalise_traces.Text = 'Local max normalisation';
            app.APDmap_traces = uibutton(app.GridLayout23, 'state');
            app.APDmap_traces.Text = 'APD map';
            app.APDpercentageEditFieldLabel_3 = uilabel(app.GridLayout23);
            app.APDpercentageEditFieldLabel_3.Text = 'APD percentage';
            app.apdpercentage_2 = uieditfield(app.GridLayout23, 'numeric');
            app.apdpercentage_2.Limits = [0 100];
            app.apdpercentage_2.Value = 80;
            app.ImportBioPACTab = uitab(app.TabGroup);
            app.ImportBioPACTab.Title = 'Import BioPAC';
            app.GridLayout17 = uigridlayout(app.ImportBioPACTab);
            app.AutomaticSearchFolderEditFieldLabel = uilabel(app.GridLayout17);
            app.AutomaticSearchFolderEditFieldLabel.Text = 'Automatic Search Folder';
            app.AutomaticSearchFolderEditField = uieditfield(app.GridLayout17, 'text');
            app.ChangesearchfolderButton = uibutton(app.GridLayout17, 'push');
            app.ChangesearchfolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChangesearchfolderButtonPushed, true);
            app.ChangesearchfolderButton.Text = 'Change search folder';
            app.AutomaticfilesearchandimportButton = uibutton(app.GridLayout17, 'push');
            app.AutomaticfilesearchandimportButton.ButtonPushedFcn = createCallbackFcn(app, @AutomaticfilesearchandimportButtonPushed, true);
            app.AutomaticfilesearchandimportButton.Text = 'Automatic file search and import';
            app.ManualfileselectionandimportButton = uibutton(app.GridLayout17, 'push');
            app.ManualfileselectionandimportButton.ButtonPushedFcn = createCallbackFcn(app, @ManualfileselectionandimportButtonPushed, true);
            app.ManualfileselectionandimportButton.Text = 'Manual file selection and import';
            app.SwitchBioPACShow_Hide = uiswitch(app.GridLayout17, 'slider');
            app.SwitchBioPACShow_Hide.Items = {'Hide', 'Show'};
            app.SwitchBioPACShow_Hide.ValueChangedFcn = createCallbackFcn(app, @SwitchBioPACShow_HideValueChanged, true);
            app.SwitchBioPACShow_Hide.Value = 'Hide';
            app.ShowBioPACDropDown = uidropdown(app.GridLayout17);
            app.ShowBioPACDropDown.Items = {'ECGI', 'ECGII', 'ECGIII', 'Electrical stimulation', 'LED stimulation', 'Camera', 'Force Transducer', 'Motor 1', 'Motor 2'};
            app.ShowBioPACDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.ShowBioPACDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowBioPACDropDownValueChanged, true);
            app.ShowBioPACDropDown.Enable = 'off';
            app.ShowBioPACDropDown.Value = '1';
            app.BioPACfileEditFieldLabel = uilabel(app.GridLayout17);
            app.BioPACfileEditFieldLabel.Text = 'BioPAC file';
            app.BioPACfileEditField = uieditfield(app.GridLayout17, 'text');
            app.BioPACfileEditField.Editable = 'off';
            app.BioPACfileEditField.Enable = 'off';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.GridLayout3 = uigridlayout(app.ExportTab);
            app.savemovie = uibutton(app.GridLayout3, 'push');
            app.savemovie.ButtonPushedFcn = createCallbackFcn(app, @save_movie, true);
            app.savemovie.Enable = 'off';
            app.savemovie.Visible = 'off';
            app.savemovie.Tooltip = {'Save movie'};
            app.savemovie.Text = 'Save movie';
            app.saveimage = uibutton(app.GridLayout3, 'push');
            app.saveimage.ButtonPushedFcn = createCallbackFcn(app, @save_image, true);
            app.saveimage.Tooltip = {'Save current image'};
            app.saveimage.Text = 'Save image';
            app.NotesTextAreaLabel = uilabel(app.GridLayout3);
            app.NotesTextAreaLabel.Text = 'Notes: ';
            app.NotesTextArea = uitextarea(app.GridLayout3);
            app.NotesTextArea.ValueChangedFcn = createCallbackFcn(app, @notes_text, true);
            app.Savelogfile = uibutton(app.GridLayout3, 'push');
            app.Savelogfile.ButtonPushedFcn = createCallbackFcn(app, @save_log_button, true);
            app.Savelogfile.Text = 'Save individual log file 💾';
            app.ExportStacktoWorkspaceButton = uibutton(app.GridLayout3, 'push');
            app.ExportStacktoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportStacktoWorkspaceButtonPushed, true);
            app.ExportStacktoWorkspaceButton.Tooltip = {'Export current stack to matlab workspace. Useful for testing.'};
            app.ExportStacktoWorkspaceButton.Text = 'Export Stack to Workspace';
            app.ExportSignalsPanel = uipanel(app.GridLayout3);
            app.ExportSignalsPanel.Title = 'Export Signals';
            app.GridLayout18 = uigridlayout(app.ExportSignalsPanel);
            app.SaveROIsButton = uibutton(app.GridLayout18, 'push');
            app.SaveROIsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveROIsButtonPushed, true);
            app.SaveROIsButton.Tooltip = {'Export .mat file with the ROI data. See wiki for more detail about how to use this file.'};
            app.SaveROIsButton.Text = 'Save ROI''s';
            app.SaveBioPACButton = uibutton(app.GridLayout18, 'push');
            app.SaveBioPACButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBioPACButtonPushed, true);
            app.SaveBioPACButton.Tooltip = {'Export .mat file with the BIOPAC data. See wiki for more detail about how to use this file.'};
            app.SaveBioPACButton.Text = 'Save BioPAC';
            app.SavetoEditFieldLabel = uilabel(app.GridLayout3);
            app.SavetoEditFieldLabel.Text = 'Save to';
            app.SavetoEditField = uieditfield(app.GridLayout3, 'text');
            app.SavetoEditField.ValueChangedFcn = createCallbackFcn(app, @SavetoEditFieldValueChanged, true);
            app.ChangeFolderButtonSaveto = uibutton(app.GridLayout3, 'push');
            app.ChangeFolderButtonSaveto.ButtonPushedFcn = createCallbackFcn(app, @ChangeFolderButtonSavetoPushed, true);
            app.ChangeFolderButtonSaveto.Tooltip = {'Select folder to where files will be exported.'};
            app.ChangeFolderButtonSaveto.Text = 'Change Folder';
            app.SaveMovieImageadvancedButton = uibutton(app.GridLayout3, 'push');
            app.SaveMovieImageadvancedButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMovieImageadvancedButtonPushed, true);
            app.SaveMovieImageadvancedButton.Text = 'Save Movie/Image advanced';
            app.saveimage_ROI = uibutton(app.GridLayout3, 'push');
            app.saveimage_ROI.ButtonPushedFcn = createCallbackFcn(app, @save_image_ROI, true);
            app.saveimage_ROI.Tooltip = {'Save current image'};
            app.saveimage_ROI.Text = 'Save image with ROI';
            app.HistoryTab = uitab(app.TabGroup);
            app.HistoryTab.Title = 'History';
            app.GridLayout20 = uigridlayout(app.HistoryTab);
            app.history_field = uitextarea(app.GridLayout20);
            app.history_field.Editable = 'off';
            % Show the figure after all components are created
            app.opticalmappingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analyse_optical_mapping

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.opticalmappingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.opticalmappingUIFigure)
        end
    end
end