classdef beat_averaging < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        beat_averaging_UIFigure  matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        GridLayout               matlab.ui.container.GridLayout
        alternans                matlab.ui.control.StateButton
        Xcorrelate               matlab.ui.control.Button
        alignbeats               matlab.ui.control.Button
        duplicateframerate       matlab.ui.control.Button
        Deletebeat               matlab.ui.control.Button
        average                  matlab.ui.control.Button
        right                    matlab.ui.control.Button
        left                     matlab.ui.control.Button
        shiftsize                matlab.ui.control.EditField
        ShiftEditFieldLabel      matlab.ui.control.Label
        beatnumber               matlab.ui.control.DropDown
        BeatLabel                matlab.ui.control.Label
        okbeats                  matlab.ui.control.Button
        lastbeatfield            matlab.ui.control.NumericEditField
        LastbeatLabel            matlab.ui.control.Label
        firstbeatfield           matlab.ui.control.NumericEditField
        FirstbeatEditFieldLabel  matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        calling_app % Main app
        AP_length
        average_beats_stack
        deleted_beats % list of deleted beats
        time_vector
        time_vector_old
        signal
        duplicate_frames_applied
    end
    
    methods (Access = private)
        
        function plot_beats(app)
            cla(app.UIAxes);
            xlabel('Time (ms)','Parent',app.UIAxes);
            ylabel('Signal (a.u.)','Parent',app.UIAxes);
            title('Beat Signals','Parent',app.UIAxes);
            hold(app.UIAxes,'off');

            for k = app.firstbeatfield.Value:app.AP_length:app.lastbeatfield.Value
                if ~ismember(k,app.deleted_beats)
                    beat_signal = app.signal(app.calling_app.averaging.beat_locations(k):app.calling_app.averaging.beat_locations(k+app.AP_length)) - app.signal(app.calling_app.averaging.beat_locations(k));
                    beat_time = 1000*(app.time_vector(app.calling_app.averaging.beat_locations(k):app.calling_app.averaging.beat_locations(k+app.AP_length))-app.time_vector(app.calling_app.averaging.beat_locations(k))) ;
                    plot(beat_time, beat_signal,'Parent',app.UIAxes,'DisplayName',num2str(k));

                    %plot(1000*(app.time_vector(app.calling_app.averaging.beat_locations(k):app.calling_app.averaging.beat_locations(k+1))-app.calling_app.processed_data.signals{end,1}(app.calling_app.averaging.beat_locations(k))) , app.calling_app.processed_data.signals{end,app.calling_app.display.normalisation+1}(app.calling_app.averaging.beat_locations(k):app.calling_app.averaging.beat_locations(k+1)),'Parent',app.UIAxes,'DisplayName',num2str(k));
                    %plot((0:(app.calling_app.averaging.beat_locations(k+1)-app.calling_app.averaging.beat_locations(k))).*(1000/app.calling_app.data.fps),app.calling_app.processed_data.signals{end,app.calling_app.display.normalisation+1}(app.calling_app.averaging.beat_locations(k):app.calling_app.averaging.beat_locations(k+1)),'Parent',app.UIAxes,'DisplayName',num2str(k));
                    hold(app.UIAxes,'on');
                end
            end
            drawnow;
            
            legend(app.UIAxes,'Location','NorthEast');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            movegui(app.beat_averaging_UIFigure,"center"); % Centre figure
            app.calling_app = main_app; % Specify calling app so as to resume it later

            app.time_vector = app.calling_app.processed_data.signals{end,1}; %time trace of the last ROI
            app.signal = app.calling_app.processed_data.signals{end,app.calling_app.display.normalisation+1}; %signal trace of the last ROI
            app.lastbeatfield.Value = length(app.calling_app.averaging.beat_locations)-1;
            app.shiftsize.Value = [num2str(1000/app.calling_app.data.fps,2) ' ms'];
            app.duplicate_frames_applied = 0;
            
            %create modified stack. All actions will be performed on this stack (deleting beats)
            app.average_beats_stack = app.calling_app.processed_data.image_stack{1};
            app.AP_length = 1;
            
            plot(1000*diff(app.calling_app.averaging.beat_locations)./app.calling_app.data.fps,'Parent',app.UIAxes);
            xlabel('Beat number','Parent',app.UIAxes);
            ylabel('Time (ms)','Parent',app.UIAxes);
        end

        % Button pushed function: okbeats
        function beats_chosen(app, event)
            plot_beats(app);
            
            app.beatnumber.Items = compose('%i',app.firstbeatfield.Value:app.AP_length:app.lastbeatfield.Value);
            app.beatnumber.Value = num2str(app.firstbeatfield.Value);
            
%             %Eliminate part of the stack to first and last accepted beats
%             idx_first = app.calling_app.averaging.beat_locations(app.firstbeatfield.Value);
%             idx_last = app.calling_app.averaging.beat_locations(app.lastbeatfield.Value);
%             app.modified_stack = app.modified_stack(:,:, idx_first:idx_last);
            
            set([app.firstbeatfield, app.lastbeatfield, app.okbeats, app.alternans],'Enable','off');
            set([app.beatnumber, app.shiftsize, app.left, app.right, app.average, app.Deletebeat, app.duplicateframerate app.alignbeats, app.Xcorrelate],'Enable','on');
        end

        % Button pushed function: left
        function move_left(app, event)
            app.calling_app.averaging.beat_locations(str2double(app.beatnumber.Value)) = app.calling_app.averaging.beat_locations(str2double(app.beatnumber.Value))+1;
            plot_beats(app);
        end

        % Button pushed function: right
        function move_right(app, event)
            app.calling_app.averaging.beat_locations(str2double(app.beatnumber.Value)) = max([1,app.calling_app.averaging.beat_locations(str2double(app.beatnumber.Value))-1]);
            plot_beats(app);
        end

        % Button pushed function: Deletebeat
        function delete_beat(app, event)
            app.deleted_beats = [app.deleted_beats str2num(app.beatnumber.Value) ];


            plot_beats(app);
        end

        % Button pushed function: average
        function average_beats(app, event)
            % Start progress bar
            progress_bar = uiprogressdlg(app.beat_averaging_UIFigure,'Title','Averaging','Indeterminate','on');
            
            % Minimum length of sequences
            length = app.AP_length*min(diff(app.calling_app.averaging.beat_locations(app.firstbeatfield.Value:app.lastbeatfield.Value)));
            
            % Create cell array for concatenation
            int_cell_array = {};
            for k = app.firstbeatfield.Value:app.AP_length:app.lastbeatfield.Value
                if ~ismember(k,app.deleted_beats)
                    int_cell_array{k-app.firstbeatfield.Value+1} = app.average_beats_stack(:,:,app.calling_app.averaging.beat_locations(k):app.calling_app.averaging.beat_locations(k)+length-1);
                end
            end
            
            % Concatenate and average array. Delete other normalisations.
            int_array = cat(4,int_cell_array{:});
            app.calling_app.processed_data.image_stack = {};
            app.average_beats_stack = nanmean(int_array,4);
            
            % Downsample the averaged stack in order to correct for the artificially increased fps
            %app.average_beats_stack = app.average_beats_stack(:,:,1:2^app.duplicate_frames_applied:end);
            app.calling_app.data.fps = 1/app.time_vector(2);
            app.calling_app.processed_data.image_stack{1} = app.average_beats_stack ;         
            % Clear signals and set that all normalisations still need to be
            % done
            app.calling_app.processed_data.signals = [];
            app.calling_app.display.renorm = ones(size(app.calling_app.display.renorm));
            app.calling_app.processed_data.n_frames = size(app.average_beats_stack,3);
          
            % Close progress bar and resume app
            uiresume(app.calling_app.opticalmappingUIFigure);
            app.calling_app.tool_tip_update;
              
            close(progress_bar);
            delete(app)
        end

        % Button pushed function: duplicateframerate
        function duplicateframerateButtonPushed(app, event)
            %This function will interpolate the stack in the time axis, effectively increassing the frame rate by 2
            progress_bar = uiprogressdlg(app.beat_averaging_UIFigure,'Title','Duplicating frame-rate','Indeterminate','on');
            
            %Generate base meshgrid
            y = 1:size(app.average_beats_stack,1);
            x = 1:size(app.average_beats_stack,2);
            t = 1:size(app.average_beats_stack,3);
            [X,Y,T] = meshgrid(x,y, t);
            %Generate a query meshgrid with the stack for using in interp3
            yq = 1:size(app.average_beats_stack,1);
            xq = 1:size(app.average_beats_stack,2);
            tq = 1:0.5:size( app.average_beats_stack,3)+1;
            [Xq,Yq,Tq] = meshgrid(xq,yq,tq);
            
            %generate interpolated stack
            app.average_beats_stack = interp3(X,Y,T,app.average_beats_stack,Xq,Yq,Tq);
            
            %Now need to update the time axis of the plots by dividng the step by half (=duplicate frame rate)
            app.time_vector_old = app.time_vector;
            app.time_vector = 0: app.time_vector(2)/2: app.time_vector(end);
            
            %Now update beat locations in respect to the new time vector app.calling_app.averaging.beat_locations(k) find(ismember(time_vector_new,time_vector_old(beat_loc)))
            app.calling_app.averaging.beat_locations = find(ismember(app.time_vector,app.time_vector_old(app.calling_app.averaging.beat_locations)));
            
            %now interpolate signal trace
            app.signal = interp1(app.time_vector_old,app.signal, app.time_vector);
            
            %align beats with interpolation to find act time
            align_beats_and_replot(app)
            
            %Update shift size value
            app.shiftsize.Value = [num2str(1000*app.time_vector(2)) 'ms'];
            
            app.duplicate_frames_applied = app.duplicate_frames_applied +1;
            
            close(progress_bar)
        end

        % Button pushed function: alignbeats
        function align_beats_and_replot(app, event)
            %% Align beats using activation time calculated from interpolating around the maximum
            
           
            %now we have to find the closest idx location of the activation time in the time_vector
            [~, app.calling_app.averaging.beat_locations] = min(abs(app.calling_app.activation_times -app.time_vector.'));
            
            % Substract 20% of mean interbeat interval from locations to ensure start of AP is
            % included
            app.calling_app.averaging.beat_locations = app.calling_app.averaging.beat_locations - ceil(0.2*mean(diff(app.calling_app.averaging.beat_locations)));
            if app.calling_app.averaging.beat_locations(1) < 1
                app.calling_app.averaging.beat_locations = app.calling_app.averaging.beat_locations + 1 - app.calling_app.averaging.beat_locations(1);
            end
            
            %Replot singals
            plot_beats(app)
            
        end

        % Button pushed function: Xcorrelate
        function cross_correlate_and_replot(app, event)
            first_beat = app.signal(app.calling_app.averaging.beat_locations(app.firstbeatfield.Value):app.calling_app.averaging.beat_locations(app.firstbeatfield.Value+1)) - app.signal(app.calling_app.averaging.beat_locations(app.firstbeatfield.Value));
            for k = app.firstbeatfield.Value+1:app.lastbeatfield.Value
                if ~ismember(k,app.deleted_beats)
                    beat_signal = app.signal(app.calling_app.averaging.beat_locations(k):app.calling_app.averaging.beat_locations(k+1)) - app.signal(app.calling_app.averaging.beat_locations(k));
                    [~,~,delay] = alignsignals(first_beat,beat_signal);
                    app.calling_app.averaging.beat_locations(k) = app.calling_app.averaging.beat_locations(k) + delay;
                end
            end
            plot_beats(app)
        end

        % Value changed function: alternans
        function alternansValueChanged(app, event)
            if app.alternans.Value == 1;
                app.AP_length = 2;
                app.lastbeatfield.Value = 2*floor(app.lastbeatfield.Value/2);
            else
                app.AP_length = 1;
                app.lastbeatfield.Value = length(app.calling_app.averaging.beat_locations)-1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.beat_averaging_UIFigure = uifigure('Visible', 'off');
            app.beat_averaging_UIFigure.Name = 'Beat averaging';
            app.UIAxes = uiaxes(app.beat_averaging_UIFigure);
            title(app.UIAxes, 'Beat period')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Panel = uipanel(app.beat_averaging_UIFigure);
            app.Panel.Tooltip = {''};
            app.GridLayout = uigridlayout(app.Panel);
            app.FirstbeatEditFieldLabel = uilabel(app.GridLayout);
            app.FirstbeatEditFieldLabel.Text = 'First beat';
            app.firstbeatfield = uieditfield(app.GridLayout, 'numeric');
            app.firstbeatfield.Limits = [1 Inf];
            app.firstbeatfield.RoundFractionalValues = 'on';
            app.firstbeatfield.ValueDisplayFormat = '%d';
            app.firstbeatfield.Value = 1;
            app.LastbeatLabel = uilabel(app.GridLayout);
            app.LastbeatLabel.Text = 'Last beat';
            app.lastbeatfield = uieditfield(app.GridLayout, 'numeric');
            app.lastbeatfield.Limits = [1 Inf];
            app.lastbeatfield.RoundFractionalValues = 'on';
            app.lastbeatfield.ValueDisplayFormat = '%d';
            app.lastbeatfield.Value = 1;
            app.okbeats = uibutton(app.GridLayout, 'push');
            app.okbeats.ButtonPushedFcn = createCallbackFcn(app, @beats_chosen, true);
            app.okbeats.Text = 'OK';
            app.BeatLabel = uilabel(app.GridLayout);
            app.BeatLabel.Text = 'Beat';
            app.beatnumber = uidropdown(app.GridLayout);
            app.beatnumber.Items = {'1', '2'};
            app.beatnumber.Enable = 'off';
            app.beatnumber.Value = '1';
            app.ShiftEditFieldLabel = uilabel(app.GridLayout);
            app.ShiftEditFieldLabel.Text = 'Shift';
            app.shiftsize = uieditfield(app.GridLayout, 'text');
            app.shiftsize.Editable = 'off';
            app.shiftsize.Enable = 'off';
            app.shiftsize.Value = '1 ms';
            app.left = uibutton(app.GridLayout, 'push');
            app.left.ButtonPushedFcn = createCallbackFcn(app, @move_left, true);
            app.left.Enable = 'off';
            app.left.Tooltip = {'Shift selected beat to the left'};
            app.left.Text = 'Left';
            app.right = uibutton(app.GridLayout, 'push');
            app.right.ButtonPushedFcn = createCallbackFcn(app, @move_right, true);
            app.right.Enable = 'off';
            app.right.Tooltip = {'Shift selected beat to the right'};
            app.right.Text = 'Right';
            app.average = uibutton(app.GridLayout, 'push');
            app.average.ButtonPushedFcn = createCallbackFcn(app, @average_beats, true);
            app.average.Enable = 'off';
            app.average.Tooltip = {'Average beat set.'};
            app.average.Text = 'Average';
            app.Deletebeat = uibutton(app.GridLayout, 'push');
            app.Deletebeat.ButtonPushedFcn = createCallbackFcn(app, @delete_beat, true);
            app.Deletebeat.Enable = 'off';
            app.Deletebeat.Tooltip = {'Exclude current selected beat from the average set'};
            app.Deletebeat.Text = 'Delete beat ⚠';
            app.duplicateframerate = uibutton(app.GridLayout, 'push');
            app.duplicateframerate.ButtonPushedFcn = createCallbackFcn(app, @duplicateframerateButtonPushed, true);
            app.duplicateframerate.Enable = 'off';
            app.duplicateframerate.Tooltip = {'Double frame-rate by interpolating data. This will allow a finer alignment.'};
            app.duplicateframerate.Text = 'Double frame-rate';
            app.alignbeats = uibutton(app.GridLayout, 'push');
            app.alignbeats.ButtonPushedFcn = createCallbackFcn(app, @align_beats_and_replot, true);
            app.alignbeats.Enable = 'off';
            app.alignbeats.Tooltip = {'Re-align beats by activation time computed as maximum upstroke velocity time.'};
            app.alignbeats.Text = 'Align beats';
            app.Xcorrelate = uibutton(app.GridLayout, 'push');
            app.Xcorrelate.ButtonPushedFcn = createCallbackFcn(app, @cross_correlate_and_replot, true);
            app.Xcorrelate.Enable = 'off';
            app.Xcorrelate.Text = 'X correlate';
            app.alternans = uibutton(app.GridLayout, 'state');
            app.alternans.ValueChangedFcn = createCallbackFcn(app, @alternansValueChanged, true);
            app.alternans.Text = 'Alternans';
            % Show the figure after all components are created
            app.beat_averaging_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = beat_averaging(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.beat_averaging_UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.beat_averaging_UIFigure)
        end
    end
end