classdef open_file_partially < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        pickrangeUIFigure               matlab.ui.Figure
        ECGPanel                        matlab.ui.container.Panel
        ShowBioPACDropDown              matlab.ui.control.DropDown
        BioPACfileEditField             matlab.ui.control.EditField
        BioPACfileEditFieldLabel        matlab.ui.control.Label
        AutomaticSearchFolderEditField  matlab.ui.control.EditField
        AutomaticSearchFolderEditFieldLabel  matlab.ui.control.Label
        ManualfileselectionandimportButton  matlab.ui.control.Button
        AutomaticfilesearchandimportButton  matlab.ui.control.Button
        ChangesearchfolderButton        matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        CancelButton                    matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        total_recording_time_field      matlab.ui.control.NumericEditField
        TotalrecordingtimesLabel        matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        end_time_field                  matlab.ui.control.NumericEditField
        EndtimesLabel                   matlab.ui.control.Label
        start_time_field                matlab.ui.control.NumericEditField
        StarttimesLabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        calling_app; % Main app
        start_frame;
        end_frame;
        fps;
        BioPAC_file;
        BioPAC_path_file;
        channel_data;
        file_name;
    end
    
    methods (Access = private)
        
        function BioPAC_file = search_BioPAC_file(app)
            % BioPAC file finder
            OM_file = app.file_name; % 'XXh-YYm-ZZs'
            
            time_stamp = regexp(OM_file,'[\d\.]+','match');
            time_stamp = str2double(time_stamp); % [XX YY ZZ]
            % Convert timestamp to absolute time (seconds)
            OM_absolute_time = time_stamp(1)*3600 + time_stamp(2)*60 + time_stamp(3);
            
            file_list  = dir(strcat(app.AutomaticSearchFolderEditField.Value,'PanoramicSystemStandardRecording*T*.acq')); %find Acqknowledge files
            file_list = file_list(2:end); % not take into account the first file which is t
            num_files = length(file_list);
            
            list_absolute_times = zeros(num_files,1);
            
            for k=1:num_files
                % compute absolute time for each file
                time_stamp = regexp(file_list(k).name,'[\d*]+','match');
                time_stamp = str2double(time_stamp);
                time_stamp = time_stamp(end-2:end);
                list_absolute_times(k) = time_stamp(1)*3600 + time_stamp(2)*60 + time_stamp(3);
            end
            
            time_diff = list_absolute_times - OM_absolute_time;
            
            idx = find(time_diff < 0 );
            idx = idx(end); % Take the BioPAC recording that started before the OM recording (ideally that's the one)
            
            BioPAC_file = file_list(idx).name;
        end


        function load_BioPAC_old(app)
            file_name = app.BioPAC_path_file;

            BioPAC = load_acq(file_name,0);
            
            chan_num = length(BioPAC.data(1,:)); %number of channels
            num_samples = length(BioPAC.data(:,1)); %length of acquisition in samples
            
            %Get channel names
            chan_names = struct2cell(BioPAC.hdr.per_chan_data);
            chan_names = chan_names(3,1,:);
            
            %get units 
            chan_units = struct2cell(BioPAC.hdr.per_chan_data);
            chan_units = chan_units(8,1,:);
            
            %Find at which position (channel) each particular measurement is stored
            app.channel_data.local_perf.idx = find(strcmp(chan_names,'Local Perfusion'));
            app.channel_data.elec_stim.idx = find(strcmp(chan_names,'Electrical Stimulation'));
            app.channel_data.ECG1.idx = find(strcmp(chan_names,'ECG I'));
            app.channel_data.ECG2.idx = find(strcmp(chan_names,'ECG II'));
            app.channel_data.ECG3.idx = find(strcmp(chan_names,'ECG III'));
            app.channel_data.aort_press.idx = find(strcmp(chan_names,'Aortic Pressure'));
            app.channel_data.aort_flow.idx = find(strcmp(chan_names,'Aortic Flow'));
            app.channel_data.temp.idx = find(strcmp(chan_names,'Temperature'));
            app.channel_data.camera.idx = find(strcmp(chan_names,'Camera Exposure'));
            
            %Get units of the measurements
%             app.channel_data.local_perf.units = chan_units{app.channel_data.local_perf.idx};
%             app.channel_data.elec_stim.units = chan_units{app.channel_data.elec_stim.idx};
%             app.channel_data.ECG1.units = chan_units{app.channel_data.ECG1.idx};
%             app.channel_data.ECG2.units = chan_units{app.channel_data.ECG2.idx};
%             app.channel_data.ECG3.units = chan_units{app.channel_data.ECG3.idx};
%             app.channel_data.aort_press.units = chan_units{app.channel_data.aort_press.idx};
%             app.channel_data.aort_flow.units = chan_units{app.channel_data.aort_flow.idx};
%             app.channel_data.temp.units = chan_units{app.channel_data.temp.idx};
%             app.channel_data.camera.units = chan_units{app.channel_data.camera.idx};
            
            %Store data vector if the measurement is registered
            if ~isempty(app.channel_data.local_perf.idx)
                app.channel_data.local_perf.units = chan_units{app.channel_data.local_perf.idx};
                app.channel_data.local_perf.data = BioPAC.data(:,app.channel_data.local_perf.idx);
            end
            if ~isempty(app.channel_data.elec_stim.idx)
                app.channel_data.elec_stim.units = chan_units{app.channel_data.elec_stim.idx};
                app.channel_data.elec_stim.data = BioPAC.data(:,app.channel_data.elec_stim.idx);
            end
            if ~isempty(app.channel_data.ECG1.idx)
                app.channel_data.ECG1.units = chan_units{app.channel_data.ECG1.idx};
                app.channel_data.ECG1.data = BioPAC.data(:,app.channel_data.ECG1.idx);
            end
            if ~isempty(app.channel_data.ECG2.idx)
                app.channel_data.ECG2.units = chan_units{app.channel_data.ECG2.idx};
                app.channel_data.ECG2.data = BioPAC.data(:,app.channel_data.ECG2.idx);
            end
            if ~isempty(app.channel_data.ECG3.idx)
                app.channel_data.ECG3.units = chan_units{app.channel_data.ECG3.idx};
                app.channel_data.ECG3.data = BioPAC.data(:,app.channel_data.ECG3.idx);
            end
            if ~isempty(app.channel_data.aort_press.idx)
                app.channel_data.aort_press.units = chan_units{app.channel_data.aort_press.idx};
                app.channel_data.aort_press.data = BioPAC.data(:,app.channel_data.aort_press.idx);
            end
            if ~isempty(app.channel_data.aort_flow.idx)
                app.channel_data.aort_flow.units = chan_units{app.channel_data.aort_flow.idx};
                app.channel_data.aort_flow.data = BioPAC.data(:,app.channel_data.aort_flow.idx);
            end
            if ~isempty(app.channel_data.temp.idx)
                app.channel_data.temp.units = chan_units{app.channel_data.temp.idx};
                app.channel_data.temp.data = BioPAC.data(:,app.channel_data.temp.idx);
            end
            if ~isempty(app.channel_data.camera.idx)
                app.channel_data.camera.units = chan_units{app.channel_data.camera.idx};
                app.channel_data.camera.data = BioPAC.data(:,app.channel_data.camera.idx);
            end
            
            app.channel_data.sampling_time = BioPAC.hdr.graph.sample_time*1e-3; %from ms to s
            sampling_rate = 1/app.channel_data.sampling_time;
            time = linspace(0,app.channel_data.sampling_time*num_samples, num_samples); %time vector in s
            
            %% Extract data only during camera exposure ON
            camera_ON = find(app.channel_data.camera.data > 0.5);
            recording_start = camera_ON(1);
            recording_end = camera_ON(end);
            
            
            if ~isempty(app.channel_data.local_perf.idx)
                app.channel_data.local_perf.data = app.channel_data.local_perf.data(recording_start:recording_end);
            end
            if ~isempty(app.channel_data.elec_stim.idx)
                app.channel_data.elec_stim.data = app.channel_data.elec_stim.data(recording_start:recording_end);
            end
            if ~isempty(app.channel_data.ECG1.idx)
                app.channel_data.ECG1.data = app.channel_data.ECG1.data(recording_start:recording_end);
%                 app.channel_data.ECG1.data = smoothdata(app.channel_data.ECG1.data,'gaussian', sampling_rate/0.03);
                app.channel_data.ECG1.data = app.channel_data.ECG1.data - min(app.channel_data.ECG1.data);
                app.channel_data.ECG1.data = app.channel_data.ECG1.data ./max(app.channel_data.ECG1.data);
            end
            if ~isempty(app.channel_data.ECG2.idx)
                app.channel_data.ECG2.data = app.channel_data.ECG2.data(recording_start:recording_end);
%                 app.channel_data.ECG2.data = smoothdata(app.channel_data.ECG2.data,'gaussian', sampling_rate/0.03);
                app.channel_data.ECG2.data = app.channel_data.ECG2.data - min(app.channel_data.ECG2.data);
                app.channel_data.ECG2.data = app.channel_data.ECG2.data ./max(app.channel_data.ECG2.data);
            end
            if ~isempty(app.channel_data.ECG3.idx)
                app.channel_data.ECG3.data = app.channel_data.ECG3.data(recording_start:recording_end);
%                 app.channel_data.ECG3.data = smoothdata(app.channel_data.ECG3.data,'gaussian', sampling_rate/0.03);
                app.channel_data.ECG3.data = app.channel_data.ECG3.data - min(app.channel_data.ECG3.data);
                app.channel_data.ECG3.data = app.channel_data.ECG3.data ./max(app.channel_data.ECG3.data);
            end
            if ~isempty(app.channel_data.aort_press.idx)
                app.channel_data.aort_press.data = app.channel_data.aort_press.data(recording_start:recording_end);
            end
            if ~isempty(app.channel_data.aort_flow.idx)
                app.channel_data.aort_flow.data = app.channel_data.aort_flow.data(recording_start:recording_end);
            end
            if ~isempty(app.channel_data.temp.idx)
                app.channel_data.temp.data = app.channel_data.temp.data(recording_start:recording_end);
            end
            if ~isempty(app.channel_data.camera.idx)
                app.channel_data.camera.data = app.channel_data.camera.data(recording_start:recording_end);
            end
            
            num_samples_recording = length(app.channel_data.camera.data);
            app.channel_data.time_recording = linspace(0,app.channel_data.sampling_time*num_samples_recording, num_samples_recording);  
            
            set([app.ShowBioPACDropDown],'Enable','on')
        end
    
        
        function plot_ECG(app)
            BioPAC_signal = app.ShowBioPACDropDown.Value;
            set([app.UIAxes],'Visible','on')
            
            if ~isempty(app.channel_data.LED.idx)
                channels_signal = {app.channel_data.ECG1.data, app.channel_data.ECG2.data, app.channel_data.ECG3.data, app.channel_data.elec_stim.data, app.channel_data.LED.data, app.channel_data.camera.data };
            else
                channels_signal = {app.channel_data.ECG1.data, app.channel_data.ECG2.data, app.channel_data.ECG3.data, app.channel_data.elec_stim.data,app.channel_data.elec_stim.data, app.channel_data.camera.data};
            end
            ECG_handle = plot(app.channel_data.time_recording, channels_signal{str2num(BioPAC_signal)},'r','Parent',app.UIAxes);
            xlim(app.UIAxes,[0, app.total_recording_time_field.Value])
        end
        
        
        function load_BioPAC(app)
            file_name = app.BioPAC_path_file;
            
            BioPAC = load_acq(file_name,0);
            
            chan_num = length(BioPAC.data(1,:)); %number of channels
            num_samples = length(BioPAC.data(:,1)); %length of acquisition in samples
            
            %Get channel names
            chan_names = struct2cell(BioPAC.hdr.per_chan_data);
            chan_names = chan_names(3,1,:);
            
            %get units
            chan_units = struct2cell(BioPAC.hdr.per_chan_data);
            chan_units = chan_units(8,1,:);
            
            %Find at which position (channel) each particular measurement is stored
            app.channel_data.local_perf.idx = find(strcmp(chan_names,'Local Perfusion'));
            app.channel_data.elec_stim.idx = find(strcmp(chan_names,'Electrical Stimulation'));
            app.channel_data.ECG1.idx = find(strcmp(chan_names,'ECG I'));
            app.channel_data.ECG2.idx = find(strcmp(chan_names,'ECG II'));
            app.channel_data.ECG3.idx = find(strcmp(chan_names,'ECG III'));
            app.channel_data.aort_press.idx = find(strcmp(chan_names,'Aortic Pressure'));
            app.channel_data.aort_flow.idx = find(strcmp(chan_names,'Aortic Flow'));
            app.channel_data.temp.idx = find(strcmp(chan_names,'Temperature'));
            app.channel_data.camera.idx = find(strcmp(chan_names,'Camera Exposure'));
            app.channel_data.LED.idx = find(strcmp(chan_names,'LED'));
            
            %Get units of the measurements
%             app.channel_data.local_perf.units = chan_units{app.channel_data.local_perf.idx};
%             app.channel_data.elec_stim.units = chan_units{app.channel_data.elec_stim.idx};
%             app.channel_data.ECG1.units = chan_units{app.channel_data.ECG1.idx};
%             app.channel_data.ECG2.units = chan_units{app.channel_data.ECG2.idx};
%             app.channel_data.ECG3.units = chan_units{app.channel_data.ECG3.idx};
%             app.channel_data.aort_press.units = chan_units{app.channel_data.aort_press.idx};
%             app.channel_data.aort_flow.units = chan_units{app.channel_data.aort_flow.idx};
%             app.channel_data.temp.units = chan_units{app.channel_data.temp.idx};
%             app.channel_data.camera.units = chan_units{app.channel_data.camera.idx};
            
             %Store data vector if the measurement is registered
            if ~isempty(app.channel_data.local_perf.idx)
                app.channel_data.local_perf.units = chan_units{app.channel_data.local_perf.idx};
                app.channel_data.local_perf.data = BioPAC.data(:,app.channel_data.local_perf.idx);
            end
            if ~isempty(app.channel_data.elec_stim.idx)
                app.channel_data.elec_stim.units = chan_units{app.channel_data.elec_stim.idx};
                app.channel_data.elec_stim.data = BioPAC.data(:,app.channel_data.elec_stim.idx);
            end
            if ~isempty(app.channel_data.ECG1.idx)
                app.channel_data.ECG1.units = chan_units{app.channel_data.ECG1.idx};
                app.channel_data.ECG1.data = BioPAC.data(:,app.channel_data.ECG1.idx);
            end
            if ~isempty(app.channel_data.ECG2.idx)
                app.channel_data.ECG2.units = chan_units{app.channel_data.ECG2.idx};
                app.channel_data.ECG2.data = BioPAC.data(:,app.channel_data.ECG2.idx);
            end
            if ~isempty(app.channel_data.ECG3.idx)
                app.channel_data.ECG3.units = chan_units{app.channel_data.ECG3.idx};
                app.channel_data.ECG3.data = BioPAC.data(:,app.channel_data.ECG3.idx);
            end
            if ~isempty(app.channel_data.aort_press.idx)
                app.channel_data.aort_press.units = chan_units{app.channel_data.aort_press.idx};
                app.channel_data.aort_press.data = BioPAC.data(:,app.channel_data.aort_press.idx);
            end
            if ~isempty(app.channel_data.aort_flow.idx)
                app.channel_data.aort_flow.units = chan_units{app.channel_data.aort_flow.idx};
                app.channel_data.aort_flow.data = BioPAC.data(:,app.channel_data.aort_flow.idx);
            end
            if ~isempty(app.channel_data.temp.idx)
                app.channel_data.temp.units = chan_units{app.channel_data.temp.idx};
                app.channel_data.temp.data = BioPAC.data(:,app.channel_data.temp.idx);
            end
            if ~isempty(app.channel_data.camera.idx)
                app.channel_data.camera.units = chan_units{app.channel_data.camera.idx};
                app.channel_data.camera.data = BioPAC.data(:,app.channel_data.camera.idx);
            end
            if ~isempty(app.channel_data.LED.idx)
                app.channel_data.LED.units = chan_units{app.channel_data.LED.idx};
                app.channel_data.LED.data = BioPAC.data(:,app.channel_data.LED.idx);
            end
            
            app.channel_data.sampling_time = BioPAC.hdr.graph.sample_time*1e-3; %from ms to s
            sampling_rate = 1/app.channel_data.sampling_time;
            time = linspace(0,app.channel_data.sampling_time*num_samples, num_samples); %Total Acq time vector in s
            
            %% Extract data only during camera exposure ON
            %Case where camera is off at the beginning of BioPAC recording
            
            camera_ON = find(app.channel_data.camera.data > 0.5);
            recording_start_notcut = camera_ON(1);
            recording_end_notcut = camera_ON(end);
            
%             if ~isempty(app.channel_data.camera.idx)
%                 app.channel_data.camera.data = app.channel_data.camera.data(recording_start_notcut:recording_end_notcut);
%             end
%             num_samples_recording = length(app.channel_data.camera.data);
            
            
            %app.channel_data.time_recording = linspace(0,app.channel_data.sampling_time*num_samples_recording, num_samples_recording);
            
            if ~(recording_start_notcut == 1) %The camera is not active when the Acq starts recording (nothing missing in the beginning)
                app.channel_data.time_recording = time - recording_start_notcut*app.channel_data.sampling_time;
            elseif recording_start_notcut == 1 && ~(recording_end_notcut == num_samples) %camera already active when Acq starts and is off when Acq stops = align with end time
                app.channel_data.time_recording = time + ( app.total_recording_time_field.Value - time(recording_end_notcut));
            end
            set([app.ShowBioPACDropDown],'Enable','on')
            % Take into account that the data could have been clipped
            % Calculate start and ending times using the current frame range
            %camera_start_time = (app.processed_data.frame_range(1)-1)/app.data.fps;
            %If the data was clipped the time is shifted so it is aligned with the movie time
            %app.channel_data.time_recording = app.channel_data.time_recording -  camera_start_time;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            movegui(app.pickrangeUIFigure,"center")
            
            app.calling_app = main_app;
            
            [~, app.file_name, file_type] = fileparts(app.calling_app.file_info.file); % Find file type
            
            if strcmp(file_type,'.sif') % If SIF load
            %Read file header
                loaded_sif = sifreadHeader([app.calling_app.file_info.path , app.calling_app.file_info.file]);
                app.fps = 1/loaded_sif.stackCycleTime;
                app.total_recording_time_field.Value = (loaded_sif.kineticLength - 1)/app.fps;

            elseif strcmp(file_type,'.fits') % If fits file
                fits_header = fitsinfo([app.calling_app.file_info.path , app.calling_app.file_info.file]);
                app.fps = 1/fits_header.PrimaryData.Keywords{1==(strcmp(fits_header.PrimaryData.Keywords(:,1),'KCT')),2};
                app.total_recording_time_field.Value = (fits_header.PrimaryData.Keywords{1==(strcmp(fits_header.PrimaryData.Keywords(:,1),'NAXIS3')),2}-1)/app.fps;

            elseif strcmp(file_type,'.dat')            
                dat_header = dat_header_reader([app.calling_app.file_info.path , app.calling_app.file_info.file],'l');
                app.fps = dat_header.acquisitionFrequency;
                app.total_recording_time_field.Value = (dat_header.frames - 1)/app.fps;
                app.start_time_field.Value = 10/app.fps;
            end
            
            %set starting and ending frames by default
            app.start_frame = 0;
            app.end_time_field.Value = app.total_recording_time_field.Value;
            app.end_frame = floor(app.end_time_field.Value*app.fps)+1;
            
            
            % Set automatic search folder for BioPAC file
            path_parts = regexp(app.calling_app.file_info.path,'/','split');
            search_folder = string(join(path_parts(1:end-3),'/'));
            app.AutomaticSearchFolderEditField.Value = strcat(search_folder, '/BioPAC files/');
            
            
            drawnow;
            
        end

        % Value changed function: start_time_field
        function start_time_fieldValueChanged(app, event)
            app.start_frame = floor(app.start_time_field.Value*app.fps)+1;
            
        end

        % Value changed function: end_time_field
        function end_time_fieldValueChanged(app, event)
            app.end_frame = floor(app.end_time_field.Value*app.fps)+1;
            
        end

        % Button pushed function: LoadButton
        function ok_pushed(app, event)
            app.calling_app.range.start_frame = app.start_frame;
            app.calling_app.range.end_frame = app.end_frame;
            app.calling_app.cancel_pickrange = 0;
            
            app.calling_app.processed_data.frame_range(2) = app.end_frame;
            app.calling_app.processed_data.frame_range(1) = app.start_frame;
            
            if isprop(app.calling_app, 'opticalmappingUIFigure')
                uiresume(app.calling_app.opticalmappingUIFigure);
            else
                uiresume(app.calling_app.normalise_referenceUIFigure);
            end
            delete(app)
        end

        % Button pushed function: CancelButton
        function Cancel_pushed(app, event)
            app.calling_app.cancel_pickrange = 1;
            if isprop(app.calling_app, 'opticalmappingUIFigure')
                uiresume(app.calling_app.opticalmappingUIFigure);
            else
                uiresume(app.calling_app.normalise_referenceUIFigure);
            end
            delete(app)
        end

        % Close request function: pickrangeUIFigure
        function close_request(app, event)
            app.calling_app.cancel_pickrange = 1;
            if isprop(app.calling_app, 'opticalmappingUIFigure')
                uiresume(app.calling_app.opticalmappingUIFigure);
            else
                uiresume(app.calling_app.normalise_referenceUIFigure);
            end   
            delete(app)
        end

        % Button pushed function: ChangesearchfolderButton
        function ChangesearchfolderButtonPushed(app, event)
            app.AutomaticSearchFolderEditField.Value = uigetdir();
            app.AutomaticSearchFolderEditField.Value = [app.AutomaticSearchFolderEditField.Value '/'];
        end

        % Button pushed function: AutomaticfilesearchandimportButton
        function AutomaticfilesearchandimportButtonPushed(app, event)
            app.BioPAC_file = search_BioPAC_file(app);
            app.BioPAC_path_file = [app.AutomaticSearchFolderEditField.Value app.BioPAC_file];
            load_BioPAC(app)
            app.BioPACfileEditField.Value = [app.AutomaticSearchFolderEditField.Value app.BioPAC_file];
            
            app.plot_ECG;
        end

        % Button pushed function: ManualfileselectionandimportButton
        function ManualfileselectionandimportButtonPushed(app, event)
            [app.BioPAC_file , BioPAC_path] = uigetfile({'*.*'},'File selector');
            app.BioPAC_path_file = [BioPAC_path app.BioPAC_file];
            load_BioPAC(app);
            app.BioPACfileEditField.Value = [app.AutomaticSearchFolderEditField.Value app.BioPAC_file];
           
            app.plot_ECG;
        end

        % Value changed function: ShowBioPACDropDown
        function ShowBioPACDropDownValueChanged(app, event)
            plot_ECG(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.pickrangeUIFigure = uifigure('Visible', 'off');
            app.pickrangeUIFigure.Name = 'UI Figure';
            app.pickrangeUIFigure.CloseRequestFcn = createCallbackFcn(app, @close_request, true);
            app.StarttimesLabel = uilabel(app.pickrangeUIFigure);
            app.StarttimesLabel.Text = 'Start time (s):';
            app.start_time_field = uieditfield(app.pickrangeUIFigure, 'numeric');
            app.start_time_field.ValueChangedFcn = createCallbackFcn(app, @start_time_fieldValueChanged, true);
            app.EndtimesLabel = uilabel(app.pickrangeUIFigure);
            app.EndtimesLabel.Text = 'End time (s):';
            app.end_time_field = uieditfield(app.pickrangeUIFigure, 'numeric');
            app.end_time_field.ValueChangedFcn = createCallbackFcn(app, @end_time_fieldValueChanged, true);
            app.LoadButton = uibutton(app.pickrangeUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @ok_pushed, true);
            app.LoadButton.Text = 'Load';
            app.TotalrecordingtimesLabel = uilabel(app.pickrangeUIFigure);
            app.TotalrecordingtimesLabel.Text = 'Total recording time (s):';
            app.total_recording_time_field = uieditfield(app.pickrangeUIFigure, 'numeric');
            app.total_recording_time_field.Editable = 'off';
            app.Label = uilabel(app.pickrangeUIFigure);
            app.Label.Text = 'Select time-frame to load:';
            app.CancelButton = uibutton(app.pickrangeUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @Cancel_pushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ECGPanel = uipanel(app.pickrangeUIFigure);
            app.ECGPanel.Title = 'ECG';
            app.UIAxes = uiaxes(app.ECGPanel);
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, 'ECG signal (a.u.)')
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.Visible = 'off';
            app.ChangesearchfolderButton = uibutton(app.ECGPanel, 'push');
            app.ChangesearchfolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChangesearchfolderButtonPushed, true);
            app.ChangesearchfolderButton.Text = 'Change search folder';
            app.AutomaticfilesearchandimportButton = uibutton(app.ECGPanel, 'push');
            app.AutomaticfilesearchandimportButton.ButtonPushedFcn = createCallbackFcn(app, @AutomaticfilesearchandimportButtonPushed, true);
            app.AutomaticfilesearchandimportButton.Text = 'Automatic file search and import';
            app.ManualfileselectionandimportButton = uibutton(app.ECGPanel, 'push');
            app.ManualfileselectionandimportButton.ButtonPushedFcn = createCallbackFcn(app, @ManualfileselectionandimportButtonPushed, true);
            app.ManualfileselectionandimportButton.Text = 'Manual file selection and import';
            app.AutomaticSearchFolderEditFieldLabel = uilabel(app.ECGPanel);
            app.AutomaticSearchFolderEditFieldLabel.Text = 'Automatic Search Folder';
            app.AutomaticSearchFolderEditField = uieditfield(app.ECGPanel, 'text');
            app.BioPACfileEditFieldLabel = uilabel(app.ECGPanel);
            app.BioPACfileEditFieldLabel.Text = 'BioPAC file';
            app.BioPACfileEditField = uieditfield(app.ECGPanel, 'text');
            app.BioPACfileEditField.Editable = 'off';
            app.BioPACfileEditField.Enable = 'off';
            app.ShowBioPACDropDown = uidropdown(app.ECGPanel);
            app.ShowBioPACDropDown.Items = {'ECGI', 'ECGII', 'ECGIII', 'Electrical stimulation', 'LED stimulation', 'Camera'};
            app.ShowBioPACDropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.ShowBioPACDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowBioPACDropDownValueChanged, true);
            app.ShowBioPACDropDown.Enable = 'off';
            app.ShowBioPACDropDown.Value = '1';
            % Show the figure after all components are created
            app.pickrangeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = open_file_partially(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.pickrangeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.pickrangeUIFigure)
        end
    end
end