classdef open_reference_recording < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        normalise_referenceUIFigure  matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        LoadreferencefileTab         matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        NormalisemaxwithreferenceminButton  matlab.ui.control.Button
        PlaynormalisedmovieButton    matlab.ui.control.Button
        ConfirmButton                matlab.ui.control.Button
        LoadreferencefileButton      matlab.ui.control.Button
        NormalisemaxButton           matlab.ui.control.Button
        ManipulatepositionTab        matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        delta                        matlab.ui.control.NumericEditField
        TransformmagnitudepxlEditFieldLabel  matlab.ui.control.Label
        ViewDropDown                 matlab.ui.control.DropDown
        ViewDropDownLabel            matlab.ui.control.Label
        YShiftPanel                  matlab.ui.container.Panel
        GridLayout3_3                matlab.ui.container.GridLayout
        button_yshift_minus          matlab.ui.control.Button
        button_yshift_plus           matlab.ui.control.Button
        XShiftPanel                  matlab.ui.container.Panel
        GridLayout3_2                matlab.ui.container.GridLayout
        button_xshift_plus           matlab.ui.control.Button
        button_xshift_minus          matlab.ui.control.Button
        RotatePanel                  matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        button_rotate_minus          matlab.ui.control.Button
        button_rotate_plus           matlab.ui.control.Button
    end

    
    properties (Access = private)
        calling_app; % Main app
        figure_size;
        working_display_axes; % Main display axes for image
        reference_display_axes;
        
    end
    
    properties (Access = public)
        
        
        data = struct('n_frames',[],'n_frames_full',[],'height',[],'width',[],'fps',[],'bit_depth',[],'regions',3,'image_stack',[],'rotation',3,'region_bounds',[]); % Loaded file data
        processed_data = struct('n_frames',[],'height',[],'width',[],'region_widths',[],'image_stack',[],'cropped',0,'signals',[],'beat_locations',[],'activation_map',[],'previous_stack',[],'previous_stack_bias',[],'frame_range',[1 1],'keep_area_poly',[]);
        display = struct('fps',15,'current_frame',1,'image_handle',[],'normalisation',1,'inversion',0,'renorm',ones(5,1),'signal_display',0,'BioPAC_signal_display',0,'ROI_handle',[],'plot_handle',[],'plot_handle_BioPAC',[],'ROI_mask',[],'colourbar_handle',gobjects(0),'map',0, 'colour_map',hot(256));
        settings = struct('quick_load',struct('regions',1,'background',1,'roi',1,'select_file_from_quick_load', 0)); % Quick load orientation/reshape, background and ROIs
        file_info = struct('file','','path','','name','','type',''); % Selected file info
        mask;
        working_frame;
        reference_frame;
        image_handle_working;
        image_handle_reference;
        first_reference_frame
        range = struct('start_frame',0, 'end_frame',0)
        old_file_info;
        quick_load_partially;
        threshold_handle;
        boundaries;
        reference_stack;
        working_stack;
        normalized_stack;
        view_bound;
        pickrange_app;
        cancel_pickrange;
    end
    
    methods (Access = private)
        
        function invert_stacks(app)            
            %Invert reference stack as it is not inverted
            app.reference_stack = max(app.reference_stack(:)) - app.reference_stack;
            %check inversion for working stack and invert if it's not
            check_inversion(app)

        end

        
        function quick_load(app)
            
            % get regions from working file
            app.data.region_bounds = app.calling_app.data.region_bounds;
            app.data.regions = app.calling_app.data.regions;
            app.data.rotation = app.calling_app.data.rotation;
            
            %store appfile info in case no file has been selected
            old_file_info_file = app.file_info.file;
            % Select new file
            app.Load_file_partially();
            
            
            progress_bar = uiprogressdlg(app.normalise_referenceUIFigure,'Title','Quick-loading','Indeterminate','on');

            % Apply region transformations
            apply_regions(app);
            
            
            close(progress_bar)

        end
        
        function Load_file_partially(app)
            select_file(app) % open file selector
            % Once file is selected, go to secondary app to read header (to know how long the recording is) and allow loading a particular time-frame
            if app.file_info.file ~= 0 % check if a file was selected. Open secondary app only if file was selected.
                app.pickrange_app = open_file_partially(app);
                uiwait(app.pickrange_app.pickrangeUIFigure);
                if app.cancel_pickrange == 1
                    app.file_info = app.old_file_info;
                else
                    
                    range = [app.range.start_frame, app.range.end_frame];
                    rangeType = 2; % Although in the subApp it is specified by time, there is already a conversion to frames performed in there. So rangeType is set to frame number.
                    load_selected_file(app,range, rangeType); %load file selected
                end
                
            else %if no new file is selected, just restore the info from before so you an choose regions again from the old file
                app.file_info = app.old_file_info;
            end
            
            
        end
        
        function select_file(app)
            if app.file_info.file ~= 0 %if there is a file name already loaded, save it in a variable in case the pop-up window is closed without selecting a file
                app.old_file_info = app.file_info;
            end
            
            [app.file_info.file , app.file_info.path] = uigetfile({'*.sif','Andor files (*.sif)';'*.dat','Photometrics files (*.dat)';'*.mat','MAT-Files (*.mat)';'*.*','All Files (*.*)'},'File selector'); % Open file selector {'*.sif';'*.dat';'*.*'},
            figure(app.normalise_referenceUIFigure);
            
        end


        function load_selected_file(app, range, rangeType)
            
            progress_bar = uiprogressdlg(app.normalise_referenceUIFigure,'Title','Loading reference data','Indeterminate','on');
            if app.file_info.file ~= 0 % check if a file was selected
                % If have already been examining data, reinitialises variables and windows
                if ~isempty(app.data.image_stack)
                    app.data = struct('n_frames',[],'n_frames_full',[],'height',[],'width',[],'fps',[],'bit_depth',[],'regions',3,'image_stack',[],'rotation',3,'region_bounds',[]); % Loaded file data
                    app.processed_data = struct('n_frames',[],'height',[],'width',[],'region_widths',[],'image_stack',[],'cropped',0,'signals',[],'beat_locations',[],'activation_map',[],'previous_stack',[],'previous_stack_bias',[],'frame_range',[1 1],'keep_area_poly',[]);
                    app.display = struct('fps',app.display.fps,'current_frame',1,'image_handle',[],'normalisation',1,'inversion',0,'renorm',zeros(5,1),'signal_display',0,'BioPAC_signal_display',0,'ROI_handle',[],'plot_handle',[],'plot_handle_BioPAC',[],'ROI_mask',[],'colourbar_handle',gobjects(0),'map',0, 'colour_map',hot(256));
                    app.settings = struct('quick_load',struct('regions',1,'background',1,'roi',0,'select_file_from_quick_load',1 ));                    
                end
                
                [~, app.file_info.name, app.file_info.type] = fileparts(app.file_info.file); % Find file type


                if app.file_info.type == '.sif' % If SIF load
                    
                    % Load .sif with MAC compatible function. Loads all the info
                    %of the file as well as all the frames (aleady
                    %reshaped, permuted and flipud
                    try
                        loaded_sif = sifreadnk([app.file_info.path,app.file_info.file],range,rangeType);
                    catch ME
                        errordlg(sprintf(['Error: there is a problem with the selected SIF file. Error code:',ME.message], 'SIF load error')) % Display error warning window if something went wrong when loading the data
                    end
                    if loaded_sif.kineticLength ~= loaded_sif.scannedFrames
                        %warndlg(['WARNING: Not all frames have been able to be loaded. Loaded frames: ', num2str(loaded_sif.scannedFrames), ' Expected frames: ', num2str(loaded_sif.kineticLength)])
                        loaded_sif.kineticLength = loaded_sif.scannedFrames;
                    end
                    app.data.n_frames = loaded_sif.kineticLength; % Number of frames
                    if app.data.n_frames > 0
                        if isempty(range)
                            app.processed_data.frame_range(1) = 0;
                            app.processed_data.frame_range(2) = app.data.n_frames;
                        else
                            app.processed_data.frame_range(1) = range(1);
                            app.processed_data.frame_range(2) = range(2);
                        end
                        app.data.height = loaded_sif.height;          % Height of the aquired image
                        app.data.width = loaded_sif.width;           % Width of the aquired image
                        app.data.fps = 1/loaded_sif.stackCycleTime;            % Frames per second (fps)
                        app.data.bit_depth = 12;                               % Set bit depth - can't seem to get from file currently | From the new MAC-compatible function, this may be the data in info.dataType
                        app.data.image_stack = loaded_sif.imageData;           % Store all frames in the correct variable
                        
                        %Create axes and display first frame only
                        if ~isempty(app.reference_display_axes)
                            delete(app.reference_display_axes);
                            app.reference_display_axes=[];
                        end
                        
                    end
                 
                end
                close(progress_bar);
                
            else %if no new file is selected, just restore the info from before so you an choose regions again from the old file
                app.file_info = app.old_file_info;
            end
            
            
            figure(app.normalise_referenceUIFigure);
            
        end
        
        function apply_regions(app)
            app.processed_data.n_frames = app.data.n_frames;
            % Set cropped image size
            if app.data.rotation == 0 || app.data.rotation == 2
                app.processed_data.height = max(app.data.region_bounds(:,5));
                app.processed_data.width = sum(app.data.region_bounds(:,6));
                app.processed_data.region_heights = app.data.region_bounds(:,5);
                app.processed_data.region_widths = app.data.region_bounds(:,6);
            else
                app.processed_data.height = max(app.data.region_bounds(:,6));
                app.processed_data.width = sum(app.data.region_bounds(:,5));
                app.processed_data.region_heights = app.data.region_bounds(:,6);
                app.processed_data.region_widths = app.data.region_bounds(:,5);
            end
            
            
            % Initialise processed image stack
            app.processed_data.image_stack{app.display.normalisation} = zeros(app.processed_data.height, app.processed_data.width, app.processed_data.n_frames);
            app.processed_data.image_stack{app.display.normalisation}(:) = min(app.data.image_stack(:));
            
            progress_bar = uiprogressdlg(app.normalise_referenceUIFigure,'Title','Applying regions','Indeterminate','on');
            if app.data.regions ~= 4 %For other than 4 views
                start = 1;
                for k = 1:app.data.regions
                    if app.data.regions == 3 && k == 1
                        app.processed_data.image_stack{app.display.normalisation}(1+floor((app.processed_data.height-app.processed_data.region_heights(k))/2):floor((app.processed_data.height-app.processed_data.region_heights(k))/2)+app.processed_data.region_heights(k),start:start+app.processed_data.region_widths(k)-1,:) = fliplr(rot90(app.data.image_stack(app.data.region_bounds(k,1):app.data.region_bounds(k,3),app.data.region_bounds(k,2):app.data.region_bounds(k,4),:),app.data.rotation));
                    else
                        app.processed_data.image_stack{app.display.normalisation}(1+floor((app.processed_data.height-app.processed_data.region_heights(k))/2):floor((app.processed_data.height-app.processed_data.region_heights(k))/2)+app.processed_data.region_heights(k),start:start+app.processed_data.region_widths(k)-1,:) = rot90(app.data.image_stack(app.data.region_bounds(k,1):app.data.region_bounds(k,3),app.data.region_bounds(k,2):app.data.region_bounds(k,4),:),app.data.rotation);
                    end
                    start=start+app.processed_data.region_widths(k);
                end
                
                % Flip to match viewing direction
                if app.data.regions == 3
                    app.processed_data.image_stack{app.display.normalisation} = fliplr(app.processed_data.image_stack{app.display.normalisation});
                end
            elseif app.data.regions == 4 %for 4 views
                start = 1;
                for k = 1:app.data.regions
                    if  k == 1 % first region just rotate counterclockwise 90 deg
                        app.processed_data.image_stack{app.display.normalisation}(1+floor((app.processed_data.height-app.processed_data.region_heights(k))/2):floor((app.processed_data.height-app.processed_data.region_heights(k))/2)+app.processed_data.region_heights(k),start:start+app.processed_data.region_widths(k)-1,:) = rot90(app.data.image_stack(app.data.region_bounds(k,1):app.data.region_bounds(k,3),app.data.region_bounds(k,2):app.data.region_bounds(k,4),:));
                    elseif k == 2 || k == 4 % second and fourth region flip left-right, flip up-down, and rotate counterclockwise 90 deg
                        app.processed_data.image_stack{app.display.normalisation}(1+floor((app.processed_data.height-app.processed_data.region_heights(k))/2):floor((app.processed_data.height-app.processed_data.region_heights(k))/2)+app.processed_data.region_heights(k),start:start+app.processed_data.region_widths(k)-1,:) = fliplr(rot90(fliplr(flipud(app.data.image_stack(app.data.region_bounds(k,1):app.data.region_bounds(k,3),app.data.region_bounds(k,2):app.data.region_bounds(k,4),:)))));
                    elseif k == 3 % third region flip up-down and rotate 90 deg counterclockwise
                        app.processed_data.image_stack{app.display.normalisation}(1+floor((app.processed_data.height-app.processed_data.region_heights(k))/2):floor((app.processed_data.height-app.processed_data.region_heights(k))/2)+app.processed_data.region_heights(k),start:start+app.processed_data.region_widths(k)-1,:) = fliplr(rot90(fliplr(app.data.image_stack(app.data.region_bounds(k,1):app.data.region_bounds(k,3),app.data.region_bounds(k,2):app.data.region_bounds(k,4),:))));
                    end
                    start=start+app.processed_data.region_widths(k);
                end
            end
            close(progress_bar)
            
            
%             app.first_reference_frame = app.processed_data.image_stack{1}(:,:,1); %saving the first frame without inversion to use for automatic segmentation because if the data is later inverted the segmentation code will not work
            app.reference_stack = app.processed_data.image_stack{1};

            
        end


        function compute_boundaries(app,ax)
            BW = app.mask;
            BW(isnan(BW))=0;
            [B,~] = bwboundaries(BW,'noholes');
            

            % plot new boundaries
            hold(ax, 'on')
            for k = 1:length(B)
               boundary = B{k};
               plot(ax, boundary(:,2), boundary(:,1), 'b', 'LineWidth', 2);

            end
        end
        
        
        function check_inversion(app)
            maximum_stack = max(app.working_stack(:));
            corner_pxl = app.working_stack(1,1,1)/maximum_stack;
%             if corner_pxl < 0.8 %data is not inverted, so invert 
%                 app.working_stack = maximum_stack - app.working_stack;
%             end
        end
        
        function update_reference_view(app)
            app.first_reference_frame = app.reference_stack(:,:,1);
            %cla(app.reference_display_axes);
            app.reference_display_axes = axes(app.normalise_referenceUIFigure, 'Units','Pixels','Position',[1, 0  ,3*floor(app.figure_size(3)./4), floor(0.49*app.figure_size(4))],'XTick',[],'YTick',[]);
 
            app.reference_frame = app.first_reference_frame./max(app.first_reference_frame(:));
            app.image_handle_reference = imshow(app.reference_frame,'Parent',app.reference_display_axes);
            colormap(app.reference_display_axes,app.display.colour_map);
            hold(app.reference_display_axes, 'on')
            compute_boundaries(app, app.reference_display_axes)

            app.image_handle_reference.Parent.CLim = [0,1];
            colormap(app.reference_display_axes,app.display.colour_map);
            
            %activate view bounds
            ViewDropDownValueChanged(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            movegui(app.normalise_referenceUIFigure,"center")
            app.calling_app = main_app; 
            app.display.colour_map = hot(256);
            app.mask = main_app.processed_data.keep_area_poly;
            app.working_stack = app.calling_app.processed_data.image_stack{1};
            % Set component sizes relative to screen. Need to check at some point the range of sizes
            % that work
            %set(app.normalise_referenceUIFigure,'WindowState','maximized');
            app.figure_size = get(app.normalise_referenceUIFigure,'position');%get(main_app.opticalmappingUIFigure,'position');
            drawnow
            set(app.TabGroup,'Position',[3*floor(app.figure_size(3)./4), 0, floor(app.figure_size(3)./4), floor(app.figure_size(4))])
            set(app.TabGroup,'Visible','on')
            drawnow
            pause(1)
            % Create axes and display figure
            app.working_display_axes = axes(app.normalise_referenceUIFigure, 'Units','Pixels','Position',[1, floor(0.5*app.figure_size(4))  ,3*floor(app.figure_size(3)./4), floor(0.49*app.figure_size(4))],'XTick',[],'YTick',[]); 
           
            app.working_frame = main_app.first_processed_frame; %main_app.processed_data.image_stack{1}(:,:,1);%get first frame of non-normalized stack
            app.working_frame = app.working_frame ./max(app.working_frame(:));
            app.image_handle_working = imshow(app.working_frame,'Parent',app.working_display_axes);
            colormap(app.working_display_axes,app.display.colour_map);
            compute_boundaries(app,app.working_display_axes)
            

        end

        % Button pushed function: NormalisemaxButton
        function normalise(app, event)
            progress_bar = uiprogressdlg(app.normalise_referenceUIFigure,'Title','Normalising','Indeterminate','on');
            invert_stacks(app)
            %apply background mask
            app.reference_stack = app.reference_stack.*app.mask;
            app.working_stack = app.working_stack.*app.mask;
            
            %% Extract baseline for each stack
            app.reference_stack = app.reference_stack - min(app.reference_stack,[],3);
            app.working_stack = app.working_stack - min(app.working_stack,[],3);
            
            %% Find local maxima for each pixel
            local_maxes_reference = max(app.reference_stack,[],3);
            local_maxes_working = max(app.working_stack,[],3);
            %%
            local_max = max(local_maxes_reference, local_maxes_working);
            %local_max(local_max==0) = NaN;
            %% Normalize
            app.normalized_stack = app.working_stack./local_max;
            app.normalized_stack(isnan(app.normalized_stack)) = 0;
            
            app.calling_app.processed_data.image_stack{7} = app.normalized_stack;
            
            set(app.PlaynormalisedmovieButton,'Enable','on');
            set(app.ConfirmButton,'Enable','on');
            app.calling_app.spikes = 0;
            close(progress_bar)
           
            
        end

        % Button pushed function: NormalisemaxwithreferenceminButton
        function NormalisemaxwithreferenceminButtonPushed(app, event)
            progress_bar = uiprogressdlg(app.normalise_referenceUIFigure,'Title','Normalising','Indeterminate','on');
            % De-invert working stack (assuming it is inverted) TODO: check for inversion to de-invert
            app.working_stack = max(app.working_stack(:))-app.working_stack;
            %clip all values of the working stack that are higher than the maximum of the reference stack (as they are not inverted, the spikes of the LED are positive
            %spikes)
            app.reference_stack = app.reference_stack - min(app.reference_stack,[],3);
            app.working_stack = app.working_stack - min(app.working_stack,[],3);
            %app.working_stack(app.working_stack>max(app.reference_stack,[],3)) = 0;
            size_stack = size(app.working_stack);
            max_reference_pixel = max(app.reference_stack,[],3);
            mean_reference_pixel = mean(app.reference_stack,3);
            for k=1:size_stack(1)
                for l=1:size_stack(2)
                    pixel_signal = app.working_stack(k,l,:);
                    pixel_signal(pixel_signal>max_reference_pixel(k,l)) = mean_reference_pixel(k,l);
                    app.working_stack(k,l,:) = pixel_signal;
                end
            end


            %now invert stacks
            invert_stacks(app)
            app.working_stack = max(app.working_stack(:))-app.working_stack;
            %apply background mask
            app.reference_stack = app.reference_stack.*app.mask;
            app.working_stack = app.working_stack.*app.mask;
            
            %% Extract baseline for each stack using the min of the reference
            app.working_stack = app.working_stack - min(app.reference_stack,[],3);
            app.reference_stack = app.reference_stack - min(app.reference_stack,[],3);
            
            
            %% Find local maxima for each pixel
            local_maxes_reference = max(app.reference_stack,[],3);
            local_maxes_working = max(app.working_stack,[],3);
            %%
            local_max = max(local_maxes_reference, local_maxes_working);
            %local_max(local_max==0) = NaN;
            %% Normalize
            app.normalized_stack = app.working_stack./local_max;
            app.normalized_stack(isnan(app.normalized_stack)) = 0;


            app.normalized_stack = app.normalized_stack - min(app.normalized_stack,[],3);
            app.normalized_stack = app.normalized_stack./max(app.normalized_stack,[],3);
            
            app.calling_app.processed_data.image_stack{7} = app.normalized_stack;
            
            set(app.PlaynormalisedmovieButton,'Enable','on');
            
            set(app.ConfirmButton,'Enable','on');
            app.calling_app.spikes = 1;
            close(progress_bar)
        end

        % Button pushed function: LoadreferencefileButton
        function LoadreferencefileButtonPushed(app, event)
            app.quick_load();
            app.update_reference_view();
            set(app.ViewDropDown,'Enable','on');
            set(app.button_rotate_minus,'Enable','on');
            set(app.button_rotate_plus,'Enable','on');
            set(app.button_xshift_minus,'Enable','on');
            set(app.button_xshift_plus,'Enable','on');
            set(app.button_yshift_minus,'Enable','on');
            set(app.button_yshift_plus,'Enable','on');

        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.calling_app.reference_stack = app.reference_stack; %pass reference stack to main app for further processing (filtering), which would require re-normalisation.
            app.calling_app.wait = 0;
            uiresume(app.calling_app.opticalmappingUIFigure);
            delete(app)
        end

        % Button pushed function: PlaynormalisedmovieButton
        function PlaynormalisedmovieButtonPushed(app, event)
            h = implay(app.normalized_stack./max(app.normalized_stack(:)),15);
            h.Visual.ColorMap.Map = hot(256);
        end

        % Value changed function: ViewDropDown
        function ViewDropDownValueChanged(app, event)
            % define bounds of the selected view to enable rotation and translations of just that particular view
            view = app.ViewDropDown.Value;
            if view == '1'                
                app.view_bound = [1,app.data.region_bounds(5)];
            elseif view == '2'
                app.view_bound = [1+app.data.region_bounds(3,5) ,app.data.region_bounds(3,5) + app.data.region_bounds(2,5)];
            elseif view == '3'
                app.view_bound = [1+app.data.region_bounds(3,5)+app.data.region_bounds(2,5) ,app.data.region_bounds(3,5) + app.data.region_bounds(2,5) + app.data.region_bounds(1,5)];
            end
            
        end

        % Button pushed function: button_rotate_plus
        function button_rotate_plusButtonPushed(app, event)
            % extract desired view from reference stack
            selected_view_stack = app.reference_stack(:,app.view_bound(1):app.view_bound(2),:);
            
            %Rotate view stack by +1 degree
            selected_view_stack = imrotate(selected_view_stack,app.delta.Value,'bicubic','crop');
            
            %insert rotated view into reference stack
            app.reference_stack(:,app.view_bound(1):app.view_bound(2),:) = selected_view_stack;
            
            %update reference view
            app.update_reference_view();
            
        end

        % Button pushed function: button_rotate_minus
        function button_rotate_minusButtonPushed(app, event)
            % extract desired view from reference stack
            selected_view_stack = app.reference_stack(:,app.view_bound(1):app.view_bound(2),:);
            
            %Rotate view stack by -1 degree
            selected_view_stack = imrotate(selected_view_stack,-app.delta.Value,'bicubic','crop');
            
            %insert rotated view into reference stack
            app.reference_stack(:,app.view_bound(1):app.view_bound(2),:) = selected_view_stack;
            
            %update reference view
            app.update_reference_view();
        end

        % Button pushed function: button_xshift_plus
        function button_xshift_plusButtonPushed(app, event)
            % extract desired view from reference stack
            selected_view_stack = app.reference_stack(:,app.view_bound(1):app.view_bound(2),:);
            
            %translate view stack by +1 pixel in X direction
            selected_view_stack = imtranslate(selected_view_stack,[app.delta.Value,0]);
            
            %insert rotated view into reference stack
            app.reference_stack(:,app.view_bound(1):app.view_bound(2),:) = selected_view_stack;
            
            %update reference view
            app.update_reference_view();
        end

        % Button pushed function: button_xshift_minus
        function button_xshift_minusButtonPushed(app, event)
             % extract desired view from reference stack
            selected_view_stack = app.reference_stack(:,app.view_bound(1):app.view_bound(2),:);
            
            %translate view stack by -1 pixel in X direction
            selected_view_stack = imtranslate(selected_view_stack,[-app.delta.Value,0]);
            
            %insert rotated view into reference stack
            app.reference_stack(:,app.view_bound(1):app.view_bound(2),:) = selected_view_stack;
            
            %update reference view
            app.update_reference_view();
        end

        % Button pushed function: button_yshift_plus
        function button_yshift_plusButtonPushed(app, event)
            % extract desired view from reference stack
            selected_view_stack = app.reference_stack(:,app.view_bound(1):app.view_bound(2),:);
            
            %translate view stack by +1 pixel in Y direction
            selected_view_stack = imtranslate(selected_view_stack,[0,app.delta.Value]);
            
            %insert rotated view into reference stack
            app.reference_stack(:,app.view_bound(1):app.view_bound(2),:) = selected_view_stack;
            
            %update reference view
            app.update_reference_view();
        end

        % Button pushed function: button_yshift_minus
        function button_yshift_minusButtonPushed(app, event)
            % extract desired view from reference stack
            selected_view_stack = app.reference_stack(:,app.view_bound(1):app.view_bound(2),:);
            
            %translate view stack by -1 pixel in Y direction
            selected_view_stack = imtranslate(selected_view_stack,[0,-app.delta.Value]);
            
            %insert rotated view into reference stack
            app.reference_stack(:,app.view_bound(1):app.view_bound(2),:) = selected_view_stack;
            
            %update reference view
            app.update_reference_view();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.normalise_referenceUIFigure = uifigure('Visible', 'off');
            app.normalise_referenceUIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.normalise_referenceUIFigure);
            app.LoadreferencefileTab = uitab(app.TabGroup);
            app.LoadreferencefileTab.Title = 'Load reference file';
            app.GridLayout = uigridlayout(app.LoadreferencefileTab);
            app.NormalisemaxButton = uibutton(app.GridLayout, 'push');
            app.NormalisemaxButton.ButtonPushedFcn = createCallbackFcn(app, @normalise, true);
            app.NormalisemaxButton.Text = 'Normalise max';
            app.LoadreferencefileButton = uibutton(app.GridLayout, 'push');
            app.LoadreferencefileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadreferencefileButtonPushed, true);
            app.LoadreferencefileButton.Text = 'Load reference file';
            app.ConfirmButton = uibutton(app.GridLayout, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Enable = 'off';
            app.ConfirmButton.Text = 'Confirm';
            app.PlaynormalisedmovieButton = uibutton(app.GridLayout, 'push');
            app.PlaynormalisedmovieButton.ButtonPushedFcn = createCallbackFcn(app, @PlaynormalisedmovieButtonPushed, true);
            app.PlaynormalisedmovieButton.Enable = 'off';
            app.PlaynormalisedmovieButton.Text = 'Play normalised movie';
            app.NormalisemaxwithreferenceminButton = uibutton(app.GridLayout, 'push');
            app.NormalisemaxwithreferenceminButton.ButtonPushedFcn = createCallbackFcn(app, @NormalisemaxwithreferenceminButtonPushed, true);
            app.NormalisemaxwithreferenceminButton.Text = {'Normalise max '; 'with reference min'};
            app.ManipulatepositionTab = uitab(app.TabGroup);
            app.ManipulatepositionTab.Title = 'Manipulate position';
            app.GridLayout2 = uigridlayout(app.ManipulatepositionTab);
            app.RotatePanel = uipanel(app.GridLayout2);
            app.RotatePanel.Title = 'Rotate';
            app.GridLayout3 = uigridlayout(app.RotatePanel);
            app.button_rotate_plus = uibutton(app.GridLayout3, 'push');
            app.button_rotate_plus.ButtonPushedFcn = createCallbackFcn(app, @button_rotate_plusButtonPushed, true);
            app.button_rotate_plus.Enable = 'off';
            app.button_rotate_plus.Text = '↺';
            app.button_rotate_minus = uibutton(app.GridLayout3, 'push');
            app.button_rotate_minus.ButtonPushedFcn = createCallbackFcn(app, @button_rotate_minusButtonPushed, true);
            app.button_rotate_minus.Enable = 'off';
            app.button_rotate_minus.Text = '↻';
            app.XShiftPanel = uipanel(app.GridLayout2);
            app.XShiftPanel.Title = 'X-Shift';
            app.GridLayout3_2 = uigridlayout(app.XShiftPanel);
            app.button_xshift_minus = uibutton(app.GridLayout3_2, 'push');
            app.button_xshift_minus.ButtonPushedFcn = createCallbackFcn(app, @button_xshift_minusButtonPushed, true);
            app.button_xshift_minus.Enable = 'off';
            app.button_xshift_minus.Text = '←';
            app.button_xshift_plus = uibutton(app.GridLayout3_2, 'push');
            app.button_xshift_plus.ButtonPushedFcn = createCallbackFcn(app, @button_xshift_plusButtonPushed, true);
            app.button_xshift_plus.Enable = 'off';
            app.button_xshift_plus.Text = '→';
            app.YShiftPanel = uipanel(app.GridLayout2);
            app.YShiftPanel.Title = 'Y-Shift';
            app.GridLayout3_3 = uigridlayout(app.YShiftPanel);
            app.button_yshift_plus = uibutton(app.GridLayout3_3, 'push');
            app.button_yshift_plus.ButtonPushedFcn = createCallbackFcn(app, @button_yshift_plusButtonPushed, true);
            app.button_yshift_plus.Enable = 'off';
            app.button_yshift_plus.Text = '↓';
            app.button_yshift_minus = uibutton(app.GridLayout3_3, 'push');
            app.button_yshift_minus.ButtonPushedFcn = createCallbackFcn(app, @button_yshift_minusButtonPushed, true);
            app.button_yshift_minus.Enable = 'off';
            app.button_yshift_minus.Text = '↑';
            app.ViewDropDownLabel = uilabel(app.GridLayout2);
            app.ViewDropDownLabel.Enable = 'off';
            app.ViewDropDownLabel.Text = 'View';
            app.ViewDropDown = uidropdown(app.GridLayout2);
            app.ViewDropDown.Items = {'View 1', 'View 2', 'View 3', ''};
            app.ViewDropDown.ItemsData = {'1', '2', '3'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewDropDownValueChanged, true);
            app.ViewDropDown.Enable = 'off';
            app.ViewDropDown.Value = '1';
            app.TransformmagnitudepxlEditFieldLabel = uilabel(app.GridLayout2);
            app.TransformmagnitudepxlEditFieldLabel.Text = 'Transform magnitude (°/pxl)';
            app.delta = uieditfield(app.GridLayout2, 'numeric');
            app.delta.Value = 1;
            % Show the figure after all components are created
            app.normalise_referenceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = open_reference_recording(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.normalise_referenceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.normalise_referenceUIFigure)
        end
    end
end