classdef pick_frame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        pickframeUIFigure          matlab.ui.Figure
        OKButton                   matlab.ui.control.Button
        entertime                  matlab.ui.control.NumericEditField
        EntertimeEditField_2Label  matlab.ui.control.Label
        enterframe                 matlab.ui.control.NumericEditField
        EnterframeEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        calling_app; % Main app
        current_frame
        fps
        AdvancedMovieSave
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            movegui(app.pickframeUIFigure,"center")
            
            app.calling_app = main_app;
            
            % Update with current values
            try
                app.enterframe.Value = app.calling_app.display.current_frame;
                app.enterframe.Limits = [1 app.calling_app.processed_data.n_frames];
                app.entertime.Value = (app.enterframe.Value-1)/app.calling_app.data.fps;
                app.entertime.Limits = [0 (app.calling_app.processed_data.n_frames-1)/app.calling_app.data.fps];
                app.fps = app.calling_app.data.fps;
                app.AdvancedMovieSave = 0;
            catch
                app.enterframe.Value = app.calling_app.CurrentframeEditField.Value;
                app.enterframe.Limits = [1 app.calling_app.n_frames];
                app.entertime.Value = (app.enterframe.Value-1)/app.calling_app.fps;
                app.entertime.Limits = [0 (app.calling_app.n_frames-1)/app.calling_app.fps];
                app.fps = app.calling_app.fps;
                app.AdvancedMovieSave = 1;
            end
            drawnow;
        end

        % Value changed function: enterframe
        function enter_frame(app, event)
            app.entertime.Value = (app.enterframe.Value-1)/app.fps;
        end

        % Value changed function: entertime
        function enter_time(app, event)
            app.enterframe.Value = floor(app.entertime.Value*app.fps)+1;
        end

        % Button pushed function: OKButton
        function ok_pushed(app, event)
            app.calling_app.display.current_frame = app.enterframe.Value;
            if app.AdvancedMovieSave == 0
                uiresume(app.calling_app.opticalmappingUIFigure); 
            else
                uiresume(app.calling_app.timestamp_save_movieUIFigure); 
            end
               
            delete(app)
        end

        % Close request function: pickframeUIFigure
        function close_request(app, event)
            uiresume(app.calling_app.opticalmappingUIFigure);    
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.pickframeUIFigure = uifigure('Visible', 'off');
            app.pickframeUIFigure.Name = 'UI Figure';
            app.pickframeUIFigure.CloseRequestFcn = createCallbackFcn(app, @close_request, true);
            app.EnterframeEditFieldLabel = uilabel(app.pickframeUIFigure);
            app.EnterframeEditFieldLabel.Text = 'Enter frame';
            app.enterframe = uieditfield(app.pickframeUIFigure, 'numeric');
            app.enterframe.ValueChangedFcn = createCallbackFcn(app, @enter_frame, true);
            app.EntertimeEditField_2Label = uilabel(app.pickframeUIFigure);
            app.EntertimeEditField_2Label.Text = {'Enter time'; ''};
            app.entertime = uieditfield(app.pickframeUIFigure, 'numeric');
            app.entertime.ValueChangedFcn = createCallbackFcn(app, @enter_time, true);
            app.OKButton = uibutton(app.pickframeUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @ok_pushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.pickframeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pick_frame(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.pickframeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.pickframeUIFigure)
        end
    end
end