classdef pick_frame_range < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        pickframerangeUIFigure   matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        CancelButton             matlab.ui.control.Button
        framesLabel              matlab.ui.control.Label
        SaveeveryEditField       matlab.ui.control.NumericEditField
        SaveeveryEditFieldLabel  matlab.ui.control.Label
        EnterendtimeLabel        matlab.ui.control.Label
        enterendtime             matlab.ui.control.NumericEditField
        EnterendframeLabel       matlab.ui.control.Label
        enterendframe            matlab.ui.control.NumericEditField
        EnterstarttimeLabel      matlab.ui.control.Label
        enterstarttime           matlab.ui.control.NumericEditField
        EnterstartframeLabel     matlab.ui.control.Label
        enterstartframe          matlab.ui.control.NumericEditField
        OKButton                 matlab.ui.control.Button
        SelectrangeLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        calling_app; % Main app
        current_frame
        fps
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            movegui(app.pickframerangeUIFigure,"center")
            
            app.calling_app = main_app;
            
            % Update with current values
            app.enterstartframe.Value = app.calling_app.CurrentframeEditField.Value;
            app.enterstartframe.Limits = [1 app.calling_app.n_frames];
            
            app.enterstarttime.Value = (app.enterstartframe.Value-1)/app.calling_app.fps;
            app.enterstarttime.Limits = [0 (app.calling_app.n_frames-1)/app.calling_app.fps];
            
            app.enterendframe.Value = app.calling_app.CurrentframeEditField.Value + 1;
            app.enterendtime.Value = (app.enterendframe.Value-1)/app.calling_app.fps;
            
            app.fps = app.calling_app.fps;
            drawnow;
        end

        % Value changed function: enterstartframe
        function enter_frame(app, event)
            app.enterstarttime.Value = (app.enterstartframe.Value-1)/app.fps;
        end

        % Value changed function: enterstarttime
        function enter_time(app, event)
            app.enterstartframe.Value = floor(app.enterstarttime.Value*app.fps)+1;
        end

        % Button pushed function: OKButton
        function ok_pushed(app, event)
            app.calling_app.frame_range_canceled = 0;
            app.calling_app.frame_range = [app.enterstartframe.Value, app.enterendframe.Value];
            app.calling_app.frame_spacing = app.SaveeveryEditField.Value;

            uiresume(app.calling_app.timestamp_save_movieUIFigure); 
 
            delete(app)
        end

        % Callback function: CancelButton, pickframerangeUIFigure
        function close_request(app, event)
            app.calling_app.frame_range_canceled = 1;
            uiresume(app.calling_app.opticalmappingUIFigure);    
            delete(app)
        end

        % Value changed function: enterendframe
        function enterendframeValueChanged(app, event)
            app.enterendtime.Value = (app.enterendframe.Value-1)/app.fps;
            
        end

        % Value changed function: enterendtime
        function enterendtimeValueChanged(app, event)
            app.enterendframe.Value = floor(app.enterendtime.Value*app.fps)+1;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.pickframerangeUIFigure = uifigure('Visible', 'off');
            app.pickframerangeUIFigure.Name = 'UI Figure';
            app.pickframerangeUIFigure.CloseRequestFcn = createCallbackFcn(app, @close_request, true);
            app.GridLayout = uigridlayout(app.pickframerangeUIFigure);
            app.SelectrangeLabel = uilabel(app.GridLayout);
            app.SelectrangeLabel.Text = 'Select range';
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @ok_pushed, true);
            app.OKButton.Text = 'OK';
            app.enterstartframe = uieditfield(app.GridLayout, 'numeric');
            app.enterstartframe.ValueChangedFcn = createCallbackFcn(app, @enter_frame, true);
            app.EnterstartframeLabel = uilabel(app.GridLayout);
            app.EnterstartframeLabel.Text = 'Enter start frame';
            app.enterstarttime = uieditfield(app.GridLayout, 'numeric');
            app.enterstarttime.ValueChangedFcn = createCallbackFcn(app, @enter_time, true);
            app.EnterstarttimeLabel = uilabel(app.GridLayout);
            app.EnterstarttimeLabel.Text = {'Enter start time'; ''};
            app.enterendframe = uieditfield(app.GridLayout, 'numeric');
            app.enterendframe.ValueChangedFcn = createCallbackFcn(app, @enterendframeValueChanged, true);
            app.EnterendframeLabel = uilabel(app.GridLayout);
            app.EnterendframeLabel.Text = 'Enter end frame';
            app.enterendtime = uieditfield(app.GridLayout, 'numeric');
            app.enterendtime.ValueChangedFcn = createCallbackFcn(app, @enterendtimeValueChanged, true);
            app.EnterendtimeLabel = uilabel(app.GridLayout);
            app.EnterendtimeLabel.Text = {'Enter end time'; ''};
            app.SaveeveryEditFieldLabel = uilabel(app.GridLayout);
            app.SaveeveryEditFieldLabel.Text = 'Save every';
            app.SaveeveryEditField = uieditfield(app.GridLayout, 'numeric');
            app.SaveeveryEditField.Value = 1;
            app.framesLabel = uilabel(app.GridLayout);
            app.framesLabel.Text = 'frames';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @close_request, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.pickframerangeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pick_frame_range(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.pickframerangeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.pickframerangeUIFigure)
        end
    end
end