classdef region_parameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        region_parameters_UIFigure     matlab.ui.Figure
        OKButton                       matlab.ui.control.Button
        AtrialsideDropDown             matlab.ui.control.DropDown
        AtrialsideDropDownLabel        matlab.ui.control.Label
        Numberofregions                matlab.ui.control.NumericEditField
        NumberofregionsEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        calling_app; % Main app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            movegui(app.region_parameters_UIFigure,"center")
            
            app.calling_app = main_app;
            
            % Update with current values
            %IF data file is .sif then do set up number of regions accorind to timestamp
            if strcmp(app.calling_app.file_info.type, '.sif')
                fileinfo = dir([app.calling_app.file_info.path, app.calling_app.file_info.file]);
                try datetime(fileinfo.date)
                    file_date= datetime(fileinfo.date);
                catch
                    file_date = datetime;
                end
                if file_date > datetime('01-Jul-2020') %4 view set up
                    app.Numberofregions.Value = 4;
                    app.AtrialsideDropDown.Value = '3';
                elseif file_date> datetime('12-Dec-2018') && file_date<datetime('01-Jul-2020') %3 view setup
                    app.Numberofregions.Value = 3;
                    app.AtrialsideDropDown.Value = '3';
                else %one view setup
                    app.Numberofregions.Value = 1;
                    app.AtrialsideDropDown.Value = '0';
                end
            elseif strcmp(app.calling_app.file_info.type, '.tif') || strcmp(app.calling_app.file_info.type, '.tiff')
                app.Numberofregions.Value = 1;
                app.AtrialsideDropDown.Value = '1';
            else %if file is .mat
                app.Numberofregions.Value = app.calling_app.data.regions;
                app.AtrialsideDropDown.Value = num2str(app.calling_app.data.rotation);
            end
            
        end

        % Button pushed function: OKButton
        function OK_button_pushed(app, event)
            app.calling_app.data.regions = app.Numberofregions.Value;
            app.calling_app.data.rotation = str2num(app.AtrialsideDropDown.Value);
            uiresume(app.calling_app.opticalmappingUIFigure);
            app.calling_app.tool_tip_update;
            delete(app)
        end

        % Close request function: region_parameters_UIFigure
        function region_parameters_UIFigureCloseRequest(app, event)
            uiresume(app.calling_app.opticalmappingUIFigure);    
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.region_parameters_UIFigure = uifigure('Visible', 'off');
            app.region_parameters_UIFigure.Name = 'UI Figure';
            app.region_parameters_UIFigure.CloseRequestFcn = createCallbackFcn(app, @region_parameters_UIFigureCloseRequest, true);
            app.NumberofregionsEditFieldLabel = uilabel(app.region_parameters_UIFigure);
            app.NumberofregionsEditFieldLabel.Text = 'Number of regions';
            app.Numberofregions = uieditfield(app.region_parameters_UIFigure, 'numeric');
            app.Numberofregions.Limits = [1 10];
            app.Numberofregions.Value = 4;
            app.AtrialsideDropDownLabel = uilabel(app.region_parameters_UIFigure);
            app.AtrialsideDropDownLabel.Text = 'Atrial side';
            app.AtrialsideDropDown = uidropdown(app.region_parameters_UIFigure);
            app.AtrialsideDropDown.Items = {'Top', 'Bottom', 'Left', 'Right'};
            app.AtrialsideDropDown.ItemsData = {'0', '2', '3', '1'};
            app.AtrialsideDropDown.Value = '0';
            app.OKButton = uibutton(app.region_parameters_UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OK_button_pushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.region_parameters_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = region_parameters(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.region_parameters_UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.region_parameters_UIFigure)
        end
    end
end