classdef segmentationAssist < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        segmentationAssistUIFigure   matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ThresholdingTab              matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        shrink_expand_slider         matlab.ui.control.Slider
        ShrinkExpandLabel            matlab.ui.control.Label
        ConfirmpolygonchangesButton  matlab.ui.control.Button
        ImagesaturationauEditField   matlab.ui.control.NumericEditField
        ImagesaturationauEditFieldLabel  matlab.ui.control.Label
        SmoothenLabel                matlab.ui.control.Label
        smoothen_slider              matlab.ui.control.Slider
        ThresholdLabel               matlab.ui.control.Label
        threshold_slider             matlab.ui.control.Slider
        ConfirmareaButton            matlab.ui.control.Button
        KeepareaButton               matlab.ui.control.Button
        RemoveareaButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        calling_app; % Main app
        figure_size;
        main_display_axes; % Main display axes for image
        
    end
    
    properties (Access = public)
        I0;
        frame_to_show;
        stack;
        I_blur;
        I_thr
        sigma_gauss = 4;
        image_handle;
        threshold_handle = [];
        threshold = 0.83;
        boundaries = []
        polygons;
        base_polygon = [];
    end
    
    methods (Access = private)
        
        function  threshold_and_plot(app)
            
            progress_bar = uiprogressdlg(app.segmentationAssistUIFigure,'Title','Computing area','Indeterminate','on');

            % Binarize image by thresholding
            app.I_thr = imbinarize(app.I_blur, 1 - app.threshold);

            % Detect boundaries of binary image
            compute_boundaries_and_plot(app)

            app.base_polygon = [];
            app.shrink_expand_slider.Value = 0;
            
            close(progress_bar)
        end


        function compute_boundaries_and_plot(app)
            [B,~] = bwboundaries(app.I_thr,'noholes');
            
            % Delete previous boundaries
            delete(app.threshold_handle)
            
            % plot new boundaries
            app.boundaries = {};
            app.threshold_handle = [];
            app.polygons = [];
            for k = 1:length(B)
               boundary = B{k};
               h = plot(app.main_display_axes, boundary(:,2), boundary(:,1), 'b', 'LineWidth', 2);
               app.threshold_handle = [app.threshold_handle, h];
               app.boundaries{end+1} = boundary;
               
               B_xy = B{k};
               %swap columns so it matches with image definition of x,y
               B_xy(:, [1 2]) = B_xy(:, [2 1]);
               %reduce points
               B_xy = reducepoly(B_xy, 0.005);
               %draw polygon from the computed boundaries
               
               polys = drawpolygon(app.main_display_axes, 'Position',B_xy);
               app.polygons = [app.polygons polys];
            end
        end


        function local_normalisation(app,main_app)
            app.stack = main_app.processed_data.image_stack{1}-min(main_app.processed_data.image_stack{1},[],3);
            app.stack = main_app.processed_data.image_stack{main_app.display.normalisation}./max(main_app.processed_data.image_stack{main_app.display.normalisation},[],3);
            app.stack(isnan(app.stack)) = 0;
        end
        
        function invert(app)
            app.stack = max(app.stack(:)) - app.stack;
        end
        
        function global_mean_norm(app, main_app)
            app.stack = main_app.processed_data.image_stack{1}-min(main_app.processed_data.image_stack{1},[],3);
            local_maxes = max(app.stack,[],3);
            local_maxes(local_maxes==0) = NaN; % Set all areas with no amplitude to NaN (only occurs in areas that have been removed)
            app.stack = app.stack./mean(local_maxes(:),'omitnan');
            app.stack(isnan(app.stack)) = 0;
                
        end
        
        function apply_polygons(app)
            % app.I_thr is the mask with 1 in the ROI and 0 elsewhere. I want to use polygons to add 1 on the selected region     
            mask = false(app.calling_app.processed_data.height, app.calling_app.processed_data.width); % Initialize final mask
            for k =1:length(app.polygons)
                poly_k = app.polygons(k);
                poly_k_mask = poly2mask(poly_k.Position(:,1), poly_k.Position(:,2), app.calling_app.processed_data.height, app.calling_app.processed_data.width);
                mask = mask | poly_k_mask;
            end
            app.I_thr = mask;
            
            app.I_thr(app.I_thr > 1) = 1;
            delete(app.polygons)
            compute_boundaries_and_plot(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            movegui(app.segmentationAssistUIFigure,"center")
            app.calling_app = main_app; 
            % Set component sizes relative to screen. Need to check at some point the range of sizes
            % that work
            %set(app.segmentationAssistUIFigure,'WindowState','maximized');
            app.figure_size = get(app.segmentationAssistUIFigure,'position');%get(main_app.opticalmappingUIFigure,'position');
            drawnow
            set(app.TabGroup,'Position',[1, 0, floor(app.figure_size(3)), floor(app.figure_size(4)./3)])
            set(app.TabGroup,'Visible','on')
            drawnow
            pause(1)
            % Create axes and display figure
            app.main_display_axes = axes(app.segmentationAssistUIFigure, 'Units','Pixels','Position',[1,app.figure_size(4)-floor(1.2*app.figure_size(4)/2)-40,app.figure_size(3),floor(1.2*app.figure_size(4)/2)],'XTick',[],'YTick',[]); 
            
            progress_bar = uiprogressdlg(app.segmentationAssistUIFigure,'Title','Computing image','Indeterminate','on');
            global_mean_norm(app, main_app)
            close(progress_bar)
            app.frame_to_show = app.stack(:,:,1);
            app.frame_to_show = app.frame_to_show ./max(app.frame_to_show(:));
            app.image_handle = imshow(app.ImagesaturationauEditField.Value*app.frame_to_show,'Parent',app.main_display_axes);
            colormap(app.main_display_axes,hot(256));
            if isempty(main_app.processed_data.keep_area_poly)
                mask = true(app.calling_app.processed_data.height, app.calling_app.processed_data.width); 
            else
                mask = main_app.processed_data.keep_area_poly(:,:,1);
            end
            
            app.I0 = main_app.processed_data.image_stack{1}(:,:,1).*mask; %get first frame. Used to use first_processed_frame
            app.I0 = app.I0./max(app.I0(:)); %normalise


            % Blur original image to smoothen the thresholding process
            app.I_blur = imgaussfilt(app.I0, app.sigma_gauss);
            hold(app.main_display_axes, 'on')
            
            threshold_and_plot(app)
           
        end

        % Value changed function: threshold_slider
        function threshold_sliderValueChanged(app, event)
            delete(app.polygons);
            app.threshold = event.Value;
            
            threshold_and_plot(app)

        end

        % Value changed function: smoothen_slider
        function smoothen_sliderValueChanged(app, event)
            delete(app.polygons);
            app.sigma_gauss = event.Value;
            
            % Recalculate blurred image with slider value
            app.I_blur = imgaussfilt(app.I0, app.sigma_gauss);
            
            % Recalculate the threshold with the new blurred image and plot boundaries
            threshold_and_plot(app)

        end

        % Value changed function: shrink_expand_slider
        function shrink_expand_sliderValueChanged(app, event)
            shrink_expand_value = app.shrink_expand_slider.Value; %value in points, shrink or expand the polygon
            
            
            if isempty(app.base_polygon)
                polyshapes = [];
                for k =1:length(app.polygons)
                    polyshape_k = polyshape(app.polygons(k).Position); %create a polyshape to expand or shrink
                    polyshapes = [polyshapes, polyshape_k];
                end
                app.base_polygon = polyshapes;
            else
                polyshapes = app.base_polygon;
            end


            delete(app.polygons)
            app.polygons = [];
            for k=1:length(polyshapes)
                polyshape_k = polybuffer(polyshapes(k), shrink_expand_value); %shrink or expand
                polyshape_k_position = polyshape_k.Vertices; %obtain vertices to generate a polygon
                polys = drawpolygon(app.main_display_axes, 'Position',polyshape_k_position);
                app.polygons = [app.polygons polys];
            end
            
            apply_polygons(app)
            
        end

        % Button pushed function: KeepareaButton
        function keep_area_segmentation_assist(app, event)
            delete(app.polygons);
            % app.I_thr is the mask with 1 in the ROI and 0 elsewhere. I want to use polygons to add 1 on the selected region            
            h = drawpolygon(app.main_display_axes);
            poly_mask = poly2mask(h.Position(:,1), h.Position(:,2), app.calling_app.processed_data.height, app.calling_app.processed_data.width);
 
            app.I_thr = app.I_thr + poly_mask;
            app.I_thr(app.I_thr > 1) = 1;
            
            % Delete draw polygons
            delete(h);

            app.base_polygon = [];
            app.shrink_expand_slider.Value = 0;
            
            compute_boundaries_and_plot(app)

        end

        % Button pushed function: RemoveareaButton
        function remove_area_segmentation_assist(app, event)
            delete(app.polygons);
            h = drawpolygon(app.main_display_axes);
            poly_mask = ~poly2mask(h.Position(:,1), h.Position(:,2), app.calling_app.processed_data.height, app.calling_app.processed_data.width);
 
            app.I_thr = app.I_thr.*poly_mask;
            
            % Delete draw polygons
            delete(h);

            app.base_polygon = [];
            app.shrink_expand_slider.Value = 0;
            
            compute_boundaries_and_plot(app)

        end

        % Button pushed function: ConfirmareaButton
        function confirm_area_segmentation_assist(app, event)
            %Convert zeros in poly to NaN
            app.I_thr = app.I_thr.*1.0;
            app.I_thr(app.I_thr==0) = NaN;
            
            app.calling_app.processed_data.keep_area_poly = app.I_thr;
            
            app.calling_app.processed_data.keep_area_poly_vertices = cell(length(app.polygons),1);
            for k=1:length(app.polygons)
                app.calling_app.processed_data.keep_area_poly_vertices{k} = app.polygons(k).Position;
            end

            app.base_polygon = [];
            app.shrink_expand_slider.Value = 0;
            
            app.calling_app.ui_resume = true;
            uiresume(app.calling_app.opticalmappingUIFigure);
            delete(app)
        end

        % Value changed function: ImagesaturationauEditField
        function ImagesaturationauEditFieldValueChanged(app, event)
            app.image_handle = imshow(app.ImagesaturationauEditField.Value*app.frame_to_show,'Parent',app.main_display_axes);
            colormap(app.main_display_axes,hot(256));
        end

        % Button pushed function: ConfirmpolygonchangesButton
        function ConfirmpolygonchangesButtonPushed(app, event)
            app.base_polygon = [];
            app.shrink_expand_slider.Value = 0;
            
            apply_polygons(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.segmentationAssistUIFigure = uifigure('Visible', 'off');
            app.segmentationAssistUIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.segmentationAssistUIFigure);
            app.ThresholdingTab = uitab(app.TabGroup);
            app.ThresholdingTab.Title = 'Thresholding';
            app.GridLayout = uigridlayout(app.ThresholdingTab);
            app.RemoveareaButton = uibutton(app.GridLayout, 'push');
            app.RemoveareaButton.ButtonPushedFcn = createCallbackFcn(app, @remove_area_segmentation_assist, true);
            app.RemoveareaButton.Text = 'Remove area ✗ ';
            app.KeepareaButton = uibutton(app.GridLayout, 'push');
            app.KeepareaButton.ButtonPushedFcn = createCallbackFcn(app, @keep_area_segmentation_assist, true);
            app.KeepareaButton.Text = 'Keep area ✓';
            app.ConfirmareaButton = uibutton(app.GridLayout, 'push');
            app.ConfirmareaButton.ButtonPushedFcn = createCallbackFcn(app, @confirm_area_segmentation_assist, true);
            app.ConfirmareaButton.Text = 'Confirm area 💾';
            app.threshold_slider = uislider(app.GridLayout);
            app.threshold_slider.Limits = [0.2 1];
            app.threshold_slider.MajorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.threshold_slider.MajorTickLabels = {'0.2', '1'};
            app.threshold_slider.ValueChangedFcn = createCallbackFcn(app, @threshold_sliderValueChanged, true);
            app.threshold_slider.MinorTicks = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29 0.3 0.31 0.32 0.33 0.34 0.35 0.36 0.37 0.38 0.39 0.4 0.41 0.42 0.43 0.44 0.45 0.46 0.47 0.48 0.49 0.5 0.51 0.52 0.53 0.54 0.55 0.56 0.57 0.58 0.59 0.6 0.61 0.62 0.63 0.64 0.65 0.66 0.67 0.68 0.69 0.7 0.71 0.72 0.73 0.74 0.75 0.76 0.77 0.78 0.79 0.8 0.81 0.82 0.83 0.84 0.85 0.86 0.87 0.88 0.89 0.9 0.91 0.92 0.93 0.94 0.95 0.96 0.97 0.98 0.99 1];
            app.threshold_slider.Value = 0.83;
            app.ThresholdLabel = uilabel(app.GridLayout);
            app.ThresholdLabel.Text = 'Threshold';
            app.smoothen_slider = uislider(app.GridLayout);
            app.smoothen_slider.Limits = [0.1 10];
            app.smoothen_slider.MajorTicks = [0.1 1 2 3 4 5 6 7 8 9 10];
            app.smoothen_slider.MajorTickLabels = {'0.1', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.smoothen_slider.ValueChangedFcn = createCallbackFcn(app, @smoothen_sliderValueChanged, true);
            app.smoothen_slider.MinorTicks = [0.5 1.5 2.5 3.5 4.5 5.5 6.5 7.5 8.5 9.5];
            app.smoothen_slider.Value = 4;
            app.SmoothenLabel = uilabel(app.GridLayout);
            app.SmoothenLabel.Text = 'Smoothen';
            app.ImagesaturationauEditFieldLabel = uilabel(app.GridLayout);
            app.ImagesaturationauEditFieldLabel.Text = 'Image saturation (a.u.)';
            app.ImagesaturationauEditField = uieditfield(app.GridLayout, 'numeric');
            app.ImagesaturationauEditField.ValueChangedFcn = createCallbackFcn(app, @ImagesaturationauEditFieldValueChanged, true);
            app.ImagesaturationauEditField.Value = 5;
            app.ConfirmpolygonchangesButton = uibutton(app.GridLayout, 'push');
            app.ConfirmpolygonchangesButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmpolygonchangesButtonPushed, true);
            app.ConfirmpolygonchangesButton.Text = 'Confirm polygon changes';
            app.ShrinkExpandLabel = uilabel(app.GridLayout);
            app.ShrinkExpandLabel.Text = 'Shrink/Expand';
            app.shrink_expand_slider = uislider(app.GridLayout);
            app.shrink_expand_slider.Limits = [-20 20];
            app.shrink_expand_slider.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.shrink_expand_slider.MajorTickLabels = {'-20', '-15', '-10', '-5', '0', '5', '10', '15', '20'};
            app.shrink_expand_slider.ValueChangedFcn = createCallbackFcn(app, @shrink_expand_sliderValueChanged, true);
            app.shrink_expand_slider.MinorTicks = [-17.5 -12.5 -7.5 -2.5 2.5 7.5 12.5 17.5];
            % Show the figure after all components are created
            app.segmentationAssistUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = segmentationAssist(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.segmentationAssistUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.segmentationAssistUIFigure)
        end
    end
end