classdef timestamp_save_movie < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        timestamp_save_movieUIFigure   matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ParametersTab                  matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        DoubleimagesizeButton          matlab.ui.control.Button
        FramePanel                     matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        CurrenttimesEditField          matlab.ui.control.NumericEditField
        CurrenttimesEditFieldLabel     matlab.ui.control.Label
        CurrentframeEditField          matlab.ui.control.NumericEditField
        CurrentframeLabel              matlab.ui.control.Label
        SaverangeButton                matlab.ui.control.Button
        SaveframeButton                matlab.ui.control.Button
        PickframeButton                matlab.ui.control.Button
        NextButton                     matlab.ui.control.Button
        PreviousButton                 matlab.ui.control.Button
        MoviePanel                     matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        MovielengthsEditField          matlab.ui.control.NumericEditField
        MovielengthsEditFieldLabel     matlab.ui.control.Label
        SaveMovieButton                matlab.ui.control.Button
        Label_quality                  matlab.ui.control.Label
        QualitySlider                  matlab.ui.control.Slider
        QualityLabel                   matlab.ui.control.Label
        FrameRateEditField             matlab.ui.control.NumericEditField
        FrameRateEditFieldLabel        matlab.ui.control.Label
        CloseButton                    matlab.ui.control.Button
        TimestampPanel                 matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        starttimesmsEditFieldLabel     matlab.ui.control.Label
        starttimesmsEditField          matlab.ui.control.NumericEditField
        timeincrementmsEditFieldLabel  matlab.ui.control.Label
        timeincrementmsEditField       matlab.ui.control.NumericEditField
        ycoordLabel                    matlab.ui.control.Label
        ycoordEditField                matlab.ui.control.NumericEditField
        xcoordLabel                    matlab.ui.control.Label
        xcoordEditField                matlab.ui.control.NumericEditField
        toprightButton                 matlab.ui.control.Button
        bottomleftButton               matlab.ui.control.Button
        bottomrightButton              matlab.ui.control.Button
        SetTimestampCheckBox           matlab.ui.control.CheckBox
        FontButton                     matlab.ui.control.Button
        topleftButton                  matlab.ui.control.Button
        ColorButton                    matlab.ui.control.Button
        topcenterButton                matlab.ui.control.Button
        bottomcenterButton             matlab.ui.control.Button
        UnitsButtonGroup               matlab.ui.container.ButtonGroup
        units_ms                       matlab.ui.control.RadioButton
        units_s                        matlab.ui.control.RadioButton
        time_stamp                     matlab.ui.control.Label
        Image                          matlab.ui.control.Image
    end

    
    properties (Access = public)
        selpath;
        selectImages;
        pressed;
        quality_value;
        font;
        t_color;
        coord(1,4);
        time_value
        origin_x;
        origin_y;
        Image_Source_Timestamp;
        calling_app
        displayed_frame
        display = struct('current_frame',1) %using this so I can use the pickframe app as used in analyse_optical_mapping
        pickframe_app
        n_frames
        fps
        frame_spacing
        frame_range
        pickrange_app
        frame_range_canceled
        
    end
    
    
    methods (Access = private)
        
        function file_name = file_exists(app,suffix,filetype)
            file_name = [app.calling_app.SavetoEditField.Value  app.calling_app.file_info.name '_' suffix];% '_1' filetype];
            if exist(file_name, 'file')
                % Get number of files:
                file_list     = dir([app.calling_app.SavetoEditField.Value  app.calling_app.file_info.name  '_' suffix '_*' filetype]);
                file_string     = lower(sprintf('%s*', file_list.name));
                file_num     = sscanf(file_string, [[app.calling_app.file_info.name '_' suffix '_'], '%d', filetype, '*']);
                new_num = length(file_list)+1;
                file_name = [app.calling_app.SavetoEditField.Value app.calling_app.file_info.name '_' suffix '_' num2str(new_num) filetype];
                %file_name = [app.file_info.path app.file_info.name '_' suffix '_' num2str(new_num) filetype];
            end
        end
        
        function dir_name = dir_exists(app,suffix)
            dir_name = [app.calling_app.SavetoEditField.Value app.calling_app.file_info.name '_' suffix];
            if exist(dir_name, 'dir')
                % Get number of folder with the same name:
                dir_list     = dir([app.calling_app.SavetoEditField.Value   app.calling_app.file_info.name '_' suffix '_*']);
                dir_string     = sprintf('%s*', dir_list.name);
                dir_num     = sscanf(dir_string, [[app.calling_app.file_info.name '_' suffix '_'], '%d']);
                new_num = length(dir_list)+1;
                dir_name = [app.calling_app.SavetoEditField.Value app.calling_app.file_info.name '_' suffix '_' num2str(new_num)];
            end
        end
        
        function update_timestamp_example(app)
            %timestamp example and initial position
            if app.units_s.Value == 1
                time = sprintf('%.2f s', app.starttimesmsEditField.Value+(app.CurrentframeEditField.Value-1)/app.fps);
                app.time_stamp.Text = time;

                
            else
                time = sprintf('%.0f ms', app.starttimesmsEditField.Value + 1000*(app.CurrentframeEditField.Value-1)/app.fps); 
                app.time_stamp.Text = time;
            end
            
            picture_file = file_exists(app,sprintf('frame-%d',app.display.current_frame),'.png');
            
        end
        
        function insert_timestamp_example(app)
            %Function to show an example of how the timestamp will be shown
            %on the frame. This is not a function that writes the timestamp
            %to the actual movie frames or image frames
            
            if app.time_value == 1
                app.time_stamp.Text = '0.00 s';
                app.time_stamp.Position = [app.Image.Position(1)+2 app.Image.Position(2)+2 500 500];
                app.coord = app.time_stamp.Position;
                app.xcoordEditField.Value = 2;
                app.ycoordEditField.Value = 2;
                
            else
                app.time_stamp.Text = '';
            end


        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            movegui(app.timestamp_save_movieUIFigure,"center")
            
            app.calling_app = main_app; % Specify calling app so as to resume it later
            
            app.origin_x = app.timestamp_save_movieUIFigure.Position(3)/2;
            app.origin_y = (app.timestamp_save_movieUIFigure.Position(4)-298)/2+298;
            app.CurrentframeEditField.Value = 1;
            app.CurrenttimesEditField.Value = 0;
            app.displayed_frame = app.calling_app.processed_data.image_stack{app.calling_app.display.normalisation}(:,:,1);
            app.Image.Position = [app.origin_x-size(app.displayed_frame,2)/2 app.origin_y-size(app.displayed_frame,1)/2 size(app.displayed_frame,2) size(app.displayed_frame,1) ];
            app.time_stamp.Position = app.Image.Position;
            app.quality_value = 100;
            app.n_frames = size(app.calling_app.processed_data.image_stack{app.calling_app.display.normalisation},3);
            app.fps = app.calling_app.data.fps;

            %set first frame to show
            app.Image.ImageSource = ind2rgb(app.displayed_frame,app.calling_app.display.colour_map );
            
            %show timestamp
            app.time_value = app.SetTimestampCheckBox.Value;
            
            %timestamp example and initial position
            insert_timestamp_example(app)
            
            %timestamp color to white
            app.time_stamp.FontColor = [1 1 1];
            
            %timestamp size and font
            app.time_stamp.FontSize = 24;
            app.time_stamp.FontName = 'Arial Black';
            
            %set time increment from original fps in s
            app.timeincrementmsEditField.Value = 1/app.fps*1000;
            app.MovielengthsEditField.Value = app.n_frames/app.FrameRateEditField.Value;


        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            closereq();
        end

        % Button pushed function: SaveMovieButton
        function SaveMovieButtonPushed(app, event)
            progress_bar = uiprogressdlg(app.timestamp_save_movieUIFigure,'Title','Saving','Indeterminate','on');
            %Colour map
            cMap = app.calling_app.display.colour_map;
            %TODO% Calcium imaging use cMap winter.
            %stack
            stack = app.calling_app.processed_data.image_stack{app.calling_app.display.normalisation};
            %movie stack
            movie_stack = (stack - min(stack(:)))/max(stack(:));
            movie_stack(movie_stack>app.calling_app.MaxColourmap.Value/100) = 1;
            movie_stack(isnan(movie_stack)) = 0;
            movie_stack = double(movie_stack); % Explicit set as double in case load single when doing motion correction
            
            time_range = [(app.calling_app.processed_data.frame_range(1)-1)/app.fps,(app.calling_app.processed_data.frame_range(2)-1)/app.fps];
            
            
            filename = sprintf('movie_%.1f-%.1f',time_range(1), time_range(2));
            movie_file = file_exists(app,filename,'.avi');
            
            % Output using ffmpeg if available otherwise use old method
            if ~system('where ffmpeg > nul 2>&1')

                %ffmpeg output

                if app.time_value==1
                    if app.units_s.Value == 1 %use seconds
                        unit_type = 's';
                        unit_precision = 2;
                    else
                        unit_type = 'ms';
                        unit_precision = 0;
                    end
                    position = [app.xcoordEditField.Value app.ycoordEditField.Value]-6;%-6 to correct for some difference in setting the coordinates with inserText. I dont know why..
                    position = [position(1)  app.Image.Position(4)-position(2)]; %y axis goes downwards in insertText
                    stack_to_video(movie_stack,movie_file,'',fps=app.FrameRateEditField.Value,quality=app.quality_value/10,cMap=cMap,timestamp=1,position=position,step_size=(1/app.fps),units=unit_type,precision=unit_precision,font=app.time_stamp.FontName,font_size=app.time_stamp.FontSize,font_color=app.time_stamp.FontColor,opacity=0);
                else
                    stack_to_video(movie_stack,movie_file,'',fps=app.FrameRateEditField.Value,quality=app.quality_value/10,cMap=cMap);
                end

            else
                % Old version
                output_video = VideoWriter(movie_file);
                output_video.Quality = app.quality_value;

                output_video.FrameRate = app.FrameRateEditField.Value;

                % Save video
                open(output_video);
                position = [app.xcoordEditField.Value app.ycoordEditField.Value]-6;%-6 to correct for some difference in setting the coordinates with inserText. I dont know why..
                position = [position(1)  app.Image.Position(4)-position(2)]; %y axis goes downwards in insertText
                for k = 1:app.calling_app.processed_data.n_frames

                    if app.time_value==1
                        if app.units_s.Value == 1 %use seconds
                            time = sprintf('%.2f s', app.starttimesmsEditField.Value+(k-1)/app.fps);
                        else
                            time = sprintf('%.0f ms', app.starttimesmsEditField.Value + 1000*(k-1)/app.fps);
                        end
                        image_without_time_stamp = ind2rgb(movie_stack(:,:,k),cMap);
                        image_with_timestamp = insertText(image_without_time_stamp , position , time,'AnchorPoint','LeftBottom','Font',app.time_stamp.FontName,'FontSize', app.time_stamp.FontSize,'TextColor',app.time_stamp.FontColor,'BoxOpacity',0);
                        frame = image_with_timestamp;
                    else
                        frame = ind2rgb(movie_stack(:,:,k),cMap);
                    end

                    writeVideo(output_video,frame);
                    clear frame image_with_timestamp image_with_timestamp
                end
                close(output_video)
            end


            log_file_name =  file_exists(app,['log_file_' filename],'.dat');
            save_log_file(app.calling_app,[log_file_name,'.dat']);
            close(progress_bar)


        end

        % Value changing function: QualitySlider
        function QualitySliderValueChanging(app, event)
            changingValue = event.Value;
            app.quality_value = round(changingValue);
            app.Label_quality.Text = strcat(num2str(app.quality_value),' %');
        end

        % Value changed function: SetTimestampCheckBox
        function SetTimestampCheckBoxValueChanged(app, event)
            app.time_value = app.SetTimestampCheckBox.Value;
            
%             position = [23 373;35 185;77 107];
%             box_color = {'red','green','yellow'};
%             text_str ='test';
%             
%             if app.time_value == 1
%                 app.time_stamp.Text = '3000 ms';
%                 app.coord = app.time_stamp.Position;
%                 app.xcoordEditField.Value = app.coord(1)-app.origin_x;
%                 app.ycoordEditField.Value = app.coord(2)-app.origin_y;
%             else
%                 app.time_stamp.Text = '';
%                 
%             end
             insert_timestamp_example(app)
             
        end

        % Button pushed function: FontButton
        function FontButtonPushed(app, event)
            app.font = uisetfont;
            
             
                if convertCharsToStrings(app.font.FontAngle) ~= 'normal' || convertCharsToStrings(app.font.FontWeight) ~= 'normal'
                 msgbox({"Sorry, different Styles are not supported from Mathworks yet!";"Fontstyle is set to normal."});
                 app.time_stamp.FontAngle = convertStringsToChars('normal');
                 app.time_stamp.FontWeight = convertStringsToChars('normal');
                end
                app.time_stamp.FontSize = app.font.FontSize;
                app.time_stamp.FontName = app.font.FontName;
                app.time_stamp.FontAngle = app.font.FontAngle;
                app.time_stamp.FontWeight = app.font.FontWeight;
        
        end

        % Button pushed function: toprightButton
        function toprightButtonPushed(app, event)
            ycoord = app.Image.Position(2) + app.Image.Position(4) -(app.time_stamp.FontSize+2);
            xcoord =  app.Image.Position(1) + app.Image.Position(3)-(app.time_stamp.FontSize*length(app.time_stamp.Text)*0.9+2);
            
            app.time_stamp.Position = [xcoord ycoord 500 500];
            app.coord = app.time_stamp.Position;
            app.xcoordEditField.Value = app.coord(1) -  app.Image.Position(1);
            app.ycoordEditField.Value = app.coord(2) - app.Image.Position(2);
            
        end

        % Button pushed function: bottomleftButton
        function bottomleftButtonPushed(app, event)
            
            app.time_stamp.Position = [app.Image.Position(1)+2 app.Image.Position(2)+2 500 500];
            
            
            app.coord = app.time_stamp.Position;
            app.xcoordEditField.Value = app.coord(1) -  app.Image.Position(1);
            app.ycoordEditField.Value = app.coord(2) - app.Image.Position(2);
        end

        % Button pushed function: bottomrightButton
        function bottomrightButtonPushed(app, event)
            xcoord =  app.Image.Position(1) + app.Image.Position(3)-(app.time_stamp.FontSize*length(app.time_stamp.Text)*0.9+2);
            
            
            app.time_stamp.Position = [xcoord  app.Image.Position(2)+2 500 500];
            
            app.coord = app.time_stamp.Position;
            app.xcoordEditField.Value = app.coord(1) -  app.Image.Position(1);
            app.ycoordEditField.Value = app.coord(2) - app.Image.Position(2);
        end

        % Button pushed function: topcenterButton
        function topcenterButtonPushed(app, event)
            ycoord = app.Image.Position(2) + app.Image.Position(4) -(app.time_stamp.FontSize+2);
            xcoord = app.Image.Position(1)+app.Image.Position(3)/2;
            app.time_stamp.Position = [xcoord  ycoord 500 500];
            
            app.coord = app.time_stamp.Position;
            app.xcoordEditField.Value = app.coord(1) -  app.Image.Position(1);
            app.ycoordEditField.Value = app.coord(2) - app.Image.Position(2);
        end

        % Button pushed function: bottomcenterButton
        function bottomcenterButtonPushed(app, event)
            xcoord = app.Image.Position(1)+app.Image.Position(3)/2;
            app.time_stamp.Position = [xcoord app.Image.Position(2)+2 500 500];
            
            
            app.coord = app.time_stamp.Position;
            app.xcoordEditField.Value = app.coord(1) -  app.Image.Position(1);
            app.ycoordEditField.Value = app.coord(2) - app.Image.Position(2);
        end

        % Button pushed function: topleftButton
        function topleftButtonPushed(app, event)
            ycoord = app.Image.Position(2) + app.Image.Position(4) -(app.time_stamp.FontSize+2);
            
            app.time_stamp.Position = [app.Image.Position(1)+2  ycoord 500 500];
            app.coord = app.time_stamp.Position;
            app.xcoordEditField.Value = app.coord(1) -  app.Image.Position(1);
            app.ycoordEditField.Value = app.coord(2) - app.Image.Position(2);
        end

        % Value changed function: xcoordEditField
        function xcoordEditFieldValueChanged(app, event)

            app.time_stamp.Position(1) = app.Image.Position(1) + app.xcoordEditField.Value;

            
        end

        % Value changed function: ycoordEditField
        function ycoordEditFieldValueChanged(app, event)

            app.time_stamp.Position(2) = app.Image.Position(2) + app.ycoordEditField.Value;

            
        end

        % Button pushed function: ColorButton
        function ColorButtonPushed(app, event)
            app.t_color = uisetcolor;
            if ~app.t_color
                app.time_stamp.FontColor = [1 1 1];
            else
                app.time_stamp.FontColor = app.t_color;
            end
            
        end

        % Value changed function: FrameRateEditField
        function FrameRateEditFieldValueChanged(app, event)
            app.MovielengthsEditField.Value = app.calling_app.processed_data.n_frames/app.FrameRateEditField.Value;
        end

        % Selection changed function: UnitsButtonGroup
        function UnitsButtonGroupSelectionChanged(app, event)
            update_timestamp_example(app)
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            %Change frame and time only if the current frame is not the
            %last one
            if app.CurrentframeEditField.Value < app.n_frames
                app.CurrentframeEditField.Value = app.CurrentframeEditField.Value + 1;
                app.CurrenttimesEditField.Value = (app.CurrentframeEditField.Value-1)/app.fps;
                
                %update displayed frame
                app.displayed_frame = app.calling_app.processed_data.image_stack{app.calling_app.display.normalisation}(:,:,app.CurrentframeEditField.Value);
                app.Image.ImageSource = ind2rgb(app.displayed_frame, app.calling_app.display.colour_map );
                
                %If timestamp needed, update for current frame
                if app.time_value == 1
                    update_timestamp_example(app)
                end
            end
            

        end

        % Button pushed function: PickframeButton
        function PickframeButtonPushed(app, event)
            app.pickframe_app = pick_frame(app);
            uiwait(app.pickframe_app.pickframeUIFigure);
            app.CurrentframeEditField.Value = app.display.current_frame;
            app.CurrenttimesEditField.Value = (app.CurrentframeEditField.Value-1)/app.fps;
            %update displayed frame
            app.displayed_frame = app.calling_app.processed_data.image_stack{app.calling_app.display.normalisation}(:,:,app.CurrentframeEditField.Value);
            app.Image.ImageSource = ind2rgb(app.displayed_frame, app.calling_app.display.colour_map );
            %If timestamp needed, update for current frame
            if app.time_value == 1
                update_timestamp_example(app)
            end
        
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            %Change frame and time only if the current frame is not the
            %first one
            if app.CurrentframeEditField.Value > 1
                app.CurrentframeEditField.Value = app.CurrentframeEditField.Value - 1;
                app.CurrenttimesEditField.Value = (app.CurrentframeEditField.Value-1)/app.fps;
                
                %update displayed frame
                app.displayed_frame = app.calling_app.processed_data.image_stack{app.calling_app.display.normalisation}(:,:,app.CurrentframeEditField.Value);
                app.Image.ImageSource = ind2rgb(app.displayed_frame, app.calling_app.display.colour_map );
                
                %If timestamp needed, update for current frame
                if app.time_value == 1
                    update_timestamp_example(app)
                end
            end
            waitfor
        end

        % Button pushed function: SaveframeButton
        function SaveframeButtonPushed(app, event)
            progress_bar = uiprogressdlg(app.timestamp_save_movieUIFigure,'Title','Saving','Indeterminate','on');
            % Save current shown frame with or without time-stamp
            cMap = app.calling_app.display.colour_map;
            if app.time_value==1
                position = [app.xcoordEditField.Value app.ycoordEditField.Value]-6;%-6 to correct for some difference in setting the coordinates with inserText. I dont know why..
                position = [position(1)  app.Image.Position(4)-position(2)]; %y axis goes downwards in insertText
                if app.units_s.Value == 1 %use seconds
                    time = sprintf('%.2f s', app.starttimesmsEditField.Value + app.CurrenttimesEditField.Value); 
                else
                    time = sprintf('%.0f ms', app.starttimesmsEditField.Value + 1000*app.CurrenttimesEditField.Value); 
                end
                image_without_time_stamp = ind2rgb(app.displayed_frame,cMap);
                image_with_timestamp = insertText(image_without_time_stamp , position , time,'AnchorPoint','LeftBottom','Font',app.time_stamp.FontName,'FontSize', app.time_stamp.FontSize,'TextColor',app.time_stamp.FontColor,'BoxOpacity',0);
                frame = image_with_timestamp;
            else
                frame = ind2rgb(app.displayed_frame,cMap);
            end
            filename = sprintf('frame-%d',app.CurrentframeEditField.Value);
            frame_file = file_exists(app,filename,'.jpg');
            h = figure;
            h.Visible = 'off';
            max_current_frame = max(frame(:));
            min_current_frame = min(frame(:));
            cmap_limits = [min_current_frame max_current_frame];
            imshow(frame./max_current_frame, cmap_limits);
            colormap(cMap)
            export_fig(h,frame_file);
            delete(h)
            close(progress_bar)
        end

        % Button pushed function: SaverangeButton
        function SaverangeButtonPushed(app, event)
            app.pickrange_app = pick_frame_range(app);
            uiwait(app.pickrange_app.pickframerangeUIFigure);
            
            if app.frame_range_canceled ==0
                progress_bar = uiprogressdlg(app.timestamp_save_movieUIFigure,'Title','Saving','Indeterminate','on');
                
                dir_name = dir_exists(app,sprintf('frame_range-%d-%d',app.frame_range(1),app.frame_range(2)));
                mkdir(dir_name)
                
                cMap = app.calling_app.display.colour_map;
                
                for k =app.frame_range(1):app.frame_spacing:app.frame_range(2)
                    k_frame = app.calling_app.processed_data.image_stack{app.calling_app.display.normalisation}(:,:,k);
                    if app.time_value==1
                        position = [app.xcoordEditField.Value app.ycoordEditField.Value]-6;%-6 to correct for some difference in setting the coordinates with inserText. I dont know why..
                        position = [position(1)  app.Image.Position(4)-position(2)]; %y axis goes downwards in insertText
                        if app.units_s.Value == 1 %use seconds
                            time = sprintf('%.2f s', app.starttimesmsEditField.Value+(k-1)/app.fps); 
                        else
                            time = sprintf('%.0f ms', app.starttimesmsEditField.Value + 1000*(k-1)/app.fps); 
                        end
                        
                        image_without_time_stamp = ind2rgb(k_frame,cMap);
                        image_with_timestamp = insertText(image_without_time_stamp , position , time,'AnchorPoint','LeftBottom','Font',app.time_stamp.FontName,'FontSize', app.time_stamp.FontSize,'TextColor',app.time_stamp.FontColor,'BoxOpacity',0);
                        frame = image_with_timestamp;
                    else
                        frame = ind2rgb(k_frame,cMap);
                    end
                    filename = sprintf('frame-%d.jpg',k);
                    frame_file = fullfile(dir_name, filename);
                    h = figure;
                    h.Visible = 'off';
                    max_current_frame = max(frame(:));
                    min_current_frame = min(frame(:));
                    cmap_limits = [min_current_frame max_current_frame];
                    imshow(frame./max_current_frame, cmap_limits);
                    colormap(cMap)
                    export_fig(h,frame_file);
                    delete(h)
                end
            end
            close(progress_bar)
        end

        % Button pushed function: DoubleimagesizeButton
        function double_image_size(app, event)
            % Need to define stack as local property variable when first
            % called so can manipulate
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.timestamp_save_movieUIFigure = uifigure('Visible', 'off');
            app.timestamp_save_movieUIFigure.Name = 'Image2Video';
            app.Image = uiimage(app.timestamp_save_movieUIFigure);
            app.time_stamp = uilabel(app.timestamp_save_movieUIFigure);
            app.time_stamp.Text = '';
            app.TabGroup = uitabgroup(app.timestamp_save_movieUIFigure);
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.GridLayout2 = uigridlayout(app.ParametersTab);
            app.TimestampPanel = uipanel(app.GridLayout2);
            app.TimestampPanel.Title = 'Timestamp';
            app.GridLayout3 = uigridlayout(app.TimestampPanel);
            app.UnitsButtonGroup = uibuttongroup(app.GridLayout3);
            app.UnitsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UnitsButtonGroupSelectionChanged, true);
            app.UnitsButtonGroup.Title = 'Units';
            app.units_s = uiradiobutton(app.UnitsButtonGroup);
            app.units_s.Text = 's';
            app.units_s.Value = true;
            app.units_ms = uiradiobutton(app.UnitsButtonGroup);
            app.units_ms.Text = 'ms';
            app.bottomcenterButton = uibutton(app.GridLayout3, 'push');
            app.bottomcenterButton.ButtonPushedFcn = createCallbackFcn(app, @bottomcenterButtonPushed, true);
            app.bottomcenterButton.Text = 'bottom center';
            app.topcenterButton = uibutton(app.GridLayout3, 'push');
            app.topcenterButton.ButtonPushedFcn = createCallbackFcn(app, @topcenterButtonPushed, true);
            app.topcenterButton.Text = 'top center';
            app.ColorButton = uibutton(app.GridLayout3, 'push');
            app.ColorButton.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPushed, true);
            app.ColorButton.Text = 'Color';
            app.topleftButton = uibutton(app.GridLayout3, 'push');
            app.topleftButton.ButtonPushedFcn = createCallbackFcn(app, @topleftButtonPushed, true);
            app.topleftButton.Text = 'top left';
            app.FontButton = uibutton(app.GridLayout3, 'push');
            app.FontButton.ButtonPushedFcn = createCallbackFcn(app, @FontButtonPushed, true);
            app.FontButton.Text = 'Font';
            app.SetTimestampCheckBox = uicheckbox(app.GridLayout3);
            app.SetTimestampCheckBox.ValueChangedFcn = createCallbackFcn(app, @SetTimestampCheckBoxValueChanged, true);
            app.SetTimestampCheckBox.Text = ' Set Timestamp';
            app.SetTimestampCheckBox.Value = true;
            app.bottomrightButton = uibutton(app.GridLayout3, 'push');
            app.bottomrightButton.ButtonPushedFcn = createCallbackFcn(app, @bottomrightButtonPushed, true);
            app.bottomrightButton.Text = 'bottom right';
            app.bottomleftButton = uibutton(app.GridLayout3, 'push');
            app.bottomleftButton.ButtonPushedFcn = createCallbackFcn(app, @bottomleftButtonPushed, true);
            app.bottomleftButton.Text = 'bottom left';
            app.toprightButton = uibutton(app.GridLayout3, 'push');
            app.toprightButton.ButtonPushedFcn = createCallbackFcn(app, @toprightButtonPushed, true);
            app.toprightButton.Text = 'top right';
            app.xcoordEditField = uieditfield(app.GridLayout3, 'numeric');
            app.xcoordEditField.ValueChangedFcn = createCallbackFcn(app, @xcoordEditFieldValueChanged, true);
            app.xcoordLabel = uilabel(app.GridLayout3);
            app.xcoordLabel.Text = 'x-coord:';
            app.ycoordEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ycoordEditField.ValueChangedFcn = createCallbackFcn(app, @ycoordEditFieldValueChanged, true);
            app.ycoordLabel = uilabel(app.GridLayout3);
            app.ycoordLabel.Text = 'y-coord:';
            app.timeincrementmsEditField = uieditfield(app.GridLayout3, 'numeric');
            app.timeincrementmsEditField.Editable = 'off';
            app.timeincrementmsEditField.Value = 1;
            app.timeincrementmsEditFieldLabel = uilabel(app.GridLayout3);
            app.timeincrementmsEditFieldLabel.Text = 'timeincrement (ms): ';
            app.starttimesmsEditField = uieditfield(app.GridLayout3, 'numeric');
            app.starttimesmsEditField.ValueDisplayFormat = '%.2f';
            app.starttimesmsEditFieldLabel = uilabel(app.GridLayout3);
            app.starttimesmsEditFieldLabel.Text = 'start time [s/ms]:';
            app.CloseButton = uibutton(app.GridLayout2, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.MoviePanel = uipanel(app.GridLayout2);
            app.MoviePanel.Title = 'Movie';
            app.GridLayout = uigridlayout(app.MoviePanel);
            app.FrameRateEditFieldLabel = uilabel(app.GridLayout);
            app.FrameRateEditFieldLabel.Text = 'Frame Rate';
            app.FrameRateEditField = uieditfield(app.GridLayout, 'numeric');
            app.FrameRateEditField.ValueChangedFcn = createCallbackFcn(app, @FrameRateEditFieldValueChanged, true);
            app.FrameRateEditField.Value = 30;
            app.QualityLabel = uilabel(app.GridLayout);
            app.QualityLabel.Text = 'Quality:';
            app.QualitySlider = uislider(app.GridLayout);
            app.QualitySlider.ValueChangingFcn = createCallbackFcn(app, @QualitySliderValueChanging, true);
            app.QualitySlider.Value = 100;
            app.Label_quality = uilabel(app.GridLayout);
            app.Label_quality.Text = '100 %';
            app.SaveMovieButton = uibutton(app.GridLayout, 'push');
            app.SaveMovieButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMovieButtonPushed, true);
            app.SaveMovieButton.Text = 'Save Movie';
            app.MovielengthsEditFieldLabel = uilabel(app.GridLayout);
            app.MovielengthsEditFieldLabel.Text = 'Movie length (s)';
            app.MovielengthsEditField = uieditfield(app.GridLayout, 'numeric');
            app.MovielengthsEditField.Editable = 'off';
            app.FramePanel = uipanel(app.GridLayout2);
            app.FramePanel.Title = 'Frame';
            app.GridLayout4 = uigridlayout(app.FramePanel);
            app.PreviousButton = uibutton(app.GridLayout4, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = '◀ Previous';
            app.NextButton = uibutton(app.GridLayout4, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next ▶';
            app.PickframeButton = uibutton(app.GridLayout4, 'push');
            app.PickframeButton.ButtonPushedFcn = createCallbackFcn(app, @PickframeButtonPushed, true);
            app.PickframeButton.Text = 'Pick frame';
            app.SaveframeButton = uibutton(app.GridLayout4, 'push');
            app.SaveframeButton.ButtonPushedFcn = createCallbackFcn(app, @SaveframeButtonPushed, true);
            app.SaveframeButton.Text = 'Save frame';
            app.SaverangeButton = uibutton(app.GridLayout4, 'push');
            app.SaverangeButton.ButtonPushedFcn = createCallbackFcn(app, @SaverangeButtonPushed, true);
            app.SaverangeButton.Text = 'Save range';
            app.CurrentframeLabel = uilabel(app.GridLayout4);
            app.CurrentframeLabel.Text = 'Current frame:';
            app.CurrentframeEditField = uieditfield(app.GridLayout4, 'numeric');
            app.CurrentframeEditField.Editable = 'off';
            app.CurrentframeEditField.Value = 1;
            app.CurrenttimesEditFieldLabel = uilabel(app.GridLayout4);
            app.CurrenttimesEditFieldLabel.Text = 'Current time (s):';
            app.CurrenttimesEditField = uieditfield(app.GridLayout4, 'numeric');
            app.CurrenttimesEditField.Editable = 'off';
            app.DoubleimagesizeButton = uibutton(app.GridLayout2, 'push');
            app.DoubleimagesizeButton.ButtonPushedFcn = createCallbackFcn(app, @double_image_size, true);
            app.DoubleimagesizeButton.Text = 'Double image size';
            % Show the figure after all components are created
            app.timestamp_save_movieUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = timestamp_save_movie(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.timestamp_save_movieUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.timestamp_save_movieUIFigure)
        end
    end
end